classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        toolGrid               matlab.ui.container.GridLayout
        tool_RFDataHubButton   matlab.ui.control.Button
        tool_FiscalizaButton   matlab.ui.control.Button
        tool_PythonButton      matlab.ui.control.Button
        tool_Separator         matlab.ui.control.Image
        tool_RefreshButton     matlab.ui.control.Button
        docGrid                matlab.ui.container.GridLayout
        Tab2_Panel             matlab.ui.container.Panel
        Tab2_Grid              matlab.ui.container.GridLayout
        gpuType                matlab.ui.control.DropDown
        gpuTypeLabel           matlab.ui.control.Label
        imgPanel               matlab.ui.container.Panel
        imgGrid                matlab.ui.container.GridLayout
        imgResolution          matlab.ui.control.DropDown
        imgResolutionLabel     matlab.ui.control.Label
        imgFormat              matlab.ui.control.DropDown
        imgFormatLabel         matlab.ui.control.Label
        imgVisibility          matlab.ui.control.DropDown
        imgVisibilityLabel     matlab.ui.control.Label
        imgPanelTitle          matlab.ui.control.Label
        mergePanel             matlab.ui.container.Panel
        mergeGrid              matlab.ui.container.GridLayout
        mergeGPSValue          matlab.ui.control.Spinner
        mergeGPSLabel          matlab.ui.control.Label
        mergeAntennaCheckbox   matlab.ui.control.CheckBox
        mergeDataTypeCheckbox  matlab.ui.control.CheckBox
        mergeLabel             matlab.ui.control.Label
        mergeImageRefresh      matlab.ui.control.Image
        mergePanelTitle        matlab.ui.control.Label
        Tab2_GridTitle         matlab.ui.container.GridLayout
        Tab2_Image             matlab.ui.control.Image
        Tab2_Title             matlab.ui.control.Label
        Tab1_Panel             matlab.ui.container.Panel
        Tab1_Grid              matlab.ui.container.GridLayout
        VersionInfo            matlab.ui.control.HTML
        Tab1_GridTitle         matlab.ui.container.GridLayout
        Tab1_Image             matlab.ui.control.Image
        Tab1_Title             matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        stableVersion
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function Layout(app)
            % PAINEL "VERSÃO"                
            verMetaData(1).group = 'SISTEMA OPERACIONAL';
            verMetaData(1).value = struct('Version', app.CallingApp.General.AppVersion.OS);
            
            verMetaData(2).group = 'MATLAB';
            verMetaData(2).value = app.CallingApp.General.AppVersion.Matlab;
            
            verMetaData(3).group = 'APPANALISE';
            verMetaData(3).value = app.CallingApp.General.AppVersion.(class.Constants.appName);
            
            verMetaData(4).group = 'UNIDADE GRÁFICA';
            verMetaData(4).value = app.CallingApp.General.AppVersion.OpenGL;
            
            verMetaData(5).group = 'PATH';
            verMetaData(5).value = struct('RootFolder', app.RootFolder, ...
                                          'userPath',   app.CallingApp.General.fileFolder.userPath);
            if isdeployed
                verMetaData(5).value.deployedPath = ctfroot;
            end

            verMetaData(6).group = 'PYTHON';
            verMetaData(6).value = struct('Version', app.CallingApp.General.AppVersion.Python);

            verMetaData(7).group = 'FISCALIZA';
            verMetaData(7).value = struct('Version', app.CallingApp.General.AppVersion.fiscaliza);

            verMetaData(8).group = 'RFDATAHUB';
            verMetaData(8).value = app.CallingApp.General.AppVersion.RFDataHub;    

            app.VersionInfo.HTMLSource = textFormatGUI.struct2PrettyPrintList(verMetaData);


            % PAINEL "CONFIGURAÇÕES GERAIS": "Junção de fluxos de dados"
            switch app.CallingApp.General.Merge.DataType
                case 'keep';   app.mergeDataTypeCheckbox.Value = 0;
                case 'remove'; app.mergeDataTypeCheckbox.Value = 1;
            end

            switch app.CallingApp.General.Merge.Antenna
                case 'keep';   app.mergeAntennaCheckbox.Value = 0;
                case 'remove'; app.mergeAntennaCheckbox.Value = 1;
            end

            app.mergeGPSValue.Value = app.CallingApp.General.Merge.Distance;


            % PAINEL "CONFIGURAÇÕES GERAIS": "Imagem"
            app.imgVisibility.Value = app.CallingApp.General.Image.Visibility;
            app.imgFormat.Value     = app.CallingApp.General.Image.Format;
            app.imgResolution.Value = string(app.CallingApp.General.Image.Resolution);


            % PAINEL "CONFIGURAÇÕES GERAIS": "Unidade gráfica"
            graphRender = opengl('data');
            switch graphRender.HardwareSupportLevel
                case 'basic'; graphRenderSupport = 'hardwarebasic';
                case 'full';  graphRenderSupport = 'hardware';
                case 'none';  graphRenderSupport = 'software';
                otherwise;    graphRenderSupport = graphRender.HardwareSupportLevel; % "driverissue"
            end

            if ~ismember(graphRenderSupport, app.gpuType.Items)
                app.gpuType.Items{end+1} = graphRenderSupport;
            end

            app.gpuType.Value = graphRenderSupport;
        end


        %-----------------------------------------------------------------%
        function SaveFile(app)
            % Salva uma nova versão do arquivo "GeneralSettings.json".
            appUtil.generalSettingsSave(class.Constants.appName, app.RootFolder, app.CallingApp.General, {'AppVersion', 'Models', 'Report'})
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % A razão de ser deste app é possibilitar visualização/edição 
            % de algumas das informações do arquivo "GeneralSettings.json",
            % além de possibilitar o mapeamento do PYTHON, a atualização da
            % lib FISCALIZA e da ETL RFDATAHUB.
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            appUtil.winPosition(app.UIFigure)

            if isempty(app.CallingApp.General.AppVersion.fiscaliza)
                app.CallingApp.General.AppVersion = fcn.startup_Versions("Full", app.RootFolder);
            end

            Layout(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWin5 = [];
            delete(app)
            
        end

        % Button pushed function: tool_RefreshButton
        function tool_RefreshButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');
            try
                appName           = (class.Constants.appName);

                presentVersion    = struct(appName,     app.CallingApp.General.AppVersion.(appName).Version, ...
                                           'fiscaliza', app.CallingApp.General.AppVersion.fiscaliza,         ...
                                           'RFDataHub', app.CallingApp.General.AppVersion.RFDataHub); 
                
                [versionLink, RFDataHubLink] = fcn.PublicLinks(app.RootFolder);
                generalVersions  = webread(versionLink,           weboptions("ContentType", "json"));
                rfdatahubVersion = webread(RFDataHubLink.Release, weboptions("ContentType", "json"));


                app.stableVersion = struct(appName,     generalVersions.(appName).Version, ...
                                           'fiscaliza', generalVersions.fiscaliza.Version, ...
                                           'RFDataHub', rfdatahubVersion.rfdatahub);
                
                if isequal(presentVersion, app.stableVersion)
                    msg = 'O appAnalise e os seus módulos - fiscaliza e RFDataHub - estão atualizados.';
                    
                else
                    StableModule    = {};
                    nonStableModule = {};
                    if strcmp(presentVersion.(appName), app.stableVersion.(appName));   StableModule(end+1)    = {'appAnalise'};
                    else;                                                               nonStableModule(end+1) = {'appAnalise'};
                    end

                    if strcmp(presentVersion.fiscaliza,  app.stableVersion.fiscaliza);  StableModule(end+1)    = {'fiscaliza'};
                    else;                                                               nonStableModule(end+1) = {'fiscaliza'}; app.tool_FiscalizaButton.Enable = 1;
                    end

                    if isequal(presentVersion.RFDataHub, app.stableVersion.RFDataHub);  StableModule(end+1)    = {'RFDataHub'};
                    else;                                                               nonStableModule(end+1) = {'RFDataHub'}; app.tool_RFDataHubButton.Enable = 1;
                    end
                    
                    if ~isempty(StableModule) && ~isempty(nonStableModule); msg = sprintf('Módulo(s) atualizado(s):\n• %s\n\nMódulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(StableModule, ', '), strjoin(nonStableModule, ', '));
                    else;                                                   msg = sprintf('Módulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(nonStableModule, ', '));
                    end

                    msg = sprintf('Arquivo de versões: %s\n\n%s', jsonencode(app.stableVersion), msg);
                end
                
            catch ME
                msg = ME.message;                
            end
            appUtil.modalWindow(app.UIFigure, 'warning', msg);
            delete(d)

        end

        % Button pushed function: tool_PythonButton
        function tool_PythonButtonButtonPushed(app, event)
            
            Python = pyenv;
            
            [fileName, filePath] = uigetfile({'python*.exe', 'python*.exe'}, '', Python.Executable);
            figure(app.UIFigure)

            if isequal(fileName, 0)
                return
            end
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');
            try
                pyPath = fullfile(filePath, fileName);
                pyenv('Version', pyPath);
                
                app.CallingApp.General.fileFolder.pythonPath = pyPath;
                app.CallingApp.General.AppVersion = fcn.startup_Versions('Full', app.RootFolder);

                Layout(app)
                SaveFile(app)

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
            delete(d)

        end

        % Button pushed function: tool_FiscalizaButton
        function tool_FiscalizaButtonButtonPushed(app, event)
            
            if strcmp(app.CallingApp.General.AppVersion.fiscaliza,  app.stableVersion.fiscaliza)
                app.tool_FiscalizaButton.Enable = 0;
                appUtil.modalWindow(app.UIFigure, 'warning', 'Módulo fiscaliza já atualizado!');
                return
            end

            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');   
            try
                Python = pyenv;
                system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), sprintf('install fiscaliza==%s', app.stableVersion.fiscaliza)));

                app.CallingApp.General.AppVersion.fiscaliza = app.stableVersion.fiscaliza;
                app.tool_FiscalizaButton.Enable = 0;

                Layout(app)

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
            delete(d)

        end

        % Button pushed function: tool_RFDataHubButton
        function tool_RFDataHubButtonPushed(app, event)
            
            if strcmp(app.CallingApp.General.AppVersion.RFDataHub,  app.stableVersion.RFDataHub)
                app.tool_RFDataHubButton.Enable = 0;
                appUtil.modalWindow(app.UIFigure, 'warning', 'Módulo RFDataHub já atualizado!');
                return
            end

            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento... esse processo pode demorar alguns minutos!</font>');
            try
                [~, RFDataHubLink] = fcn.PublicLinks(app.RootFolder);
                websave(fullfile(app.RootFolder, 'Temp', 'estacoes.parquet.gzip'), RFDataHubLink.Table);
                websave(fullfile(app.RootFolder, 'Temp', 'log.parquet.gzip'),      RFDataHubLink.Log);
                websave(fullfile(app.RootFolder, 'Temp', 'Release.json'),          RFDataHubLink.Release);

                if isfile(fullfile(app.RootFolder, 'DataBase', 'RFDataHub_old.mat'))
                    delete(fullfile(app.RootFolder, 'DataBase', 'RFDataHub_old.mat'))
                end

                while true
                    status = system(sprintf('rename "%s" "%s"', fullfile(app.RootFolder, 'DataBase', 'RFDataHub.mat'), 'RFDataHub_old.mat'));
                    if ~status
                        break
                    end
                    pause(.1)
                end

                clear global RFDataHub
                clear global RFDataHubLog
                clear global RFDataHub_info
                class.RFDataHub.read(app.RootFolder)
                
                global RFDataHub_info
                app.CallingApp.General.AppVersion.RFDataHub = RFDataHub_info;
                app.tool_RFDataHubButton.Enable = 0;

                Layout(app)
                
            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
            delete(d)

        end

        % Callback function: gpuType, imgFormat, imgResolution, 
        % ...and 5 other components
        function controlValueChanged(app, event)
            
            switch event.Source
                %---------------------------------------------------------%
                case {app.mergeDataTypeCheckbox, app.mergeAntennaCheckbox, app.mergeGPSValue}                    
                    switch app.mergeDataTypeCheckbox.Value
                        case 0; DataTypeStatus = 'keep';
                        case 1; DataTypeStatus = 'remove';
                    end
                    
                    switch app.mergeAntennaCheckbox.Value
                        case 0; AntennaStatus  = 'keep';
                        case 1; AntennaStatus  = 'remove';
                    end
                                
                    app.CallingApp.General.Merge.DataType = DataTypeStatus;
                    app.CallingApp.General.Merge.Antenna  = AntennaStatus;
                    app.CallingApp.General.Merge.Distance = app.mergeGPSValue.Value;

                %---------------------------------------------------------%
                case {app.imgVisibility, app.imgFormat, app.imgResolution}
                    app.CallingApp.General.Image = struct('Visibility', app.imgVisibility.Value, ...
                                                          'Format',     app.imgFormat.Value,     ...
                                                          'Resolution', str2double(app.imgResolution.Value));

                %---------------------------------------------------------%
                case app.gpuType
                    if ismember(app.gpuType.Value, {'software', 'hardware', 'hardwarebasic'})
                        eval(sprintf('opengl %s', app.gpuType.Value))

                        graphRender = opengl('data');
                        
                        app.CallingApp.General.openGL         = app.gpuType.Value;
                        app.CallingApp.General.AppVersion.OpenGL = rmfield(graphRender, {'MaxTextureSize', 'Visual', 'SupportsGraphicsSmoothing', 'SupportsDepthPeelTransparency', 'SupportsAlignVertexCenters', 'Extensions', 'MaxFrameBufferSize'});
                    end


                %---------------------------------------------------------%
                case app.mergeImageRefresh
                    app.CallingApp.General.Merge = struct('DataType', 'keep', ...
                                                          'Antenna',  'keep',  ...
                                                          'Distance', 100);

                    app.CallingApp.General.Image = struct('Visibility', 'on',   ...
                                                          'Format',     'jpeg', ...
                                                          'Resolution', 120);

            end
            
            Layout(app)
            SaveFile(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.docGrid = uigridlayout(app.GridLayout);
            app.Tab1_GridTitle = uigridlayout(app.docGrid);
            app.Tab1_GridTitle.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_GridTitle);
            app.Tab1_Title.Text = 'VERSÃO';
            app.Tab1_Image = uiimage(app.Tab1_GridTitle);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab1_Panel = uipanel(app.docGrid);
            app.Tab1_Grid = uigridlayout(app.Tab1_Panel);
            app.VersionInfo = uihtml(app.Tab1_Grid);
            app.VersionInfo.HTMLSource = ' ';
            app.Tab2_GridTitle = uigridlayout(app.docGrid);
            app.Tab2_GridTitle.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_GridTitle);
            app.Tab2_Title.Text = 'CONFIGURAÇÕES GERAIS';
            app.Tab2_Image = uiimage(app.Tab2_GridTitle);
            app.Tab2_Image.ImageSource = 'LT_Dots2.png';
            app.Tab2_Panel = uipanel(app.docGrid);
            app.Tab2_Grid = uigridlayout(app.Tab2_Panel);
            app.mergePanelTitle = uilabel(app.Tab2_Grid);
            app.mergePanelTitle.Text = 'Junção de fluxos de dados';
            app.mergeImageRefresh = uiimage(app.Tab2_Grid);
            app.mergeImageRefresh.ImageClickedFcn = createCallbackFcn(app, @controlValueChanged, true);
            app.mergeImageRefresh.ImageSource = 'LT_refresh.png';
            app.mergePanel = uipanel(app.Tab2_Grid);
            app.mergeGrid = uigridlayout(app.mergePanel);
            app.mergeLabel = uilabel(app.mergeGrid);
            app.mergeLabel.Text = 'Metadados a ignorar:';
            app.mergeDataTypeCheckbox = uicheckbox(app.mergeGrid);
            app.mergeDataTypeCheckbox.ValueChangedFcn = createCallbackFcn(app, @controlValueChanged, true);
            app.mergeDataTypeCheckbox.Text = 'DataType';
            app.mergeAntennaCheckbox = uicheckbox(app.mergeGrid);
            app.mergeAntennaCheckbox.ValueChangedFcn = createCallbackFcn(app, @controlValueChanged, true);
            app.mergeAntennaCheckbox.Text = 'Antenna';
            app.mergeGPSLabel = uilabel(app.mergeGrid);
            app.mergeGPSLabel.Text = 'Distância máxima na mesclagem dos fluxos (metros):';
            app.mergeGPSValue = uispinner(app.mergeGrid);
            app.mergeGPSValue.Step = 50;
            app.mergeGPSValue.Limits = [50 Inf];
            app.mergeGPSValue.RoundFractionalValues = 'on';
            app.mergeGPSValue.ValueDisplayFormat = '%.0f';
            app.mergeGPSValue.ValueChangedFcn = createCallbackFcn(app, @controlValueChanged, true);
            app.mergeGPSValue.Value = 100;
            app.imgPanelTitle = uilabel(app.Tab2_Grid);
            app.imgPanelTitle.Text = 'Imagem';
            app.imgPanel = uipanel(app.Tab2_Grid);
            app.imgGrid = uigridlayout(app.imgPanel);
            app.imgVisibilityLabel = uilabel(app.imgGrid);
            app.imgVisibilityLabel.Text = 'Visibilidade:';
            app.imgVisibility = uidropdown(app.imgGrid);
            app.imgVisibility.Items = {'on', 'off'};
            app.imgVisibility.ValueChangedFcn = createCallbackFcn(app, @controlValueChanged, true);
            app.imgVisibility.Value = 'on';
            app.imgFormatLabel = uilabel(app.imgGrid);
            app.imgFormatLabel.Text = 'Formato:';
            app.imgFormat = uidropdown(app.imgGrid);
            app.imgFormat.Items = {'jpeg', 'png'};
            app.imgFormat.ValueChangedFcn = createCallbackFcn(app, @controlValueChanged, true);
            app.imgFormat.Value = 'jpeg';
            app.imgResolutionLabel = uilabel(app.imgGrid);
            app.imgResolutionLabel.Text = 'Resolução (dpi):';
            app.imgResolution = uidropdown(app.imgGrid);
            app.imgResolution.Items = {'100', '120', '150', '200'};
            app.imgResolution.ValueChangedFcn = createCallbackFcn(app, @controlValueChanged, true);
            app.imgResolution.Value = '120';
            app.gpuTypeLabel = uilabel(app.Tab2_Grid);
            app.gpuTypeLabel.Text = 'Unidade gráfica:';
            app.gpuType = uidropdown(app.Tab2_Grid);
            app.gpuType.Items = {'hardwarebasic', 'hardware', 'software'};
            app.gpuType.ValueChangedFcn = createCallbackFcn(app, @controlValueChanged, true);
            app.gpuType.Value = 'hardwarebasic';
            app.toolGrid = uigridlayout(app.GridLayout);
            app.tool_RefreshButton = uibutton(app.toolGrid, 'push');
            app.tool_RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @tool_RefreshButtonPushed, true);
            app.tool_RefreshButton.Icon = 'LT_refresh.png';
            app.tool_RefreshButton.Tooltip = {''};
            app.tool_RefreshButton.Text = '';
            app.tool_Separator = uiimage(app.toolGrid);
            app.tool_Separator.Enable = 'off';
            app.tool_Separator.ImageSource = 'LT_LineV.png';
            app.tool_PythonButton = uibutton(app.toolGrid, 'push');
            app.tool_PythonButton.ButtonPushedFcn = createCallbackFcn(app, @tool_PythonButtonButtonPushed, true);
            app.tool_PythonButton.Icon = 'LT_Python.png';
            app.tool_PythonButton.Tooltip = {''};
            app.tool_PythonButton.Text = '';
            app.tool_FiscalizaButton = uibutton(app.toolGrid, 'push');
            app.tool_FiscalizaButton.ButtonPushedFcn = createCallbackFcn(app, @tool_FiscalizaButtonButtonPushed, true);
            app.tool_FiscalizaButton.Icon = 'LT_redmine.png';
            app.tool_FiscalizaButton.Enable = 'off';
            app.tool_FiscalizaButton.Tooltip = {''};
            app.tool_FiscalizaButton.Text = '';
            app.tool_RFDataHubButton = uibutton(app.toolGrid, 'push');
            app.tool_RFDataHubButton.ButtonPushedFcn = createCallbackFcn(app, @tool_RFDataHubButtonPushed, true);
            app.tool_RFDataHubButton.Icon = 'LT_mosaic.png';
            app.tool_RFDataHubButton.Enable = 'off';
            app.tool_RFDataHubButton.Tooltip = {''};
            app.tool_RFDataHubButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end