classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid                           matlab.ui.container.GridLayout
        axesToolbarGrid                matlab.ui.container.GridLayout
        geoAxesTool_PlotSize           matlab.ui.control.Slider
        geoAxesTool_PlotType           matlab.ui.control.Image
        geoAxesTool_RegionZoom         matlab.ui.control.Image
        geoAxesTool_RestoreView        matlab.ui.control.Image
        axesToolbarGrid_2              matlab.ui.container.GridLayout
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        geoAxesTool_RegionZoom_2       matlab.ui.control.Image
        geoAxesTool_RestoreView_2      matlab.ui.control.Image
        ControlTabGrid                 matlab.ui.container.GridLayout
        menu_MainGrid                  matlab.ui.container.GridLayout
        menu_Button5Grid               matlab.ui.container.GridLayout
        menu_Button5Icon               matlab.ui.control.Image
        menu_Button5Label              matlab.ui.control.Label
        menu_Button4Grid               matlab.ui.container.GridLayout
        menu_Button4Icon               matlab.ui.control.Image
        menu_Button4Label              matlab.ui.control.Label
        menu_Button3Grid               matlab.ui.container.GridLayout
        menu_Button3Icon               matlab.ui.control.Image
        menu_Button3Label              matlab.ui.control.Label
        menu_Button2Grid               matlab.ui.container.GridLayout
        menu_Button2Icon               matlab.ui.control.Image
        menu_Button2Label              matlab.ui.control.Label
        menu_Button1Grid               matlab.ui.container.GridLayout
        menu_Button1Icon               matlab.ui.control.Image
        menu_Button1Label              matlab.ui.control.Label
        menuUnderline                  matlab.ui.control.Image
        ControlTabGroup                matlab.ui.container.TabGroup
        Tab1_Emission                  matlab.ui.container.Tab
        Tab1_Panel                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        emissionMetaData               matlab.ui.control.HTML
        filter_Customization           matlab.ui.control.CheckBox
        emissionList                   matlab.ui.control.DropDown
        emissionListGrid               matlab.ui.container.GridLayout
        emissionListRefresh            matlab.ui.control.Image
        emissionListLabel              matlab.ui.control.Label
        Tab2_Filter                    matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        filter_addImage                matlab.ui.control.Image
        filter_Tree                    matlab.ui.container.Tree
        filter_roiOptions              matlab.ui.container.Tree
        levelFilterNode                matlab.ui.container.TreeNode
        levelFilter_thr                matlab.ui.container.TreeNode
        geoFilterNode                  matlab.ui.container.TreeNode
        geoFilter_Circle               matlab.ui.container.TreeNode
        geoFilter_Rectangle            matlab.ui.container.TreeNode
        geoFilter_Polygon              matlab.ui.container.TreeNode
        filter_TreeLabel               matlab.ui.control.Label
        Tab3_Points                    matlab.ui.container.Tab
        Tab2_Panel                     matlab.ui.container.GridLayout
        points_Tree                    matlab.ui.container.CheckBoxTree
        points_addImage                matlab.ui.control.Image
        points_addValuePanel           matlab.ui.container.Panel
        points_addValueGrid            matlab.ui.container.GridLayout
        points_addSubtype2Distance     matlab.ui.control.Spinner
        points_addSubtype2DistanceLabel  matlab.ui.control.Label
        points_addSubtype2NPeaks       matlab.ui.control.Spinner
        points_addSubtype2NPeaksLabel  matlab.ui.control.Label
        points_addSubtype2DropDown     matlab.ui.control.DropDown
        points_addSubtype2Label        matlab.ui.control.Label
        points_addSubtype1Distance     matlab.ui.control.NumericEditField
        points_addSubtype1DistanceLabel  matlab.ui.control.Label
        points_addSubtype1TextArea     matlab.ui.control.TextArea
        points_addSubtype1DropDown     matlab.ui.control.DropDown
        points_addSubtype1Label        matlab.ui.control.Label
        points_addTypePanel            matlab.ui.container.ButtonGroup
        points_addFindPeaks            matlab.ui.control.RadioButton
        points_addRFDataHub            matlab.ui.control.RadioButton
        points_TreeLabel               matlab.ui.control.Label
        Tab4_DataBinning               matlab.ui.container.Tab
        Tab3_Panel                     matlab.ui.container.GridLayout
        binTable_Info                  matlab.ui.control.Label
        binTable_UITable               matlab.ui.control.Table
        binTable_Panel                 matlab.ui.container.Panel
        binTable_Grid1                 matlab.ui.container.GridLayout
        binTable_Fcn                   matlab.ui.control.DropDown
        binTable_FcnLabel              matlab.ui.control.Label
        binTable_Value                 matlab.ui.control.Spinner
        binTable_ValueLabel            matlab.ui.control.Label
        binTable_Type                  matlab.ui.control.DropDown
        binTable_TypeLabel             matlab.ui.control.Label
        binTable_Label                 matlab.ui.control.Label
        Tab5_Config                    matlab.ui.container.Tab
        Tab4_Panel                     matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        Panel2                         matlab.ui.container.Panel
        play_Waterfall_Colormap        matlab.ui.control.DropDown
        play_Waterfall_ColormapLabel   matlab.ui.control.Label
        play_Waterfall_Colorbar        matlab.ui.control.DropDown
        play_Waterfall_ColorbarLabel   matlab.ui.control.Label
        play_Waterfall_FcnLabel        matlab.ui.control.Label
        play_Waterfall_Fcn             matlab.ui.control.DropDown
        play_MinPlotTimeLabel          matlab.ui.control.Label
        play_MinPlotTime               matlab.ui.control.Spinner
        layout_cartesianPlotPanel      matlab.ui.container.Panel
        layout_cartesianPlotGrid       matlab.ui.container.GridLayout
        layout_cartesianPlotSubPanel   matlab.ui.container.Panel
        layout_cartesianPlotSubGrid    matlab.ui.container.GridLayout
        layout_Smoothed_Panel          matlab.ui.container.Panel
        layout_Smoothed_Grid           matlab.ui.container.GridLayout
        layout_Smoothed_Factor         matlab.ui.control.Spinner
        layout_Smoothed_FactorLabel    matlab.ui.control.Label
        layout_Smoothed_Type           matlab.ui.control.DropDown
        layout_Smoothed_TypeLabel      matlab.ui.control.Label
        layout_chPowerSource_smoothed  matlab.ui.control.CheckBox
        layout_chPowerSource_binTable  matlab.ui.control.CheckBox
        layout_chPowerSource_rawTable  matlab.ui.control.CheckBox
        layout_cartesianPlotLabel      matlab.ui.control.Label
        layout_cartesianPlotTitle      matlab.ui.control.Label
        layout_mapPlotPanel            matlab.ui.container.Panel
        layout_mapPlotGrid             matlab.ui.container.GridLayout
        layout_mapPlotLayoutPanel      matlab.ui.container.Panel
        layout_mapPlotLayoutGrid       matlab.ui.container.GridLayout
        layout_Colormap                matlab.ui.control.DropDown
        layout_ColormapLabel           matlab.ui.control.Label
        layout_points_Panel            matlab.ui.container.Panel
        layout_points_Grid             matlab.ui.container.GridLayout
        layout_points_Size             matlab.ui.control.Slider
        layout_points_Color            matlab.ui.control.Button
        layout_points_LineStyle        matlab.ui.control.DropDown
        layout_points_Label            matlab.ui.control.Label
        layout_Car_Panel               matlab.ui.container.Panel
        layout_Car_Grid                matlab.ui.container.GridLayout
        layout_Car_Size                matlab.ui.control.Slider
        layout_Car_Color               matlab.ui.control.Button
        layout_Car_LineStyle           matlab.ui.control.DropDown
        layout_Car_Label               matlab.ui.control.Label
        layout_route_Panel             matlab.ui.container.Panel
        layout_route_Grid              matlab.ui.container.GridLayout
        layout_route_Size              matlab.ui.control.Slider
        layout_route_Color2            matlab.ui.control.Button
        layout_route_Color1            matlab.ui.control.Button
        layout_route_LineStyle         matlab.ui.control.DropDown
        layout_route_Label             matlab.ui.control.Label
        layout_mapPlotLayoutLabel      matlab.ui.control.Label
        layout_mapPlotSourcePanel      matlab.ui.container.ButtonGroup
        layout_mapPlotSource_binData   matlab.ui.control.RadioButton
        layout_mapPlotSource_rawData   matlab.ui.control.RadioButton
        layout_mapPlotSourceLabel      matlab.ui.control.Label
        layout_mapPlotTitle            matlab.ui.control.Label
        toolGrid                       matlab.ui.container.GridLayout
        tool_ExportButton              matlab.ui.control.Image
        tool_TimestampSlider           matlab.ui.control.Slider
        tool_LoopControl               matlab.ui.control.Image
        tool_Play                      matlab.ui.control.Image
        tool_TimestampLabel            matlab.ui.control.Label
        tool_RefreshScreenSize         matlab.ui.control.Image
        jsBackDoor                     matlab.ui.control.HTML
        tool_resetButton               matlab.ui.control.Image
        tool_ControlPanelVisibility    matlab.ui.control.Image
        plotPanel                      matlab.ui.container.Panel
        filter_ContextMenu             matlab.ui.container.ContextMenu
        filter_delButton               matlab.ui.container.Menu
        filter_delAllButton            matlab.ui.container.Menu
        points_ContextMenu             matlab.ui.container.ContextMenu
        points_delButton               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        General
        rootFolder

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag = {true, ...
                          true, ...
                          true, ...
                          true, ...
                          true};

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        % Propriedades do app, além de handle para RFDataHub (variável
        % global)
        specData
        projectData
        rfDataHub        

        %-----------------------------------------------------------------%
        % ESPECIFICIDADES AUXAPP.WINDRIVETEST
        %-----------------------------------------------------------------%
        % (a) OBJETO QUE SIMPLIFICA OPERAÇÕES RELACIONADAS AO FLUXO ESPECTRAL 
        %     SELECIONADO
        tempBandObj

        % (b) POTÊNCIA DE CANAL, FILTRAGEM, DATA-BINNING
        specTable                                                           % General table (instantaneous channel power)
        filtTable                                                           % Filtered specData
        binTable                                                            % Summarized filtTable (Data Binning Criterium)

        filterTable = table({}, {}, struct('handle', {}, 'specification', {}),      true(0, 1), 'VariableNames', {'type', 'subtype', 'roi', 'enable'})
        pointsTable = table({}, struct('source', {}, 'idx', {}, 'Coordinates', {}), true(0, 1), 'VariableNames', {'type', 'value', 'visible'})

        % (c) PLOT+PLAYBACK
        UIAxes1
        UIAxes2
        UIAxes3
        UIAxes4
        restoreView = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})
        
        plotFlag = 0
        idxTime  = 1
        
        hCar
        hClearWrite
        hPersistanceObj
        hWaterfallTime
        hChannelPowerTime
        hChannelPowerTimeLabel
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app, idxThread, idxEmission)
            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)startup_timerFcn(app, idxThread, idxEmission));
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        function startup_timerFcn(app, idxThread, idxEmission)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)

                % Define tamanho mínimo do app (não aplicável à versão webapp).
                if ~strcmp(app.CallingApp.executionMode, 'webApp')
                    appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
                end
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app, 0)
                jsBackDoor_Customizations(app, 1)

                app.progressDialog.Visible = 'visible';

                % Criação do eixo e leitura dos dados...    
                startup_AxesCreation(app)
                startup_AppProperties(app)
                startup_GUIComponents(app, idxThread, idxEmission)

                % Plot inicial:
                plot_startupFcn(app, idxThread, idxEmission)
                plot_Draw(app, idxThread, idxEmission)

                app.progressDialog.Visible = 'hidden';
            end
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end

        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            % O menu gráfico controla, programaticamente, qual das abas de
            % app.TabGroup estará visível. 

            % Lembrando que o MATLAB renderiza em tela apenas as abas visíveis.
            % Por isso as customizações de abas e suas subabas somente é possível 
            % após a renderização da aba.

            switch tabIndex
                case 0 % STARTUP
                    app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                           'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                               '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                               '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));
        
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                           'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));
                    
                    ccTools.compCustomizationV2(app.jsBackDoor, app.ControlTabGroup, 'transparentHeader', 'transparent')
                    ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid,   'borderBottomLeftRadius', '5px', 'borderBottomRightRadius', '5px')
                    ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid_2, 'borderTopLeftRadius',    '5px', 'borderBottomLeftRadius',  '5px')

                otherwise
                    if any(app.jsBackDoorFlag{tabIndex})
                        app.jsBackDoorFlag{tabIndex} = false;

                        switch tabIndex
                            case 1 % ASPECTOS GERAIS
                                % ...            
                            case 2 % FILTRO
                                % ...
                            case 3 % PONTOS DE INTERESSE
                                % ...            
                            case 4 % DATA BINNING
                                % ...
                            case 5 % LAYOUT
                                % ...
                        end
                    end
            end
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            hParent     = tiledlayout(app.plotPanel, 5, 5, "Padding", "none", "TileSpacing", "compact");

            % Os eixos são criados na ordem apresentada a seguir, de forma
            % que não seja necessário a troca do StackingOrder, garantindo
            % que o DataTip do mapa não fique num plano inferior a um dos
            % eixos cartesianos, por exemplo.

            % Eixo cartesiano: ESPECTRO
            app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'Box', 1, 'XTickLabels', {}, 'YAxisLocation', 'right', 'Layer', 'top'});
            app.UIAxes2.Layout.Tile = 5;
            app.UIAxes2.Layout.TileSpan = [1, 1];
            ysecondarylabel(app.UIAxes2, {' ' ' '})

            % Eixo cartesiano: WATERFALL
            app.UIAxes3 = plot.axes.Creation(hParent, 'Cartesian', {'XGrid', 0, 'XMinorGrid', 0, 'YGrid', 0, 'YMinorGrid', 0, 'Layer', 'top', 'Box', 1, 'YTick', {}});
            app.UIAxes3.Layout.Tile = 10;
            app.UIAxes3.Layout.TileSpan = [4, 1];
            app.UIAxes3.XAxis.TickLabelRotation = 0;
            xlabel(app.UIAxes3, {'Emissão' ' '})
            ysecondarylabel(app.UIAxes3, {'dd/mm/yyyy HH:MM:SS' 'dd/mm/yyyy HH:MM:SS'})            

            % Eixo cartesiano: POTÊNCIA DO CANAL
            app.UIAxes4 = plot.axes.Creation(hParent, 'Cartesian', {'Box', 1});
            app.UIAxes4.Layout.Tile = 21;
            app.UIAxes4.Layout.TileSpan = [1, 4];
            xlabel(app.UIAxes4, {'Varredura' ' '})
            ysecondarylabel(app.UIAxes4, {'Potência do canal' '(dBm)'})

            % Eixo geográfico: MAPA
            app.UIAxes1 = plot.axes.Creation(hParent, 'Geographic', {'Basemap', 'satellite'});
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [4, 4];

            set(app.UIAxes1.LatitudeAxis,  'TickLabels', {}, 'Color', 'none')
            set(app.UIAxes1.LongitudeAxis, 'TickLabels', {}, 'Color', 'none')
            geolimits(app.UIAxes1, 'auto')
            plot.axes.Colormap(app.UIAxes1, app.layout_Colormap.Value)

            % Interações:
            linkaxes([app.UIAxes2, app.UIAxes3], 'x')
            plot.axes.Interactivity.DefaultCreation(app.UIAxes1, [dataTipInteraction, zoomInteraction, panInteraction])
            plot.axes.Interactivity.DefaultCreation(app.UIAxes2, [dataTipInteraction, regionZoomInteraction])
            plot.axes.Interactivity.DefaultCreation(app.UIAxes3, [dataTipInteraction, regionZoomInteraction])
            plot.axes.Interactivity.DefaultCreation(app.UIAxes4, [dataTipInteraction, regionZoomInteraction])
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            app.tempBandObj = class.Band('appAnalise:DRIVETEST', app);
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app, idxThread, idxEmission)
            if isempty(app.specData(idxThread).UserData.Emissions)
                closeFcn(app)
                return
            end

            % Expande opções de filtros:
            expand(app.filter_roiOptions, 'all')

            % Opção inicial de plot:
            app.geoAxesTool_PlotType.UserData = 'distortion';
            
            % Lista as emissões:
            emissionTable = app.specData(idxThread).UserData.Emissions;
            app.emissionList.Items = {};
            for ii = 1:height(emissionTable)
                app.emissionList.Items{end+1} = sprintf('%.3f MHz ⌂ %.1f kHz', emissionTable.Frequency(ii), emissionTable.BW(ii));
            end
            app.emissionList.Value = app.emissionList.Items{idxEmission};
        end

        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            if ~isempty(app.filterTable)
                for ii = 1:height(app.filterTable)
                    uitreenode(app.filter_Tree, 'Text', app.filterTable.subtype{ii}, 'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                end

                set(app.filter_Tree, 'SelectedNodes', app.filter_Tree.Children(end), 'Enable', 1)
                filter_TreeSelectionChanged(app)

                set(app.filter_ContextMenu.Children, Enable=1)
            else
                app.filter_Tree.Enable = 1;
                set(app.filter_ContextMenu.Children, Enable=0)
            end

            if isempty(find(app.filterTable.subtype == "Threshold", 1))
                s = uistyle("FontColor", [0,0,0]);                    
                app.levelFilter_thr.NodeData = 1;
            else
                s = uistyle("FontColor", [.8,.8,.8]);
                app.levelFilter_thr.NodeData = [];
            end
            addStyle(app.filter_roiOptions, s, 'node', app.levelFilter_thr)
        end

        %-----------------------------------------------------------------%
        function filter_updateInfo(app, idxThread, idxEmission)
            % d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            % try
            if nargin == 1
                [idxThread, idxEmission] = specDataIndex(app);
            end
    
                binning_filtTable(app)
                binning_binTable(app)
                binning_UITable(app)
                plot_MainControl(app, idxThread)

                filter_updateProp(app, idxThread, idxEmission)             

            % catch ME
            %     appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            % end

            % delete(d)
        end

        %-----------------------------------------------------------------%
        function filter_updateProp(app, idxThread, idxEmission)
            if app.filter_Customization.Value
                app.specData(idxThread).UserData.Emissions.UserData{idxEmission} = ParametersCreation(app);
            else
                app.specData(idxThread).UserData.Emissions.UserData{idxEmission} = [];
            end
        end

        %-----------------------------------------------------------------%
        function specification = filter_roiSpecification(app, subtype, hROI)
            switch subtype
                case 'Threshold'; specification = struct('Position',  hROI.Position);
                case 'Circle';    specification = struct('Center',    hROI.Center,   'Radius',        hROI.Radius);
                case 'Rectangle'; specification = struct('Position',  hROI.Position, 'RotationAngle', hROI.RotationAngle);
                case 'Polygon';   specification = struct('Position',  hROI.Position);
            end
        end

        %-----------------------------------------------------------------%
        function binning_Layout(app)
            switch app.binTable_Type.Value
                case 'Grid retangular - Comprimento bin'
                    set(app.binTable_ValueLabel, 'Text', 'Comprimento <i>bin</i> (metros):')
                    set(app.binTable_Value, 'Limits', [50, 1500], 'Value', 100, 'Step', 50)

                case 'Grid retangular - Coordenadas geográficas truncadas'
                    set(app.binTable_ValueLabel, 'Text', 'Casas decimais:')
                    set(app.binTable_Value, 'Limits', [1, 10], 'Value', 6, 'Step', 1)
            end
        end       

        %-----------------------------------------------------------------%
        function binning_specTableCreation(app, idxThread, idxEmission)
            Timestamp = app.specData(idxThread).Data{1}';
            
            Latitude  = [];
            Longitude = [];
            for ii = 1:height(app.specData(idxThread).RelatedFiles)
                Latitude  = [Latitude;  app.specData(idxThread).RelatedFiles.GPS{ii}.Matrix(:,1)];
                Longitude = [Longitude; app.specData(idxThread).RelatedFiles.GPS{ii}.Matrix(:,2)];
            end
            
            emissionFrequency = app.specData(idxThread).UserData.Emissions.Frequency(idxEmission) * 1e+6;
            emissionBW        = app.specData(idxThread).UserData.Emissions.BW(idxEmission)        * 1e+3;
            emissionPower     = RF.ChannelPower(app.specData(idxThread), [emissionFrequency-emissionBW/2, emissionFrequency+emissionBW/2]);

            app.specTable     = table(Timestamp, Latitude, Longitude, emissionPower);
        end        
        
        %-----------------------------------------------------------------%
        function binning_filtTable(app)
            idy  = ones(height(app.specTable), 1, 'logical');

            idx1 = find(app.filterTable.type == "Level");
            idx2 = find(app.filterTable.type == "Geographic ROI");

            if ~isempty(idx1) & ~isempty(idx2)
                for ii = idx1'
                    idy1 = app.specTable.emissionPower >= app.filterTable.roi(ii).handle.Position(3);
                end

                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.filterTable.roi(ii).handle, app.specTable.Latitude, app.specTable.Longitude));
                end

                idy = and(idy1, idy2);

            elseif ~isempty(idx1)
                for ii = idx1'
                    idy1 = app.specTable.emissionPower >= app.filterTable.roi(ii).handle.Position(3);
                end

                idy = idy1;

            elseif ~isempty(idx2)
                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.filterTable.roi(ii).handle, app.specTable.Latitude, app.specTable.Longitude));
                end

                idy = idy2;                
            end
            
            if ~sum(idy)
                appUtil.modalWindow(app.UIFigure, 'warning', 'Não foi obtida nenhuma amostra ao aplicar o filtro. Por essa razão, esse filtro será excluído automaticamente.');
                app.filter_Tree.SelectedNodes = app.filter_Tree.Children(end);
                filter_delFilter(app, struct('Source', app.filter_delButton))

                return
            end

            app.specTable.filtered = idy;
            app.specTable.PK1      = int32(1:height(app.specTable))';

            app.filtTable = app.specTable(idy,2:4);
            [app.filtTable.LatEq, app.filtTable.LongEq] = grn2eqa(app.filtTable.Latitude, app.filtTable.Longitude);

            app.filtTable.FK1 = find(idy);
            app.filtTable.FK2 = -1*ones(height(app.filtTable), 1, 'int32');

            if ~isempty(idx1)
                app.filterTable.roi(idx1).handle.Position(2) = height(app.filtTable);
            end
        end

        %-----------------------------------------------------------------%
        function binning_binTable(app)
            switch app.binTable_Type.Value
                case 'Grid retangular - Comprimento bin'
                    [binLat, binLong, binCount] = hista(app.filtTable.Latitude, app.filtTable.Longitude, (app.binTable_Value.Value/1000)^2);            
                    [binLatEq, binLongEq] = grn2eqa(binLat, binLong);
                    PK2 = int32(1:numel(binLat))';
                    
                    dist = pdist2([app.filtTable.LongEq, app.filtTable.LatEq], [binLongEq, binLatEq]);            
                    [~, app.filtTable.FK2] = min(dist, [], 2);
                    binPower = binning_binPower(app);
        
                    app.binTable = table(binLat, binLong, binPower, binCount, PK2);

                case 'Grid retangular - Coordenadas geográficas truncadas'
                    lat_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Value.Value), app.filtTable.Latitude),  '\n')';
                    lon_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Value.Value), app.filtTable.Longitude), '\n')';
                    latlong    = string(lat_String(1:end-1)) + ", " + string(lon_String(1:end-1));
        
                    [latlong_String, latlong_Index, app.filtTable.FK2] = unique(latlong, 'stable');
                    
                    PK2      = int32(1:numel(latlong_String))';
                    binLat   = round(app.filtTable.Latitude(latlong_Index),  app.binTable_Value.Value);
                    binLong  = round(app.filtTable.Longitude(latlong_Index), app.binTable_Value.Value);
                    binCount = accumarray(app.filtTable.FK2, 1);
                    binPower = binning_binPower(app);
        
                    app.binTable = table(binLat, binLong, binPower, binCount, PK2);
            end
        end

        %-----------------------------------------------------------------%
        function binPower = binning_binPower(app)
            switch app.binTable_Fcn.Value
                case 'min';    binFcn = @min;
                case 'mean';   binFcn = @(x) pow2db(  mean(db2pow(x)));
                case 'median'; binFcn = @(x) pow2db(median(db2pow(x)));
                case 'rms';    binFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'max';    binFcn = @max;
            end
            binPower = splitapply(binFcn, app.filtTable.emissionPower, app.filtTable.FK2);
        end


        %-----------------------------------------------------------------%
        function binning_UITable(app)
            binLat_String  = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLat),  'UniformOutput', false);
            binLong_String = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLong), 'UniformOutput', false);
            app.binTable_UITable.Data = table(app.binTable{:,5}, binLat_String, binLong_String, app.binTable{:,4});

            s = uistyle("HorizontalAlignment", 'right');
            addStyle(app.binTable_UITable, s, "column", [2,3])            

            % Identificar as distâncias entre pontos adjacentes (ajustados
            % no grid definido pelo número das casas decimais da latitude e
            % longitude)
            locIndex = app.filtTable.FK2(1);
            for ii = 1:height(app.filtTable)
                if locIndex(end) ~= app.filtTable.FK2(ii)
                    locIndex(end+1,1) = app.filtTable.FK2(ii);
                end
            end

            [binCount_min, binCount_max] = bounds(app.binTable.binCount);

            if numel(locIndex) >= 2
                ptDist = deg2km(distance('gc', [app.binTable.binLat(locIndex(1:end-1)), app.binTable.binLong(locIndex(1:end-1))], ...
                                               [app.binTable.binLat(locIndex(2:end)),   app.binTable.binLong(locIndex(2:end))]));
                [ptDist_min, ptDist_max] = bounds(ptDist*1000);
                ptDist_mean = mean(ptDist)*1000; 
    
                % Sumário:            
                auxString0 = sprintf('%.0f pontos', height(app.binTable));
                auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', ptDist_min, ptDist_mean, ptDist_max);
                auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto',    binCount_min, binCount_max);
    
                app.binTable_Info.Text = sprintf('%s\n%s\n%.0f |–––––––%s| %.0f varreduras/ponto', auxString0, auxString1, binCount_min, repmat('–', 1, numel(auxString1)-numel(auxString2)+1), binCount_max);
            else
                app.binTable_Info.Text = '';
            end
        end

        %-----------------------------------------------------------------%
        function points_TreeBuilding(app)
            if ~isempty(app.points_Tree.Children)
                delete(app.points_Tree.Children)
            end

            checkedTreeNodes = [];
            for ii = 1:height(app.pointsTable)
                treeNode = uitreenode(app.points_Tree, 'Text', sprintf('%s: %s', app.pointsTable.type{ii}, strjoin(string(app.pointsTable.value(ii).idx), ',')), ...
                                                       'NodeData', ii, 'ContextMenu', app.points_ContextMenu);
                if app.pointsTable.visible(ii)
                    checkedTreeNodes = [checkedTreeNodes, treeNode];
                end
            end
            app.points_Tree.CheckedNodes = checkedTreeNodes;

            if app.filter_Customization.Value
                [idxThread, idxEmission] = specDataIndex(app);
                app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.pointsTable = app.pointsTable;
            end
        end

        %-----------------------------------------------------------------%
        function points_TableCheck(app, newRow)
            status = true;
            for ii = 1:height(app.pointsTable)
                if isequal(newRow(1), app.pointsTable{ii,1}) && ...
                   isequal(newRow{2}, app.pointsTable{ii,2})
                    status = false;
                    break
                end
            end

            if status
                app.pointsTable(end+1,:) = newRow;
            else
                error('Registro já incluído!')
            end
        end

        %-----------------------------------------------------------------%
        function [idx, Coordinates] = points_FindPeaks(app, Source, NPeaks, MinDistance)
            idx = [];
            Coordinates = [];

            switch Source
                case 'Dados brutos';                     sourceData = get(findobj(app.UIAxes4.Children, 'Tag', 'rawTable'),     'YData');
                case 'Dados processados (Data Binning)'; sourceData = get(findobj(app.UIAxes4.Children, 'Tag', 'binTable'),     'YData');
                case 'Curva suave';                      sourceData = get(findobj(app.UIAxes4.Children, 'Tag', 'SmoothedData'), 'YData');
            end

            [~, pksIndex] = findpeaks(sourceData, 'SortStr', 'descend');

            if ~isempty(pksIndex)
                idx = pksIndex(1);

                for ii = 2:numel(pksIndex)
                    if numel(idx) >= NPeaks
                        break
                    end

                    switch Source
                        case {'Dados brutos', 'Curva suave'}
                            refCoordinates = app.filtTable{idx, 1:2};
                            Coordinates    = app.filtTable{pksIndex(ii), 1:2};    
                        
                        case 'Dados processados (Data Binning)'
                            refCoordinates = app.binTable{app.filtTable.FK2(arrayfun(@(x) find(app.filtTable.FK1 == x, 1), idx)), 1:2};
                            Coordinates    = app.binTable{app.filtTable.FK2(arrayfun(@(x) find(app.filtTable.FK1 == x, 1), pksIndex(ii))), 1:2};
                    end
    
                    tempDistance = deg2km(distance(refCoordinates, Coordinates(end,:))); % em km
                    if all(tempDistance >= MinDistance)
                        idx(end+1) = pksIndex(ii);
                    end
                end

                switch Source
                    case {'Dados brutos', 'Curva suave'};    Coordinates = app.filtTable{idx, 1:2};    
                    case 'Dados processados (Data Binning)'; Coordinates = app.binTable{app.filtTable.FK2(arrayfun(@(x) find(app.filtTable.FK1 == x, 1), idx)), 1:2};
                end
            end
        end

        %-----------------------------------------------------------------%
        % ## PLOT ANTIGO ##
        %-----------------------------------------------------------------%
        function plot_MainControl(app, idxThread)            
            % app.UIAxes1
            plot.old_axesDraw.geographicAxes_type1(app.UIAxes1, ParametersCreation(app), 'auxApp.winDriveTest')
            plot_Car(app)

            % app.UIAxes4
            plot_ChannelPower(app, idxThread)

            drawnow
        end

        %-----------------------------------------------------------------%
        function plot_ChannelPower(app, idxThread)
            delete(findobj(app.UIAxes4.Children, '-not', 'Tag', 'ROI'))

            chColor         = app.CallingApp.General.Plot.ClearWrite.EdgeColor;
            binColor        = app.CallingApp.General.Plot.MaxHold.EdgeColor;
            smoothedColor   = app.CallingApp.General.Plot.MinHold.EdgeColor;

            [~, idx]        = min(abs(app.filtTable.FK1 - app.idxTime));

            [minY, maxY]    = bounds(app.specTable.emissionPower);
            set(app.UIAxes4, 'XLim', [1, height(app.filtTable)+.001], 'YLim', [minY, maxY+.001])
            
            app.restoreView(4).xLim = app.UIAxes4.XLim;
            app.restoreView(4).yLim = app.UIAxes4.YLim;
                                
            smoothedTable               = app.filtTable;
            smoothedTable.emissionPower = smoothdata(app.filtTable.emissionPower, app.layout_Smoothed_Type.Value, 'SmoothingFactor', app.layout_Smoothed_Factor.Value);

            chPowerLine           = plot(app.UIAxes4, app.filtTable.emissionPower,              'Marker', app.layout_points_LineStyle.Value, 'MarkerSize', 8, 'MarkerIndices', [], 'MarkerFaceColor', chColor,       'MarkerEdgeColor', chColor,       'Color', chColor,       'Visible', app.layout_chPowerSource_rawTable.Value, 'Tag', 'rawTable');
            binPowerLine          = plot(app.UIAxes4, app.binTable.binPower(app.filtTable.FK2), 'Marker', app.layout_points_LineStyle.Value, 'MarkerSize', 8, 'MarkerIndices', [], 'MarkerFaceColor', binColor,      'MarkerEdgeColor', binColor,      'Color', binColor,      'Visible', app.layout_chPowerSource_binTable.Value, 'Tag', 'binTable');
            smoothedLine          = plot(app.UIAxes4, smoothedTable.emissionPower,              'Marker', app.layout_points_LineStyle.Value, 'MarkerSize', 8, 'MarkerIndices', [], 'MarkerFaceColor', smoothedColor, 'MarkerEdgeColor', smoothedColor, 'Color', smoothedColor, 'Visible', app.layout_chPowerSource_smoothed.Value, 'Tag', 'SmoothedData');

            app.hChannelPowerTime        = plot(app.UIAxes4, [idx idx], [minY maxY], 'Color', 'red', 'Marker', '.', 'MarkerSize', 12, 'PickableParts', 'none');
            app.hChannelPowerTimeLabel   = text(app.UIAxes4, idx, app.UIAxes4.YLim(1)+.94*diff(app.UIAxes4.YLim), sprintf('  %.0f dBm', app.filtTable.emissionPower(idx)), 'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red', 'PickableParts', 'none');

            plot.datatip.Template(chPowerLine,  'SweepID+ChannelPower', app.specData(idxThread).MetaData.LevelUnit)
            plot.datatip.Template(binPowerLine, 'SweepID+ChannelPower', app.specData(idxThread).MetaData.LevelUnit)
            plot.datatip.Template(smoothedLine, 'SweepID+ChannelPower', app.specData(idxThread).MetaData.LevelUnit)
        end

        %-----------------------------------------------------------------%
        function plot_Car(app)
            delete(app.hCar)

            if ~isempty(app.hChannelPowerTime)
                idx = round(app.hChannelPowerTime.XData(1));
                app.hCar = geoscatter(app.UIAxes1, app.filtTable.Latitude(idx), app.filtTable.Longitude(idx), 'filled',                                              ...
                                      'Marker', app.layout_Car_LineStyle.Value, 'MarkerFaceColor', app.layout_Car_Color.BackgroundColor, 'MarkerEdgeColor', 'black', ...
                                      'SizeData', 10*app.layout_Car_Size.Value, 'PickableParts', 'none', 'Tag', 'Car');
            end
        end

        %-----------------------------------------------------------------%
        function Parameters = ParametersCreation(app)
            for ii = 1:height(app.filterTable)
                app.filterTable.roi(ii).specification = filter_roiSpecification(app, app.filterTable.subtype{ii}, app.filterTable.roi(ii).handle);
            end

            switch app.layout_mapPlotSourcePanel.SelectedObject.Text
                case 'Dados brutos'; dataSource = 'RawData';
                otherwise;           dataSource = 'BinData';
            end

            Parameters = struct('Source',                dataSource,                              ...
                                'specTable',             app.specTable,                           ...
                                'filtTable',             app.filtTable,                           ...
                                'binTable',              app.binTable,                            ...
                                'filterTable',           app.filterTable,                         ...
                                'pointsTable',           app.pointsTable,                         ...
                                'binning_Type',          app.binTable_Type.Value,                 ...
                                'binning_Value',         app.binTable_Value.Value,               ...
                                'binning_Fcn',           app.binTable_Fcn.Value,                  ...
                                'route_LineStyle',       app.layout_route_LineStyle.Value,        ...
                                'route_OutColor',        app.layout_route_Color1.BackgroundColor, ...
                                'route_InColor',         app.layout_route_Color2.BackgroundColor, ...
                                'route_MarkerSize',      app.layout_route_Size.Value,             ...
                                'plotType',              app.geoAxesTool_PlotType.UserData,       ...
                                'plotSize',              app.geoAxesTool_PlotSize.Value,          ...
                                'points_Marker',         app.layout_points_LineStyle.Value,       ...
                                'points_Color',          app.layout_points_Color.BackgroundColor, ...
                                'points_Size',           app.layout_points_Size.Value,            ...
                                'Colormap',              app.layout_Colormap.Value,               ...
                                'Basemap',               app.UIAxes1.Basemap);
        end

        %-----------------------------------------------------------------%
        % ## PLOT ##
        %-----------------------------------------------------------------%
        %-----------------------------------------------------------------%
        function prePlot_customProperties(app, idxThread, idxEmission)
            if ~isempty(app.specData(idxThread).UserData.Emissions.UserData{idxEmission})
                app.filter_Customization.Value = 1;

                % Se tiver algum ROI em tela, exclui-se...
                if ~isempty(app.filterTable)
                    arrayfun(@(x) delete(x), [app.filterTable.roi.handle]);
                end

                app.filterTable                         = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.filterTable;
                app.pointsTable                         = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.pointsTable;

                app.binTable_Type.Value                 = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.binning_Type;
                binning_Layout(app)
                app.binTable_Value.Value                = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.binning_Value;
                app.binTable_Fcn.Value                  = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.binning_Fcn;

                app.layout_route_LineStyle.Value        = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.route_LineStyle;
                app.layout_route_Color1.BackgroundColor = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.route_OutColor;
                app.layout_route_Color2.BackgroundColor = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.route_InColor;
                app.layout_route_Size.Value             = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.route_MarkerSize;
                app.layout_points_LineStyle.Value       = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.points_Marker;
                app.layout_points_Color.BackgroundColor = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.points_Color;
                app.layout_points_Size.Value            = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.points_Size;
                app.geoAxesTool_PlotSize.Value          = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.plotSize;
                app.layout_Colormap.Value               = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.Colormap;
                app.UIAxes1.Basemap                     = app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.Basemap;

                switch app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.Source
                    case 'RawData'; app.layout_mapPlotSource_rawData.Value = 1;
                    otherwise;      app.layout_mapPlotSource_binData.Value = 1;
                end

                switch app.specData(idxThread).UserData.Emissions.UserData{idxEmission}.plotType
                    case 'distortion'
                        app.geoAxesTool_PlotType.ImageSource = 'DriveTestDistortion_32.png';
                    case 'density'
                        app.geoAxesTool_PlotType.ImageSource = 'DriveTestDensity_32.png';
                end

                points_TreeBuilding(app)
                plot_layoutCallbacks(app, struct('Source', app.layout_Colormap))

                % Criação dos ROIs (caso existentes)
                if ~isempty(app.filterTable)
                    app.filter_roiOptions.SelectedNodes = [];
                    filter_ROISelectionChanged(app)

                    for ii = 1:height(app.filterTable)
                        switch app.filterTable.subtype{ii}
                            case 'Threshold'
                                hAxes = app.UIAxes4;
                            otherwise
                                hAxes = app.UIAxes1;
                        end
                        hROI = plot.ROI(app.filterTable.subtype{ii}, hAxes, {'Tag', 'ROI'});

                        fieldsList = fields(app.filterTable.roi(ii).specification);
                        for jj = 1:numel(fieldsList)
                            hROI.(fieldsList{jj}) = app.filterTable.roi(ii).specification.(fieldsList{jj});
                        end
                        plot_AddListener2ROI(app, hROI)
                        app.filterTable.roi(ii).handle = hROI;
                    end
                end

            else
                app.filter_Customization.Value = 0;

                idxFindPeaks = find(strcmp(app.pointsTable.type, 'FindPeaks'));
                if ~isempty(idxFindPeaks)
                    app.pointsTable(idxFindPeaks,:) = [];
                    points_TreeBuilding(app)
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_startupFcn(app, idxThread, idxEmission)
            % Inicialmente, a informação acerca do fluxo espectral e da emissão 
            % sob análise são salvas na propriedade "UserData" de app.emissionMetaData.
            [htmlContent, emissionID] = auxApp.drivetest.htmlCode_EmissionInfo(app.specData, idxThread, idxEmission);
            set(app.emissionMetaData, 'HTMLSource', htmlContent, 'UserData', emissionID)

            % O objeto app.tempBandObj armazena propriedades de app.specData(idxThreads) 
            % que simplifica o processo do plot, em especial na passagem de 
            % argumentos para as funções plot.draw2D e plot.draw3D.
            axesLimits = update(app.tempBandObj, idxThread, idxEmission);
            prePlot_restartProperties(app, axesLimits)
            
            % Afere-se, então, a potência do canal para cada uma das varreduras, 
            % criando a tabela app.specTable com as colunas "Timestamp", "Latitude", 
            % "Longitude" e "emissionPower".
            binning_specTableCreation(app, idxThread, idxEmission)
            filter_TreeBuilding(app)
            filter_updateInfo(app, idxThread, idxEmission)            
        end

        %-----------------------------------------------------------------%
        function prePlot_restartProperties(app, axesLimits)
            cla([app.UIAxes1, app.UIAxes4])

            app.UIAxes1.UserData.CLimMode = 'auto';
            app.UIAxes3.UserData.CLimMode = 'auto';

            app.restoreView(1) = struct('ID', 'app.UIAxes1', 'xLim', [],              'yLim', [],                   'cLim', 'auto');          % Eixo geográfico
            app.restoreView(2) = struct('ID', 'app.UIAxes2', 'xLim', axesLimits.xLim, 'yLim', axesLimits.yLevelLim, 'cLim', 'auto');          % Eixo cartesiano (Espectro)
            app.restoreView(3) = struct('ID', 'app.UIAxes3', 'xLim', axesLimits.xLim, 'yLim', [],                   'cLim', axesLimits.cLim); % Eixo cartesiano (Waterfall)
            app.restoreView(4) = struct('ID', 'app.UIAxes4', 'xLim', [],              'yLim', [],                   'cLim', 'auto');          % Eixo cartesiano (Potência do canal)
        
            app.hCar                   = [];
            app.hChannelPowerTime      = [];
            app.hChannelPowerTimeLabel = [];

            app.idxTime = 1;
            app.tool_TimestampSlider.Value = 0;
        end

        %-----------------------------------------------------------------%
        function plot_Draw(app, idxThread, idxEmission)
            % No processo de inicialização do PLOT, que ocorre toda vez que é
            % alterado o fluxo espectral selecionado, na árvore principal do
            % appAnalise, os eixos são limpos e os handles dos principais
            % componentes do plot são apagados. app.hClearWrite vazio significa 
            % que deverá ser desenhado um novo PLOT. Por outro lado, caso não 
            % seja vazio, então o PLOT deverá ser atualizado.
        
            if isempty(app.hClearWrite)
                % Eixo geográfico - app.UIAxes1
                plot.old_axesDraw.geographicAxes_type1(app.UIAxes1, ParametersCreation(app), 'auxApp.winDriveTest')
                plot_Car(app)

                % Eixo cartesiano 1 - app.UIAxes2 (ClearWrite & EmissionROI)
                app.hClearWrite = plot.draw2D.OrdinaryLine(app.UIAxes2, app.tempBandObj, idxThread, 'ClearWrite');
                plot.datatip.Template(app.hClearWrite, "Frequency+Level", app.tempBandObj.LevelUnit)

                app.hPersistanceObj = plot.draw3D.Persistance('Creation', app.hPersistanceObj, app.UIAxes2, app.tempBandObj, idxThread);
                plot.draw2D.rectangularROI(app.UIAxes2, app.tempBandObj, idxThread, 'ROI', idxEmission, {'EdgeAlpha', 0, 'InteractionsAllowed', 'none'})

                % Eixo cartesiano 2 - app.UIAxes3 (Waterfall)
                plot.draw3D.Waterfall('Creation', app.UIAxes3, app.tempBandObj, idxThread);
                app.hWaterfallTime = plot.draw2D.OrdinaryLine(app.UIAxes3, app.tempBandObj, idxThread, 'WaterfallTime');

                % Eixo cartesiano 3 - app.UIAxes4 (ChannelPower)
                plot_ChannelPower(app, idxThread)

                % Limites dos eixos:
                emissionID = app.emissionMetaData.UserData;
                set(app.UIAxes2, 'XLim', app.restoreView(2).xLim, 'YLim', app.restoreView(2).yLim, 'XTick', emissionID.Emission.Frequency)
                set(app.UIAxes3, 'XTick', emissionID.Emission.Frequency, 'XTickLabel', emissionID.Emission.Tag, 'YTick', {})

                ysecondarylabel(app.UIAxes3, {datestr(app.specData(idxThread).Data{1}(1),   'dd/mm/yyyy HH:MM:SS'), ...
                                              datestr(app.specData(idxThread).Data{1}(end), 'dd/mm/yyyy HH:MM:SS')})

                app.restoreView(1).xLim = app.UIAxes1.LatitudeLimits;
                app.restoreView(1).yLim = app.UIAxes1.LongitudeLimits;

                app.restoreView(3).yLim = app.UIAxes4.YLim;

                app.restoreView(4).xLim = app.UIAxes4.XLim;
                app.restoreView(4).yLim = app.UIAxes4.YLim;
        
            else
                if app.plotFlag
                    % ClearWrite, MinHold, Average, e MaxHold
                    plot.draw2D.OrdinaryLineUpdate(app.hClearWrite, app.tempBandObj, idxThread, 'ClearWrite')
                    
                    % WaterfallTime
                    plot.draw2D.OrdinaryLineUpdate(app.hWaterfallTime, app.tempBandObj, idxThread, 'WaterfallTime');
    
                    % ChannelPowerTime
                    % PENDENTE

                else
                    % Houve mudança de emissão... o gráfico de espectro e
                    % de waterfall não muda, apenas se reposiciona.
                    emissionID = app.emissionMetaData.UserData;
                    set(app.UIAxes2, 'XLim', app.restoreView(2).xLim, 'YLim', app.restoreView(2).yLim, 'XTick', emissionID.Emission.Frequency)
                    set(app.UIAxes3, 'XTick', emissionID.Emission.Frequency, 'XTickLabel', emissionID.Emission.Tag)

                    app.restoreView(1).xLim = app.UIAxes1.LatitudeLimits;
                    app.restoreView(1).yLim = app.UIAxes1.LongitudeLimits;
    
                    app.restoreView(4).xLim = app.UIAxes4.XLim;
                    app.restoreView(4).yLim = app.UIAxes4.YLim;
                end
            end
            drawnow
        end

        %-----------------------------------------------------------------%
        function plot_mainLoop(app, idxThread, idxEmission, nSweeps)
            while app.idxTime <= nSweeps
                % A variável app.plotFlag pode assumir os valores -1 | 0 | 1.
                % - -1: alteração de fluxo espectral
                % -  0: finalização do plot
                % -  1: atualização do plot
                switch app.plotFlag
                    case -1; [idxThread, idxEmission, nSweeps] = plot_updateIndex(app);
                    case  0; return
                end
                sweepTic = tic;
                
                plot_Draw(app, idxThread, idxEmission)
                app.tool_TimestampLabel.Text   = sprintf('%d de %d\n%s', app.idxTime, nSweeps, app.specData(idxThread).Data{1}(app.idxTime));
                app.tool_TimestampSlider.Value = 100 * app.idxTime/nSweeps;
                
                pause(max(app.play_MinPlotTime.Value/1000-toc(sweepTic), .001))
                
                % Reload Flag
                if app.idxTime == nSweeps
                    if app.tool_LoopControl.Tag == "loop"
                        app.idxTime = 1;
                    else
                        break
                    end
                else
                    app.idxTime = app.idxTime+1;
                end                
            end
            
            play_PlaybackToolbarButtonCallback(app, struct('Source', app.tool_Play))
        end

        %-----------------------------------------------------------------%
        function [idxThread, idxEmission, nSweeps] = plot_updateIndex(app)
            [idxThread, idxEmission] = specDataIndex(app, 'EmissionSelectedChanged');
            nSweeps = numel(app.specData(idxThread).Data{1});
            
            plot_startupFcn(app, idxThread, idxEmission)
            plot_Draw(app, idxThread, idxEmission)

            app.plotFlag = 1;
        end

        %-----------------------------------------------------------------%
        function plot_AddListener2ROI(app, hROI)
            addlistener(hROI, 'MovingROI',            @(~, evt)plot.axes.Interactivity.CustomROIInteractionFcn(evt, [app.UIAxes1, app.UIAxes4], []))
            addlistener(hROI, 'ROIMoved',             @(~, evt)plot.axes.Interactivity.CustomROIInteractionFcn(evt, [app.UIAxes1, app.UIAxes4], @app.filter_updateInfo))
            addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plot.axes.Interactivity.DeleteROIListeners(src));
        end

        %-----------------------------------------------------------------%
        % ÍNDICES DO FLUXO ESPECTRAL E DA EMISSÃO
        %-----------------------------------------------------------------%
        function [idxThread, idxEmission] = specDataIndex(app, operationType)
            arguments
                app 
                operationType char {mustBeMember(operationType, {'EmissionSelectedChanged', 'EmissionShowed'})} = 'EmissionShowed'
            end

            % É salva na propriedade "UserData" de app.emissionMetaData a
            % informação relacionada ao fluxo espectral e a emissão sob análise.

            % emissionID = struct('Thread',   struct('Index',     idx,                                                     ...
            %                                        'UUID',      {specData(idx).RelatedFiles.uuid}),                      ...
            %                     'Emission', struct('Index',     idxEmission,                                             ...
            %                                        'Frequency', specData(idx).UserData.Emissions.Frequency(idxEmission), ...
            %                                        'BW',        specData(idx).UserData.Emissions.BW(idxEmission),        ...
            %                                        'Tag',       emissionTag));

            try
                emissionID  = app.emissionMetaData.UserData;

                % Inicialmente, busca-se o fluxo espectral (referenciado 
                % unicamente pela lista UUID dos arquivos brutos).
                threadUUID  = emissionID.Thread.UUID;
                listOfAllThreadsUUID = arrayfun(@(x) x.RelatedFiles.uuid, app.specData, 'UniformOutput', false);
                
                idxThread   = find(cellfun(@(x) isequal(x, threadUUID), listOfAllThreadsUUID), 1);
                idxEmission = [];
    
                % Caso seja identificado o fluxo espectral, busca na lista
                % de emissões à emissão sob análise.
                if ~isempty(idxThread)
                    switch operationType
                        case 'EmissionSelectedChanged'
                        newEmissionTag = app.emissionList.Value;
                        emissionTable  = app.specData(idxThread).UserData.Emissions;

                        for ii = 1:height(emissionTable)
                            if strcmp(newEmissionTag, sprintf('%.3f MHz ⌂ %.1f kHz', emissionTable.Frequency(ii), emissionTable.BW(ii)))
                                idxEmission = ii;
                                break
                            end
                        end

                        case 'EmissionShowed'
                            emissionFrequency = emissionID.Emission.Frequency;
                            emissionBW        = emissionID.Emission.BW;
            
                            idxEmission = find(abs(app.specData(idxThread).UserData.Emissions.Frequency - emissionFrequency) <= class.Constants.floatDiffTolerance & ...
                                               abs(app.specData(idxThread).UserData.Emissions.BW        - emissionBW)        <= class.Constants.floatDiffTolerance, 1);
                    end
                end
    
                if isempty(idxThread)
                    error('DriveTest:specDataIndex:ThreadNotFound',   'O fluxo espectral sob análise foi editado ou excluído.')
                elseif isempty(idxEmission)
                    error('DriveTest:specDataIndex:EmissionNotFound', 'A emissão sob análise foi editada ou excluída.')
                end

            catch ME
                msgQuestion   = sprintf('%s\n\nDeseja fechar esse módulo do appAnalise?', getReport(ME));
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                if userSelection == "Sim"
                    closeFcn(app)
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idxThread, idxEmission)
            
            global RFDataHub

            app.CallingApp  = mainapp;
            app.General     = mainapp.General;
            app.rootFolder  = mainapp.rootFolder;
            app.rfDataHub   = RFDataHub;
            app.specData    = mainapp.specData;
            app.projectData = mainapp.projectData;

            app.Tab2_Panel.RowHeight{3} = 118;
            points_addTypePanelSelectionChanged(app)

            appUtil.winPosition(app.UIFigure)
            jsBackDoor_Initialization(app)
            startup_timerCreation(app, idxThread, idxEmission)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWin2 = [];
            delete(app.timerObj)
            delete(app)
            
        end

        % Image clicked function: tool_resetButton
        function resetFcn(app, event)
            
            msg = ['<font style="font-size:12;">Essa funcionalidade possibilita reinicializar alguns dos parâmetros do <i>app</i>, ' ...
                   'como a INTERAÇÃO com os plots e a exclusão de FILTROS.</font>'];
            selection = uiconfirm(app.UIFigure, msg, '', 'Options', {'Interação', 'Filtros', 'Cancelar'}, 'DefaultOption', 1, 'Icon', 'question', 'Interpreter', 'html');

            if strcmp(selection, 'Cancelar')
                return
            end

            switch selection
                case 'Interação'
                    plot.axes.Interactivity.DefaultDisable([app.UIAxes1, app.UIAxes4])
                    pause(.1)
                    plot.axes.Interactivity.DefaultEnable([app.UIAxes1, app.UIAxes4])

                    filter_TreeSelectionChanged(app)

                case 'Filtros'
                    filter_delFilter(app, struct('Source', app.filter_delAllButton))
            end

        end

        % Image clicked function: tool_ControlPanelVisibility, 
        % ...and 1 other component
        function toolbarCallbacks(app, event)
            
            focus(app.jsBackDoor)

            switch event.Source
                %---------------------------------------------------------%
                case app.tool_ControlPanelVisibility
                    if app.Grid.ColumnWidth{2}
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowRight_32.png';
                        app.Grid.ColumnWidth(2:3) = {0,0};
                    else
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                        app.Grid.ColumnWidth(2:3) = {325,10};
                    end

                %---------------------------------------------------------%
                case app.tool_RefreshScreenSize
                    app.UIFigure.Position(3:4) = [1244, 660];
                    appUtil.winPosition(app.UIFigure)
            end

        end

        % Image clicked function: geoAxesTool_PlotType, 
        % ...and 2 other components
        function axesToolbarCallbacks(app, event)
            
            switch event.Source
                case app.geoAxesTool_RestoreView
                    geolimits(app.UIAxes1, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.geoAxesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes1, app.geoAxesTool_RegionZoom)

                case app.geoAxesTool_PlotType
                    switch app.geoAxesTool_PlotType.UserData
                        case 'distortion'
                            app.geoAxesTool_PlotType.UserData = 'density';
                            app.geoAxesTool_PlotType.ImageSource = 'DriveTestDensity_32.png';
                        case 'density'
                            app.geoAxesTool_PlotType.UserData = 'distortion';
                            app.geoAxesTool_PlotType.ImageSource = 'DriveTestDistortion_32.png';
                    end
                    % plota o novo gráfico...
            end

        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 3 other components
        function layout_LeftPanelSelectedTabControl(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.menu_MainGrid.ColumnWidth   = columnWidth;
            app.menuUnderline.Layout.Column = idx;
            app.ControlTabGroup.SelectedTab = app.ControlTabGroup.Children(idx);

            jsBackDoor_Customizations(app, idx)
            focus(app.jsBackDoor)

        end

        % Image clicked function: tool_ExportButton
        function tool_ExportFileButtonPushed(app, event)
            
            defaultFilename = class.Constants.DefaultFileName(app.CallingApp.config_Folder_userPath.Value, 'Drive-test', app.CallingApp.report_Issue.Value);

            [fileName, filePath, fileIndex] = uiputfile({'*.xlsx', 'appAnalise (*.xlsx)'}, '', defaultFilename);
            figure(app.UIFigure)
    
            if fileIndex
                % d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

                % try
                    FullFileName = fullfile(filePath, fileName);                    
        
                    writetable(app.specTable,              FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'replacefile',    'Sheet', 'specTable')
                    writetable(app.filtTable(:,[1:3,6:7]), FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'filtTable')
                    writetable(app.binTable,               FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'binTable')

                    kmlwriteline(replace(FullFileName, '.xlsx', '.kml'), app.specTable.Latitude, app.specTable.Longitude, 'Color', 'red', 'LineWidth', 3);

                % catch ME
                %     appUtil.modalWindow(app.UIFigure, 'error', getReport(ME));
                % end

                % delete(d)
            end

        end

        % Image clicked function: emissionListRefresh
        function emission_RefreshListImageClicked(app, event)
            
            preSelection = extractAfter(app.emissionList.Value, ': ');
            
            % try
                % O try/catch aqui apenas evita a situação em que as emissões 
                % foram excluídas no modo PLAYBACK, então não há emissões para 
                % analisar... nesse caso, o app auxiliar será fechado.
                startup_EmissionsList(app)
    
                idx = find(contains(app.emissionList.Items, preSelection), 1);
                if ~isempty(idx)
                    app.emissionList.Value = app.emissionList.Items(idx);
                end
                emission_ValueChanged(app, struct('Source', app.emissionList, 'PreviousValue', app.emissionList.Value))

            % catch
            % end

        end

        % Value changed function: emissionList
        function emission_ValueChanged(app, event)

            if app.plotFlag
                app.plotFlag = -1;
            else
                [idxThread, idxEmission] = specDataIndex(app, 'EmissionSelectedChanged');

                plot_startupFcn(app, idxThread, idxEmission)
                plot_Draw(app, idxThread, idxEmission)
            end
            
        end

        % Selection changed function: filter_roiOptions
        function filter_ROISelectionChanged(app, event)
            
            if ~isempty(app.filter_roiOptions.SelectedNodes) && ~isempty(app.filter_roiOptions.SelectedNodes.NodeData)
                app.filter_addImage.Enable = 1;
            else
                app.filter_addImage.Enable = 0;
            end
            
        end

        % Selection changed function: filter_Tree
        function filter_TreeSelectionChanged(app, event)
            
            if ~isempty(app.filter_Tree.SelectedNodes)
                idx = app.filter_Tree.SelectedNodes.NodeData;        
    
                for ii = 1:height(app.filterTable)
                    if ii ~= idx
                        set(app.filterTable.roi(ii).handle, 'InteractionsAllowed', 'none')
    
                    else
                        if app.filterTable.type == "Level"
                            set(app.filterTable.roi(ii).handle, 'InteractionsAllowed',  'translate')
                        else
                            set(app.filterTable.roi(ii).handle, 'InteractionsAllowed',  'all')
                        end                    
                    end
                end
                uistack(app.filterTable.roi(idx).handle, "top")
            end
            
        end

        % Image clicked function: filter_addImage
        function filter_addImageCallback(app, event)
            
            NodeData = app.filter_roiOptions.SelectedNodes.NodeData;
            
            app.filter_roiOptions.SelectedNodes = [];
            filter_ROISelectionChanged(app)

            set(app.filter_Tree, 'SelectedNodes', [], 'Enable', 0)
            set(app.filter_ContextMenu.Children, Enable=0)

            plot.axes.Interactivity.DefaultDisable([app.UIAxes1, app.UIAxes4])
            pause(.1)
            
            switch NodeData
                case 1
                    Type = 'Level'; SubType = 'Threshold';                        
                    THR  = min(app.filtTable.emissionPower);
                    hROI = images.roi.Line(app.UIAxes4, 'Position', [1 THR; height(app.filtTable) THR],  ...
                                                        'Color', 'red', 'MarkerSize', 4, 'LineWidth', 1, ...
                                                        'Deletable', 0, 'InteractionsAllowed', 'translate', 'Tag', 'ROI');
                otherwise
                    switch NodeData
                        case 2; Type = 'Geographic ROI'; SubType = 'Circle';    roiFcn = 'drawcircle';    roiNameArgument = '';
                        case 3; Type = 'Geographic ROI'; SubType = 'Rectangle'; roiFcn = 'drawrectangle'; roiNameArgument = 'Rotatable=true, ';
                        case 4; Type = 'Geographic ROI'; SubType = 'Polygon';   roiFcn = 'drawpolygon';   roiNameArgument = '';
                            
                    end
                    eval(sprintf('hROI = %s(app.UIAxes1, LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI");', roiFcn, roiNameArgument))
            end

            plot.axes.Interactivity.DefaultEnable([app.UIAxes1, app.UIAxes4])

            if isempty(hROI.Position)
                delete(hROI)
                
                app.filter_Tree.Enable = 1;
                if ~isempty(app.filter_Tree.Children)
                    app.filter_Tree.SelectedNodes = app.filter_Tree.Children(end);
                    filter_TreeSelectionChanged(app)
                end

                return
            end
            plot_AddListener2ROI(app, hROI)

            app.filterTable(end+1,:) = {Type, SubType, struct('handle', hROI, 'specification', filter_roiSpecification(app, SubType, hROI)), 1};
            filter_TreeBuilding(app)
            filter_updateInfo(app)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_Tree.SelectedNodes)
                        return
                    end
                    idx = arrayfun(@(x) x.NodeData, app.filter_Tree.SelectedNodes);

                case app.filter_delAllButton
                    idx = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx)
                arrayfun(@(x) delete(x), [app.filterTable.roi(idx).handle]); 
                app.filterTable(idx,:) = [];

                filter_TreeBuilding(app)
                filter_updateInfo(app)
            end

        end

        % Value changed function: filter_Customization
        function filter_CustomizationCheckBox(app, event)

            % try
                [idxThread, idxEmission] = specDataIndex(app);

            % catch ME
            %     app.filter_Customization.Value = event.PreviousValue;
            %     appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            %     return
            % end

            if app.filter_Customization.Value
                app.specData(idxThread).UserData.Emissions.UserData{idxEmission} = ParametersCreation(app);
            else
                app.specData(idxThread).UserData.Emissions.UserData{idxEmission} = [];
            end

        end

        % Selection changed function: points_addTypePanel
        function points_addTypePanelSelectionChanged(app, event)
            
            selectedButton = app.points_addTypePanel.SelectedObject;
            switch selectedButton
                case app.points_addRFDataHub;  app.points_addValueGrid.RowHeight = {17,22,22,22,0,0,0,0};
                case app.points_addFindPeaks;  app.points_addValueGrid.RowHeight = {0,0,0,0,17,22,22,22};
            end
            
        end

        % Image clicked function: points_addImage
        function points_addImageImageClicked(app, event)
            
            selectedButton = app.points_addTypePanel.SelectedObject;

            % try
                switch selectedButton
                    %-----------------------------------------------------%
                    case app.points_addRFDataHub
                        global RFDataHub
            
                        if isempty(char(app.points_addSubtype1TextArea.Value))
                            return
                        end
                        entryText = char(strjoin(app.points_addSubtype1TextArea.Value, ','));
            
                        switch app.points_addSubtype1DropDown.Value
                            case 'Índices de registros do RFDataHub'
                                idx = regexp(entryText, '#(\d+)', 'tokens');
                                idx = str2double([idx{:}]);
            
                            case 'Lista de frequências'
                                freqList = regexp(entryText, '(\d+.\d+)', 'tokens');
                                freqList = cellfun(@(x) str2double(x), [freqList{:}]);
            
                                filterTempTable = table('Size',          [0, 9],                                                                      ...
                                                        'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                                                        'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'});
            
                                for ii = 1:numel(freqList)
                                    if ii == 1; Order = 'Node';  RelatedID = -1;
                                    else;       Order = 'Child'; RelatedID = 1;
                                    end
                                    filterTempTable(ii,:) = {Order, ii, RelatedID, 'Frequência', '=', 1, {freqList(ii)}, true, ''};
                                end            
                                idx = find(fcn.TableFiltering(RFDataHub, filterTempTable));
                        end
        
                        if ~isempty(idx)
                            NN = numel(idx);
                            distanceArray = deg2km(distance(RFDataHub.Latitude(idx),   RFDataHub.Longitude(idx),  ...
                                                            app.specData(idxThread).GPS.Latitude, app.specData(idxThread).GPS.Longitude));
                            
                            idx(distanceArray > app.points_addSubtype1Distance.Value) = [];
                            MM = numel(idx);
                            if ~MM
                                error('Não identificada estação de telecomunicações que atenda às especificações...')
                            end
                            
                            newRow = {'RFDataHub', struct('source', app.points_addSubtype1DropDown.Value, 'idx', idx, 'Coordinates', RFDataHub{idx,6:7}), true};
                            points_TableCheck(app, newRow)
        
                            msg = sprintf('Identificada(s) %d estação(ões) de telecomunicações que atende(m) às especificações.', NN);
                            if MM ~= NN
                                msg = sprintf('%s Contudo, apenas %d atende(m) à distância máxima especificada.', msg, MM);
                            end
                        else
                            error('Não identificada estação de telecomunicações que atenda às especificações...')
                        end
                        appUtil.modalWindow(app.UIFigure, 'warning', msg);
    
                    %---------------------------------------------------------%
                    case app.points_addFindPeaks
                        [idx, Coordinates] = points_FindPeaks(app, app.points_addSubtype2DropDown.Value, app.points_addSubtype2NPeaks.Value, app.points_addSubtype2Distance.Value/1000);
                        newRow = {'FindPeaks', struct('source', app.points_addSubtype2DropDown.Value, 'idx', idx, 'Coordinates', Coordinates), true};
                        points_TableCheck(app, newRow)

                        msg = sprintf('Identificado(s) %d ponto(s) que atende(m) às especificações.', numel(idx));
                        appUtil.modalWindow(app.UIFigure, 'warning', msg);
                end

            % catch ME
            %     appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            %     return
            % end

            points_TreeBuilding(app)
            plot.old_axesDraw.DriveTestPointsPlot(app.UIAxes1, ParametersCreation(app))
            plotFcn.axes.StackingOrder.execute(app.UIAxes1, 'appAnalise:DRIVETEST')

        end

        % Value changed function: points_addSubtype1TextArea
        function points_addSubtype1TextAreaValueChanged(app, event)

            app.points_addSubtype1TextArea.Value = strtrim(app.points_addSubtype1TextArea.Value);
            
        end

        % Callback function: points_Tree
        function points_TreeCheckedNodesChanged(app, event)
            
            visibleNodeData   = arrayfun(@(x) x.NodeData, app.points_Tree.CheckedNodes);
            invisibleNodeData = setdiff(1:height(app.pointsTable), visibleNodeData);

            app.pointsTable.visible(visibleNodeData)   = true;
            app.pointsTable.visible(invisibleNodeData) = false;

            plot.old_axesDraw.DriveTestPointsPlot(app.UIAxes1, ParametersCreation(app))
            plotFcn.axes.StackingOrder.execute(app.UIAxes1, 'appAnalise:DRIVETEST')
            
        end

        % Menu selected function: points_delButton
        function points_delButtonMenuSelected(app, event)
            
            if isempty(app.pointsTable) || isempty(app.points_Tree.SelectedNodes)
                return
            end

            idx = arrayfun(@(x) x.NodeData, app.points_Tree.SelectedNodes);
    
            if ~isempty(idx)
                app.pointsTable(idx,:) = [];

                points_TreeBuilding(app)
                plot.old_axesDraw.DriveTestPointsPlot(app.UIAxes1, ParametersCreation(app))
                plot.axes.StackingOrder.execute(app.UIAxes1, 'appAnalise:DRIVETEST')
            end

        end

        % Value changed function: binTable_Type
        function binTable_TypeChanged(app, event)

            binning_Layout(app)
            binTable_ValueChanged(app)
            
        end

        % Value changed function: binTable_Fcn, binTable_Value
        function binTable_ValueChanged(app, event)
            
            % d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            
            binning_binTable(app)
            binning_UITable(app)

            [idxThread, idxEmission] = specDataIndex(app);
            if app.layout_mapPlotSource_binData.Value
                plot_MainControl(app, idxThread)
            else                
                h = findall(app.UIAxes4, 'Tag', 'binTable');
                h.YData = app.binTable.binPower(app.filtTable.FK2);
            end

            filter_updateProp(app, idxThread, idxEmission)

            % delete(d)
            
        end

        % Selection changed function: layout_mapPlotSourcePanel
        function plot_RefreshCallback(app, event)
            
            % d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            
            [idxThread, idxEmission] = specDataIndex(app);
            plot_MainControl(app, idxThread)
            filter_updateProp(app, idxThread, idxEmission)

            % delete(d)

        end

        % Button pushed function: layout_Car_Color, layout_points_Color, 
        % ...and 2 other components
        function plot_colorCallbacks(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                event.Source.BackgroundColor = selectedColor;

                switch event.Source
                    case app.layout_route_Color1
                        set(findobj(app.UIAxes1.Children, 'Tag', 'OutRoute'), 'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                    case app.layout_route_Color2
                        set(findobj(app.UIAxes1.Children, 'Tag', 'InRoute'),  'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                    case app.layout_Car_Color
                        set(app.hCar, 'MarkerFaceColor', selectedColor)

                    case app.layout_points_Color
                        hPoints = findobj(app.UIAxes1.Children, 'Tag', 'Points');
                        for ii = 1:numel(hPoints)
                            if strcmp(hPoints(ii).MarkerFaceColor, 'none')
                                set(hPoints(ii), 'Color', selectedColor, 'MarkerEdgeColor', selectedColor)
                            else
                                set(hPoints(ii), 'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
                            end
                        end
                end
            end

            [idxThread, idxEmission] = specDataIndex(app);
            filter_updateProp(app, idxThread, idxEmission)

        end

        % Callback function: layout_Car_LineStyle, layout_Car_Size, 
        % ...and 5 other components
        function plot_layoutCallbacks(app, event)
            
            % try
                switch event.Source
                    case app.layout_route_LineStyle
                        switch event.Value
                            case 'none'; markerSize = 1;
                            otherwise;   markerSize = 8*app.layout_route_Size.Value;
                        end
                        set(findobj(app.UIAxes1.Children, 'Tag', 'OutRoute'),                          'MarkerSize', markerSize)
                        set(findobj(app.UIAxes1.Children, 'Tag', 'InRoute'), 'LineStyle', event.Value, 'MarkerSize', markerSize)

                    case app.layout_route_Size
                        if ~strcmp(app.layout_route_LineStyle.Value, 'none')
                            set(findobj(app.UIAxes1.Children, 'Tag', 'OutRoute'), 'MarkerSize', 8*event.Value)
                            set(findobj(app.UIAxes1.Children, 'Tag', 'InRoute'),  'MarkerSize', 8*event.Value)
                        end

                    case app.layout_Car_Size
                        set(app.hCar, 'SizeData', 10*event.Value)

                    case app.layout_Car_LineStyle
                        set(app.hCar, 'Marker', event.Value)

                    case app.layout_points_Size
                        set(findobj(app.UIAxes1.Children, 'Tag', 'Points'), 'MarkerSize', event.Value)

                    case app.layout_points_LineStyle
                        set(findobj(app.UIAxes1.Children, 'Tag', 'Points'), 'Marker', event.Value)

                    case app.layout_Colormap
                        plot.axes.Colormap(app.UIAxes1, app.layout_Colormap.Value)
                        plot.axes.Colormap(app.UIAxes2, app.layout_Colormap.Value)
                        plot.axes.Colormap(app.UIAxes3, app.layout_Colormap.Value)
                end

                [idxThread, idxEmission] = specDataIndex(app);
                filter_updateProp(app, idxThread, idxEmission)
            % catch
            % end

        end

        % Value changed function: geoAxesTool_PlotSize
        function plot_SizeChangedCallback(app, event)

            % d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            set(findobj(app.UIAxes1, 'Tag', 'Distortion'), 'SizeData', 20*event.Value)
            set(findobj(app.UIAxes1, 'Tag', 'Density'),    'Radius',  100*event.Value)
            drawnow

            [idxThread, idxEmission] = specDataIndex(app);
            filter_updateProp(app, idxThread, idxEmission)

            % delete(d)

        end

        % Value changed function: layout_chPowerSource_binTable, 
        % ...and 1 other component
        function plot_SourceChanged(app, event)
            
            switch event.Source
                case app.layout_chPowerSource_rawTable; Tag = 'rawTable';
                case app.layout_chPowerSource_binTable; Tag = 'binTable';
            end

            h = findall(app.UIAxes4, 'Tag', Tag);
            if event.Source.Value
                h.Visible = 1;
            else
                h.Visible = 0;
            end

        end

        % Value changed function: layout_chPowerSource_smoothed
        function plot_smoothedDataVisibility(app, event)
            
            if app.layout_chPowerSource_smoothed.Value
                set(app.layout_Smoothed_Grid.Children, 'Enable', 1)
                set(findobj(app.UIAxes4.Children, 'Tag', 'SmoothedData'), Visible=1)

            else
                set(app.layout_Smoothed_Grid.Children, 'Enable', 0)
                set(findobj(app.UIAxes4.Children, 'Tag', 'SmoothedData'), Visible=0)
            end            
            
        end

        % Value changed function: layout_Smoothed_Factor, 
        % ...and 1 other component
        function plot_smoothedDataUpdate(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");            
            set(findobj(app.UIAxes4.Children, 'Tag', 'SmoothedData'), YData=smoothdata(app.filtTable.emissionPower, app.layout_Smoothed_Type.Value, 'SmoothingFactor', app.layout_Smoothed_Factor.Value))
            delete(d)
            
        end

        % Image clicked function: tool_LoopControl, tool_Play
        function play_PlaybackToolbarButtonCallback(app, event)
            
            switch event.Source
                case app.tool_Play
                    idx = [];
                    if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                        idx = app.play_PlotPanel.UserData.NodeData;
                    end
        
                    if idx && ~app.plotFlag                        
                        app.plotFlag = 1;
                        app.tool_Play.ImageSource = 'stop_32.png';                
                        plot_mainLoop(app, idx, numel(app.specData(idx).Data{1}))
        
                    else
                        app.plotFlag = 0;
                        app.tool_Play.ImageSource = 'play_32.png';
                    end

                %---------------------------------------------------------%
                case app.tool_LoopControl
                    switch app.tool_LoopControl.Tag
                        case 'loop';   set(app.tool_LoopControl, Tag='direct', ImageSource='playbackStraight_32Blue.png')
                        case 'direct'; set(app.tool_LoopControl, Tag='loop',   ImageSource='playbackLoop_32Blue.png')
                    end
            end

        end

        % Value changing function: tool_TimestampSlider
        function tool_TimestampSliderValueChanging(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            nSweeps = app.tempBandObj.nSweeps;
            
            app.idxTime = round(event.Value/100 * nSweeps);
            if app.idxTime < 1
                app.idxTime = 1;
            elseif app.idxTime > nSweeps
                app.idxTime = nSweeps;
            end

            if ~app.plotFlag
                app.hClearWrite.YData = app.specData(idx).Data{2}(:, app.idxTime)';
                plot.draw2D.OrdinaryLineUpdate(app.hWaterfallTime, app.bandObj, idx, 'WaterfallTime');
                app.tool_TimestampLabel.Text = sprintf('%d de %d\n%s', app.idxTime, nSweeps, app.specData(idx).Data{1}(app.idxTime));
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Drive-test';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.plotPanel = uipanel(app.Grid);
            app.toolGrid = uigridlayout(app.Grid);
            app.tool_ControlPanelVisibility = uiimage(app.toolGrid);
            app.tool_ControlPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @toolbarCallbacks, true);
            app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.tool_resetButton = uiimage(app.toolGrid);
            app.tool_resetButton.ImageClickedFcn = createCallbackFcn(app, @resetFcn, true);
            app.tool_resetButton.ImageSource = 'Dots_32.png';
            app.jsBackDoor = uihtml(app.toolGrid);
            app.tool_RefreshScreenSize = uiimage(app.toolGrid);
            app.tool_RefreshScreenSize.ImageClickedFcn = createCallbackFcn(app, @toolbarCallbacks, true);
            app.tool_RefreshScreenSize.ImageSource = 'layout1_32.png';
            app.tool_TimestampLabel = uilabel(app.toolGrid);
            app.tool_TimestampLabel.Text = {'22 de 328 '; '22/02/2022 08:00:00 '};
            app.tool_Play = uiimage(app.toolGrid);
            app.tool_Play.ImageClickedFcn = createCallbackFcn(app, @play_PlaybackToolbarButtonCallback, true);
            app.tool_Play.Tooltip = {'Playback'};
            app.tool_Play.ImageSource = 'play_32.png';
            app.tool_LoopControl = uiimage(app.toolGrid);
            app.tool_LoopControl.ImageClickedFcn = createCallbackFcn(app, @play_PlaybackToolbarButtonCallback, true);
            app.tool_LoopControl.Tag = 'loop';
            app.tool_LoopControl.Tooltip = {'Loop do playback'};
            app.tool_LoopControl.ImageSource = 'playbackLoop_32Blue.png';
            app.tool_TimestampSlider = uislider(app.toolGrid);
            app.tool_TimestampSlider.MajorTicks = [0 50 100];
            app.tool_TimestampSlider.MajorTickLabels = {''};
            app.tool_TimestampSlider.ValueChangingFcn = createCallbackFcn(app, @tool_TimestampSliderValueChanging, true);
            app.tool_TimestampSlider.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.tool_TimestampSlider.Value = 56.5677966101695;
            app.tool_ExportButton = uiimage(app.toolGrid);
            app.tool_ExportButton.ImageClickedFcn = createCallbackFcn(app, @tool_ExportFileButtonPushed, true);
            app.tool_ExportButton.ImageSource = 'Export_24.png';
            app.ControlTabGrid = uigridlayout(app.Grid);
            app.ControlTabGroup = uitabgroup(app.ControlTabGrid);
            app.Tab1_Emission = uitab(app.ControlTabGroup);
            app.Tab1_Panel = uigridlayout(app.Tab1_Emission);
            app.emissionListGrid = uigridlayout(app.Tab1_Panel);
            app.emissionListLabel = uilabel(app.emissionListGrid);
            app.emissionListLabel.Text = 'EMISSÃO';
            app.emissionListRefresh = uiimage(app.emissionListGrid);
            app.emissionListRefresh.ImageClickedFcn = createCallbackFcn(app, @emission_RefreshListImageClicked, true);
            app.emissionListRefresh.ImageSource = 'Refresh_18.png';
            app.emissionList = uidropdown(app.Tab1_Panel);
            app.emissionList.Items = {''};
            app.emissionList.ValueChangedFcn = createCallbackFcn(app, @emission_ValueChanged, true);
            app.emissionList.Value = '';
            app.filter_Customization = uicheckbox(app.Tab1_Panel);
            app.filter_Customization.ValueChangedFcn = createCallbackFcn(app, @filter_CustomizationCheckBox, true);
            app.filter_Customization.Text = 'Customizar controles do plot, habilitando a sua inclusão em relatório.';
            app.Panel = uipanel(app.Tab1_Panel);
            app.GridLayout = uigridlayout(app.Panel);
            app.emissionMetaData = uihtml(app.GridLayout);
            app.emissionMetaData.HTMLSource = ' ';
            app.Tab2_Filter = uitab(app.ControlTabGroup);
            app.GridLayout2 = uigridlayout(app.Tab2_Filter);
            app.filter_TreeLabel = uilabel(app.GridLayout2);
            app.filter_TreeLabel.Text = 'FILTRO';
            app.filter_roiOptions = uitree(app.GridLayout2);
            app.filter_roiOptions.SelectionChangedFcn = createCallbackFcn(app, @filter_ROISelectionChanged, true);
            app.levelFilterNode = uitreenode(app.filter_roiOptions);
            app.levelFilterNode.Text = 'NÍVEL';
            app.levelFilter_thr = uitreenode(app.levelFilterNode);
            app.levelFilter_thr.NodeData = 1;
            app.levelFilter_thr.Text = 'THRESHOLD';
            app.geoFilterNode = uitreenode(app.filter_roiOptions);
            app.geoFilterNode.Text = 'GEOGRÁFICO';
            app.geoFilter_Circle = uitreenode(app.geoFilterNode);
            app.geoFilter_Circle.NodeData = 2;
            app.geoFilter_Circle.Text = 'CÍRCULO';
            app.geoFilter_Rectangle = uitreenode(app.geoFilterNode);
            app.geoFilter_Rectangle.NodeData = 3;
            app.geoFilter_Rectangle.Text = 'RETÂNGULO';
            app.geoFilter_Polygon = uitreenode(app.geoFilterNode);
            app.geoFilter_Polygon.NodeData = 4;
            app.geoFilter_Polygon.Text = 'POLÍGONO';
            app.filter_Tree = uitree(app.GridLayout2);
            app.filter_Tree.SelectionChangedFcn = createCallbackFcn(app, @filter_TreeSelectionChanged, true);
            app.filter_addImage = uiimage(app.GridLayout2);
            app.filter_addImage.ImageClickedFcn = createCallbackFcn(app, @filter_addImageCallback, true);
            app.filter_addImage.Enable = 'off';
            app.filter_addImage.ImageSource = 'addSymbol_32.png';
            app.Tab3_Points = uitab(app.ControlTabGroup);
            app.Tab2_Panel = uigridlayout(app.Tab3_Points);
            app.points_TreeLabel = uilabel(app.Tab2_Panel);
            app.points_TreeLabel.Text = 'PONTOS DE INTERESSE';
            app.points_addTypePanel = uibuttongroup(app.Tab2_Panel);
            app.points_addTypePanel.SelectionChangedFcn = createCallbackFcn(app, @points_addTypePanelSelectionChanged, true);
            app.points_addRFDataHub = uiradiobutton(app.points_addTypePanel);
            app.points_addRFDataHub.Text = {'Estações de telecomunicações'; '(RFDataHub)'};
            app.points_addRFDataHub.Value = true;
            app.points_addFindPeaks = uiradiobutton(app.points_addTypePanel);
            app.points_addFindPeaks.Text = {'Picos da potência'; 'do canal'};
            app.points_addValuePanel = uipanel(app.Tab2_Panel);
            app.points_addValueGrid = uigridlayout(app.points_addValuePanel);
            app.points_addSubtype1Label = uilabel(app.points_addValueGrid);
            app.points_addSubtype1Label.Text = 'Tipo de registro:';
            app.points_addSubtype1DropDown = uidropdown(app.points_addValueGrid);
            app.points_addSubtype1DropDown.Items = {'Índices de registros do RFDataHub', 'Lista de frequências'};
            app.points_addSubtype1DropDown.Value = 'Índices de registros do RFDataHub';
            app.points_addSubtype1TextArea = uitextarea(app.points_addValueGrid);
            app.points_addSubtype1TextArea.ValueChangedFcn = createCallbackFcn(app, @points_addSubtype1TextAreaValueChanged, true);
            app.points_addSubtype1DistanceLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype1DistanceLabel.Text = 'Distância máxima entre estação e local da monitoração (km):';
            app.points_addSubtype1Distance = uieditfield(app.points_addValueGrid, 'numeric');
            app.points_addSubtype1Distance.Limits = [1 Inf];
            app.points_addSubtype1Distance.RoundFractionalValues = 'on';
            app.points_addSubtype1Distance.ValueDisplayFormat = '%d';
            app.points_addSubtype1Distance.Value = 200;
            app.points_addSubtype2Label = uilabel(app.points_addValueGrid);
            app.points_addSubtype2Label.Text = 'Fonte da informação:';
            app.points_addSubtype2DropDown = uidropdown(app.points_addValueGrid);
            app.points_addSubtype2DropDown.Items = {'Dados brutos', 'Dados processados (Data Binning)', 'Curva suave'};
            app.points_addSubtype2DropDown.Value = 'Dados brutos';
            app.points_addSubtype2NPeaksLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype2NPeaksLabel.Text = 'Número de picos:';
            app.points_addSubtype2NPeaks = uispinner(app.points_addValueGrid);
            app.points_addSubtype2NPeaks.Limits = [1 100];
            app.points_addSubtype2NPeaks.RoundFractionalValues = 'on';
            app.points_addSubtype2NPeaks.ValueDisplayFormat = '%.0f';
            app.points_addSubtype2NPeaks.Value = 1;
            app.points_addSubtype2DistanceLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype2DistanceLabel.Text = 'Distância mínima entre picos (metros):';
            app.points_addSubtype2Distance = uispinner(app.points_addValueGrid);
            app.points_addSubtype2Distance.Step = 100;
            app.points_addSubtype2Distance.Limits = [0 10000];
            app.points_addSubtype2Distance.RoundFractionalValues = 'on';
            app.points_addSubtype2Distance.ValueDisplayFormat = '%.0f';
            app.points_addSubtype2Distance.Value = 1000;
            app.points_addImage = uiimage(app.Tab2_Panel);
            app.points_addImage.ImageClickedFcn = createCallbackFcn(app, @points_addImageImageClicked, true);
            app.points_addImage.ImageSource = 'addSymbol_32.png';
            app.points_Tree = uitree(app.Tab2_Panel, 'checkbox');
            % Assign Checked Nodes
            app.points_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @points_TreeCheckedNodesChanged, true);
            app.Tab4_DataBinning = uitab(app.ControlTabGroup);
            app.Tab3_Panel = uigridlayout(app.Tab4_DataBinning);
            app.binTable_Label = uilabel(app.Tab3_Panel);
            app.binTable_Label.Text = 'DATA BINNING';
            app.binTable_Panel = uipanel(app.Tab3_Panel);
            app.binTable_Grid1 = uigridlayout(app.binTable_Panel);
            app.binTable_TypeLabel = uilabel(app.binTable_Grid1);
            app.binTable_TypeLabel.Text = 'Tipo:';
            app.binTable_Type = uidropdown(app.binTable_Grid1);
            app.binTable_Type.Items = {'Grid retangular - Comprimento bin', 'Grid retangular - Coordenadas geográficas truncadas'};
            app.binTable_Type.ValueChangedFcn = createCallbackFcn(app, @binTable_TypeChanged, true);
            app.binTable_Type.Value = 'Grid retangular - Comprimento bin';
            app.binTable_ValueLabel = uilabel(app.binTable_Grid1);
            app.binTable_ValueLabel.Interpreter = 'html';
            app.binTable_ValueLabel.Text = 'Comprimento <i>bin</i> (metros):';
            app.binTable_Value = uispinner(app.binTable_Grid1);
            app.binTable_Value.Step = 50;
            app.binTable_Value.Limits = [50 1500];
            app.binTable_Value.RoundFractionalValues = 'on';
            app.binTable_Value.ValueDisplayFormat = '%.0f';
            app.binTable_Value.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Value.Value = 100;
            app.binTable_FcnLabel = uilabel(app.binTable_Grid1);
            app.binTable_FcnLabel.Text = 'Operação de sumarização:';
            app.binTable_Fcn = uidropdown(app.binTable_Grid1);
            app.binTable_Fcn.Items = {'min', 'mean', 'median', 'rms', 'max'};
            app.binTable_Fcn.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Fcn.Value = 'min';
            app.binTable_UITable = uitable(app.Tab3_Panel);
            app.binTable_UITable.ColumnName = {'ID'; 'LATITUDE'; 'LONGITUDE'; 'AMOSTRAS'};
            app.binTable_UITable.RowName = {};
            app.binTable_UITable.SelectionType = 'row';
            app.binTable_Info = uilabel(app.Tab3_Panel);
            app.binTable_Info.Text = 'Placeholder...';
            app.Tab5_Config = uitab(app.ControlTabGroup);
            app.Tab4_Panel = uigridlayout(app.Tab5_Config);
            app.layout_mapPlotTitle = uilabel(app.Tab4_Panel);
            app.layout_mapPlotTitle.Text = 'EIXO GEOGRÁFICO';
            app.layout_mapPlotPanel = uipanel(app.Tab4_Panel);
            app.layout_mapPlotGrid = uigridlayout(app.layout_mapPlotPanel);
            app.layout_mapPlotSourceLabel = uilabel(app.layout_mapPlotGrid);
            app.layout_mapPlotSourceLabel.Text = 'Fonte da informação:';
            app.layout_mapPlotSourcePanel = uibuttongroup(app.layout_mapPlotGrid);
            app.layout_mapPlotSourcePanel.SelectionChangedFcn = createCallbackFcn(app, @plot_RefreshCallback, true);
            app.layout_mapPlotSource_rawData = uiradiobutton(app.layout_mapPlotSourcePanel);
            app.layout_mapPlotSource_rawData.Text = 'Dados brutos';
            app.layout_mapPlotSource_rawData.Value = true;
            app.layout_mapPlotSource_binData = uiradiobutton(app.layout_mapPlotSourcePanel);
            app.layout_mapPlotSource_binData.Text = 'Dados processados (Data Binning)';
            app.layout_mapPlotLayoutLabel = uilabel(app.layout_mapPlotGrid);
            app.layout_mapPlotLayoutLabel.Text = 'Layout:';
            app.layout_mapPlotLayoutPanel = uipanel(app.layout_mapPlotGrid);
            app.layout_mapPlotLayoutGrid = uigridlayout(app.layout_mapPlotLayoutPanel);
            app.layout_route_Label = uilabel(app.layout_mapPlotLayoutGrid);
            app.layout_route_Label.Text = 'Rota:';
            app.layout_route_LineStyle = uidropdown(app.layout_mapPlotLayoutGrid);
            app.layout_route_LineStyle.Items = {'none', ':', '-'};
            app.layout_route_LineStyle.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_route_LineStyle.Value = 'none';
            app.layout_route_Panel = uipanel(app.layout_mapPlotLayoutGrid);
            app.layout_route_Grid = uigridlayout(app.layout_route_Panel);
            app.layout_route_Color1 = uibutton(app.layout_route_Grid, 'push');
            app.layout_route_Color1.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.layout_route_Color1.Text = '';
            app.layout_route_Color2 = uibutton(app.layout_route_Grid, 'push');
            app.layout_route_Color2.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.layout_route_Color2.Text = '';
            app.layout_route_Size = uislider(app.layout_route_Grid);
            app.layout_route_Size.Limits = [1 9];
            app.layout_route_Size.MajorTicks = [];
            app.layout_route_Size.ValueChangingFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_route_Size.MinorTicks = [];
            app.layout_route_Size.Value = 1;
            app.layout_Car_Label = uilabel(app.layout_mapPlotLayoutGrid);
            app.layout_Car_Label.Text = 'Veículo:';
            app.layout_Car_LineStyle = uidropdown(app.layout_mapPlotLayoutGrid);
            app.layout_Car_LineStyle.Items = {'none', 'o', 'square', '^'};
            app.layout_Car_LineStyle.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_Car_LineStyle.Value = 'none';
            app.layout_Car_Panel = uipanel(app.layout_mapPlotLayoutGrid);
            app.layout_Car_Grid = uigridlayout(app.layout_Car_Panel);
            app.layout_Car_Color = uibutton(app.layout_Car_Grid, 'push');
            app.layout_Car_Color.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.layout_Car_Color.Text = '';
            app.layout_Car_Size = uislider(app.layout_Car_Grid);
            app.layout_Car_Size.Limits = [1 19];
            app.layout_Car_Size.MajorTicks = [];
            app.layout_Car_Size.ValueChangingFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_Car_Size.MinorTicks = [];
            app.layout_Car_Size.Value = 10;
            app.layout_points_Label = uilabel(app.layout_mapPlotLayoutGrid);
            app.layout_points_Label.Text = 'Pontos interesse:';
            app.layout_points_LineStyle = uidropdown(app.layout_mapPlotLayoutGrid);
            app.layout_points_LineStyle.Items = {'none', 'o', 'square', '^'};
            app.layout_points_LineStyle.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_points_LineStyle.Value = 'none';
            app.layout_points_Panel = uipanel(app.layout_mapPlotLayoutGrid);
            app.layout_points_Grid = uigridlayout(app.layout_points_Panel);
            app.layout_points_Color = uibutton(app.layout_points_Grid, 'push');
            app.layout_points_Color.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.layout_points_Color.Text = '';
            app.layout_points_Size = uislider(app.layout_points_Grid);
            app.layout_points_Size.Limits = [6 12];
            app.layout_points_Size.MajorTicks = [];
            app.layout_points_Size.ValueChangingFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_points_Size.MinorTicks = [];
            app.layout_points_Size.Value = 9;
            app.layout_ColormapLabel = uilabel(app.layout_mapPlotLayoutGrid);
            app.layout_ColormapLabel.Text = {'Mapa de'; 'cor:'};
            app.layout_Colormap = uidropdown(app.layout_mapPlotLayoutGrid);
            app.layout_Colormap.Items = {'hot', 'summer', 'winter', 'jet'};
            app.layout_Colormap.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_Colormap.Value = 'hot';
            app.layout_cartesianPlotTitle = uilabel(app.Tab4_Panel);
            app.layout_cartesianPlotTitle.Text = 'EIXO CARTESIANO';
            app.layout_cartesianPlotPanel = uipanel(app.Tab4_Panel);
            app.layout_cartesianPlotGrid = uigridlayout(app.layout_cartesianPlotPanel);
            app.layout_cartesianPlotLabel = uilabel(app.layout_cartesianPlotGrid);
            app.layout_cartesianPlotLabel.Text = 'Fonte da informação:';
            app.layout_cartesianPlotSubPanel = uipanel(app.layout_cartesianPlotGrid);
            app.layout_cartesianPlotSubGrid = uigridlayout(app.layout_cartesianPlotSubPanel);
            app.layout_chPowerSource_rawTable = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.layout_chPowerSource_rawTable.ValueChangedFcn = createCallbackFcn(app, @plot_SourceChanged, true);
            app.layout_chPowerSource_rawTable.Text = 'Dados brutos';
            app.layout_chPowerSource_rawTable.Value = true;
            app.layout_chPowerSource_binTable = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.layout_chPowerSource_binTable.ValueChangedFcn = createCallbackFcn(app, @plot_SourceChanged, true);
            app.layout_chPowerSource_binTable.Text = 'Dados processados (Data Binning)';
            app.layout_chPowerSource_smoothed = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.layout_chPowerSource_smoothed.ValueChangedFcn = createCallbackFcn(app, @plot_smoothedDataVisibility, true);
            app.layout_chPowerSource_smoothed.Text = 'Curva suave';
            app.layout_Smoothed_Panel = uipanel(app.layout_cartesianPlotSubGrid);
            app.layout_Smoothed_Grid = uigridlayout(app.layout_Smoothed_Panel);
            app.layout_Smoothed_TypeLabel = uilabel(app.layout_Smoothed_Grid);
            app.layout_Smoothed_TypeLabel.Enable = 'off';
            app.layout_Smoothed_TypeLabel.Text = 'Tipo:';
            app.layout_Smoothed_Type = uidropdown(app.layout_Smoothed_Grid);
            app.layout_Smoothed_Type.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.layout_Smoothed_Type.ValueChangedFcn = createCallbackFcn(app, @plot_smoothedDataUpdate, true);
            app.layout_Smoothed_Type.Enable = 'off';
            app.layout_Smoothed_Type.Value = 'movmean';
            app.layout_Smoothed_FactorLabel = uilabel(app.layout_Smoothed_Grid);
            app.layout_Smoothed_FactorLabel.Enable = 'off';
            app.layout_Smoothed_FactorLabel.Text = 'Fator:';
            app.layout_Smoothed_Factor = uispinner(app.layout_Smoothed_Grid);
            app.layout_Smoothed_Factor.Step = 0.1;
            app.layout_Smoothed_Factor.Limits = [0 1];
            app.layout_Smoothed_Factor.ValueDisplayFormat = '%.2f';
            app.layout_Smoothed_Factor.ValueChangedFcn = createCallbackFcn(app, @plot_smoothedDataUpdate, true);
            app.layout_Smoothed_Factor.Enable = 'off';
            app.layout_Smoothed_Factor.Value = 0.25;
            app.Panel_2 = uipanel(app.Tab4_Panel);
            app.Panel_2.Title = 'Panel';
            app.play_MinPlotTime = uispinner(app.Panel_2);
            app.play_MinPlotTime.Step = 25;
            app.play_MinPlotTime.Limits = [50 1000];
            app.play_MinPlotTime.RoundFractionalValues = 'on';
            app.play_MinPlotTime.ValueDisplayFormat = '%.0f';
            app.play_MinPlotTime.Value = 50;
            app.play_MinPlotTimeLabel = uilabel(app.Panel_2);
            app.play_MinPlotTimeLabel.Text = {'Tempo mínimo'; 'escrita (ms):'};
            app.Panel2 = uipanel(app.Panel_2);
            app.Panel2.Title = 'Panel2';
            app.play_Waterfall_Fcn = uidropdown(app.Panel2);
            app.play_Waterfall_Fcn.Items = {'image', 'mesh'};
            app.play_Waterfall_Fcn.Value = 'image';
            app.play_Waterfall_FcnLabel = uilabel(app.Panel2);
            app.play_Waterfall_FcnLabel.Text = 'Função:';
            app.play_Waterfall_ColorbarLabel = uilabel(app.Panel2);
            app.play_Waterfall_ColorbarLabel.Text = 'Legenda de cor:';
            app.play_Waterfall_Colorbar = uidropdown(app.Panel2);
            app.play_Waterfall_Colorbar.Items = {'off', 'west', 'east', 'eastoutside'};
            app.play_Waterfall_Colorbar.Enable = 'off';
            app.play_Waterfall_Colorbar.Value = 'off';
            app.play_Waterfall_ColormapLabel = uilabel(app.Panel2);
            app.play_Waterfall_ColormapLabel.Text = 'Mapa de cor:';
            app.play_Waterfall_Colormap = uidropdown(app.Panel2);
            app.play_Waterfall_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.play_Waterfall_Colormap.Value = 'winter';
            app.menu_MainGrid = uigridlayout(app.ControlTabGrid);
            app.menuUnderline = uiimage(app.menu_MainGrid);
            app.menuUnderline.ImageSource = 'LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'Aspectos gerais';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'Dots_18.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'Filtragem';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'Filter_18.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'Pontos de interesse';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'Pin_18.png';
            app.menu_Button4Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button4Label = uilabel(app.menu_Button4Grid);
            app.menu_Button4Label.Text = 'Data binning';
            app.menu_Button4Icon = uiimage(app.menu_Button4Grid);
            app.menu_Button4Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button4Icon.Tag = '4';
            app.menu_Button4Icon.ImageSource = 'Detection_18.png';
            app.menu_Button5Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button5Label = uilabel(app.menu_Button5Grid);
            app.menu_Button5Label.Text = 'Layout';
            app.menu_Button5Icon = uiimage(app.menu_Button5Grid);
            app.menu_Button5Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button5Icon.Tag = '5';
            app.menu_Button5Icon.ImageSource = 'Layers_18.png';
            app.axesToolbarGrid_2 = uigridlayout(app.Grid);
            app.geoAxesTool_RestoreView_2 = uiimage(app.axesToolbarGrid_2);
            app.geoAxesTool_RestoreView_2.ImageSource = 'Home_18.png';
            app.geoAxesTool_RegionZoom_2 = uiimage(app.axesToolbarGrid_2);
            app.geoAxesTool_RegionZoom_2.ImageSource = 'Pan_32.png';
            app.Image = uiimage(app.axesToolbarGrid_2);
            app.Image.ImageSource = 'simple-black-vector-ruler-icon-800x566c.png';
            app.Image2 = uiimage(app.axesToolbarGrid_2);
            app.Image2.ImageSource = 'ZoomOut_20.png';
            app.axesToolbarGrid = uigridlayout(app.Grid);
            app.geoAxesTool_RestoreView = uiimage(app.axesToolbarGrid);
            app.geoAxesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @axesToolbarCallbacks, true);
            app.geoAxesTool_RestoreView.ImageSource = 'Home_18.png';
            app.geoAxesTool_RegionZoom = uiimage(app.axesToolbarGrid);
            app.geoAxesTool_RegionZoom.ImageClickedFcn = createCallbackFcn(app, @axesToolbarCallbacks, true);
            app.geoAxesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.geoAxesTool_PlotType = uiimage(app.axesToolbarGrid);
            app.geoAxesTool_PlotType.ImageClickedFcn = createCallbackFcn(app, @axesToolbarCallbacks, true);
            app.geoAxesTool_PlotType.ImageSource = 'DriveTestDistortion_32.png';
            app.geoAxesTool_PlotSize = uislider(app.axesToolbarGrid);
            app.geoAxesTool_PlotSize.Limits = [1 19];
            app.geoAxesTool_PlotSize.MajorTicks = [1 10 19];
            app.geoAxesTool_PlotSize.MajorTickLabels = {''};
            app.geoAxesTool_PlotSize.ValueChangedFcn = createCallbackFcn(app, @plot_SizeChangedCallback, true);
            app.geoAxesTool_PlotSize.Value = 1;
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.points_ContextMenu = uicontextmenu(app.UIFigure);
            app.points_delButton = uimenu(app.points_ContextMenu);
            app.points_delButton.MenuSelectedFcn = createCallbackFcn(app, @points_delButtonMenuSelected, true);
            app.points_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end