classdef winRFDataHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid                           matlab.ui.container.GridLayout
        menu_MainGrid                  matlab.ui.container.GridLayout
        menu_Button3Grid               matlab.ui.container.GridLayout
        menu_Button3Icon               matlab.ui.control.Image
        menu_Button3Label              matlab.ui.control.Label
        menu_Button2Grid               matlab.ui.container.GridLayout
        menu_Button2Icon               matlab.ui.control.Image
        menu_Button2Label              matlab.ui.control.Label
        menu_Button1Grid               matlab.ui.container.GridLayout
        menu_Button1Icon               matlab.ui.control.Image
        menu_Button1Label              matlab.ui.control.Label
        menu_Image                     matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Tab1_infoGrid                  matlab.ui.container.GridLayout
        points_Panel                   matlab.ui.container.Panel
        points_Grid                    matlab.ui.container.GridLayout
        points_editButton              matlab.ui.control.Button
        points_AntennaHeight           matlab.ui.control.NumericEditField
        points_AntennaHeightLabel      matlab.ui.control.Label
        points_Longitude               matlab.ui.control.NumericEditField
        points_LongitudeLabel          matlab.ui.control.Label
        points_Latitude                matlab.ui.control.NumericEditField
        points_LatitudeLabel           matlab.ui.control.Label
        points_Tree                    matlab.ui.container.Tree
        points_refreshImage            matlab.ui.control.Image
        points_TreeLabel               matlab.ui.control.Label
        layout_tableButton             ccTools.Button
        layout_pdfButton               ccTools.Button
        layout_histogramButton         ccTools.Button
        layout_mapButton               ccTools.Button
        Tab_2                          matlab.ui.container.Tab
        Tab2_infoGrid                  matlab.ui.container.GridLayout
        filter_Tree                    matlab.ui.container.CheckBoxTree
        filter_addImage                matlab.ui.control.Image
        filter_operationPanel          matlab.ui.container.ButtonGroup
        filter_operationValuesPanel    matlab.ui.container.Panel
        filter_operationValuesGrid     matlab.ui.container.GridLayout
        filter_operationLogical        matlab.ui.control.DropDown
        filter_operationLogicalLabel   matlab.ui.control.Label
        filter_operationLogicalReference  matlab.ui.control.DropDown
        filter_operationLogicalReferenceLabel  matlab.ui.control.Label
        filter_operationTextListValue  matlab.ui.control.DropDown
        filter_operationTextFreeValue  matlab.ui.control.EditField
        filter_operationNumValue2      matlab.ui.control.NumericEditField
        filter_operationSeparator      matlab.ui.control.Label
        filter_operationNumValue1      matlab.ui.control.NumericEditField
        filter_operationButton10       matlab.ui.control.ToggleButton
        filter_operationButton9        matlab.ui.control.ToggleButton
        filter_operationButton8        matlab.ui.control.ToggleButton
        filter_operationButton7        matlab.ui.control.ToggleButton
        filter_operationButton6        matlab.ui.control.ToggleButton
        filter_operationButton5        matlab.ui.control.ToggleButton
        filter_operationButton4        matlab.ui.control.ToggleButton
        filter_operationButton3        matlab.ui.control.ToggleButton
        filter_operationButton2        matlab.ui.control.ToggleButton
        filter_operationButton1        matlab.ui.control.ToggleButton
        filter_TypePanel               matlab.ui.container.ButtonGroup
        filter_TypeRadioButton12       matlab.ui.control.RadioButton
        filter_TypeRadioButton11       matlab.ui.control.RadioButton
        filter_TypeRadioButton10       matlab.ui.control.RadioButton
        filter_TypeRadioButton9        matlab.ui.control.RadioButton
        filter_TypeRadioButton8        matlab.ui.control.RadioButton
        filter_TypeRadioButton7        matlab.ui.control.RadioButton
        filter_TypeRadioButton6        matlab.ui.control.RadioButton
        filter_TypeRadioButton5        matlab.ui.control.RadioButton
        filter_TypeRadioButton4        matlab.ui.control.RadioButton
        filter_TypeRadioButton3        matlab.ui.control.RadioButton
        filter_TypeRadioButton2        matlab.ui.control.RadioButton
        filter_TypeRadioButton1        matlab.ui.control.RadioButton
        Tab_3                          matlab.ui.container.Tab
        Tab3_infoGrid                  matlab.ui.container.GridLayout
        misc_ElevationSourcePanel      matlab.ui.container.Panel
        misc_ElevationSourceGrid       matlab.ui.container.GridLayout
        misc_PointsPerLink             matlab.ui.control.DropDown
        misc_PointsPerLinkLabel        matlab.ui.control.Label
        misc_ElevationAPISource        matlab.ui.control.DropDown
        misc_ElevationAPISourceLabel   matlab.ui.control.Label
        misc_ElevationSourceLabel      matlab.ui.control.Label
        misc_LinkPanel                 matlab.ui.container.Panel
        misc_LinkGrid                  matlab.ui.container.GridLayout
        misc_LinkGeneralInfoPanel      matlab.ui.container.Panel
        misc_LinkGeneralInfoGrid       matlab.ui.container.GridLayout
        misc_LinkGeneralInfo           matlab.ui.control.HTML
        misc_LinkInfoLabel             matlab.ui.control.Label
        misc_LinkInfoPanel             matlab.ui.container.Panel
        misc_LinkInfoGrid              matlab.ui.container.GridLayout
        misc_LinkAttenuation           matlab.ui.control.NumericEditField
        misc_LinkAttenuationLabel      matlab.ui.control.Label
        misc_LinkAzimuth               matlab.ui.control.NumericEditField
        misc_LinkAzimuthLabel          matlab.ui.control.Label
        misc_LinkDistance              matlab.ui.control.NumericEditField
        misc_LinkDistanceLabel         matlab.ui.control.Label
        misc_LinkGeneralInfoLabel      matlab.ui.control.Label
        misc_Intro                     matlab.ui.control.Label
        toolGrid                       matlab.ui.container.GridLayout
        tool_ExportButton              matlab.ui.control.Image
        tool_nRows                     matlab.ui.control.Label
        tool_nRowsIcon                 matlab.ui.control.Image
        tool_resetButton               matlab.ui.control.Image
        LeftPanelVisibility            matlab.ui.control.Image
        jsBackDoor                     matlab.ui.control.HTML
        tool_simulationButton          matlab.ui.control.Button
        tool_PDFButton                 matlab.ui.control.Button
        tool_LOGButton                 matlab.ui.control.Button
        tool_Serarator2                matlab.ui.control.Image
        tool_simulationAxesVisibility  matlab.ui.control.StateButton
        tool_HistogramAxesVisibility   matlab.ui.control.StateButton
        tool_Serarator1                matlab.ui.control.Image
        Document                       matlab.ui.container.GridLayout
        chReportGrid                   matlab.ui.container.GridLayout
        chReportLabel                  matlab.ui.control.Label
        chReportHTML                   matlab.ui.control.HTML
        UITable                        matlab.ui.control.Table
        plot_Panel2                    matlab.ui.container.Panel
        plot_Panel1                    matlab.ui.container.Panel
        filter_ContextMenu             matlab.ui.container.ContextMenu
        filter_delButton               matlab.ui.container.Menu
        filter_delAllButton            matlab.ui.container.Menu
        points_ContextMenu             matlab.ui.container.ContextMenu
        points_referenceButton         matlab.ui.container.Menu
        points_editRowButton           matlab.ui.container.Menu
        points_delButton               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        General
        rootFolder

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag = {true, ...
                          true, ...
                          true, ...
                          true};

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        % Propriedades do app, além de handle para RFDataHub (variável
        % global)
        specData

        %-----------------------------------------------------------------%
        % ESPECIFICIDADES AUXAPP.WINDRIVETEST
        %-----------------------------------------------------------------%
        UIAxes1
        UIAxes2
        UIAxes3
        UIAxes4

        compVisibilityStatus = [true, false, false, false]
        restoreView = {[0,1], [0,1]}

        elevationObj = RF.Elevation        

        rfDataHub
        rfDataHubSummary
        rfDataHubLogical

        pointsTable = table('Size',          [0, 6],                                                    ...
                            'VariableTypes', {'cell', 'double', 'double', 'cell', 'double', 'logical'}, ...
                            'VariableNames', {'Type', 'Latitude', 'Longitude', 'Location', 'AntennaHeight', 'Reference'})

        filterTable = table('Size',          [0, 9],                                                                      ...
                            'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                            'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'})        
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)

                % Define tamanho mínimo do app (não aplicável à versão webapp).
                if ~strcmp(app.CallingApp.executionMode, 'webApp')
                    appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
                end
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app, 0)
                jsBackDoor_Customizations(app, 1)

                app.progressDialog.Visible = 'visible';

                startup_AppProperties(app)
                startup_AxesCreation(app)
                startup_GUIComponents(app)

                points_NodeList(app)
                filter_RFDataHub(app)
                filter_typePanelSelectionChanged(app)
    
                % Define tamanho mínimo da GUI (aplicável apenas para a versão 
                % desktop do app.
                ccTools.compCustomization(app.UIFigure, 'windowMinSize', class.Constants.windowMinSize);

                app.progressDialog.Visible = 'hidden';
            end
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource           = ccTools.fcn.jsBackDoorHTMLSource();
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_Listener(app, evt);
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Listener(app, event)
            % switch event.HTMLEventName
            %     case 'app.filter_Tree'
            %         filter_delFilter(app, struct('Source', app.filter_delButton))
            %     case 'app.points_Tree'
            %         points_delButtonMenuSelected(app)
            % end
            drawnow
        end

        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            % O menu gráfico controla, programaticamente, qual das abas de
            % app.TabGroup estará visível. 

            % Lembrando que o MATLAB renderiza em tela apenas as abas visíveis.
            % Por isso as customizações de abas e suas subabas somente é possível 
            % após a renderização da aba.

            switch tabIndex
                case 0 % STARTUP
                    app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                           'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                               '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                               '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));
        
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                           'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));
                    
                    ccTools.compCustomizationV2(app.jsBackDoor, app.TabGroup, 'transparentHeader', 'transparent')                    

                otherwise
                    if any(app.jsBackDoorFlag{tabIndex})
                        app.jsBackDoorFlag{tabIndex} = false;

                        switch tabIndex
                            case 1 % ASPECTOS GERAIS
                                % ...            
                            case 2 % FILTRO
                                % ...
                            case 3 % PONTOS DE INTERESSE
                                % ...
                            case 4 % LAYOUT
                                % ...
                        end
                    end
            end
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            % ...
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            % Eixo geográfico (MAPA)
            app.plot_Panel1.AutoResizeChildren = 'off';
            app.UIAxes1 = plot.axes.Creation(app.plot_Panel1, 'Geographic', {'Units', 'normalized', 'Position', [0,0,1,1], 'Basemap', 'satellite'});
            geolimits(app.UIAxes1, 'auto')

            % Eixos cartesianos
            t = tiledlayout(app.plot_Panel2, 1, 3, "Padding", "tight", "TileSpacing", "tight");
            app.UIAxes2 = plot.axes.Creation(t, 'Cartesian', {'Color', [.98 .98 .98], 'FontSize', 8, 'YScale', 'log'});
            app.UIAxes2.Layout.Tile = 1;

            app.UIAxes3 = plot.axes.Creation(t, 'Cartesian', {'Color', [.98 .98 .98], 'FontSize', 8, 'YScale', 'log'});
            app.UIAxes3.Layout.Tile = 2;

            app.UIAxes4 = plot.axes.Creation(t, 'Cartesian', {'Color', [.98 .98 .98], 'XColor', [0,0,0], 'YColor', [0,0,0], 'TickDir', 'none', 'Visible', 0, 'UserData', struct('TX', struct('ID', []), 'RX', [])});
            app.UIAxes4.Layout.Tile = 3;
            app.UIAxes4.XAxis.Exponent = 0;
            app.UIAxes4.YAxis.Exponent = 0;            

            xlabel(app.UIAxes2, 'Frequência (MHz)')
            ylabel(app.UIAxes2, 'Registros')
            
            xlabel(app.UIAxes3, 'Serviço')
            ylabel(app.UIAxes3, '')

            xlabel(app.UIAxes4, 'Distância (km)')
            ylabel(app.UIAxes4, 'Elevação (m)')

            plot.axes.Interactivity.ToolbarCreation([app.UIAxes2, app.UIAxes3, app.UIAxes4])
            plot.axes.Interactivity.DefaultCreation([app.UIAxes2, app.UIAxes3, app.UIAxes4])
            plot.axes.Interactivity.CustomCallbacks(app.UIAxes2, [app.UIAxes3, app.UIAxes4], [], {'pan'})
            plot.axes.Interactivity.CustomCallbacks(app.UIAxes4, [], [], {'restoreview'})

            layout_CartesianAxesLayout(app)
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            % ...
        end

        %-----------------------------------------------------------------%
        function layout_CartesianAxesLayout(app)
            if app.tool_HistogramAxesVisibility.Value
                app.tool_simulationAxesVisibility.Value = 0;

                layout_CartesianAxesSwitch(app, 'Visible',   2, [1 2])
                layout_CartesianAxesSwitch(app, 'Visible',   3, [3 1])
                layout_CartesianAxesSwitch(app, 'Invisible', 4, -1)

            else
                app.tool_simulationAxesVisibility.Value = 1;

                layout_CartesianAxesSwitch(app, 'Invisible', 2, -1)
                layout_CartesianAxesSwitch(app, 'Invisible', 3, -1)
                layout_CartesianAxesSwitch(app, 'Visible',   4, [1 3])
            end
        end

        %-----------------------------------------------------------------%
        function layout_CartesianAxesSwitch(app, axesVisibility, axesID, axesTiledPosition)
            switch axesVisibility
                case 'Invisible'
                    eval(sprintf('set(app.UIAxes%d,          Visible=0)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Children, Visible=0)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Toolbar,  Visible=0)', axesID))

                case 'Visible'
                    eval(sprintf('set(app.UIAxes%d,          Visible=1)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Children, Visible=1)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Toolbar,  Visible=1)', axesID))
    
                    eval(sprintf('app.UIAxes%d.Layout.Tile = axesTiledPosition(1);', axesID))
                    eval(sprintf('app.UIAxes%d.Layout.TileSpan = [1 axesTiledPosition(2)];', axesID))
            end
        end

        %-----------------------------------------------------------------%
        function layout_FilterOperationPanel(app, filterType, filterDefault)
            
            layout_FilterDefaultValues(app)
            filter_operationLogicalReferenceValueChanged(app)

            hComp = findobj(app.filter_operationPanel, 'Type', 'uitogglebutton');
            hCompTagFlag = contains(arrayfun(@(x) x.Tag, hComp, 'UniformOutput', false), filterType);

            set(hComp(hCompTagFlag),  Enable=1)
            set(hComp(~hCompTagFlag), Enable=0)

            selectedButton = app.filter_operationPanel.SelectedObject;
            if ~selectedButton.Enable
                switch filterType
                    case {'textList1', 'ROI'}
                        app.filter_operationButton3.Value = 1; % CONTÉM

                    otherwise
                        app.filter_operationButton1.Value = 1; % IGUAL
                end
            end

            switch filterType
                case 'numeric'
                    app.filter_operationValuesGrid.RowHeight(1:3) = {22,0,0};

                case 'textFree'
                    app.filter_operationValuesGrid.RowHeight(1:3) = {0,22,0};

                case {'textList1', 'textList2', 'ROI'}
                    app.filter_operationValuesGrid.RowHeight(1:3) = {0,0,22};
                    app.filter_operationTextListValue.Items = filterDefault;
            end
        end


        %-----------------------------------------------------------------%
        function layout_FilterDefaultValues(app)
            app.filter_operationNumValue1.Value     = -1;
            app.filter_operationNumValue2.Value     = -1;
            app.filter_operationTextFreeValue.Value = '';
            app.filter_operationTextListValue.Items = {};
            app.filter_operationLogical.Items       = {'E (&&)'};
        end

        %-----------------------------------------------------------------%
        function layout_simulationLink(app, idx)
            simulationFlag = false;

            app.tool_PDFButton.Enable = 0;
            if ~isempty(idx)
                if app.UITable.Data.URL(idx) ~= "-1"
                    app.tool_PDFButton.Enable = 1;
                end

                if ~isempty(app.pointsTable)
                    simulationFlag = true;
                end
            end

            if simulationFlag
                txIndex = idx;
                rxIndex = find(app.pointsTable.Reference, 1);

                [arclen, az] = distance(app.UITable.Data.Latitude(txIndex), ...
                                        app.UITable.Data.Longitude(txIndex), ...
                                        app.pointsTable.Latitude(rxIndex),  ...
                                        app.pointsTable.Longitude(rxIndex)); 
                R = deg2km(arclen);

                app.misc_LinkGeneralInfo.HTMLSource = textFormatGUI.struct2PrettyPrintList(misc_simulationLinkSummary(app, idx), 'delete');
                app.misc_LinkDistance.Value         = R;
                app.misc_LinkAzimuth.Value          = az;
                app.misc_LinkAttenuation.Value      = fspl(R*1000, physconst('LightSpeed')/(app.UITable.Data.Frequency(txIndex)*10^6));
                app.tool_simulationButton.Enable    = 1;

            else
                app.misc_LinkGeneralInfo.HTMLSource = ' ';
                app.misc_LinkDistance.Value         = -1;
                app.misc_LinkAzimuth.Value          = -1;
                app.misc_LinkAttenuation.Value      = -1;
                app.tool_simulationButton.Enable    = 0;
            end
        end

        %-----------------------------------------------------------------%
        function linkSummary = misc_simulationLinkSummary(app, idx)
            [txObj, rxObj]  = misc_simulationObjects(app, idx);
            txObj.Frequency = sprintf('%.3f MHz', txObj.TransmitterFrequency/1e+6);

            linkSummary(1)  = struct('group', 'ESTAÇÃO TRANSMISSORA (TX)', 'value', txObj);
            linkSummary(2)  = struct('group', 'ESTAÇÃO RECEPTORA (RX)',    'value', rxObj);
        end

        %-----------------------------------------------------------------%
        function [txObj, rxObj] = misc_simulationObjects(app, idxStation)
            txIndex = str2double(extractAfter(app.UITable.Data.ID{idxStation}, '#'));
            rxIndex = find(app.pointsTable.Reference, 1);

            txObj = struct('TransmitterFrequency', app.rfDataHub.Frequency(txIndex) * 1e+6,                ...
                           'Latitude',             app.rfDataHub.Latitude(txIndex),                        ...
                           'Longitude',            app.rfDataHub.Longitude(txIndex),                       ...
                           'AntennaHeight',        str2double(char(app.rfDataHub.AntennaHeight(txIndex))), ...
                           'AntennaPattern',       char(app.rfDataHub.AntennaPattern(txIndex)),            ...
                           'Description',          app.UITable.Data.Description{idxStation},               ...
                           'Service',              app.rfDataHub.Service(txIndex),                         ...
                           'Station',              app.rfDataHub.Station(txIndex), ...
                           'ID',                   app.UITable.Data.ID{idxStation});

            rxObj = struct('Latitude',       app.pointsTable.Latitude(rxIndex),  ...
                           'Longitude',      app.pointsTable.Longitude(rxIndex), ...
                           'AntennaHeight',  app.pointsTable.AntennaHeight(rxIndex));
        end

        %-----------------------------------------------------------------%
        function points_NodeList(app)
            app.pointsTable(:,:) = [];

            if ~isempty(app.CallingApp)
                for ii = 1:numel(app.CallingApp.specData)
                    if app.CallingApp.specData(ii).GPS.Status
                        AntennaHeight = -1;
                        if isfield(app.CallingApp.specData(ii).MetaData.Antenna, 'Height')
                            AntennaHeight = str2double(extractBefore(app.CallingApp.specData(ii).MetaData.Antenna.Height, 'm'));
                        end

                        newPoint      = {'auto',                                    ...
                                         app.CallingApp.specData(ii).GPS.Latitude,  ...
                                         app.CallingApp.specData(ii).GPS.Longitude, ...
                                         app.CallingApp.specData(ii).GPS.Location,  ...
                                         AntennaHeight,                             ...
                                         false};
                        newPointFlag = true;

                        for jj = 1:height(app.pointsTable)
                            if isequal(newPoint(2:5), table2cell(app.pointsTable(jj,2:5)))
                                newPointFlag = false;
                                break
                            end
                        end

                        if newPointFlag
                            app.pointsTable(end+1,:) = newPoint;
                        end
                    end
                end
            end

            if isempty(app.pointsTable)
                % Caso não tenha um ponto de monitoração, insere-se a sede
                % da Anatel. Isso garante a visualização do mapa centralizada
                % em Brasília/DF.
                app.pointsTable(1,:) = {'manual', -15.805479, -47.882499, 'Brasília/DF', 30, true};
            else
                app.pointsTable.Reference(1) = true;
            end            

            points_TreeBuilding(app, 1)
        end


        %-----------------------------------------------------------------%
        function points_TreeBuilding(app, selectedID)
            if ~isempty(app.points_Tree.Children)
                delete(app.points_Tree.Children)
                drawnow nocallbacks
            end

            if ~isempty(app.pointsTable)
                for ii = 1:height(app.pointsTable)
                    antennaHeight = '';
                    if app.pointsTable.AntennaHeight(ii) > 0
                        antennaHeight = sprintf(', %dm', app.pointsTable.AntennaHeight(ii));
                    end

                    nodeTree = uitreenode(app.points_Tree, 'Text', sprintf('#%d: %s (%.6fº, %.6fº%s)', ii, app.pointsTable.Location{ii},  ...
                                                                                                           app.pointsTable.Latitude(ii),  ...
                                                                                                           app.pointsTable.Longitude(ii), ...
                                                                                                           antennaHeight),                ...
                                                           'NodeData', ii, 'ContextMenu', app.points_ContextMenu);
                    if app.pointsTable.Reference(ii)
                        nodeTree.Icon = 'Pin_18.png';
                    end
                end

                if ~ismember(selectedID, 1:height(app.pointsTable))
                    selectedID = 1;
                end
                app.points_Tree.SelectedNodes = app.points_Tree.Children(selectedID);

            else
                app.points_Grid.ColumnWidth{4} = 22;
                set(app.points_Latitude,      Value=-1, Editable=1)
                set(app.points_Longitude,     Value=-1, Editable=1)
                set(app.points_AntennaHeight, Value=-1, Editable=1)
            end

            points_TreeSelectionChanged(app)
        end


        %-----------------------------------------------------------------%
        function filter_RFDataHub(app)
            global RFDataHub
            
            app.rfDataHub = RFDataHub;
            
            % Contorna erro da função inROI, que retorna como se todos os
            % pontos estivessem internos ao ROI, quando as coordenadas
            % estão em float32. No float64 isso não acontece... aberto BUG
            % na Mathworks, que indicou estar ciente.
            app.rfDataHub.Latitude    = double(app.rfDataHub.Latitude);
            app.rfDataHub.Longitude   = double(app.rfDataHub.Longitude);

            app.rfDataHub.ID          = "#" + string((1:height(RFDataHub))');
            app.rfDataHub.Description = "[" + string(RFDataHub.Source) + "] " + string(RFDataHub.Status) + ", " + string(RFDataHub.StationClass) + ", " + string(RFDataHub.Name) + " (Fistel=" + string(RFDataHub.Fistel) + ", M=" + string(RFDataHub.MergeCount) + "), " + string(RFDataHub.Location) + "/" + string(RFDataHub.State);            

            app.rfDataHubSummary      = summary(RFDataHub);
            app.tool_nRows.Text       = sprintf('0 de %d registros\n0.0 %%', height(app.rfDataHub));

            filter_DistanceColumn(app)
            plot_Nodes(app)
        end


        %-----------------------------------------------------------------%
        function filter_DistanceColumn(app)
            if ~isempty(app.pointsTable)
                idx  = find(app.pointsTable.Reference, 1);
                app.rfDataHub.Distance = round(deg2km(distance(app.rfDataHub.Latitude,        ...
                                                               app.rfDataHub.Longitude,       ...
                                                               app.pointsTable.Latitude(idx), ...
                                                               app.pointsTable.Longitude(idx))), 1);

            else
                app.rfDataHub.Distance = -1*ones(height(app.rfDataHub), 1);
            end
        end


        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            idx1 = find(strcmp(app.filterTable.Order, 'Node'))';
            if ~isempty(idx1)
                checkedNodes = {};
                for ii = idx1
                    parentNode = uitreenode(app.filter_Tree, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(ii),                        ...
                                                                                                            app.filterTable.Type{ii},                      ...
                                                                                                            app.filterTable.Operation{ii},                 ...
                                                                                                            filter_Value(app, app.filterTable.Value{ii})), ...
                                                             'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                    if app.filterTable.Enable(ii)
                        checkedNodes = [checkedNodes, parentNode];
                    end
    
                    idx2 = find(app.filterTable.RelatedID == ii)';                
                    for jj = idx2
                        childNode = uitreenode(parentNode, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(jj),                        ...
                                                                                                          app.filterTable.Type{jj},                      ...
                                                                                                          app.filterTable.Operation{jj},                 ...
                                                                                                          filter_Value(app, app.filterTable.Value{jj})), ...
                                                           'NodeData', jj, 'ContextMenu', app.filter_ContextMenu);
    
                        if app.filterTable.Enable(jj)
                            checkedNodes = [checkedNodes, childNode];
                        end
                    end
                end
                app.filter_Tree.CheckedNodes = checkedNodes;
                expand(app.filter_Tree, 'all')
            end

            app.filter_operationLogicalReference.Items = [{''}, cellstr("#" + string((idx1)))];
        end


        %-----------------------------------------------------------------%
        function filterValue = filter_Value(app, filterValue)
            if isnumeric(filterValue)
                if isscalar(filterValue)
                    filterValue = string(filterValue);
                else
                    filterValue = "[" + strjoin(string(filterValue), ', ') + "]";
                end
            elseif ischar(filterValue)
                filterValue = sprintf('"%s"', upper(filterValue));
            else
                filterValue = '⌂';
            end
        end


        %-----------------------------------------------------------------%
        function filter_ROICallbacks(app, src, event)
            switch(event.EventName)
                case 'MovingROI'
                    plot.axes.Interactivity.DefaultDisable(app.UIAxes1)
                    
                case 'ROIMoved'
                    plot.axes.Interactivity.DefaultEnable(app.UIAxes1)
                    
                    filter_TableFiltering(app, true)
                    if isvalid(event.Source)
                        uistack(event.Source, 'top')
                    end
            end            
        end


        %-----------------------------------------------------------------%
        function filter_TableFiltering(app, ProgressDialogFlag)
            if ProgressDialogFlag
                d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');
            end

            app.rfDataHubLogical   = fcn.TableFiltering(app.rfDataHub, app.filterTable);

            if isempty(app.UITable.Selection)
                initialSelectedRow = app.UIAxes4.UserData.TX.ID;
            else
                initialSelectedRow = app.UITable.Data.ID{app.UITable.Selection(1)};
            end

            app.UITable.Selection  = [];
            app.UITable.Data       = app.rfDataHub(app.rfDataHubLogical,[30, 1, 6:7, 31, 4:5, 12, 13, 29, 32]);

            if ~isempty(initialSelectedRow)
                finalSelectedRow = find(strcmp(app.UITable.Data.ID, initialSelectedRow), 1);
                if ~isempty(finalSelectedRow)
                    app.UITable.Selection = [finalSelectedRow, 1];
                end                
            end

            filter_TableStyle(app, 'ProgrammaticallySelectedRow')
            filter_TableStyle(app, 'TerrainProfilePlot')

            NN = sum(app.rfDataHubLogical);
            MM = numel(app.rfDataHubLogical);
            app.tool_nRows.Text = sprintf('%d de %d registros\n%.1f %%', NN, MM, (NN/MM)*100);

            plot_General(app)
            plot_SelectedRow(app)
            plot_Nodes(app)
            if app.tool_simulationAxesVisibility.Value
                plot_simulationLink(app)
            end
            drawnow

            if ProgressDialogFlag
                delete(d)
            end
        end


        %-----------------------------------------------------------------%
        function filter_TableStyle(app, Type)
            switch Type
                case {'SelectionRowChanged', 'ProgrammaticallySelectedRow'}
                    idx1 = find(app.UITable.StyleConfigurations.Target == "cell");
                    if ~isempty(idx1)
                        removeStyle(app.UITable, idx1)
                    end

                    if strcmp(Type, 'ProgrammaticallySelectedRow')
                        if ~isempty(app.UITable.Selection)
                            idx2 = app.UITable.Selection(1);
                            addStyle(app.UITable, uistyle("BackgroundColor", '#ddf0ff'), 'cell', [idx2,1])
                        end
                    end

                case 'HorizontalAlign'
                    addStyle(app.UITable, uistyle('HorizontalAlignment', 'right'), 'column', [8,10])

                case 'TerrainProfilePlot'
                    idx1 = find(app.UITable.StyleConfigurations.Target == "row");
                    if ~isempty(idx1)
                        removeStyle(app.UITable, idx1)
                    end

                    idx2 = app.UIAxes4.UserData.TX.ID;
                    if ~isempty(idx2)
                        idx3 = find(strcmp(app.UITable.Data.ID, idx2), 1);
                        if ~isempty(idx3)
                            addStyle(app.UITable, uistyle("BackgroundColor", '#c94756', 'FontColor', 'white'), 'row', idx3)
                        end
                    end
            end
        end


        %-----------------------------------------------------------------%
        function plot_General(app)
            hObjects = app.UIAxes1.Children;
            idx = contains(arrayfun(@(x) x.Tag, app.UIAxes1.Children, 'UniformOutput', false), 'ROI');
            delete(hObjects(~idx))

            rfDataHubFlag = height(app.UITable.Data);
            if rfDataHubFlag
                p1 = geoscatter(app.UIAxes1, app.UITable.Data.Latitude, app.UITable.Data.Longitude, MarkerEdgeColor='#009578', Tag='Station');
            end

            uistack(hObjects(idx))

            p2 = histogram(app.UIAxes2, app.UITable.Data.Frequency,            EdgeColor='none', FaceColor="#009578");
            p3 = histogram(app.UIAxes3, categorical(app.UITable.Data.Service), EdgeColor='none', FaceColor="#009578");
            
            if rfDataHubFlag
                plot.datatip.Template(p1, 'winRFDataHub.Geographic', app.UITable.Data)
                plot.datatip.Template(p2, 'winRFDataHub.Histogram1')
                plot.datatip.Template(p3, 'winRFDataHub.Histogram2')
            end
        end


        %-----------------------------------------------------------------%
        function plot_Nodes(app)
            if ~isempty(app.pointsTable)
                idx1 = find(app.pointsTable.Reference, 1);                
                if height(app.pointsTable) > 1
                    idx2 = setdiff(1:height(app.pointsTable), idx1);
                    p1 = geoscatter(app.UIAxes1, app.pointsTable.Latitude(idx2), app.pointsTable.Longitude(idx2), 'Marker', '^', 'MarkerEdgeColor', '#c94756', 'Tag', 'OthersNodes');
                    plot.datatip.Template(p1, 'winRFDataHub.SelectedNode')
                end
                
                p2 = geoscatter(app.UIAxes1, app.pointsTable.Latitude(idx1), app.pointsTable.Longitude(idx1), 'Marker', '^', 'MarkerEdgeColor', '#c94756', 'MarkerFaceColor', '#c94756', 'Tag', 'SelectedNode');
                plot.datatip.Template(p2, 'winRFDataHub.SelectedNode')
            end
        end


        %-----------------------------------------------------------------%
        function plot_SelectedRow(app)
            hSelected = findobj(app.UIAxes1, Tag='SelectedRow');
            
            if ~isempty(app.UITable.Selection)
                idx = app.UITable.Selection(1);
                app.tool_LOGButton.Enable = 1;

                if isempty(hSelected)
                    hSelected = geoscatter(app.UIAxes1, app.UITable.Data.Latitude(idx), app.UITable.Data.Longitude(idx), MarkerEdgeColor='#c94756', ...
                                                                                                                         MarkerFaceColor='#c94756', ...
                                                                                                                         Tag='SelectedRow');
                else
                    set(hSelected, 'LatitudeData',  app.UITable.Data.Latitude(idx), ...
                                   'LongitudeData', app.UITable.Data.Longitude(idx))
                end
                plot.datatip.Template(hSelected, 'winRFDataHub.Geographic', app.UITable.Data(idx,:))
                layout_simulationLink(app, idx)
                
            else
                delete(hSelected)
                app.tool_LOGButton.Enable = 0;

                layout_simulationLink(app, [])
            end
        end


        %-----------------------------------------------------------------%
        function plot_simulationLink(app)
            % VALIDAÇÕES
            if isempty(app.UITable.Selection) || isempty(app.pointsTable) || ~app.compVisibilityStatus(2)
                return
            end
            idx = app.UITable.Selection(1);

            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');
            
            % OBJETOS TX e RX
            [txObj, rxObj] = misc_simulationObjects(app, idx);       

            % ELEVAÇÃO DO LINK TX-RX
            nPoints = str2double(app.misc_PointsPerLink.Value);
            Server  = app.misc_ElevationAPISource.Value;

            [wayPoints3D, msgWarning] = Get(app.elevationObj, txObj, rxObj, nPoints, false, Server);
            if ~isempty(msgWarning)
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
            end

            % PLOT: MAPA
            delete(findobj(app.UIAxes1, 'Tag', 'RFLinkView', '-or', 'Tag', 'RFLink'))
            geoplot(app.UIAxes1, wayPoints3D(:,1), wayPoints3D(:,2), Color='#c94756', LineStyle='-.', PickableParts='none', Tag='RFLinkView');

            % PLOT: RFLink
            plot.RFLink(app.UIAxes4, txObj, rxObj, wayPoints3D)

            app.restoreView = {app.UIAxes4.XLim, app.UIAxes4.YLim};
            filter_TableStyle(app, 'TerrainProfilePlot')

            delete(d)
        end
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function externalRequest(app, request)
            switch request.Type
                case 'TerrainProfilePlot'
                    try
                        % Aba 1: PONTO DE MONITORAÇÃO
                        % Avalia a distância do sensor registrada na tabela
                        % app.peaksTable (propriedade do app principal) e a
                        % distância dos pontos de monitoração registrados
                        % em app.pointsTable (os quais estão sujeitos à
                        % edição).
                        rxLat = request.rxLatitude;
                        rxLon = request.rxLongitude;

                        refIndex = find(app.pointsTable.Reference, 1);                        
                        minDist  = deg2km(distance(app.pointsTable.Latitude(refIndex), app.pointsTable.Longitude(refIndex), rxLat, rxLon))/1000;

                        if minDist > class.Constants.floatDiffTolerance
                            points_NodeList(app)
                            [~, minDistIndex] = min(distance(app.pointsTable.Latitude, app.pointsTable.Longitude, rxLat, rxLon));

                            refIndex = find(app.pointsTable.Reference, 1);
                            if refIndex ~= minDistIndex
                                app.pointsTable.Reference               = zeros(height(app.pointsTable), 1, 'logical');
                                app.pointsTable.Reference(minDistIndex) = true;
                                
                                points_TreeBuilding(app, minDistIndex)
                            end
                        end

                        % Aba 2: FILTRO
                        filter_delFilter(app, struct('Source', app.filter_delAllButton))
                        txStationNumber = request.txStationNumber;

                        app.filter_TypeRadioButton8.Value = true;
                        filter_typePanelSelectionChanged(app)
                        app.filter_operationNumValue1.Value = double(txStationNumber);
                        filter_addFilter(app)

                        app.UITable.Selection = [1 1];
                        UITableSelectionChanged(app)

                        % AJUSTES FINAIS: ENLACE E LAYOUT
                        % A versão compilada está deixando de renderizar em
                        % tela algumas das personalizações abaixo... por
                        % isso inserido o drawnow.
                        drawnow nocallbacks
                        pause(.1)
                        misc_TerrainProfileRefreshImage(app)

                        if ~app.compVisibilityStatus(1)
                            layout_compButtonPushed(app, struct('Source', app.layout_mapButton))
                        end

                        if app.compVisibilityStatus(3)
                            layout_compButtonPushed(app, struct('Source', app.layout_tableButton))
                        end
                        
                        if app.compVisibilityStatus(4)
                            layout_compButtonPushed(app, struct('Source', app.layout_pdfButton))
                        end
                        
                        layout_LeftPanelSelectedTabControl(app, struct('Source', app.menu_Button3Icon))
                        figure(app.UIFigure)
                    catch

                    end

                case 'StationsMap'

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.General    = mainapp.General;
            app.rootFolder = mainapp.rootFolder;
            app.specData   = mainapp.specData;

            appUtil.winPosition(app.UIFigure)
            jsBackDoor_Initialization(app)

            startup_timerCreation(app)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            if ~isempty(app.CallingApp)
                app.CallingApp.auxiliarWin3 = [];
            end

            delete(app)
            
        end

        % Image clicked function: tool_resetButton
        function resetFcn(app, event)
            
            msg = ['<p style="font-size:12; text-align:justify;">Essa funcionalidade possibilita reinicializar alguns dos parâmetros do <i>app</i>, ' ...
                   'como a <b>INTERAÇÃO</b> com os plots, a exclusão de edições manuais na lista dos <b>PONTOS</b> de monitoração e <b>FILTROS</b>.</p>'];
            selection = uiconfirm(app.UIFigure, msg, '', 'Options', {'INTERAÇÃO', ' PONTOS  ', ' FILTROS ', 'CANCELAR '}, 'DefaultOption', 1, 'Icon', 'question', 'Interpreter', 'html');

            if strcmp(selection, 'CANCELAR ')
                return
            end

            switch selection
                case 'INTERAÇÃO'
                    plot.axes.Interactivity.DefaultDisable([app.UIAxes1, app.UIAxes2, app.UIAxes3, app.UIAxes4])
                    pause(.1)
                    plot.axes.Interactivity.DefaultEnable([app.UIAxes1, app.UIAxes2, app.UIAxes3, app.UIAxes4])

                case ' PONTOS  '
                    points_refreshImageClicked(app)

                case ' FILTROS '
                    filter_delFilter(app, struct('Source', app.filter_delAllButton))
            end

        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 1 other component
        function layout_LeftPanelSelectedTabControl(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.menu_MainGrid.ColumnWidth = columnWidth;
            app.menu_Image.Layout.Column  = idx;
            app.TabGroup.SelectedTab      = app.TabGroup.Children(idx);

            focus(app.jsBackDoor)
            
        end

        % Image clicked function: LeftPanelVisibility
        function layout_LeftPanelVisibilityControl(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.LeftPanelVisibility.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

            focus(app.jsBackDoor)

        end

        % Callback function: layout_histogramButton, layout_mapButton, 
        % ...and 2 other components
        function layout_compButtonPushed(app, event)
            
            switch event.Source
                case app.layout_mapButton
                    idx = 1;

                case app.layout_histogramButton
                    idx = 2;

                case app.layout_tableButton
                    idx = 3;

                case app.layout_pdfButton
                    idx = 4;
            end
            app.compVisibilityStatus(idx) = ~app.compVisibilityStatus(idx);

            if all(~app.compVisibilityStatus)
                app.compVisibilityStatus(idx) = ~app.compVisibilityStatus(idx);
                return
            end

            % Layout vertical
            if     all(app.compVisibilityStatus([1,2,3]));                               app.Document.RowHeight = {'1x','.5x','.5x'};
            elseif all(app.compVisibilityStatus([1,2]));                                 app.Document.RowHeight = {'1.5x','.5x',0};
            elseif all(app.compVisibilityStatus([1,3]));                                 app.Document.RowHeight = {'1.5x',0,'.5x'};
            elseif all(app.compVisibilityStatus([2,3])); if app.compVisibilityStatus(4); app.Document.RowHeight = {0,'1.5x','.5x'};
                                                         else;                           app.Document.RowHeight = {0,'1x','1x'}; end
            elseif app.compVisibilityStatus(1);                                          app.Document.RowHeight = {'2x',0,0};
            elseif app.compVisibilityStatus(2);                                          app.Document.RowHeight = {0,'2x',0};
            elseif app.compVisibilityStatus(3);          if app.compVisibilityStatus(4); app.Document.RowHeight = {0,'1.5x','.5x'};
                                                         else;                           app.Document.RowHeight = {0,0,'2x'}; end
            else;                                                                        app.Document.RowHeight = {'1x','.5x',0};
            end

            % Layout horizontal
            if any(app.compVisibilityStatus([1,2]));     if app.compVisibilityStatus(4); app.Document.ColumnWidth = {'1x','1x'};
                                                         else;                           app.Document.ColumnWidth = {'1x',0}; end
            else;                                                                        app.Document.ColumnWidth = {0,'1x'};
            end

            % Cor e visibilidade            
            if app.compVisibilityStatus(idx)
                event.Source.BackgroundColor = [0.96,0.96,0.96];
                if idx == 3; app.UITable.Visible = 1;
                end
            else
                event.Source.BackgroundColor = [1,1,1];
                if idx == 3; app.UITable.Visible = 0;
                end
            end

            % Habilita/desabilita botões relacionados ao eixo cartesiano
            if app.compVisibilityStatus(2)
                app.tool_HistogramAxesVisibility.Enable  = 1;
                app.tool_simulationAxesVisibility.Enable = 1;
            else
                app.tool_HistogramAxesVisibility.Enable  = 0;
                app.tool_simulationAxesVisibility.Enable = 0;
            end
            drawnow
            
        end

        % Image clicked function: points_refreshImage
        function points_refreshImageClicked(app, event)
            
            refPoint = app.pointsTable(app.pointsTable.Reference,:);

            points_NodeList(app)

            if ~isequal(refPoint, app.pointsTable(app.pointsTable.Reference,:))
                filter_DistanceColumn(app)
                filter_TableFiltering(app, true)
            end

        end

        % Selection changed function: points_Tree
        function points_TreeSelectionChanged(app, event)
            
            if ~isempty(app.points_Tree.SelectedNodes)
                idx  = app.points_Tree.SelectedNodes.NodeData;
    
                app.points_Latitude.Value      = app.pointsTable.Latitude(idx);
                app.points_Longitude.Value     = app.pointsTable.Longitude(idx);
                app.points_AntennaHeight.Value = app.pointsTable.AntennaHeight(idx);
    
                if app.pointsTable.Reference(idx)
                    app.points_referenceButton.Enable = 0;
                else
                    app.points_referenceButton.Enable = 1;
                end
            end
            
        end

        % Menu selected function: points_delButton, points_editRowButton, 
        % ...and 1 other component
        function points_ContextMenuCallbacks(app, event)
            
            idx  = app.points_Tree.SelectedNodes.NodeData;

            if ~isempty(idx)
                editFlag = false;
                refIndex = find(app.pointsTable.Reference, 1);

                switch event.Source
                    case app.points_referenceButton
                        app.pointsTable.Reference      = zeros(height(app.pointsTable), 1, 'logical');
                        app.pointsTable.Reference(idx) = true;

                        editFlag = true;                        
    
                    case app.points_editRowButton
                        if app.points_Grid.ColumnWidth{4}
                            app.points_Grid.ColumnWidth{4} = 0;
        
                            app.points_Latitude.Editable      = 0;
                            app.points_Longitude.Editable     = 0;
                            app.points_AntennaHeight.Editable = 0;
                            points_TreeSelectionChanged(app)
                            return

                        else
                            app.points_Grid.ColumnWidth{4} = 22;
        
                            app.points_Latitude.Editable      = 1;
                            app.points_Longitude.Editable     = 1;
                            app.points_AntennaHeight.Editable = 1;
                            focus(app.points_Latitude)
                        end
    
                    case app.points_delButton                            
                        app.pointsTable(idx,:) = [];

                        if idx == refIndex
                            editFlag = true;
                            if ~isempty(app.pointsTable)
                                app.pointsTable.Reference(1) = true;
                            end
                        end
                end
                
                points_TreeBuilding(app, idx)
    
                if editFlag
                    filter_DistanceColumn(app)
                    filter_TableFiltering(app, true)
                end
            end

        end

        % Callback function: points_AntennaHeight, points_Latitude, 
        % ...and 2 other components
        function points_editButtonPushed(app, event)
            
            switch event.Source
                case app.points_Latitude
                    focus(app.points_Longitude)

                case app.points_Longitude
                    focus(app.points_AntennaHeight)

                case app.points_AntennaHeight
                    focus(app.points_editButton)

                case app.points_editButton
                    refIndex = find(app.pointsTable.Reference, 1);
                    editFlag = true;
                    
                    if isempty(app.pointsTable)
                        idx = 1;
                    else
                        idx  = app.points_Tree.SelectedNodes.NodeData;
                        if idx ~= refIndex
                            editFlag = false;
                        end
                    end

                    newLatitude  = app.points_Latitude.Value;
                    newLongitude = app.points_Longitude.Value;
                    newLocation  = fcn.gpsFindCity(struct('Latitude', newLatitude, 'Longitude', newLongitude));
                    newHeight    = app.points_AntennaHeight.Value;

                    app.pointsTable(idx,:) = {'manual',     ...
                                              newLatitude,  ...
                                              newLongitude, ...
                                              newLocation,  ...
                                              newHeight,    ...
                                              editFlag};

                    points_TreeBuilding(app, idx)
                    
                    if editFlag
                        filter_DistanceColumn(app)
                        filter_TableFiltering(app, true)
                    end

                    % O painel de visualização/edição das coordenadas do ponto
                    % selecionado.
                    app.points_Grid.ColumnWidth{4} = 0;
                    set(app.points_Latitude,      Editable=0)
                    set(app.points_Longitude,     Editable=0)
                    set(app.points_AntennaHeight, Editable=0)
            end

        end

        % Selection changed function: filter_TypePanel
        function filter_typePanelSelectionChanged(app, event)
                        
            selectedButton = app.filter_TypePanel.SelectedObject;
            switch selectedButton
                case app.filter_TypeRadioButton1                            % BASE DE DADOS
                    filterType = 'textList1';
                    
                    tempDefault   = cellfun(@(x) strsplit(x, ' | '), app.rfDataHubSummary.Source.Categories, 'UniformOutput', false);
                    filterDefault = {};
                    for ii = 1:numel(tempDefault)
                        filterDefault = [filterDefault, tempDefault{ii}];
                    end
                    filterDefault = unique(filterDefault);

                case {app.filter_TypeRadioButton2, ...                      % FREQUÊNCIA
                      app.filter_TypeRadioButton3, ...                      % LARGURA BANDA
                      app.filter_TypeRadioButton6, ...                      % FISTEL
                      app.filter_TypeRadioButton7, ...                      % SERVIÇO
                      app.filter_TypeRadioButton8, ...                      % ESTAÇÃO
                      app.filter_TypeRadioButton11}                         % DISTÂNCIA
                    filterType = 'numeric';
                    filterDefault = {};

                case {app.filter_TypeRadioButton4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_TypeRadioButton5, ...                      % ENTIDADE
                      app.filter_TypeRadioButton10}                         % MUNICÍPIO
                    filterType = 'textFree';
                    filterDefault = {};

                case app.filter_TypeRadioButton9                            % UF
                    filterType = 'textList2';
                    filterDefault = app.rfDataHubSummary.State.Categories;

                case app.filter_TypeRadioButton12                           % ROI
                    filterType = 'ROI';
                    filterDefault = {'CÍRCULO', 'RETÂNGULO', 'POLÍGONO'};
            end

            layout_FilterOperationPanel(app, filterType, filterDefault)
            filter_operationPanelSelectionChanged(app)
            
        end

        % Selection changed function: filter_operationPanel
        function filter_operationPanelSelectionChanged(app, event)
            
            selectedButton = app.filter_operationPanel.SelectedObject;
            switch selectedButton
                case {app.filter_operationButton9, app.filter_operationButton10}
                    app.filter_operationSeparator.Enable = 1;
                    app.filter_operationNumValue2.Enable = 1;
                    
                otherwise
                    app.filter_operationSeparator.Enable = 0;
                    app.filter_operationNumValue2.Enable = 0;
            end
            
        end

        % Value changed function: filter_operationLogicalReference
        function filter_operationLogicalReferenceValueChanged(app, event)
            
            value = app.filter_operationLogicalReference.Value;
            if isempty(value)
                app.filter_operationLogical.Items = {'E (&&)'};
            else
                app.filter_operationLogical.Items = {'OU (||)'};
            end
            
        end

        % Image clicked function: filter_addImage
        function filter_addFilter(app, event)
            
            selectedFilterType      = app.filter_TypePanel.SelectedObject;
            selectedFilterOperation = app.filter_operationPanel.SelectedObject;

            if isempty(app.filter_operationLogicalReference.Value)
                Order     = 'Node';
                RelatedID = -1;
            else
                Order     = 'Child';
                RelatedID = str2double(app.filter_operationLogicalReference.Value(2:end));
            end
            UUID = char(matlab.lang.internal.uuid());

            switch selectedFilterType
                case {app.filter_TypeRadioButton1, ...                      % BASE DE DADOS
                      app.filter_TypeRadioButton9}                          % UF
                    Value = app.filter_operationTextListValue.Value;

                case {app.filter_TypeRadioButton2, ...                      % FREQUÊNCIA
                      app.filter_TypeRadioButton3, ...                      % LARGURA BANDA
                      app.filter_TypeRadioButton6, ...                      % FISTEL
                      app.filter_TypeRadioButton7, ...                      % SERVIÇO
                      app.filter_TypeRadioButton8, ...                      % ESTAÇÃO
                      app.filter_TypeRadioButton11}                         % DISTÂNCIA

                    if ismember(selectedFilterOperation.Text, {'<>', '><'})
                        Value = [app.filter_operationNumValue1.Value, ...
                                 app.filter_operationNumValue2.Value];
                    else
                        Value = app.filter_operationNumValue1.Value;
                    end

                case {app.filter_TypeRadioButton4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_TypeRadioButton5, ...                      % ENTIDADE
                      app.filter_TypeRadioButton10}                         % MUNICÍPIO
                    Value = app.filter_operationTextFreeValue.Value;

                case app.filter_TypeRadioButton12                           % ROI
                    hROI = [];

                    plot.axes.Interactivity.DefaultDisable(app.UIAxes1)
                    pause(.1)

                    switch app.filter_operationTextListValue.Value
                        case 'CÍRCULO';   roiFcn = 'drawcircle';    roiNameArgument = '';
                        case 'RETÂNGULO'; roiFcn = 'drawrectangle'; roiNameArgument = 'Rotatable=true, ';
                        case 'POLÍGONO';  roiFcn = 'drawpolygon';   roiNameArgument = '';
                    end
                    eval(sprintf('hROI = %s(app.UIAxes1, Color=[0.40,0.73,0.88], LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI_%s");', roiFcn, roiNameArgument, UUID))
                    plot.axes.Interactivity.DefaultEnable(app.UIAxes1)

                    if isempty(hROI.Position)
                        delete(hROI)
                        return
                    end
                    addlistener(hROI, 'MovingROI', @app.filter_ROICallbacks);
                    addlistener(hROI, 'ROIMoved',  @app.filter_ROICallbacks);
                    addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plot.axes.Interactivity.DeleteListeners(src));
                    Value = {hROI};                    
            end

            newFilter     = {Order, height(app.filterTable)+1, RelatedID,           ...
                             selectedFilterType.Text, selectedFilterOperation.Text, ...
                             str2double(selectedFilterType.Tag), Value, true, UUID};
            newFilterFlag = true;

            for ii = 1:height(app.filterTable)
                if isequal(app.filterTable{ii,[4,5,7]}, newFilter([4,5,7]))
                    newFilterFlag = false;
                    break
                end
            end

            if ~newFilterFlag
                msg = 'Filtro já incluído!';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                
                return
            end

            app.filterTable(end+1,[1:6,8:9]) = newFilter([1:6,8:9]);
            app.filterTable.Value{end} = Value;

            filter_TreeBuilding(app)
            filter_TableFiltering(app, true)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_Tree.SelectedNodes)
                        return
                    end
                    idx1 = arrayfun(@(x) x.NodeData, app.filter_Tree.SelectedNodes);
                    
                    % Identifica se algum dos fluxos selecionado é um nó de
                    % filtros, inserindo na lista os seus filhos.
                    idx1 = [idx1, find(ismember(app.filterTable.RelatedID, idx1))'];

                case app.filter_delAllButton
                    idx1 = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx1)
                % Apaga os ROI's, caso existentes.
                idx2 = find(strcmp(app.filterTable.Type, 'ROI'))';
                for ii = idx2
                    if ismember(ii, idx1)
                        delete(findobj(app.UIAxes1.Children, Tag=sprintf('ROI_%s', app.filterTable.uuid{ii})))
                    end
                end                

                % E depois atualiza a tabela de filtros, a tabela renderizada
                % no app e, por fim, o plot.
                app.filterTable(idx1,:) = [];

                filter_TreeBuilding(app)
                filter_TableFiltering(app, true)
            end

        end

        % Callback function: filter_Tree
        function filter_TreeCheckedNodesChanged(app, event)
            
            hTree             = findobj(app.filter_Tree, '-not', 'Type', 'uicheckboxtree');
            hTreeNodeDataList = arrayfun(@(x) x.NodeData, hTree);
            hCheckedNodeData  = arrayfun(@(x) x.NodeData, app.filter_Tree.CheckedNodes);

            disableIndexList  = setdiff(hTreeNodeDataList, hCheckedNodeData);
            enableIndexList   = setdiff((1:height(app.filterTable))', disableIndexList);

            app.filterTable.Enable(disableIndexList) = false;
            app.filterTable.Enable(enableIndexList)  = true;

            filter_TableFiltering(app, true)
            
        end

        % Button pushed function: tool_PDFButton
        function misc_PDFButtonButtonPushed(app, event)
            
            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');

            try
                idx = app.UITable.Selection(1);    
                fileName = fullfile(app.CallingApp.config_Folder_userPath.Value, sprintf('Relatório do Canal - Estação %d.pdf', app.UITable.Data.Station(idx)));

                if ~isfile(fileName)
                    websave(fileName, char(app.UITable.Data.URL(idx)), weboptions(Timeout=30));
                end
                app.chReportHTML.HTMLSource = fileName;
                app.chReportLabel.Text      = sprintf('Estação nº %d', app.UITable.Data.Station(idx));

                if ~app.compVisibilityStatus(4)
                    layout_compButtonPushed(app, struct('Source', app.layout_pdfButton))
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
            end

            delete(d)

        end

        % Image clicked function: tool_ExportButton
        function misc_exportButtonPushed(app, event)
            
                defaultFilename = class.Constants.DefaultFileName(app.CallingApp.config_Folder_userPath.Value, 'RFDataHub', -1);

                [fileName, filePath, fileIndex] = uiputfile({'*.xlsx', 'Excel (*.xlsx)'}, '', defaultFilename);
                figure(app.UIFigure)
                
                if fileIndex
                    d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');

                    try
                        tempRFDataHub = class.RFDataHub.ColumnNames(app.rfDataHub(app.rfDataHubLogical,1:29), 'eng2port');
                        writetable(tempRFDataHub, fullfile(filePath, fileName), 'WriteMode', 'overwritesheet')
                    catch ME
                        appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
                    end

                    delete(d)
                end

        end

        % Value changed function: tool_HistogramAxesVisibility, 
        % ...and 1 other component
        function misc_HistogramOrTerrainProfileButtonPushed(app, event)
            
            if event.PreviousValue
                event.Source.Value = true;
                return
            end

            switch event.Source
                case app.tool_HistogramAxesVisibility; app.tool_simulationAxesVisibility.Value = 0;
                otherwise;                             app.tool_HistogramAxesVisibility.Value  = 0;
            end

            layout_CartesianAxesLayout(app)
            drawnow
            
        end

        % Button pushed function: tool_simulationButton
        function misc_TerrainProfileRefreshImage(app, event)
            
            % A ideia aqui é ajustar o layout dos componentes, apresentando
            % o enlace...
            % (a) Clique programático no botão que habilita o eixo cartesiano.
            % (b) Clique programático no botão que habilita a visualização
            %     do app.UIAxes4 (ao invés do app.UIAxes2 e 3).

            if ~app.compVisibilityStatus(2)
                layout_compButtonPushed(app, struct('Source', app.layout_histogramButton))
            end            

            if ~app.tool_simulationAxesVisibility.Value
                app.tool_simulationAxesVisibility.Value = true;
                misc_HistogramOrTerrainProfileButtonPushed(app, struct('Source', app.tool_simulationAxesVisibility, 'PreviousValue', false))
            end

            plot_simulationLink(app)

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
            filter_TableStyle(app, 'SelectionRowChanged')
            plot_SelectedRow(app)
            drawnow
            
        end

        % Button pushed function: tool_LOGButton
        function tool_LOGButtonPushed(app, event)
            
            if ~isempty(app.UITable.Selection)
                global RFDataHubLog

                tableIndex = app.UITable.Selection(1);
                txIndex    = str2double(extractAfter(app.UITable.Data.ID{tableIndex}, '#'));                
                logIndex   = app.rfDataHub.Log(txIndex);

                [logInfo, msgError] = class.RFDataHub.queryLog(RFDataHubLog, logIndex);

                if isempty(logInfo) && isempty(msgError)
                    msg = 'Não evidenciada alteração do registro.';
                elseif isempty(msgError)
                    msg = jsonencode(logInfo, "PrettyPrint", true);
                else
                    msg = msgError;
                end
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: RFDataHub';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.Document = uigridlayout(app.Grid);
            app.plot_Panel1 = uipanel(app.Document);
            app.plot_Panel2 = uipanel(app.Document);
            app.UITable = uitable(app.Document);
            app.UITable.ColumnName = {'ID'; 'FREQUÊNCIA|(MHz)'; 'LATITUDE'; 'LONGITUDE'; 'DESCRIÇÃO|(Entidade+Fistel+Multiplicidade+Localidade)'; 'SERVIÇO'; 'ESTAÇÃO'; 'CLASSE|EMISSÃO'; 'LARGURA|(kHz)'; 'URL'; 'DISTÂNCIA|(km)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false false false false false false false false true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.UITable.Visible = 'off';
            app.chReportGrid = uigridlayout(app.Document);
            app.chReportHTML = uihtml(app.chReportGrid);
            app.chReportHTML.HTMLSource = 'pdfLogo.html';
            app.chReportLabel = uilabel(app.chReportGrid);
            app.chReportLabel.Text = '';
            app.toolGrid = uigridlayout(app.Grid);
            app.tool_Serarator1 = uiimage(app.toolGrid);
            app.tool_Serarator1.Enable = 'off';
            app.tool_Serarator1.ImageSource = 'LineV.png';
            app.tool_HistogramAxesVisibility = uibutton(app.toolGrid, 'state');
            app.tool_HistogramAxesVisibility.ValueChangedFcn = createCallbackFcn(app, @misc_HistogramOrTerrainProfileButtonPushed, true);
            app.tool_HistogramAxesVisibility.Tooltip = {'Eixo cartesiano: Histogramas'};
            app.tool_HistogramAxesVisibility.Icon = 'Detection_32.png';
            app.tool_HistogramAxesVisibility.Text = '';
            app.tool_HistogramAxesVisibility.Value = true;
            app.tool_simulationAxesVisibility = uibutton(app.toolGrid, 'state');
            app.tool_simulationAxesVisibility.ValueChangedFcn = createCallbackFcn(app, @misc_HistogramOrTerrainProfileButtonPushed, true);
            app.tool_simulationAxesVisibility.Tooltip = {'Eixo cartesiano: Enlace'};
            app.tool_simulationAxesVisibility.Icon = 'Connect_32.png';
            app.tool_simulationAxesVisibility.Text = '';
            app.tool_Serarator2 = uiimage(app.toolGrid);
            app.tool_Serarator2.Enable = 'off';
            app.tool_Serarator2.ImageSource = 'LineV.png';
            app.tool_LOGButton = uibutton(app.toolGrid, 'push');
            app.tool_LOGButton.ButtonPushedFcn = createCallbackFcn(app, @tool_LOGButtonPushed, true);
            app.tool_LOGButton.Icon = 'LOG_32.png';
            app.tool_LOGButton.Enable = 'off';
            app.tool_LOGButton.Tooltip = {'Registro de alterações nos dados'};
            app.tool_LOGButton.Text = '';
            app.tool_PDFButton = uibutton(app.toolGrid, 'push');
            app.tool_PDFButton.ButtonPushedFcn = createCallbackFcn(app, @misc_PDFButtonButtonPushed, true);
            app.tool_PDFButton.Icon = 'Publish_PDF_16.png';
            app.tool_PDFButton.Enable = 'off';
            app.tool_PDFButton.Tooltip = {'Relatório do canal'};
            app.tool_PDFButton.Text = '';
            app.tool_simulationButton = uibutton(app.toolGrid, 'push');
            app.tool_simulationButton.ButtonPushedFcn = createCallbackFcn(app, @misc_TerrainProfileRefreshImage, true);
            app.tool_simulationButton.Icon = 'Publish_HTML_16.png';
            app.tool_simulationButton.Enable = 'off';
            app.tool_simulationButton.Tooltip = {'Enlace'};
            app.tool_simulationButton.Text = '';
            app.jsBackDoor = uihtml(app.toolGrid);
            app.LeftPanelVisibility = uiimage(app.toolGrid);
            app.LeftPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelVisibilityControl, true);
            app.LeftPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.tool_resetButton = uiimage(app.toolGrid);
            app.tool_resetButton.ImageClickedFcn = createCallbackFcn(app, @resetFcn, true);
            app.tool_resetButton.ImageSource = 'Dots_32.png';
            app.tool_nRowsIcon = uiimage(app.toolGrid);
            app.tool_nRowsIcon.Enable = 'off';
            app.tool_nRowsIcon.ImageSource = 'Filter_18.png';
            app.tool_nRows = uilabel(app.toolGrid);
            app.tool_nRows.Text = {'0 de 979522 registros'; '0.0 %'};
            app.tool_ExportButton = uiimage(app.toolGrid);
            app.tool_ExportButton.ImageClickedFcn = createCallbackFcn(app, @misc_exportButtonPushed, true);
            app.tool_ExportButton.ImageSource = 'Export_24.png';
            app.TabGroup = uitabgroup(app.Grid);
            app.Tab = uitab(app.TabGroup);
            app.Tab1_infoGrid = uigridlayout(app.Tab);
            app.layout_mapButton = ccTools.Button(app.Tab1_infoGrid);
            app.layout_mapButton.Text = 'Mapa';
            app.layout_mapButton.Description = 'Plot georeferenciado das estações';
            app.layout_mapButton.Icon = 'Globo_32.png';
            app.layout_mapButton.HorizontalAlign = 'left';
            app.layout_mapButton.VerticalAlign = 'top';
            app.layout_mapButton.borderRadius = '0px';
            app.layout_mapButton.borderPadding = 3;
            app.layout_mapButton.IconWidth = 20;
            app.layout_mapButton.IconHeight = 20;
            app.layout_mapButton.tFontWeight = 'normal';
            app.layout_mapButton.tFontSize = 11;
            app.layout_mapButton.dFontSize = 9;
            app.layout_mapButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.layout_histogramButton = ccTools.Button(app.Tab1_infoGrid);
            app.layout_histogramButton.Text = 'Eixo cartesiano';
            app.layout_histogramButton.Description = 'Histogramas, enlace';
            app.layout_histogramButton.Icon = 'Channel_32.png';
            app.layout_histogramButton.HorizontalAlign = 'left';
            app.layout_histogramButton.VerticalAlign = 'top';
            app.layout_histogramButton.borderRadius = '0px';
            app.layout_histogramButton.borderPadding = 3;
            app.layout_histogramButton.IconWidth = 20;
            app.layout_histogramButton.IconHeight = 20;
            app.layout_histogramButton.tFontWeight = 'normal';
            app.layout_histogramButton.tFontSize = 11;
            app.layout_histogramButton.dFontSize = 9;
            app.layout_histogramButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.layout_pdfButton = ccTools.Button(app.Tab1_infoGrid);
            app.layout_pdfButton.Text = 'Leitor PDF';
            app.layout_pdfButton.Description = 'Relatório do canal: outorga, licenciamento etc';
            app.layout_pdfButton.Icon = 'Publish_PDF_16.png';
            app.layout_pdfButton.HorizontalAlign = 'left';
            app.layout_pdfButton.VerticalAlign = 'top';
            app.layout_pdfButton.borderRadius = '0px';
            app.layout_pdfButton.borderPadding = 3;
            app.layout_pdfButton.tFontWeight = 'normal';
            app.layout_pdfButton.tFontSize = 11;
            app.layout_pdfButton.dFontSize = 9;
            app.layout_pdfButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.layout_tableButton = ccTools.Button(app.Tab1_infoGrid);
            app.layout_tableButton.Text = 'Tabela';
            app.layout_tableButton.Description = 'Recorte RFDataHub e coluna calculada "Distância"';
            app.layout_tableButton.Icon = 'View_24.png';
            app.layout_tableButton.HorizontalAlign = 'left';
            app.layout_tableButton.VerticalAlign = 'top';
            app.layout_tableButton.borderRadius = '0px';
            app.layout_tableButton.borderPadding = 3;
            app.layout_tableButton.tFontWeight = 'normal';
            app.layout_tableButton.tFontSize = 11;
            app.layout_tableButton.dFontSize = 9;
            app.layout_tableButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.points_TreeLabel = uilabel(app.Tab1_infoGrid);
            app.points_TreeLabel.Interpreter = 'html';
            app.points_TreeLabel.Text = {'<b>Pontos de monitoração:</b>'; '<font style="font-size: 9px";>(referência da coluna calculada "Distância")</font>'};
            app.points_refreshImage = uiimage(app.Tab1_infoGrid);
            app.points_refreshImage.ImageClickedFcn = createCallbackFcn(app, @points_refreshImageClicked, true);
            app.points_refreshImage.ImageSource = 'Refresh_18.png';
            app.points_Tree = uitree(app.Tab1_infoGrid);
            app.points_Tree.SelectionChangedFcn = createCallbackFcn(app, @points_TreeSelectionChanged, true);
            app.points_Panel = uipanel(app.Tab1_infoGrid);
            app.points_Grid = uigridlayout(app.points_Panel);
            app.points_LatitudeLabel = uilabel(app.points_Grid);
            app.points_LatitudeLabel.Text = 'Latitude:';
            app.points_Latitude = uieditfield(app.points_Grid, 'numeric');
            app.points_Latitude.ValueDisplayFormat = '%.6f';
            app.points_Latitude.ValueChangedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.points_Latitude.Editable = 'off';
            app.points_Latitude.Value = -1;
            app.points_LongitudeLabel = uilabel(app.points_Grid);
            app.points_LongitudeLabel.Text = 'Longitude:';
            app.points_Longitude = uieditfield(app.points_Grid, 'numeric');
            app.points_Longitude.ValueDisplayFormat = '%.6f';
            app.points_Longitude.ValueChangedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.points_Longitude.Editable = 'off';
            app.points_Longitude.Value = -1;
            app.points_AntennaHeightLabel = uilabel(app.points_Grid);
            app.points_AntennaHeightLabel.Text = 'Altura (m):';
            app.points_AntennaHeight = uieditfield(app.points_Grid, 'numeric');
            app.points_AntennaHeight.ValueDisplayFormat = '%.6f';
            app.points_AntennaHeight.ValueChangedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.points_AntennaHeight.Editable = 'off';
            app.points_AntennaHeight.Value = -1;
            app.points_editButton = uibutton(app.points_Grid, 'push');
            app.points_editButton.ButtonPushedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.points_editButton.Icon = 'Edit_32.png';
            app.points_editButton.Text = '';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab2_infoGrid = uigridlayout(app.Tab_2);
            app.filter_TypePanel = uibuttongroup(app.Tab2_infoGrid);
            app.filter_TypePanel.SelectionChangedFcn = createCallbackFcn(app, @filter_typePanelSelectionChanged, true);
            app.filter_TypeRadioButton1 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton1.Tag = '16';
            app.filter_TypeRadioButton1.Text = 'Fonte';
            app.filter_TypeRadioButton1.Value = true;
            app.filter_TypeRadioButton2 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton2.Tag = '1';
            app.filter_TypeRadioButton2.Text = 'Frequência';
            app.filter_TypeRadioButton3 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton3.Tag = '13';
            app.filter_TypeRadioButton3.Text = 'Largura banda';
            app.filter_TypeRadioButton4 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton4.Tag = '12';
            app.filter_TypeRadioButton4.Text = 'Classe emissão';
            app.filter_TypeRadioButton5 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton5.Tag = '2';
            app.filter_TypeRadioButton5.Text = 'Entidade';
            app.filter_TypeRadioButton6 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton6.Tag = '3';
            app.filter_TypeRadioButton6.Text = 'Fistel';
            app.filter_TypeRadioButton7 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton7.Tag = '4';
            app.filter_TypeRadioButton7.Text = 'Serviço';
            app.filter_TypeRadioButton8 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton8.Tag = '5';
            app.filter_TypeRadioButton8.Text = 'Estação';
            app.filter_TypeRadioButton9 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton9.Tag = '10';
            app.filter_TypeRadioButton9.Text = 'UF';
            app.filter_TypeRadioButton10 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton10.Tag = '9';
            app.filter_TypeRadioButton10.Text = 'Município';
            app.filter_TypeRadioButton11 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton11.Tag = '32';
            app.filter_TypeRadioButton11.Text = 'Distância';
            app.filter_TypeRadioButton12 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton12.Tag = '-1';
            app.filter_TypeRadioButton12.Text = 'ROI';
            app.filter_operationPanel = uibuttongroup(app.Tab2_infoGrid);
            app.filter_operationPanel.SelectionChangedFcn = createCallbackFcn(app, @filter_operationPanelSelectionChanged, true);
            app.filter_operationButton1 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton1.Tag = 'numeric+textFree+textList2';
            app.filter_operationButton1.Tooltip = {'Igual'};
            app.filter_operationButton1.Text = '=';
            app.filter_operationButton2 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton2.Tag = 'numeric+textFree+textList2';
            app.filter_operationButton2.Tooltip = {'Diferente'};
            app.filter_operationButton2.Text = '≠';
            app.filter_operationButton3 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton3.Tag = 'ROI+textFree+textList1';
            app.filter_operationButton3.Tooltip = {'Contém'};
            app.filter_operationButton3.Text = '⊃';
            app.filter_operationButton3.Value = true;
            app.filter_operationButton4 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton4.Tag = 'textFree+textList1';
            app.filter_operationButton4.Tooltip = {'Não contém'};
            app.filter_operationButton4.Text = '⊅';
            app.filter_operationButton5 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton5.Tag = 'numeric';
            app.filter_operationButton5.Tooltip = {'Menor'};
            app.filter_operationButton5.Text = '<';
            app.filter_operationButton6 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton6.Tag = 'numeric';
            app.filter_operationButton6.Tooltip = {'Menor ou igual'};
            app.filter_operationButton6.Text = '≤';
            app.filter_operationButton7 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton7.Tag = 'numeric';
            app.filter_operationButton7.Tooltip = {'Maior'};
            app.filter_operationButton7.Text = '>';
            app.filter_operationButton8 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton8.Tag = 'numeric';
            app.filter_operationButton8.Tooltip = {'Maior ou igual'};
            app.filter_operationButton8.Text = '≥';
            app.filter_operationButton9 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton9.Tag = 'numeric';
            app.filter_operationButton9.Tooltip = {'Dentro do intervalo'};
            app.filter_operationButton9.Text = '><';
            app.filter_operationButton10 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton10.Tag = 'numeric';
            app.filter_operationButton10.Tooltip = {'Fora do intervalo'};
            app.filter_operationButton10.Text = '<>';
            app.filter_operationValuesPanel = uipanel(app.filter_operationPanel);
            app.filter_operationValuesGrid = uigridlayout(app.filter_operationValuesPanel);
            app.filter_operationNumValue1 = uieditfield(app.filter_operationValuesGrid, 'numeric');
            app.filter_operationNumValue1.Limits = [-1 Inf];
            app.filter_operationNumValue1.ValueDisplayFormat = '%10.10g';
            app.filter_operationNumValue1.Value = -1;
            app.filter_operationSeparator = uilabel(app.filter_operationValuesGrid);
            app.filter_operationSeparator.Text = '-';
            app.filter_operationNumValue2 = uieditfield(app.filter_operationValuesGrid, 'numeric');
            app.filter_operationNumValue2.Limits = [-1 Inf];
            app.filter_operationNumValue2.Value = -1;
            app.filter_operationTextFreeValue = uieditfield(app.filter_operationValuesGrid, 'text');
            app.filter_operationTextListValue = uidropdown(app.filter_operationValuesGrid);
            app.filter_operationTextListValue.Items = {};
            app.filter_operationTextListValue.Value = {};
            app.filter_operationLogicalReferenceLabel = uilabel(app.filter_operationValuesGrid);
            app.filter_operationLogicalReferenceLabel.Text = {'Filtro de '; 'referência (ID):'};
            app.filter_operationLogicalReference = uidropdown(app.filter_operationValuesGrid);
            app.filter_operationLogicalReference.Items = {};
            app.filter_operationLogicalReference.ValueChangedFcn = createCallbackFcn(app, @filter_operationLogicalReferenceValueChanged, true);
            app.filter_operationLogicalReference.Value = {};
            app.filter_operationLogicalLabel = uilabel(app.filter_operationValuesGrid);
            app.filter_operationLogicalLabel.Text = {'Operador '; 'lógico:'};
            app.filter_operationLogical = uidropdown(app.filter_operationValuesGrid);
            app.filter_operationLogical.Items = {'E (&&)'};
            app.filter_operationLogical.Value = 'E (&&)';
            app.filter_addImage = uiimage(app.Tab2_infoGrid);
            app.filter_addImage.ImageClickedFcn = createCallbackFcn(app, @filter_addFilter, true);
            app.filter_addImage.Tooltip = {'Adicionar filtro'};
            app.filter_addImage.ImageSource = 'addSymbol_32.png';
            app.filter_Tree = uitree(app.Tab2_infoGrid, 'checkbox');
            % Assign Checked Nodes
            app.filter_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @filter_TreeCheckedNodesChanged, true);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab3_infoGrid = uigridlayout(app.Tab_3);
            app.misc_Intro = uilabel(app.Tab3_infoGrid);
            app.misc_Intro.Interpreter = 'html';
            app.misc_Intro.Text = '<p style="text-align: justify; line-height: 1; margin-right: 2px;">O enlace entre a estação transmissora - estação de telecomunicações selecionada na tabela - e a estação receptora - o ponto de monitoração de referência escolhido na guia "Aspectos gerais" - é representado por meio do perfil de terreno e da 1ª zona de Fresnel.</p>';
            app.misc_LinkPanel = uipanel(app.Tab3_infoGrid);
            app.misc_LinkGrid = uigridlayout(app.misc_LinkPanel);
            app.misc_LinkGeneralInfoLabel = uilabel(app.misc_LinkGrid);
            app.misc_LinkGeneralInfoLabel.Text = 'Descrição:';
            app.misc_LinkInfoPanel = uipanel(app.misc_LinkGrid);
            app.misc_LinkInfoGrid = uigridlayout(app.misc_LinkInfoPanel);
            app.misc_LinkDistanceLabel = uilabel(app.misc_LinkInfoGrid);
            app.misc_LinkDistanceLabel.Text = {'Distância:'; '(km)'};
            app.misc_LinkDistance = uieditfield(app.misc_LinkInfoGrid, 'numeric');
            app.misc_LinkDistance.Limits = [-1 Inf];
            app.misc_LinkDistance.Editable = 'off';
            app.misc_LinkDistance.Value = -1;
            app.misc_LinkAzimuthLabel = uilabel(app.misc_LinkInfoGrid);
            app.misc_LinkAzimuthLabel.Text = {'Azimute:'; '(º)'};
            app.misc_LinkAzimuth = uieditfield(app.misc_LinkInfoGrid, 'numeric');
            app.misc_LinkAzimuth.Limits = [-1 Inf];
            app.misc_LinkAzimuth.Editable = 'off';
            app.misc_LinkAzimuth.Value = -1;
            app.misc_LinkAttenuationLabel = uilabel(app.misc_LinkInfoGrid);
            app.misc_LinkAttenuationLabel.Text = {'Perda espaço'; 'livre (dB):'};
            app.misc_LinkAttenuation = uieditfield(app.misc_LinkInfoGrid, 'numeric');
            app.misc_LinkAttenuation.Limits = [-1 Inf];
            app.misc_LinkAttenuation.Editable = 'off';
            app.misc_LinkAttenuation.Value = -1;
            app.misc_LinkInfoLabel = uilabel(app.misc_LinkGrid);
            app.misc_LinkInfoLabel.Text = 'Características:';
            app.misc_LinkGeneralInfoPanel = uipanel(app.misc_LinkGrid);
            app.misc_LinkGeneralInfoGrid = uigridlayout(app.misc_LinkGeneralInfoPanel);
            app.misc_LinkGeneralInfo = uihtml(app.misc_LinkGeneralInfoGrid);
            app.misc_LinkGeneralInfo.HTMLSource = ' ';
            app.misc_ElevationSourceLabel = uilabel(app.Tab3_infoGrid);
            app.misc_ElevationSourceLabel.Text = 'Elevação do terreno';
            app.misc_ElevationSourcePanel = uipanel(app.Tab3_infoGrid);
            app.misc_ElevationSourceGrid = uigridlayout(app.misc_ElevationSourcePanel);
            app.misc_ElevationAPISourceLabel = uilabel(app.misc_ElevationSourceGrid);
            app.misc_ElevationAPISourceLabel.Text = 'Fonte:';
            app.misc_ElevationAPISource = uidropdown(app.misc_ElevationSourceGrid);
            app.misc_ElevationAPISource.Items = {'Open-Elevation', 'MathWorks WMS Server'};
            app.misc_ElevationAPISource.Value = 'Open-Elevation';
            app.misc_PointsPerLinkLabel = uilabel(app.misc_ElevationSourceGrid);
            app.misc_PointsPerLinkLabel.Text = 'Pontos enlace:';
            app.misc_PointsPerLink = uidropdown(app.misc_ElevationSourceGrid);
            app.misc_PointsPerLink.Items = {'64', '128', '256', '512', '1024'};
            app.misc_PointsPerLink.Value = '256';
            app.menu_MainGrid = uigridlayout(app.Grid);
            app.menu_Image = uiimage(app.menu_MainGrid);
            app.menu_Image.ImageSource = 'LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'ASPECTOS GERAIS';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'Home_18.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'FILTRAGEM';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'Filter_18.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'ENLACE';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'Connect_18.png';
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.points_ContextMenu = uicontextmenu(app.UIFigure);
            app.points_referenceButton = uimenu(app.points_ContextMenu);
            app.points_referenceButton.MenuSelectedFcn = createCallbackFcn(app, @points_ContextMenuCallbacks, true);
            app.points_referenceButton.Text = 'Referência';
            app.points_editRowButton = uimenu(app.points_ContextMenu);
            app.points_editRowButton.MenuSelectedFcn = createCallbackFcn(app, @points_ContextMenuCallbacks, true);
            app.points_editRowButton.Separator = 'on';
            app.points_editRowButton.Text = 'Editar';
            app.points_delButton = uimenu(app.points_ContextMenu);
            app.points_delButton.MenuSelectedFcn = createCallbackFcn(app, @points_ContextMenuCallbacks, true);
            app.points_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRFDataHub(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end