classdef winSignalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout8            matlab.ui.container.GridLayout
        GridLayout5            matlab.ui.container.GridLayout
        tool_simulationButton  matlab.ui.control.Button
        Image_2                matlab.ui.control.Image
        jsBackDoor             matlab.ui.control.HTML
        Image                  matlab.ui.control.Image
        Button_globalList      matlab.ui.control.Button
        Button_edit            matlab.ui.control.Button
        Button_del             matlab.ui.control.Button
        Button_save            matlab.ui.control.Button
        Grid1                  matlab.ui.container.GridLayout
        Panel_5                matlab.ui.container.Panel
        GridLayout6            matlab.ui.container.GridLayout
        LOG                    matlab.ui.control.Label
        LOGLabel               matlab.ui.control.Label
        Panel_4                matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        Grid4_2                matlab.ui.container.GridLayout
        InfoPlayback           matlab.ui.control.TextArea
        InfoPlaybackLabel      matlab.ui.control.Label
        Info                   matlab.ui.control.TextArea
        InfoLabel              matlab.ui.control.Label
        RiskLevel_edited       matlab.ui.control.DropDown
        RiskLevel_editedLabel  matlab.ui.control.Label
        Compliance             matlab.ui.control.DropDown
        ComplianceLabel        matlab.ui.control.Label
        Type                   matlab.ui.control.DropDown
        TypeLabel              matlab.ui.control.Label
        StationID              matlab.ui.control.EditField
        StationIDLabel         matlab.ui.control.Label
        Service_edited         matlab.ui.control.NumericEditField
        ServiceLabel_edited    matlab.ui.control.Label
        RegInfo_edited         matlab.ui.control.DropDown
        RegInfoLabel_edited    matlab.ui.control.Label
        Sublabel2              matlab.ui.control.Label
        Label2                 matlab.ui.control.Label
        Grid4                  matlab.ui.container.GridLayout
        Method                 matlab.ui.control.TextArea
        MethodLabel            matlab.ui.control.Label
        StationLabel_raw       matlab.ui.control.Label
        Station_raw            matlab.ui.control.NumericEditField
        ServiceLabel_raw       matlab.ui.control.Label
        Service_raw            matlab.ui.control.NumericEditField
        RegInfo_raw            matlab.ui.control.TextArea
        RegInfoLabel_raw       matlab.ui.control.Label
        Sublabel1              matlab.ui.control.Label
        Label1                 matlab.ui.control.Label
        Description            matlab.ui.control.TextArea
        DescriptionLabel       matlab.ui.control.Label
        Panel_3                matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        Station_dash           matlab.ui.control.Label
        StationLabel_dash      matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        Service_dash           matlab.ui.control.Label
        ServiceLabel_dash      matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Channel                matlab.ui.control.Label
        ChannelLabel           matlab.ui.control.Label
        Grid2                  matlab.ui.container.GridLayout
        UITable                matlab.ui.control.Table
    end

    
    properties (Access = public)
        %-------------------------------------------------------------------------%
        CallingApp
        General
        rootFolder

        timerObj_startup

        specData
        projectData
        tempBandObj

        UIAxes1
        restoreView = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj_startup = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj_startup)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj_startup)
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app)

                % Criação do eixo e leitura dos dados...
                startup_AppProperties(app)
                startup_Axes(app)
                renderProjectDataOnScreen(app)                

                % Libera screen... 
                if ~strcmp(app.CallingApp.executionMode, 'webApp')
                    appUtil.winMinSize(app.UIFigure, [1244,660])
                end
                drawnow

                pause(.100)
                focus(app.UITable)
            end
        end

        %------------------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end

        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                   'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                       '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                       '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                   'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

            ccTools.compCustomizationV2(app.jsBackDoor, app.Method,      'textAlign', 'justify')
            ccTools.compCustomizationV2(app.jsBackDoor, app.Description, 'textAlign', 'justify')
            ccTools.compCustomizationV2(app.jsBackDoor, app.Info,        'textAlign', 'justify')
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            app.tempBandObj = class.Band('appAnalise:SIGNALANALYSIS', app);
        end

        %-------------------------------------------------------------------------%
        function startup_Axes(app)
            % Axes creation:
            app.UIAxes1 = plot.axes.Creation(app.Grid1, 'Cartesian', {'XColor', [0,0,0], 'YColor', [0,0,0]});
            app.UIAxes1.Layout.Row = [4 5];
            app.UIAxes1.Layout.Column = [2 3];

            % Axes fixed labels:
            xlabel(app.UIAxes1, 'Frequência (MHz)')
            ylabel(app.UIAxes1, 'Nível (dB)')

            % Axes interactions:
            plot.axes.Interactivity.DefaultCreation(app.UIAxes1, [dataTipInteraction, rulerPanInteraction, regionZoomInteraction])
            plot.axes.Interactivity.ToolbarCreation(app.UIAxes1, {'pan', 'restoreview'})
            plot.axes.Interactivity.CustomCallbacks(app.UIAxes1, [], {'pan', 'restoreview'}, app)
        end

        %-------------------------------------------------------------------------%
        function TableStyle(app)            
            removeStyle(app.UITable)
                        
            if ~isempty(app.projectData.exceptionList)
                ind1 = [];
                for ii = 1:height(app.projectData.exceptionList)
                    ind2 = intersect(find(strcmp(app.projectData.peaksTable.Tag,  app.projectData.exceptionList.Tag(ii))), ...
                                     find(abs(app.projectData.peaksTable.Frequency - app.projectData.exceptionList.Frequency(ii)) <= class.Constants.floatDiffTolerance));

                    if ~isempty(ind2)
                        ind1 = [ind1; ind2];
                    end
                end
                
                if ~isempty(ind1)
                    addStyle(app.UITable, uistyle("BackgroundColor", '#c94756', 'FontColor', 'white'), 'row', ind1)
                end
            end            
        end        
        
        %-------------------------------------------------------------------------%
        function selectedEmission_FillComponents(app)   
            idxPrjPeaks     = app.UITable.Selection;
            idxPrjException = exceptionListIndex(app);

            % Panel 1
            app.Method.Value      = app.projectData.peaksTable.Detection{idxPrjPeaks};
            app.RegInfo_raw.Value = app.projectData.peaksTable.Regulatory{idxPrjPeaks};
            app.Service_raw.Value = double(app.projectData.peaksTable.Service(idxPrjPeaks));
            app.Station_raw.Value = double(app.projectData.peaksTable.Station(idxPrjPeaks));
            app.Description.Value = app.projectData.peaksTable.Description{idxPrjPeaks};

            detectionInfo = jsondecode(app.projectData.peaksTable.Detection{idxPrjPeaks});
            if isfield(detectionInfo, 'Description')
                app.Method.Value  = jsonencode(rmfield(detectionInfo, 'Description'), 'ConvertInfAndNaN', false);
                set(app.InfoPlayback, 'Value', detectionInfo.Description, 'UserData', detectionInfo.Description)
            else
                app.Method.Value  = app.projectData.peaksTable.Detection{idxPrjPeaks};
                set(app.InfoPlayback, 'Value', '', 'UserData', '')
            end

            % Panel 2
            if isempty(idxPrjException)
                app.RegInfo_edited.Value = app.RegInfo_raw.Value;
                RegInfo_editedValueChanged(app)

                app.Service_edited.Value = app.Service_raw.Value;
                app.StationID.Value      = num2str(app.Station_raw.Value);
                
                app.Type.Value           = app.projectData.peaksTable.Type{idxPrjPeaks};
                app.Compliance.Value     = app.projectData.peaksTable.Irregular{idxPrjPeaks};
                ComplianceValueChanged(app)

                app.RiskLevel_edited.Value = app.projectData.peaksTable.RiskLevel{idxPrjPeaks};
                app.Info.Value             = '';
                app.Button_del.Enable      = 0;
                
            else
                app.RegInfo_edited.Value = app.projectData.exceptionList.Regulatory(idxPrjException);
                RegInfo_editedValueChanged(app)

                app.Service_edited.Value = double(app.projectData.exceptionList.Service(idxPrjException));
                app.StationID.Value      = num2str(app.projectData.exceptionList.Station(idxPrjException));

                app.Type.Value           = app.projectData.exceptionList.Type(idxPrjException);
                app.Compliance.Value     = app.projectData.exceptionList.Irregular(idxPrjException);
                ComplianceValueChanged(app)

                app.RiskLevel_edited.Value = app.projectData.exceptionList.RiskLevel(idxPrjException);
                app.Info.Value             = replace(app.projectData.exceptionList.Description(idxPrjException), '[EXC] ', '');
                app.Button_del.Enable      = 1;
            end

            % Panel 3 (Dashboard)
            app.Channel.Text = sprintf('%.3f MHz ⌂ %.3f kHz', app.projectData.peaksTable.Frequency(idxPrjPeaks), ...
                                                              app.projectData.peaksTable.BW(idxPrjPeaks));

            app.Service_dash.Text = num2str(app.Service_edited.Value);
            app.Station_dash.Text = app.StationID.Value;

            % Plot, and LOG
            selectedEmission_CreatePlot(app)

            columnNames2LOG = {'Type', 'Regulatory', 'Service', 'Station', 'Description', 'Irregular', 'RiskLevel'};
            app.LOG.Text    = textFormatGUI.editionTable2LOG(app.projectData.peaksTable(idxPrjPeaks,columnNames2LOG), ...
                                                             app.projectData.exceptionList(idxPrjException,columnNames2LOG), columnNames2LOG);
        end

        %-------------------------------------------------------------------------%
        function selectedEmission_CreatePlot(app)
            idxPrjPeaks   = app.UITable.Selection;            
            [idxThread, ...
             idxEmission] = specDataIndex(app, idxPrjPeaks);

            cla(app.UIAxes1)
            if ~isempty(idxThread) && ~isempty(idxEmission)
                axesLimits = update(app.tempBandObj, idxThread, idxPrjPeaks);
                app.restoreView = struct('ID', 'app.UIAxes1', 'xLim', axesLimits.xLim, 'yLim', axesLimits.yLevelLim, 'cLim', 'auto');
                set(app.UIAxes1, 'XLim', app.restoreView.xLim, 'YLim', app.restoreView.yLim)
                ylabel(app.UIAxes1, sprintf('Nível (%s)', app.tempBandObj.LevelUnit))

                % (a) MinHold, Average, e MaxHold
                for plotTag = ["MinHold", "Average", "MaxHold"]
                    eval(sprintf('hLine = plot.draw2D.OrdinaryLine(app.UIAxes1, app.tempBandObj, idxThread, "%s");', plotTag))
                    plot.datatip.Template(hLine, "Frequency+Level", app.tempBandObj.LevelUnit)
                end

                % (b) ROI
                plot.draw2D.rectangularROI(app.UIAxes1, app.tempBandObj, idxThread, 'ROI', idxEmission, {'EdgeAlpha', 0, 'InteractionsAllowed', 'none'})

            else
                msgWarning = 'Não identificado o fluxo de dados relacionado à emissão...';
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
            end
        end

        %-------------------------------------------------------------------------%
        function [idxThread, idxEmission] = specDataIndex(app, idxPrjPeaks)
            idxThread   = [];
            idxEmission = [];

            for ii = 1:numel(app.specData)
                Tag = sprintf('%s\n%.3f - %.3f MHz', app.specData(ii).Receiver,                  ...
                                                     app.specData(ii).MetaData.FreqStart / 1e+6, ...
                                                     app.specData(ii).MetaData.FreqStop  / 1e+6);
                if strcmp(Tag, app.projectData.peaksTable.Tag{idxPrjPeaks})
                    idxThread   = ii;
                    idxEmission = find(app.specData(idxThread).UserData.Emissions.Index == app.projectData.peaksTable.Index(idxPrjPeaks), 1);
                    break
                end
            end
        end

        %-------------------------------------------------------------------------%
        function idxPrjException = exceptionListIndex(app)
            idxPrjPeaks     = app.UITable.Selection;
            idxPrjException = intersect(find(strcmp(app.projectData.exceptionList.Tag,  app.projectData.peaksTable.Tag(idxPrjPeaks))), ...
                                        find(abs(app.projectData.exceptionList.Frequency - app.projectData.peaksTable.Frequency(idxPrjPeaks)) <= class.Constants.floatDiffTolerance));

            if ~isempty(idxPrjException)
                idxPrjException = idxPrjException(1);
            end
        end
    end


    methods (Access = public)
        %-------------------------------------------------------------------------%
        function renderProjectDataOnScreen(app)
            tempData       = app.projectData.peaksTable(:, [1, 1, 5:12, 17:18]);
            tempData.Tag   = extractBefore(tempData.Tag,  newline);
            tempData.Tag_1 = extractAfter(tempData.Tag_1, newline);

            set(app.UITable, 'Data', tempData, 'Selection', 1)
            TableStyle(app)
            selectedEmission_FillComponents(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp  = mainapp;
            app.General     = mainapp.General;
            app.rootFolder  = mainapp.rootFolder;
            app.specData    = mainapp.specData;
            app.projectData = mainapp.projectData;

            appUtil.winPosition(app.UIFigure)            
            jsBackDoor_Initialization(app)
            startup_timerCreation(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWinFunction(app, 'closeFcn')
            app.CallingApp.auxiliarWin1 = [];
            delete(app)
            
        end

        % Selection changed function: UITable
        function UITable_CellSelection(app, event)

            if isempty(event.Selection)
                app.UITable.Selection = event.PreviousSelection;
            else
                selectedEmission_FillComponents(app)
            end
            
        end

        % Button pushed function: Button_globalList
        function Button_globalListPushed(app, event)
                        
            ExceptionGlobalList = app.CallingApp.channelObj.Exception;

            if isempty(ExceptionGlobalList)
                msg = 'Não identificada emissão na lista global de exceções contida no arquivo "ChannelLib.json".';

            else
                msg = [];
                for ii = 1:height(ExceptionGlobalList)
                    msg = sprintf('%s\n%s', msg, sprintf('<b>%.3f MHz</b>: "%s"', ExceptionGlobalList.FreqCenter(ii), ExceptionGlobalList.Description{ii}));
                end
            end
            appUtil.modalWindow(app.UIFigure, 'warning', msg);

        end

        % Button pushed function: Button_del
        function Button_delPushed(app, event)
            
            idxPrjException = exceptionListIndex(app);
            app.projectData.exceptionList(idxPrjException,:) = [];

            app.UITable.Selection = 1;
            TableStyle(app)            
            selectedEmission_FillComponents(app)

            scroll(app.UITable, 'top')

        end

        % Button pushed function: Button_edit
        function Button_edit_Clicked(app, event)
            
            idxPrjPeaks     = app.UITable.Selection;
            idxPrjException = exceptionListIndex(app);
            [idxThread, ...
             idxEmission]   = specDataIndex(app, idxPrjPeaks);

            infoField       = strjoin(app.Info.Value);

            if ~strcmp(app.projectData.peaksTable.Regulatory{idxPrjPeaks}, app.RegInfo_edited.Value)   || ...
               app.projectData.peaksTable.Station(idxPrjPeaks) ~= str2double(app.StationID.Value)      || ...
               ~strcmp(app.projectData.peaksTable.Type{idxPrjPeaks}, app.Type.Value)                   || ...
               ~strcmp(app.projectData.peaksTable.Irregular{idxPrjPeaks}, app.Compliance.Value)        || ...
               ~strcmp(app.projectData.peaksTable.RiskLevel{idxPrjPeaks}, app.RiskLevel_edited.Value)  || ...
               ~isequal(app.InfoPlayback.Value, app.InfoPlayback.UserData)                             || ...
               ~isempty(infoField)

                if ~isempty(idxThread) && ~isempty(idxEmission)
                    if ~isequal(app.InfoPlayback.Value, app.InfoPlayback.UserData)
                        detectionInfo = jsondecode(app.specData(idxThread).UserData.Emissions.Detection{idxEmission});
                        detectionInfo.Description = app.InfoPlayback.Value;

                        app.specData(idxThread).UserData.Emissions.Detection{idxEmission} = jsonencode(detectionInfo, 'ConvertInfAndNaN', false);
                        app.projectData.peaksTable.Detection{idxPrjPeaks}                 = app.specData(idxThread).UserData.Emissions.Detection{idxEmission};
                    end
                end

                stationInfo = '';
                try
                    stationInfo = jsondecode(infoField);
                catch
                end
    
                if isstruct(stationInfo)
                    Service = str2double(stationInfo.Service);
                    if Service ~= -1; Regulatory = 'Licenciada';     Irregular = 'Não'; RiskLevel = '-';
                    else;             Regulatory = 'Não licenciada'; Irregular = 'Sim'; RiskLevel = 'Baixo';
                    end

                    exceptionValue = {app.projectData.peaksTable.Tag{idxPrjPeaks},       ...
                                      app.projectData.peaksTable.Frequency(idxPrjPeaks), ...
                                      'Fundamental',                                     ...
                                      Regulatory,                                        ...
                                      Service,                                           ...
                                      stationInfo.Station,                               ...
                                      stationInfo.Description,                           ...
                                      stationInfo.Distance,                              ...
                                      Irregular,                                         ...
                                      RiskLevel};
    
                else
                    exceptionValue = {app.projectData.peaksTable.Tag{idxPrjPeaks},       ...
                                      app.projectData.peaksTable.Frequency(idxPrjPeaks), ...
                                      app.Type.Value,                                    ...
                                      app.RegInfo_edited.Value,                          ...
                                      [], [], [], [],                                    ...
                                      app.Compliance.Value,                              ...
                                      app.RiskLevel_edited.Value};

                    switch app.RegInfo_edited.Value
                        case 'Licenciada'
                            if isempty(idxPrjException)
                                exceptionValue(5:8) = {app.projectData.peaksTable.Service(idxPrjPeaks),     ...
                                                       app.projectData.peaksTable.Station(idxPrjPeaks),     ...
                                                       app.projectData.peaksTable.Description{idxPrjPeaks}, ...
                                                       app.projectData.peaksTable.Distance{idxPrjPeaks}};
                            else
                                if int32(str2double(app.StationID.Value)) == app.projectData.peaksTable.Station(idxPrjPeaks)
                                    Distance = app.projectData.peaksTable.Distance{idxPrjPeaks};
                                else
                                    Distance = app.projectData.exceptionList.Distance{idxPrjException};
                                end
                                exceptionValue(5:8) = {app.Service_edited.Value,        ...
                                                       str2double(app.StationID.Value), ...
                                                       infoField,                       ...
                                                       Distance};
                            end

                        otherwise
                            if isempty(infoField)
                                msg = 'Campo "Informações adicionais" não pode ficar vazio.';
                                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                                return
                            end
                            exceptionValue(5:8) = {-1,                   ...
                                                   -1,                   ...
                                                   ['[EXC] ' infoField], ...
                                                   '-'};
                    end
                end

                if isempty(idxPrjException)
                    app.projectData.exceptionList(end+1,:)           = exceptionValue;
                else
                    app.projectData.exceptionList(idxPrjException,:) = exceptionValue;
                end

                TableStyle(app)            
                selectedEmission_FillComponents(app)
            end
            
        end

        % Value changed function: StationID
        function StationIDValueChanged(app, event)
            
            try
                app.StationID.Value = strtrim(app.StationID.Value);
                if isempty(regexp(app.StationID.Value, '^\#?\d+$', 'once'))
                    error(['Esse campo deve ser preenchido com o número da estação ou a ID do registro no RFDataHub. ' ...
                           'O seu formato é numérico, com a ressalva de que quando inserido a ID do registro '         ...
                           'deve ser colocado o caractere "#" à frente do número. Por exemplo: 123456 ou #123456.'])
                end

                global RFDataHub
                
                idxPrjPeaks   = app.UITable.Selection;
                latitudeNode  = app.projectData.peaksTable.Latitude(idxPrjPeaks);
                longitudeNode = app.projectData.peaksTable.Longitude(idxPrjPeaks);
                stationInfo   = class.RFDataHub.query(RFDataHub, app.StationID.Value, latitudeNode, longitudeNode);

                dataStruct(1) = struct('group', 'INDICAÇÃO AUTOMÁTICA',                                                                          ...
                                        'value', struct('Regulatory',  app.projectData.peaksTable.Regulatory{idxPrjPeaks},                     ...
                                                        'Frequency',   sprintf('%.3f MHz', app.projectData.peaksTable.Frequency(idxPrjPeaks))));
                
                if app.projectData.peaksTable.Regulatory{idxPrjPeaks} == "Licenciada"
                    dataStruct(1).value.Description = app.projectData.peaksTable.Description{idxPrjPeaks};
                    dataStruct(1).value.Distance    = sprintf('%s km', app.projectData.peaksTable.Distance{idxPrjPeaks});
                end

                switch stationInfo.Service
                    case -1
                        emissionRegulatory = '<font style="color:red;">Emissão Não Licenciada</font>';
                    otherwise
                        emissionRegulatory = 'Emissão Licenciada';
                end
                dataStruct(2)  = struct('group', 'MANUAL',                                                       ...
                                        'value', struct('Regulatory',  emissionRegulatory,                       ...
                                                        'Frequency',   sprintf('%s MHz', stationInfo.Frequency), ...
                                                        'Description', stationInfo.Description,                  ...
                                                        'Distance',    sprintf('%.1f km', stationInfo.Distance)));

                msgQuestion    = sprintf('%s<br><font style="font-size: 12px;">Confirma edição?<font>', textFormatGUI.struct2PrettyPrintList(dataStruct));
                userSelection  = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                switch userSelection
                    case 'Sim'
                        set(app.Info, 'Value', jsonencode(struct('ID',          stationInfo.ID,                          ...
                                                                 'Service',     num2str(stationInfo.Service),            ...
                                                                 'Station',     stationInfo.Station,                     ...
                                                                 'Description', stationInfo.Description,                 ...
                                                                 'Distance',    sprintf('%.1f', stationInfo.Distance))), ...
                                      'Editable', 0)
                        Button_edit_Clicked(app)
                    case 'Não'
                        app.StationID.Value = num2str(app.projectData.peaksTable.Station(idxPrjPeaks));
                end

            catch ME
                app.StationID.Value = num2str(app.projectData.peaksTable.Station(idxPrjPeaks));                
                appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
            end
            
        end

        % Value changed function: RegInfo_edited
        function RegInfo_editedValueChanged(app, event)
            
            switch app.RegInfo_edited.Value
                case 'Licenciada'
                    set(app.StationID, 'Editable', 1)
                    set(app.Info,    'Editable', 0, 'Value', '')
                    
                    set(app.Type, 'Value', 'Fundamental', 'Enable', 0)
                    app.TypeLabel.Enable = 0;
    
                    set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')

                case {'Não licenciada', 'Não passível de licenciamento'}
                    set(app.Info,    'Editable', 1)
    
                    app.Type.Enable      = 1;
                    app.TypeLabel.Enable = 1;
    
                    switch app.RegInfo_edited.Value
                        case 'Não licenciada'
                            set(app.Compliance, 'Items', {'Sim'})
                            set(app.StationID, 'Editable', 1, 'Value', '-1')
                        case 'Não passível de licenciamento'
                            set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')
                            set(app.StationID, 'Editable', 0, 'Value', '-1')
                    end
            end
            ComplianceValueChanged(app)
            
        end

        % Value changed function: Compliance
        function ComplianceValueChanged(app, event)
            
            if strcmp(app.Compliance.Value, 'Não')
                set(app.RiskLevel_edited,      'Enable', 0, 'Items', {'-'})
                set(app.RiskLevel_editedLabel, 'Enable', 0)
            else
                set(app.RiskLevel_edited,      'Enable', 1, 'Items', {'Baixo', 'Médio', 'Alto'})
                set(app.RiskLevel_editedLabel, 'Enable', 1)
            end

        end

        % Button pushed function: Button_save
        function Button_savePushed(app, event)
            
            report.Controller(app, 'auxApp.winSignalAnalysis')

        end

        % Value changed function: Info, InfoPlayback
        function InfoValueChanged(app, event)
            
            switch event.Source
                case app.Info
                    app.Info.Value         = strtrim(app.Info.Value);
                case app.InfoPlayback
                    app.InfoPlayback.Value = strtrim(app.InfoPlayback.Value);
            end

        end

        % Button pushed function: tool_simulationButton
        function tool_simulationButtonPushed(app, event)
            
            idxPrjPeaks     = app.UITable.Selection;
            idxPrjException = exceptionListIndex(app);

            if isempty(idxPrjException)
                txStationNumber = app.projectData.peaksTable.Station(idxPrjPeaks);
            else
                txStationNumber = app.projectData.exceptionList.Station(idxPrjException);
            end

            if txStationNumber == -1
                msg = 'Funcionalidade aplicável apenas para estações licenciadas...';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                
                return
            end

            if ~isempty(app.CallingApp.auxiliarWin3)
                msgQuestion   = ['O módulo de consulta ao RFDataHub está aberto e, por essa razão, caso se opte ' ...
                                 'por continuar, todas as inserções de dados inseridas no módulo de consulta ao ' ...
                                 'RFDataHub serão perdidas.<br><br>Deseja continuar?'];
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                if userSelection == "Não"
                    return
                end
            end

            request = struct('Type',           'TerrainProfilePlot',                                  ...
                             'rxLatitude',      double(app.projectData.peaksTable.Latitude(idxPrjPeaks)),  ...
                             'rxLongitude',     double(app.projectData.peaksTable.Longitude(idxPrjPeaks)), ...
                             'txStationNumber', txStationNumber);

            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');
            app.CallingApp.auxiliarWinFunction(app, 'tool_simulationButtonPushed', request)
            delete(d)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Análise de emissões';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout8 = uigridlayout(app.UIFigure);
            app.Grid1 = uigridlayout(app.GridLayout8);
            app.Grid2 = uigridlayout(app.Grid1);
            app.UITable = uitable(app.Grid2);
            app.UITable.ColumnName = {'Sensor'; 'Fluxo espectral'; 'Frequência central (MHz)'; 'Frequência truncada (MHz)'; 'Largura ocupada (kHz)'; 'Nível mínimo (dB)'; 'Nível médio (dB)'; 'Nível máximo (dB)'; 'Ocupação média (%)'; 'Ocupação máxima (%)'; 'Provável emissor'; 'Distância (km)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITable_CellSelection, true);
            app.UITable.Multiselect = 'off';
            app.Panel = uipanel(app.Grid1);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout2.Tag = 'COLORLOCKED';
            app.ChannelLabel = uilabel(app.GridLayout2);
            app.ChannelLabel.Text = 'EMISSÃO';
            app.Channel = uilabel(app.GridLayout2);
            app.Channel.Text = '';
            app.Panel_2 = uipanel(app.Grid1);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.GridLayout3.Tag = 'COLORLOCKED';
            app.ServiceLabel_dash = uilabel(app.GridLayout3);
            app.ServiceLabel_dash.Text = 'SERVIÇO';
            app.Service_dash = uilabel(app.GridLayout3);
            app.Service_dash.Text = '';
            app.Panel_3 = uipanel(app.Grid1);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.GridLayout4.Tag = 'COLORLOCKED';
            app.StationLabel_dash = uilabel(app.GridLayout4);
            app.StationLabel_dash.Text = 'ESTAÇÃO';
            app.Station_dash = uilabel(app.GridLayout4);
            app.Station_dash.Text = '';
            app.Panel_4 = uipanel(app.Grid1);
            app.GridLayout7 = uigridlayout(app.Panel_4);
            app.Grid4 = uigridlayout(app.GridLayout7);
            app.DescriptionLabel = uilabel(app.Grid4);
            app.DescriptionLabel.Interpreter = 'html';
            app.DescriptionLabel.Text = {'Descrição:'; '<p style="line-height:10px; font-size:9px; color:gray; text-align: justify;">Serviço-Status, Classe, Entidade (Fistel, Estação), Município/UF</p>'};
            app.Description = uitextarea(app.Grid4);
            app.Description.Editable = 'off';
            app.Label1 = uilabel(app.Grid4);
            app.Label1.Text = 'ASPECTOS GERAIS';
            app.Sublabel1 = uilabel(app.Grid4);
            app.Sublabel1.Text = 'Informações gerais acerca do processo de detecção e classificação de emissões.';
            app.RegInfoLabel_raw = uilabel(app.Grid4);
            app.RegInfoLabel_raw.Text = 'Emissão:';
            app.RegInfo_raw = uitextarea(app.Grid4);
            app.RegInfo_raw.Editable = 'off';
            app.Service_raw = uieditfield(app.Grid4, 'numeric');
            app.Service_raw.Limits = [-1 Inf];
            app.Service_raw.RoundFractionalValues = 'on';
            app.Service_raw.ValueDisplayFormat = '%.0f';
            app.Service_raw.Editable = 'off';
            app.Service_raw.Value = -1;
            app.ServiceLabel_raw = uilabel(app.Grid4);
            app.ServiceLabel_raw.Text = 'Serviço:';
            app.Station_raw = uieditfield(app.Grid4, 'numeric');
            app.Station_raw.Limits = [-1 Inf];
            app.Station_raw.RoundFractionalValues = 'on';
            app.Station_raw.ValueDisplayFormat = '%.0f';
            app.Station_raw.Editable = 'off';
            app.Station_raw.Value = -1;
            app.StationLabel_raw = uilabel(app.Grid4);
            app.StationLabel_raw.Text = 'Estação:';
            app.MethodLabel = uilabel(app.Grid4);
            app.MethodLabel.Text = 'Algoritmo detecção:';
            app.Method = uitextarea(app.Grid4);
            app.Method.Editable = 'off';
            app.Grid4_2 = uigridlayout(app.GridLayout7);
            app.Label2 = uilabel(app.Grid4_2);
            app.Label2.Text = 'ANÁLISE QUALITATIVA';
            app.Sublabel2 = uilabel(app.Grid4_2);
            app.Sublabel2.Text = 'Informações que possibilitam uma compreensão mais profunda acerca das condições de uso do espectro de radiofrequências.';
            app.RegInfoLabel_edited = uilabel(app.Grid4_2);
            app.RegInfoLabel_edited.Text = 'Emissão:';
            app.RegInfo_edited = uidropdown(app.Grid4_2);
            app.RegInfo_edited.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            app.RegInfo_edited.ValueChangedFcn = createCallbackFcn(app, @RegInfo_editedValueChanged, true);
            app.RegInfo_edited.Value = 'Licenciada';
            app.ServiceLabel_edited = uilabel(app.Grid4_2);
            app.ServiceLabel_edited.Text = 'Serviço:';
            app.Service_edited = uieditfield(app.Grid4_2, 'numeric');
            app.Service_edited.Limits = [-1 Inf];
            app.Service_edited.RoundFractionalValues = 'on';
            app.Service_edited.ValueDisplayFormat = '%.0f';
            app.Service_edited.Editable = 'off';
            app.Service_edited.Value = -1;
            app.StationIDLabel = uilabel(app.Grid4_2);
            app.StationIDLabel.Text = 'Estação/ID:';
            app.StationID = uieditfield(app.Grid4_2, 'text');
            app.StationID.ValueChangedFcn = createCallbackFcn(app, @StationIDValueChanged, true);
            app.TypeLabel = uilabel(app.Grid4_2);
            app.TypeLabel.Text = 'Tipo de emissão:';
            app.Type = uidropdown(app.Grid4_2);
            app.Type.Items = {'Fundamental', 'Harmônico de fundamental', 'Produto de intermodulação', 'Espúrio', 'Não identificado', 'Não se manifestou', 'Pendente de identificação'};
            app.Type.Value = 'Fundamental';
            app.ComplianceLabel = uilabel(app.Grid4_2);
            app.ComplianceLabel.Text = 'Indício irregularidade?';
            app.Compliance = uidropdown(app.Grid4_2);
            app.Compliance.Items = {'Não', 'Sim'};
            app.Compliance.ValueChangedFcn = createCallbackFcn(app, @ComplianceValueChanged, true);
            app.Compliance.Value = 'Não';
            app.RiskLevel_editedLabel = uilabel(app.Grid4_2);
            app.RiskLevel_editedLabel.Text = 'Potencial lesivo:';
            app.RiskLevel_edited = uidropdown(app.Grid4_2);
            app.RiskLevel_edited.Items = {'-', 'Baixo', 'Médio', 'Alto'};
            app.RiskLevel_edited.Value = '-';
            app.InfoLabel = uilabel(app.Grid4_2);
            app.InfoLabel.Text = 'Descrição:';
            app.Info = uitextarea(app.Grid4_2);
            app.Info.ValueChangedFcn = createCallbackFcn(app, @InfoValueChanged, true);
            app.InfoPlaybackLabel = uilabel(app.Grid4_2);
            app.InfoPlaybackLabel.Text = 'Informações complementares:';
            app.InfoPlayback = uitextarea(app.Grid4_2);
            app.InfoPlayback.ValueChangedFcn = createCallbackFcn(app, @InfoValueChanged, true);
            app.Panel_5 = uipanel(app.Grid1);
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.LOGLabel = uilabel(app.GridLayout6);
            app.LOGLabel.Text = 'LOG';
            app.LOG = uilabel(app.GridLayout6);
            app.LOG.Interpreter = 'html';
            app.LOG.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout8);
            app.Button_save = uibutton(app.GridLayout5, 'push');
            app.Button_save.ButtonPushedFcn = createCallbackFcn(app, @Button_savePushed, true);
            app.Button_save.Icon = 'Export_24.png';
            app.Button_save.Tooltip = {'Exportar lista de emissões'};
            app.Button_save.Text = '';
            app.Button_del = uibutton(app.GridLayout5, 'push');
            app.Button_del.ButtonPushedFcn = createCallbackFcn(app, @Button_delPushed, true);
            app.Button_del.Icon = 'Delete_32Red.png';
            app.Button_del.Enable = 'off';
            app.Button_del.Tooltip = {'Excluir alterações'};
            app.Button_del.Text = '';
            app.Button_edit = uibutton(app.GridLayout5, 'push');
            app.Button_edit.ButtonPushedFcn = createCallbackFcn(app, @Button_edit_Clicked, true);
            app.Button_edit.Icon = 'Edit_32.png';
            app.Button_edit.Text = 'Editar emissão';
            app.Button_globalList = uibutton(app.GridLayout5, 'push');
            app.Button_globalList.ButtonPushedFcn = createCallbackFcn(app, @Button_globalListPushed, true);
            app.Button_globalList.Icon = 'Info_32.png';
            app.Button_globalList.Tooltip = {'Lista global de exceções'};
            app.Button_globalList.Text = '';
            app.Image = uiimage(app.GridLayout5);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'LineV.png';
            app.jsBackDoor = uihtml(app.GridLayout5);
            app.jsBackDoor.HTMLSource = ' ';
            app.Image_2 = uiimage(app.GridLayout5);
            app.Image_2.Enable = 'off';
            app.Image_2.ImageSource = 'LineV.png';
            app.tool_simulationButton = uibutton(app.GridLayout5, 'push');
            app.tool_simulationButton.ButtonPushedFcn = createCallbackFcn(app, @tool_simulationButtonPushed, true);
            app.tool_simulationButton.Icon = 'Publish_HTML_16.png';
            app.tool_simulationButton.Tooltip = {'Enlace'};
            app.tool_simulationButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSignalAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end