classdef winAnatelDB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Grid1             matlab.ui.container.GridLayout
        panel_Grid1_3     matlab.ui.container.Panel
        GridLayout2_2     matlab.ui.container.GridLayout
        Title_3           matlab.ui.control.Label
        panel_Grid1_2     matlab.ui.container.Panel
        tool_Grid         matlab.ui.container.GridLayout
        Button            matlab.ui.control.Button
        panel_Grid1       matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        Title_2           matlab.ui.control.Label
        instrTag          matlab.ui.control.Label
        table_Grid        matlab.ui.container.GridLayout
        Column10          matlab.ui.control.Label
        Column9           matlab.ui.control.Label
        Column8           matlab.ui.control.Label
        Column7           matlab.ui.control.Label
        Column6           matlab.ui.control.Label
        Column5           matlab.ui.control.Label
        Column4           matlab.ui.control.Label
        Column3           matlab.ui.control.Label
        Column2           matlab.ui.control.Label
        Column1           matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        filter_Panel      matlab.ui.container.Panel
        GridLayout9       matlab.ui.container.GridLayout
        Status_2          matlab.ui.control.DropDown
        StatusLabel_2     matlab.ui.control.Label
        GridLayout13      matlab.ui.container.GridLayout
        Image             matlab.ui.control.Image
        Value3            matlab.ui.control.NumericEditField
        Value2            matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        Value1            matlab.ui.control.EditField
        Tree              matlab.ui.container.Tree
        Family            matlab.ui.control.DropDown
        FamilyLabel       matlab.ui.control.Label
        TypeLabel         matlab.ui.control.Label
        Type              matlab.ui.control.DropDown
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        ContextMenu       matlab.ui.container.ContextMenu
        ExcluirMenu       matlab.ui.container.Menu
        ExcluirtodosMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        UIAxes1
        Parameters = struct('Family', {}, 'Type', {}, 'Value', {});
        nodeTable
        
    end
    
    methods (Access = private)

        function Fcn1_AxesCreation(app)

            app.UIAxes1 = geoaxes(app.Grid1, 'FontSize', 6, 'Units', 'pixels', 'Toolbar', []);

            while true
                try
                    app.UIAxes1.Layout.Row    = [1,3];
                    app.UIAxes1.Layout.Column = 2;
                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';                    

                    break
                catch
                end
            end

            app.UIAxes2.Interactions = dataTipInteraction;
            app.UIAxes3.Interactions = dataTipInteraction;
            
            enableDefaultInteractivity(app.UIAxes2)
            enableDefaultInteractivity(app.UIAxes3)

        end


        function Fcn1_NodeList(app)

            specData  = app.CallingApp.specData;
            app.nodeTable = table('Size', [0, 3], ...
                              'VariableTypes', {'double', 'double', 'cell'}, ...
                              'VariableNames', {'lat', 'long', 'location'});

            app.Status_2.Items = {};
            for ii = 1:numel(specData)
                if specData(ii).gps.Status
                    msg = sprintf('%.6f, %.6f (%s)', specData(ii).gps.Latitude, ...
                                                     specData(ii).gps.Longitude, ...
                                                     specData(ii).gps.Location);

                    if isempty(app.Status_2.Items) | ~contains(app.Status_2.Items, msg)
                        app.Status_2.Items{end+1} = msg;
                        app.nodeTable(end+1,:) = {specData(ii).gps.Latitude, specData(ii).gps.Longitude, specData(ii).gps.Location};
                    end
                end
            end

        end


        function Fcn2_TreeBuilding(app)

            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end

            Parameters_table = struct2table(app.Parameters);
            FamilyItems      = {};
            if ischar(Parameters_table.Family)
                FamilyItems = {Parameters_table.Family};
            elseif iscell(Parameters_table.Family)
                FamilyItems = unique(Parameters_table.Family);
            end

            for ii = 1:numel(FamilyItems)
                idx1 = find(Parameters_table.Family == string(FamilyItems{ii}));
                
                NodeTree = uitreenode(app.Tree, 'Text', upper(FamilyItems{ii}), ...
                                                'NodeData', idx1, 'ContextMenu', app.ContextMenu);
                
                for jj = idx1'
                    if isnumeric(app.Parameters(jj).Value)
                        if numel(app.Parameters(jj).Value) == 1; Value = sprintf('%g', app.Parameters(jj).Value);
                        else;                                    Value = sprintf('%g e %g', app.Parameters(jj).Value(1), app.Parameters(jj).Value(2));
                        end
                    else
                        Value = sprintf('"%s"', app.Parameters(jj).Value);
                    end

                    uitreenode(NodeTree, 'Text', sprintf('%s %s', app.Parameters(jj).Type(1:end-3), Value), ...
                                         'NodeData', jj, 'ContextMenu', app.ContextMenu);
                end
            end

            expand(app.Tree, 'all')

        end


        function Fcn2_AnatelDB(app)

            global AnatelDB

            anatelDB = AnatelDB;

            anatelDB.Frequency = double(anatelDB.Frequency);
            anatelDB.Distance  = -1*ones(height(anatelDB), 1, 'single');

            if ~isempty(app.Tree.Children)
                Parameters_table = struct2table(app.Parameters);
                Tolerance = 1e-5;
                kk = 0;
                idx2 = zeros(height(anatelDB), 4, 'logical');

                % Frequência
                idx1 = find(Parameters_table.Family == "Frequência");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        switch app.Parameters(ii).Type
                            case 'É igual a...'
                                idx2(:,kk) = idx2(:,kk) | (abs(anatelDB.Frequency-app.Parameters(ii).Value) <= Tolerance);
    
                            case 'Está entre...'
                                idx2(:,kk) = idx2(:,kk) | ((anatelDB.Frequency >= app.Parameters(ii).Value(1) - Tolerance) & (anatelDB.Frequency <= app.Parameters(ii).Value(2) + Tolerance));
                        end
                    end
                end

                % Descrição
                idx1 = find(Parameters_table.Family == "Descrição");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        idx2(:,kk) = idx2(:,kk) | contains(string(anatelDB.Description), app.Parameters(ii).Value);
                    end
                end

                % Serviço
                idx1 = find(Parameters_table.Family == "Serviço");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        switch app.Parameters(ii).Type
                            case 'É igual a...'
                                idx2(:,kk) = idx2(:,kk) | anatelDB.Service == int16(app.Parameters(ii).Value);
    
                            case 'Está entre...'
                                idx2(:,kk) = idx2(:,kk) | ((anatelDB.Service >= int16(app.Parameters(ii).Value(1))) & (anatelDB.Service <= int16(app.Parameters(ii).Value(2))));
                        end
                    end
                end

                % Estação
                idx1 = find(Parameters_table.Family == "Estação");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        switch app.Parameters(ii).Type
                            case 'É igual a...'
                                idx2(:,kk) = idx2(:,kk) | anatelDB.Station == int16(app.Parameters(ii).Value);
    
                            case 'Está entre...'
                                idx2(:,kk) = idx2(:,kk) | ((anatelDB.Station >= int32(app.Parameters(ii).Value(1))) & (anatelDB.Station <= int32(app.Parameters(ii).Value(2))));
                        end
                    end
                end

                idx3 = all(idx2(:,1:kk), 2);
                anatelDB(~idx3,:) = [];
                
                % Distância
                if ~isempty(app.Status_2.Value)
                    if height(anatelDB) <= 10000
                        Node = regexp(app.Status_2.Value, '(?<lat>[-]\d+[.]{1}\d{6}), (?<lon>[-]\d+[.]{1}\d{6}) .*', 'names');
                        Node.lat = str2double(Node.lat);
                        Node.lon = str2double(Node.lon);

                        anatelDB.Distance = round(geo_lldistkm_v2(Node, anatelDB(:, 3:4)));
    
                        idx1 = find(Parameters_table.Family == "Distância");                        
                        if ~isempty(idx1)
                            idx2 = zeros(height(anatelDB), 1, 'logical');

                            for ii = idx1'
                                idx2 = idx2 | (anatelDB.Distance <= app.Parameters(ii).Value + Tolerance);
                            end

                            anatelDB(~idx2,:) = [];
                        end                        
                    end
                end
            end            

            app.UITable.Data = anatelDB;
            app.Title_2.Text = {sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> FREQUÊNCIAS</font>', height(anatelDB)); ...
                                sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> ESTAÇÕES</font>',    numel(unique(anatelDB.Station))); ...
                                sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> SERVIÇOS</font>',    numel(unique(anatelDB.Service)))};

            Fcn3_Plot(app)
            Fcn3_PlotNodes(app)

        end


        function Fcn2_AnatelDB_info(app)

            global AnatelDB_info

            app.Title_3.Text = {'<b style="color: #000000; font-size: 14;">anateldb </b>'; ...
                                sprintf('<font> v. %s </font>', AnatelDB_info.Version);    ...
                                sprintf('%s ', extractBefore(AnatelDB_info.ReleaseDate, ' '))};

        end
        
        
        function Fcn3_Plot(app)

            hold(app.UIAxes1, "on")
            cla(app.UIAxes1)
            p1 = geoscatter(app.UIAxes1, app.UITable.Data.Latitude, app.UITable.Data.Longitude);
            hold(app.UIAxes1, "off")

            p2 = histogram(app.UIAxes2, app.UITable.Data.Frequency);
            p3 = histogram(app.UIAxes3, categorical(app.UITable.Data.Service));
            
            while true
                try
                    app.UIAxes2.Children.EdgeColor = 'none';
                    app.UIAxes2.Children.FaceColor = [0.00,0.45,0.74];

                    app.UIAxes3.Children.EdgeColor = 'none';
                    app.UIAxes3.Children.FaceColor = [0.00,0.45,0.74];

                    break
                catch
                end
            end

            % DataTip
            set(p1.DataTipTemplate, FontName='Calibri', FontSize=10)
            p1.DataTipTemplate.DataTipRows(1).Label = 'Lat:';
            p1.DataTipTemplate.DataTipRows(2).Label = 'Lon:';
            p1.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('', app.UITable.Data.Frequency, '%.3f MHz');
            p1.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('', app.UITable.Data.Description);
            p1.DataTipTemplate.DataTipRows = p1.DataTipTemplate.DataTipRows([3:4, 1:2]);


            set(p2.DataTipTemplate, FontName='Calibri', FontSize=10)
            p2.DataTipTemplate.DataTipRows = flip(p2.DataTipTemplate.DataTipRows);
            p2.DataTipTemplate.DataTipRows(1).Label = 'Banda (MHz):';
            p2.DataTipTemplate.DataTipRows(2).Label = 'Registros:';

            set(p3.DataTipTemplate, FontName='Calibri', FontSize=10)
            p3.DataTipTemplate.DataTipRows = flip(p3.DataTipTemplate.DataTipRows);
            p3.DataTipTemplate.DataTipRows(1).Label = 'Serviço:';
            p3.DataTipTemplate.DataTipRows(2).Label = 'Registros:';

%             Misc_DataTipSettings(app.UIAxes1.Children, '')
%             Misc_DataTipSettings(app.UIAxes2.Children, 'MHz')
%             Misc_DataTipSettings(app.UIAxes3.Children, '')

        end


        function Fcn3_PlotNodes(app)

            if ~isempty(app.nodeTable)
                hold(app.UIAxes1, 'on')

                if height(app.nodeTable) > 1
                    p1 = geoscatter(app.UIAxes1, app.nodeTable.lat, app.nodeTable.long, 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'Tag', 'OthersNodes');
                    
                    set(p1.DataTipTemplate, FontName='Calibri', FontSize=10)
                    p1.DataTipTemplate.DataTipRows(1).Label = 'Lat:';
                    p1.DataTipTemplate.DataTipRows(2).Label = 'Lon:';
                end

                idx = find(strcmp(app.Status_2.Items, app.Status_2.Value), 1);
                p2 = geoscatter(app.UIAxes1, app.nodeTable.lat(idx), app.nodeTable.long(idx), 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'MarkerFaceColor', '#A2142F', 'Tag', 'SelectedNode');
                
                set(p2.DataTipTemplate, FontName='Calibri', FontSize=10)
                p2.DataTipTemplate.DataTipRows(1).Label = 'Lat:';
                p2.DataTipTemplate.DataTipRows(2).Label = 'Lon:';

                hold(app.UIAxes1, 'off')
            end

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'toolbar_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];
            
            Fcn1_AxesCreation(app)
            Fcn1_NodeList(app)
            
            Fcn2_AnatelDB(app)
            Fcn2_AnatelDB_info(app)
            
            FamilyValueChanged(app)            

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin8 = [];
            delete(app)
            
        end

        % Button pushed function: Button
        function Button_info(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = char(extractBetween(info, sprintf('<winAnatelDB>\n'), sprintf('\n</winAnatelDB>')));

            Misc_uialertCreation(app, 'info', msg)

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            try
                switch app.Family.Value
                    case {'Frequência', 'Distância'}
                        switch app.Type.Value
                            case {'É igual a...', 'É igual ou inferior a...'}
                                if app.Value2.Value <= 0
                                    error('O valor deve ser maior do que zero.')
                                end
                                app.Parameters(end+1).Value = app.Value2.Value;
            
                            case 'Está entre...'
                                if (app.Value2.Value <= 0) || (app.Value3.Value <= 0) || (app.Value2.Value >= app.Value3.Value)
                                    error('Os valores devem ser maiores do que zero e crescentes.')
                                end            
                                app.Parameters(end+1).Value = [app.Value2.Value, app.Value3.Value];
                        end

                    case {'Serviço', 'Estação'}
                        switch app.Type.Value
                            case 'É igual a...'
                                if app.Value2.Value == 0
                                    error('O valor deve ser diferente de zero.')
                                end
                                app.Parameters(end+1).Value = app.Value2.Value;
            
                            case 'Está entre...'
                                if (app.Value2.Value == 0) || (app.Value3.Value == 0) || (app.Value2.Value >= app.Value3.Value)
                                    error('Os valores devem ser diferentes de zero e crescentes.')
                                end            
                                app.Parameters(end+1).Value = [app.Value2.Value, app.Value3.Value];
                        end
                    
                    case 'Descrição'
                        if isempty(deblank(app.Value1.Value))
                            error('O campo textual não pode estar vazio.')
                        end
                        app.Parameters(end+1).Value = app.Value1.Value;
                end
    
                app.Parameters(end).Family = app.Family.Value;
                app.Parameters(end).Type   = app.Type.Value;
                
                Fcn2_TreeBuilding(app)
                Fcn2_AnatelDB(app)

            catch ME
                Misc_uialertCreation(app, 'warning', ME.message)
            end

            delete(d)

        end

        % Value changed function: Family
        function FamilyValueChanged(app, event)
            
            switch app.Family.Value
                case 'Frequência'; app.Type.Items = {'É igual a...', 'Está entre...'};
                case 'Descrição';  app.Type.Items = {'Contém...'};
                case 'Serviço';    app.Type.Items = {'É igual a...', 'Está entre...'};
                case 'Estação';    app.Type.Items = {'É igual a...', 'Está entre...'};
                case 'Distância';  app.Type.Items = {'É igual ou inferior a...'};
            end

            TypeValueChanged(app)

        end

        % Value changed function: Type
        function TypeValueChanged(app, event)
            
            switch app.Type.Value
                case {'É igual a...', 'É igual ou inferior a...'}
                    app.GridLayout9.RowHeight(7:8) = {0, 22};
                    app.Value1.Enable = 0;
                    app.Value2.Enable = 1;
                    app.Value3.Enable = 0;

                case 'Está entre...'
                    app.GridLayout9.RowHeight(7:8) = {0, 22};
                    app.Value1.Enable = 0;
                    app.Value2.Enable = 1;
                    app.Value3.Enable = 1;

                case 'Contém...'
                    app.GridLayout9.RowHeight(7:8) = {22, 0};
                    app.Value1.Enable = 1;
                    app.Value2.Enable = 0;
                    app.Value3.Enable = 0;
            end
            
        end

        % Menu selected function: ExcluirMenu, ExcluirtodosMenu
        function ExcluirMenuSelected(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            switch event.Source
                case app.ExcluirMenu
                    idx = [app.Tree.SelectedNodes.NodeData];
                    if isempty(idx)
                        return
                    end
                    app.Parameters(idx) = [];

                case app.ExcluirtodosMenu
                    app.Parameters = struct('Family', {}, 'Type', {}, 'Value', {});
            end

            Fcn2_TreeBuilding(app)
            Fcn2_AnatelDB(app)

            delete(d)

        end

        % Value changed function: Status_2
        function Status_2ValueChanged(app, event)
            
                Fcn2_AnatelDB(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'home_Puzzle.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Grid1);
            xlabel(app.UIAxes2, 'Frequência (MHz)')
            ylabel(app.UIAxes2, 'Registros')
            app.UIAxes2.YScale = 'log';
            app.UIAxes3 = uiaxes(app.Grid1);
            xlabel(app.UIAxes3, 'Serviço')
            ylabel(app.UIAxes3, 'Registros')
            app.UIAxes3.YScale = 'log';
            app.filter_Panel = uipanel(app.Grid1);
            app.GridLayout9 = uigridlayout(app.filter_Panel);
            app.Type = uidropdown(app.GridLayout9);
            app.Type.Items = {'É igual a...', 'Está entre...', 'Contém...'};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @TypeValueChanged, true);
            app.Type.Value = 'É igual a...';
            app.TypeLabel = uilabel(app.GridLayout9);
            app.TypeLabel.Text = 'Tipo:';
            app.FamilyLabel = uilabel(app.GridLayout9);
            app.FamilyLabel.Text = 'Filtro:';
            app.Family = uidropdown(app.GridLayout9);
            app.Family.Items = {'Frequência', 'Descrição', 'Serviço', 'Estação', 'Distância'};
            app.Family.ValueChangedFcn = createCallbackFcn(app, @FamilyValueChanged, true);
            app.Family.Value = 'Frequência';
            app.Tree = uitree(app.GridLayout9);
            app.Tree.Multiselect = 'on';
            app.Value1 = uieditfield(app.GridLayout9, 'text');
            app.Label = uilabel(app.GridLayout9);
            app.Label.Text = '-';
            app.Value2 = uieditfield(app.GridLayout9, 'numeric');
            app.Value2.Limits = [-1 Inf];
            app.Value2.Value = -1;
            app.Value3 = uieditfield(app.GridLayout9, 'numeric');
            app.Value3.Limits = [-1 Inf];
            app.Value3.Value = -1;
            app.GridLayout13 = uigridlayout(app.GridLayout9);
            app.Image = uiimage(app.GridLayout13);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'General_Add.png';
            app.StatusLabel_2 = uilabel(app.GridLayout9);
            app.StatusLabel_2.Text = 'Local da monitoração:';
            app.Status_2 = uidropdown(app.GridLayout9);
            app.Status_2.Items = {};
            app.Status_2.ValueChangedFcn = createCallbackFcn(app, @Status_2ValueChanged, true);
            app.Status_2.Value = {};
            app.table_Grid = uigridlayout(app.Grid1);
            app.UITable = uitable(app.table_Grid);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            app.Column1 = uilabel(app.table_Grid);
            app.Column1.Text = {'  ID'; '  '};
            app.Column2 = uilabel(app.table_Grid);
            app.Column2.Text = {'  Frequência'; '  (MHz)'};
            app.Column3 = uilabel(app.table_Grid);
            app.Column3.Text = {'  Latitude'; '  '};
            app.Column4 = uilabel(app.table_Grid);
            app.Column4.Text = {'  Longitude'; '  '};
            app.Column5 = uilabel(app.table_Grid);
            app.Column5.Text = {'  Descrição'; '  '};
            app.Column6 = uilabel(app.table_Grid);
            app.Column6.Text = {'  Serviço'; '  '};
            app.Column7 = uilabel(app.table_Grid);
            app.Column7.Text = {'  Estação'; '  '};
            app.Column8 = uilabel(app.table_Grid);
            app.Column8.Text = {'  Classe'; '  designação'};
            app.Column9 = uilabel(app.table_Grid);
            app.Column9.Text = {'  Largura de'; '  banda (kHz)'};
            app.Column10 = uilabel(app.table_Grid);
            app.Column10.Text = {'  Distância'; '  (km)'};
            app.panel_Grid1 = uipanel(app.Grid1);
            app.GridLayout2 = uigridlayout(app.panel_Grid1);
            app.instrTag = uilabel(app.GridLayout2);
            app.instrTag.Text = 'REGISTROS';
            app.Title_2 = uilabel(app.GridLayout2);
            app.Title_2.Interpreter = 'html';
            app.Title_2.Text = '';
            app.tool_Grid = uigridlayout(app.Grid1);
            app.Button = uibutton(app.tool_Grid, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_info, true);
            app.Button.Icon = 'toolbar_info.png';
            app.Button.Text = '';
            app.panel_Grid1_2 = uipanel(app.Grid1);
            app.panel_Grid1_3 = uipanel(app.Grid1);
            app.GridLayout2_2 = uigridlayout(app.panel_Grid1_3);
            app.Title_3 = uilabel(app.GridLayout2_2);
            app.Title_3.Interpreter = 'html';
            app.Title_3.Text = '';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExcluirMenu = uimenu(app.ContextMenu);
            app.ExcluirMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcluirMenuSelected, true);
            app.ExcluirMenu.Text = 'Excluir';
            app.ExcluirtodosMenu = uimenu(app.ContextMenu);
            app.ExcluirtodosMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcluirMenuSelected, true);
            app.ExcluirtodosMenu.Text = 'Excluir todos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAnatelDB(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end