classdef winClassification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Grid1            matlab.ui.container.GridLayout
        Grid2            matlab.ui.container.GridLayout
        Button1          matlab.ui.control.Button
        Button2          matlab.ui.control.Button
        Image            matlab.ui.control.Image
        Title            matlab.ui.control.Label
        Panel1           matlab.ui.container.Panel
        Grid6            matlab.ui.container.GridLayout
        Multiplier       matlab.ui.control.Spinner
        MultiplierLabel  matlab.ui.control.Label
        Contour          matlab.ui.control.Spinner
        ContourLabel     matlab.ui.control.Label
        Algorithm        matlab.ui.control.DropDown
        AlgorithmLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        
    end
    
    methods (Access = private)

        function Fcn_InitialValues(app)

            idx1 = app.CallingApp.Report_Tree.SelectedNodes.NodeData;
            
            app.Algorithm.Value  = app.CallingApp.specData(idx1).reportClassification.Algorithm;

            InitialValues        = jsondecode(app.CallingApp.specData(idx1).reportClassification.Parameters);
            app.Contour.Value    = InitialValues.Contour;
            app.Multiplier.Value = InitialValues.ClassMultiplier;

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            Fcn_InitialValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            
            uialert(app.UIFigure, 'Pendente criar HTML com informações acerca do algoritmo...', 'appAnálise', 'Icon', 'warning')
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;

            app.CallingApp.specData(idx).reportClassification.Algorithm  = app.Algorithm.Value;
            app.CallingApp.specData(idx).reportClassification.Parameters = jsonencode(struct('Contour',        app.Contour.Value, ...
                                                                                             'ClassMultiplier', app.Multiplier.Value));

            app.CallingApp.Layout_Report
            app.CallingApp.auxWin_TreeEdit
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel1 = uipanel(app.Grid1);
            app.Grid6 = uigridlayout(app.Panel1);
            app.AlgorithmLabel = uilabel(app.Grid6);
            app.AlgorithmLabel.Text = 'Algoritmo:';
            app.Algorithm = uidropdown(app.Grid6);
            app.Algorithm.Items = {'appAnálise v. 1.00'};
            app.Algorithm.Value = 'appAnálise v. 1.00';
            app.ContourLabel = uilabel(app.Grid6);
            app.ContourLabel.Tag = 'Offset';
            app.ContourLabel.Text = {'Distância máxima entre sensor e estação sob '; 'análise (km):'};
            app.Contour = uispinner(app.Grid6);
            app.Contour.Step = 10;
            app.Contour.Limits = [10 200];
            app.Contour.RoundFractionalValues = 'on';
            app.Contour.ValueDisplayFormat = '%d';
            app.Contour.Tag = 'Offset';
            app.Contour.Value = 30;
            app.MultiplierLabel = uilabel(app.Grid6);
            app.MultiplierLabel.Tag = 'THR';
            app.MultiplierLabel.Text = 'Fator multiplicador do raio do contorno protegido de estações de FM e TV na aferição da distâcia máxima:';
            app.Multiplier = uispinner(app.Grid6);
            app.Multiplier.Limits = [1 4];
            app.Multiplier.RoundFractionalValues = 'on';
            app.Multiplier.ValueDisplayFormat = '%d';
            app.Multiplier.Tag = 'THR';
            app.Multiplier.Value = 2;
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Classificação</b>'; '<font>Comparação com bases de dados, indicando provável gerador de cada emissão</font>'; ' '};
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'Classification.png';
            app.Grid2 = uigridlayout(app.Grid1);
            app.Button2 = uibutton(app.Grid2, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'White_Play.png';
            app.Button2.Text = 'Confirmar';
            app.Button1 = uibutton(app.Grid2, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Icon = 'Toolbar_Icon3.png';
            app.Button1.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winClassification(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end