classdef winCredential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Grid1                 matlab.ui.container.GridLayout
        Button                matlab.ui.control.Button
        CheckBox              matlab.ui.control.CheckBox
        Panel                 matlab.ui.container.Panel
        Grid2                 matlab.ui.container.GridLayout
        SenhaEditFieldLabel   matlab.ui.control.Label
        Password              matlab.ui.control.HTML
        Login                 matlab.ui.control.EditField
        UsurioEditFieldLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Title                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        Issue
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Position, prjIssue)
            
            app.CallingApp = mainapp;
            app.Password.HTMLSource = fullfile(app.CallingApp.RootFolder, 'Template', 'PasswordBox.html');       
            
            x0 = fix(Position(1)+Position(3)/2-164);
            x1 = fix(Position(2)+Position(4)/2-91);
            app.UIFigure.Position(1:2) = [x0, x1];

            app.Issue = prjIssue;
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if ~isempty(app.Login.Value) && ~isempty(app.Password.Data)
                try
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Verificando credenciais e demanda de fiscalização.');
                    
                    pyModule    = py.importlib.import_module('fiscaliza.redmine');
                    py.importlib.reload(pyModule);
                    
                    issueInfo   = pyModule.detalhar_issue(pyargs('inspecao', num2str(app.Issue), ...
                                                                 'login', app.Login.Value,       ...
                                                                 'senha', app.Password.Data,     ...
                                                                 'teste', logical(app.CheckBox.Value)));
                    
                    issueInfo   = Misc_py2matRedmine(issueInfo, 1);
                    
                    app.CallingApp.auxWin3 = winFiscaliza(app.CallingApp, pyModule, app.Login.Value, app.Password.Data, logical(app.CheckBox.Value), issueInfo, app.Issue);
                    drawnow nocallbacks
                    
                    delete(app)
                catch ME
                    app.CallingApp.auxWin_Credential(ME.message)
                    delete(app)
                end
                    
            else
                uialert(app.UIFigure, 'Favor inserir as suas credenciais.', 'Fiscaliza', 'Icon', 'error')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.Grid1);
            app.Title.Text = {'Fiscaliza'; 'Sistema de Contole e Gestão da Fiscalização'};
            app.Label = uilabel(app.Grid1);
            app.Label.Text = 'Credenciais';
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.UsurioEditFieldLabel = uilabel(app.Grid2);
            app.UsurioEditFieldLabel.Text = 'Usuário:';
            app.Login = uieditfield(app.Grid2, 'text');
            app.Password = uihtml(app.Grid2);
            app.SenhaEditFieldLabel = uilabel(app.Grid2);
            app.SenhaEditFieldLabel.Text = 'Senha:';
            app.CheckBox = uicheckbox(app.Grid1);
            app.CheckBox.Text = 'Fiscaliza Homologação';
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Media_PlayWhite.png';
            app.Button.Text = 'Confirmar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winCredential(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end