classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Grid                        matlab.ui.container.GridLayout
        imgShowHide                 matlab.ui.control.Image
        Panel                       matlab.ui.container.Panel
        Panel_Grid                  matlab.ui.container.GridLayout
        GridLayout                  matlab.ui.container.GridLayout
        Panel_5                     matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        Smoothed_Panel              matlab.ui.container.Panel
        Smoothed_Grid               matlab.ui.container.GridLayout
        Smoothed_Type               matlab.ui.control.DropDown
        TipoDropDownLabel           matlab.ui.control.Label
        Smoothed_Factor             matlab.ui.control.Spinner
        FatorSpinnerLabel           matlab.ui.control.Label
        CurvasuaveCheckBox          matlab.ui.control.CheckBox
        DadosprocessadosDataBinningCheckBox  matlab.ui.control.CheckBox
        DadosprocessadosPontosdemedioCheckBox  matlab.ui.control.CheckBox
        DadosbrutosCheckBox         matlab.ui.control.CheckBox
        legend_Label_2              matlab.ui.control.Label
        Panel_4                     matlab.ui.container.Panel
        legend_Grid                 matlab.ui.container.GridLayout
        legend_Location             matlab.ui.control.DropDown
        PosioLabel                  matlab.ui.control.Label
        legend_Visibility           matlab.ui.control.DropDown
        VisibilidadeDropDownLabel   matlab.ui.control.Label
        legend_Label                matlab.ui.control.Label
        Tab4_Panel                  matlab.ui.container.GridLayout
        Tab4_Grid1_2                matlab.ui.container.GridLayout
        Tab4_Panel1                 matlab.ui.container.Panel
        Tab4_Grid1                  matlab.ui.container.GridLayout
        imgScatter_Radius           matlab.ui.control.Slider
        SourceLabel                 matlab.ui.control.Label
        Source_ButtonGroup          matlab.ui.container.ButtonGroup
        Source_binData              matlab.ui.control.RadioButton
        Source_locData              matlab.ui.control.RadioButton
        Source_rawData              matlab.ui.control.RadioButton
        Colormap                    matlab.ui.control.DropDown
        ColormapLabel               matlab.ui.control.Label
        imgScatter_Title            matlab.ui.control.Label
        imgRoute_Title_2            matlab.ui.control.Label
        imgRoute_Panel              matlab.ui.container.Panel
        imgRoute_Grid               matlab.ui.container.GridLayout
        VeculoCheckBox              matlab.ui.control.CheckBox
        GridretangularDataBinningCheckBox  matlab.ui.control.CheckBox
        imgRoute_Size               matlab.ui.control.Slider
        imgRoute_Color              matlab.ui.control.Button
        imgRoute_PropertyLabel      matlab.ui.control.Label
        imgRoute_Type               matlab.ui.control.DropDown
        imgRoute_TypeLabel          matlab.ui.control.Label
        imgRoute_Title              matlab.ui.control.Label
        imgDensity                  matlab.ui.control.Image
        imgScatter                  matlab.ui.control.Image
        imgRoute                    matlab.ui.control.Image
        binTable_Panel              matlab.ui.container.GridLayout
        binTable_Distance           matlab.ui.control.Spinner
        binTable_DistanceLabel      matlab.ui.control.Label
        binTable_Type               matlab.ui.control.DropDown
        binTable_TypeLabel          matlab.ui.control.Label
        binTable_Grid               matlab.ui.container.GridLayout
        binTable_Col2               matlab.ui.control.Label
        binTable_Info               matlab.ui.control.Label
        binTable_Col4               matlab.ui.control.Label
        binTable_Col3               matlab.ui.control.Label
        binTable_Col1               matlab.ui.control.Label
        binTable_UITable            matlab.ui.control.Table
        binTable_Fcn                matlab.ui.control.DropDown
        binTable_FcnLabel           matlab.ui.control.Label
        locTable_Panel              matlab.ui.container.GridLayout
        binTable_Distance_2         matlab.ui.control.Spinner
        binTable_DistanceLabel_2    matlab.ui.control.Label
        binTable_Type_2             matlab.ui.control.DropDown
        binTable_TypeLabel_2        matlab.ui.control.Label
        locTable_Grid               matlab.ui.container.GridLayout
        locTable_Info               matlab.ui.control.Label
        locTable_Col4               matlab.ui.control.Label
        locTable_Col3               matlab.ui.control.Label
        locTable_Col2               matlab.ui.control.Label
        locTable_Col1               matlab.ui.control.Label
        locTable_UITable            matlab.ui.control.Table
        locTable_Fcn                matlab.ui.control.DropDown
        locTable_FcnLabel           matlab.ui.control.Label
        Tab5_Grid                   matlab.ui.container.GridLayout
        Tab5_Image                  matlab.ui.control.Image
        Tab5_Title                  matlab.ui.control.Label
        Tab4_Grid                   matlab.ui.container.GridLayout
        Tab4_Title                  matlab.ui.control.Label
        Tab4_Image                  matlab.ui.control.Image
        Tab3_Grid                   matlab.ui.container.GridLayout
        Tab3_Title                  matlab.ui.control.Label
        Tab3_Image                  matlab.ui.control.Image
        Tab2_Grid                   matlab.ui.container.GridLayout
        Tab2_Image                  matlab.ui.control.Image
        Tab2_Title                  matlab.ui.control.Label
        Tab1_Grid                   matlab.ui.container.GridLayout
        Tab1_Lock                   matlab.ui.control.Image
        Tab1_Title                  matlab.ui.control.Label
        Tab1_Image                  matlab.ui.control.Image
        Tab1_Grid1                  matlab.ui.container.GridLayout
        Tree2                       matlab.ui.container.Tree
        nodeLevel                   matlab.ui.container.TreeNode
        nodeGeo                     matlab.ui.container.TreeNode
        Info                        matlab.ui.control.TextArea
        InfoLabel                   matlab.ui.control.Label
        Tree                        matlab.ui.container.CheckBoxTree
        TreeLabel                   matlab.ui.control.Label
        Emissions                   matlab.ui.control.DropDown
        Tab1_Grid2                  matlab.ui.container.GridLayout
        EmissionsRefresh            matlab.ui.control.Image
        EmissionsLabel              matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        ContextMenu_FilterLevel     matlab.ui.container.ContextMenu
        FilterLevel_Add             matlab.ui.container.Menu
        IncluirMenu                 matlab.ui.container.Menu
        LimiteinferiorMenu          matlab.ui.container.Menu
        RegioMenu                   matlab.ui.container.Menu
        ExcluirMenu                 matlab.ui.container.Menu
        ContextMenu_FilterPosition  matlab.ui.container.ContextMenu
        IncluirMenu_2               matlab.ui.container.Menu
        FilterPosition_Circle       matlab.ui.container.Menu
        FilterPosition_Rectangle    matlab.ui.container.Menu
        FilterPosition_Polygon      matlab.ui.container.Menu
        FilterPosition_del          matlab.ui.container.Menu
        FilterPosition_On           matlab.ui.container.Menu
        FilterPosition_Off          matlab.ui.container.Menu
        FilterPosition_unlock       matlab.ui.container.Menu
        FilterPosition_lock         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
        specData
        Index
        
        specTable                                                           % General table (instantaneous channel power)
        filtTable = table('Size', [0, 4],                                      ...
                          'VariableTypes', {'cell', 'cell', 'cell', 'double'}, ...
                          'VariableNames', {'type', 'subtype', 'handle', 'enable'})
        
        rawTable                                                            % Filtered specData
        locTable                                                            % Summarized rawTable (Location Criterium)
        binTable                                                            % Summarized rawTable (Data Binning Criterium)

        UIAxes1
        hRoute
        hBin
        hCar
        hScatter
        hDensity

        % UIAxes2
        hChannelPower
        hSmoothedData
        hROI_Label        
        
    end
    
    properties (Access = public)

        hROI_Line

    end

    
    methods (Access = private)

        function Fcn1_AxesCreation(app)

            app.UIAxes1 = geoaxes(app.Grid, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater');

            while true
                try
                    addToolbarMapButton(axtoolbar(app.UIAxes1, "default"), "basemap", BasemapNames=["darkwater", "satellite", "streets-light", "streets-dark", "topographic", "streets", "colorterrain", "grayterrain"])

                    app.UIAxes1.Layout.Row    = 1;
                    app.UIAxes1.Layout.Column = 3;
                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';

                    colormap(app.UIAxes1, app.Colormap.Value)
                    hold(app.UIAxes1, 'on')

                    break
                catch
                end
            end
      
            app.UIAxes2.Interactions = [];

        end
        

        function Fcn2_General(app)   

            % EMISSÕES 
            Fcn2_General_Emissions(app)

            % ARQUIVOS
            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end

            for ii = 1:height(app.specData.RelatedFiles)
                uitreenode(app.Tree, 'Text', app.specData.RelatedFiles.Name{ii});
            end
            app.Tree.CheckedNodes = app.Tree.Children(1);
            TreeCheckedNodesChanged(app)
        
        end


        function Fcn2_General_Emissions(app)

            emissionTable = app.specData.Emissions;
            
            app.Emissions.Items = {};
            for ii = 1:height(emissionTable)
                app.Emissions.Items{end+1} = sprintf('%d: %.3f MHz @ %.3f kHz',        ...
                                                          ii,                          ...
                                                          emissionTable.Frequency(ii), ...
                                                          emissionTable.BW(ii));
            end

        end


        function Fcn2_General_TreeBuilding(app)

            if ~isempty(app.nodeLevel.Children)
                delete(app.nodeLevel.Children)
            end

            if ~isempty(app.nodeGeo.Children)
                delete(app.nodeGeo.Children)
            end

            idx1 = find(app.filtTable.type == "Level");
            idx2 = find(app.filtTable.type == "Geographic ROI");

            app.nodeLevel.NodeData = idx1;
            for ii = idx1'
                uitreenode(app.nodeLevel, 'Text', 'THR', 'NodeData', ii);
            end

            app.nodeGeo.NodeData = idx2;
            for ii = idx2'
                uitreenode(app.nodeGeo, 'Text', 'ROI',  ...
                                        'NodeData', ii, ...
                                        'ContextMenu', app.ContextMenu_FilterPosition);
            end

            expand(app.Tree2, 'all')

        end


        function Fcn3_geoTables(app)

            Fcn3_specTable(app)
            Fcn3_rawTable(app)
            Fcn3_locTable(app)
            Fcn3_binTable(app)

        end
       

        function Fcn3_specTable(app)

            % app.specTable
            FK1 = -1*ones(app.specData.Samples, 1, 'int32');
            FK2 = FK1; 

            timestamp = app.specData.Data{1}';
            
            Lat  = [];
            Long = [];
            for ii = 1:numel(app.specData.RelatedGPS)
                Lat  = [Lat;  app.specData.RelatedGPS{ii}.Matrix(:,1)];
                Long = [Long; app.specData.RelatedGPS{ii}.Matrix(:,2)];
            end
            [LatEq, LongEq] = grn2eqa(Lat, Long);
            
            rawPower = Fcn3_chPower(app);
            app.specTable = table(Lat, Long, rawPower, timestamp, LatEq, LongEq, FK1, FK2);

        end
        

        function Fcn3_rawTable(app)


            % app.rawTable
            FK1 = -1*ones(app.specData.Samples, 1, 'int32');
            FK2 = FK1; 

            timestamp = app.specData.Data{1}';
            
            Lat  = [];
            Long = [];
            for ii = 1:numel(app.specData.RelatedGPS)
                Lat  = [Lat;  app.specData.RelatedGPS{ii}.Matrix(:,1)];
                Long = [Long; app.specData.RelatedGPS{ii}.Matrix(:,2)];
            end
            [LatEq, LongEq] = grn2eqa(Lat, Long);
            
            rawPower = Fcn3_chPower(app);
            app.rawTable = table(Lat, Long, rawPower, timestamp, LatEq, LongEq, FK1, FK2);                      

        end


        function Fcn3_locTable(app)

            lat_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Distance_2.Value), app.rawTable.Lat),  '\n')';
            lon_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Distance_2.Value), app.rawTable.Long), '\n')';
            latlong    = string(lat_String(1:end-1)) + ", " + string(lon_String(1:end-1));

            [latlong_String, latlong_Index, app.rawTable.FK1] = unique(latlong, 'stable');
            
            PK1      = int32(1:numel(latlong_String))';
            locLat   = app.rawTable.Lat(latlong_Index);
            locLong  = app.rawTable.Long(latlong_Index);
            locCount = accumarray(app.rawTable.FK1, 1);
            locPower = Fcn3_locPower(app);

            app.locTable = table(locLat, locLong, locPower, locCount, PK1);  

        end


        function Fcn3_binTable(app)

            [binLat, binLong, binCount] = hista(app.rawTable.Lat, app.rawTable.Long, (app.binTable_Distance.Value/1000)^2);            
            [binLatEq, binLongEq] = grn2eqa(binLat, binLong);
            PK2 = int32(1:numel(binLat))';
            
            dist = pdist2([app.rawTable.LongEq, app.rawTable.LatEq], [binLongEq, binLatEq]);            
            [~, app.rawTable.FK2] = min(dist, [], 2);
            binPower = Fcn3_binPower(app);

            app.binTable = table(binLat, binLong, binPower, binCount, PK2);

        end


        function chPower = Fcn3_chPower(app)

            idx = find(strcmp(app.Emissions.Items, app.Emissions.Value), 1);
            FreqCenter = app.specData.Emissions.Frequency(idx) * 1e+6;
            BW         = app.specData.Emissions.BW(idx)        * 1e+3;

            chPower = Misc_chPower(app.specData, [FreqCenter-BW/2, FreqCenter+BW/2]);

        end


        function locPower = Fcn3_locPower(app)

            switch app.locTable_Fcn.Value
                case 'Mínima';  locFcn = @min;
                case 'Média';   locFcn = @(x) pow2db(  mean(db2pow(x)));
                case 'Mediana'; locFcn = @(x) pow2db(median(db2pow(x)));
                case 'RMS';     locFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'Máxima';  locFcn = @max;
            end
            locPower = splitapply(locFcn, app.rawTable.rawPower, app.rawTable.FK1);

        end


        function binPower = Fcn3_binPower(app)

            switch app.binTable_Fcn.Value
                case 'Mínima';  binFcn = @min;
                case 'Média';   binFcn = @(x) pow2db(  mean(db2pow(x)));
                case 'Mediana'; binFcn = @(x) pow2db(median(db2pow(x)));
                case 'RMS';     binFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'Máxima';  binFcn = @max;
            end
            binPower = splitapply(binFcn, app.rawTable.rawPower, app.rawTable.FK2);

        end


        function Fcn4_UITables(app)

            Fcn4_UITable_locTable(app)
            Fcn4_UITable_binTable(app)

        end


        function Fcn4_UITable_locTable(app)

            locLat_String  = cellfun(@(x) sprintf('%.6f', x), num2cell(app.locTable.locLat),  'UniformOutput', false);
            locLong_String = cellfun(@(x) sprintf('%.6f', x), num2cell(app.locTable.locLong), 'UniformOutput', false);
            app.locTable_UITable.Data = table(app.locTable{:,5}, locLat_String, locLong_String, app.locTable{:,4});

            s = uistyle("HorizontalAlignment", 'right');
            addStyle(app.locTable_UITable, s, "column", [2,3])

            [locCount_min, locCount_max] = bounds(app.locTable.locCount);

            ptDist = deg2km(distance('gc', [app.locTable.locLat(1:end-1), app.locTable.locLong(1:end-1)], [app.locTable.locLat(2:end), app.locTable.locLong(2:end)]));
            [ptDist_min, ptDist_max] = bounds(ptDist*1000);
            ptDist_mean = mean(ptDist)*1000; 
            
            auxString0 = sprintf('%.0f pontos', height(app.locTable));
            auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', ptDist_min, ptDist_mean, ptDist_max);
            auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto',    locCount_min, locCount_max);

            app.locTable_Info.Text = sprintf('%s\n%s\n%.0f |–––––––%s| %.0f varreduras/ponto', auxString0, auxString1, locCount_min, repmat('–', 1, numel(auxString1)-numel(auxString2)+1), locCount_max);

        end


        function Fcn4_UITable_binTable(app)

            binLat_String  = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLat),  'UniformOutput', false);
            binLong_String = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLong), 'UniformOutput', false);
            app.binTable_UITable.Data = table(app.binTable{:,5}, binLat_String, binLong_String, app.binTable{:,4});

            s = uistyle("HorizontalAlignment", 'right');
            addStyle(app.binTable_UITable, s, "column", [2,3])

            [binCount_min, binCount_max] = bounds(app.binTable.binCount);

            auxString0 = sprintf('%.0f pontos', height(app.binTable));
            auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', app.binTable_Distance.Value, app.binTable_Distance.Value, app.binTable_Distance.Value);
            auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto',    binCount_min, binCount_max);

            app.binTable_Info.Text = sprintf('%s\n%s\n%.0f |–––––––%s| %.0f varreduras/ponto', auxString0, auxString1, binCount_min, repmat('–', 1, numel(auxString1)-numel(auxString2)+1), binCount_max);

        end


        function Fcn5_Plots(app)

            Fcn5_Plot_chPower(app)
            Fcn5_Plot_Route(app)
            Fcn5_Plot_Scatter(app)
            Fcn5_Plot_Density(app)
            Fcn5_Plot_Car(app)

        end


        function Fcn5_Plot_chPower(app)

            colors = app.CallingApp.General.Colors;

            % Time index
            idx = 1;
            if strcmp(app.Tab1_Lock.ImageSource, 'play_lockClose.png')
                idx = app.CallingApp.timeIndex;
            end

            % Plot
            cla(app.UIAxes2)

            [minY, maxY] = bounds(app.rawTable.rawPower);
            set(app.UIAxes2, 'XLim', [1, height(app.rawTable)], 'YLim', [minY, maxY], ...
                             'XTick', round(linspace(1, height(app.rawTable), 5)))
            app.UIAxes2.XAxis.Exponent = 0;
            
            hold(app.UIAxes2, 'on')            
            
            plot(app.UIAxes2, app.rawTable.rawPower,                   'Tag', 'rawTable', 'Color', colors(4,:), 'Visible', app.DadosbrutosCheckBox.Value);
            plot(app.UIAxes2, app.locTable.locPower(app.rawTable.FK1), 'Tag', 'locTable', 'Color', colors(1,:), 'Visible', app.DadosprocessadosPontosdemedioCheckBox.Value);
            plot(app.UIAxes2, app.binTable.binPower(app.rawTable.FK2), 'Tag', 'binTable', 'Color', colors(2,:), 'Visible', app.DadosprocessadosDataBinningCheckBox.Value);
            
            app.hSmoothedData = plot(app.UIAxes2, smoothdata(app.rawTable.rawPower, app.Smoothed_Type.Value, 'SmoothingFactor', app.Smoothed_Factor.Value), 'Color', colors(3,:), 'Visible', app.CurvasuaveCheckBox.Value);
            app.hROI_Line     = images.roi.Line(app.UIAxes2, 'Position', [idx minY; idx maxY], 'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate');
            app.hROI_Label    = text(app.UIAxes2, idx, app.UIAxes2.YLim(1)+.94*diff(app.UIAxes2.YLim), sprintf('  %.0f dBm', app.rawTable.rawPower(idx)), 'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red', 'FontSmoothing', 'on');

            legend(app.UIAxes2, {'Dados brutos', 'Pontos de medição', 'Data Binning', 'Curva suave'})
            set(app.UIAxes2.Legend, Color=[1,1,1], FontSize=6, Visible=app.legend_Visibility.Value, Location=app.legend_Location.Value, Box='off', Color=[.98,.98,.98])

            hold(app.UIAxes2, 'off')            
            
            addlistener(app.hROI_Line, 'MovingROI', @app.timeROICallback);
            addlistener(app.hROI_Line, 'ROIMoved',  @app.timeROICallback);

        end


        function Fcn5_Plot_Route(app)

            delete([app.hRoute, app.hBin])
            tempTable = app.rawTable;

            if app.imgRoute.ImageSource == "DriveTest_Route_ON.png"; Visibility = 1;
            else;                                                    Visibility = 0;
            end

            switch app.imgRoute_Type.Value
                case 'Ponto';     app.hRoute = geoscatter(    app.UIAxes1, tempTable.Lat, tempTable.Long, 'filled', 'Marker', 'o', 'MarkerFaceColor', app.imgRoute_Color.BackgroundColor, 'MarkerEdgeColor', app.imgRoute_Color.BackgroundColor, 'SizeData', 2*app.imgRoute_Size.Value, 'Visible', Visibility);
                case 'Linha';     app.hRoute = geoplot(       app.UIAxes1, tempTable.Lat, tempTable.Long, 'Color',     app.imgRoute_Color.BackgroundColor, 'LineWidth', .5*app.imgRoute_Size.Value, 'Visible', Visibility);
                case 'Densidade'; app.hRoute = geodensityplot(app.UIAxes1, tempTable.Lat, tempTable.Long, 'FaceColor', app.imgRoute_Color.BackgroundColor, 'Radius',    50*app.imgRoute_Size.Value, 'Visible', Visibility);
            end

            color = app.CallingApp.General.Colors(4,:);
            app.hBin = geoscatter(app.UIAxes1, app.binTable.binLat, app.binTable.binLong, 'filled',  ...
                                  'Marker', '^', 'MarkerFaceColor', color, 'MarkerEdgeColor', color, ...
                                  'SizeData', 2*app.imgRoute_Size.Value,                             ...
                                  'Visible', app.GridretangularDataBinningCheckBox.Value);

        end


        function Fcn5_Plot_Car(app)

            delete(app.hCar)

            idx = round(app.hROI_Line.Position(1));
            app.hCar = geoscatter(app.UIAxes1, app.rawTable.Lat(idx), app.rawTable.Long(idx), 'filled',     ...
                                  'Marker', 'square', 'MarkerFaceColor', 'red', 'MarkerEdgeColor', 'black', ...
                                  'SizeData', 40,                                                           ...
                                  'Visible', app.VeculoCheckBox.Value);

        end
        

        function Fcn5_Plot_Scatter(app)

            delete(app.hScatter)

            switch app.Source_ButtonGroup.SelectedObject
                case app.Source_rawData; tempTable = app.rawTable;
                case app.Source_locData; tempTable = app.locTable;
                case app.Source_binData; tempTable = app.binTable;
            end

            if app.imgScatter.ImageSource == "DriveTest_Scatter_ON.png"; Visibility = 1;
            else;                                                        Visibility = 0;
            end

            app.hScatter = geoscatter(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, [], tempTable{:,3}, 'filled', ...
                                      'SizeData', 20*app.imgScatter_Radius.Value,                                ...
                                      'Visible', Visibility);

        end


        function Fcn5_Plot_Density(app)

            delete(app.hDensity)

            switch app.Source_ButtonGroup.SelectedObject
                case app.Source_rawData; tempTable = app.rawTable;
                case app.Source_locData; tempTable = app.locTable;
                case app.Source_binData; tempTable = app.binTable;
            end

            weights = tempTable{:,3};
            if min(weights) < 0
                weights = weights+abs(min(weights));
            end

            if app.imgDensity.ImageSource == "DriveTest_Density_ON.png"; Visibility = 1;
            else;                                                        Visibility = 0;
            end

            app.hDensity = geodensityplot(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, weights,              ...
                                          'FaceColor','interp',                                              ...
                                          'Radius', app.binTable_Distance.Value*app.imgScatter_Radius.Value, ...
                                          'Visible', Visibility);

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

        
        function results = func15(app)
            
        end
    end
    
    
    methods (Access = public)
        
        function timeROICallback(app, src, event)
            
            idx = round(event.Source.Position(1));

            switch(event.EventName)
                case 'MovingROI'
                    disableDefaultInteractivity(app.UIAxes2)                    
                    try
                        app.hROI_Label.Position(1) = idx;
                        app.hROI_Label.String      = sprintf('  %.0f dBm', app.rawTable.rawPower(idx));
                        set(app.hCar, 'LatitudeData', app.rawTable.Lat(idx), 'LongitudeData', app.rawTable.Long(idx))

                        if strcmp(app.Tab1_Lock.ImageSource, 'play_lockClose.png')
                            app.CallingApp.auxWin_DriveTest(100*idx/app.specData.Samples)
                        end
                    catch
                    end
                    
                case 'ROIMoved'
                    enableDefaultInteractivity(app.UIAxes2)

                    [minY, maxY] = bounds(app.rawTable.rawPower);
                    app.hROI_Line.Position = [idx minY; idx maxY];
            end
            
        end


        function auxWin_timeROICallback(app, idx)

            try
                app.hROI_Label.Position(1) = idx;
                app.hROI_Label.String      = sprintf('  %.0f dBm', app.rawTable.rawPower(idx));
                set(app.hCar, 'LatitudeData', app.rawTable.Lat(idx), 'LongitudeData', app.rawTable.Long(idx))
            catch
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx, Tag)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.UIFigure.Tag = Tag;
            
            app.specData = app.CallingApp.specData(idx);
            app.Index    = idx;

            Fcn1_AxesCreation(app)
            Fcn2_General(app)
            Fcn3_geoTables(app)
            Fcn4_UITables(app)
            Fcn5_Plots(app)

            imgTreeSelectionChanged(app, struct('Source', app.Tab1_Image))

            set(app.CallingApp.mainInfo_Lock, 'Enable', 0, 'ImageSource', 'play_lockClose.png')
            app.CallingApp.mainInfo_Tree.Enable = 0;
            
            drawnow nocallbacks
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            set(app.CallingApp.mainInfo_Lock, 'Enable', 1, 'ImageSource', 'play_lockOpen.png')
            app.CallingApp.mainInfo_Tree.Enable = 1;

            delete(app)
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            
            checkedNodes = app.Tree.CheckedNodes;
            if isempty(checkedNodes)
                app.Tree.CheckedNodes = event.PreviousCheckedNodes;
                return
            end

            metaData = '';
            for ii = 1:numel(checkedNodes)
                idx = find(strcmp(app.specData.RelatedFiles.Name, checkedNodes(ii).Text), 1);

                ObservationTime = sprintf('%s - %s', datestr(app.specData.RelatedFiles.BeginTime(idx), 'dd/mm/yyyy HH:MM:SS'), datestr(app.specData.RelatedFiles.EndTime(idx), 'dd/mm/yyyy HH:MM:SS'));

                metaData = sprintf(['%s• %s\n' ...
                                    '  %s\n' ...
                                    '  %.0f varreduras, %.3f seg (tempo de revisita)\n\n'], ...
                                    metaData, ...
                                    app.specData.RelatedFiles.Name{idx}, ...
                                    ObservationTime, ...
                                    app.specData.RelatedFiles.Samples(idx), ...
                                    app.specData.RelatedFiles.RevisitTime(idx));
            end

            app.Info.Value = sprintf(['Faixa: %.3f - %.3f MHz\n\n'  ...
                                      'Pontos por traço: %.0f\n'    ...
                                      'Unidade: %s\n'               ...
                                      'Resolução: %.3f kHz\n\n%s'], ...
                                      app.specData.MetaData.FreqStart / 1e+6,  ...
                                      app.specData.MetaData.FreqStop  / 1e+6,  ...
                                      app.specData.MetaData.DataPoints,        ...
                                      app.specData.MetaData.metaString{1},     ...
                                      app.specData.MetaData.Resolution / 1e+3, ...
                                      metaData);

        end

        % Image clicked function: EmissionsRefresh
        function EmissionsRefreshImageClicked(app, event)
            
            preSelection = extractAfter(app.Emissions.Value, ': ');
            
            app.specData = app.CallingApp.specData(app.Index);
            Fcn2_General_Emissions(app)

            idx = find(contains(app.Emissions.Items, preSelection), 1);
            if ~isempty(idx)
                app.Emissions.Value = app.Emissions.Items(idx);
            else
                Fcn5_Plot(app)
            end

        end

        % Image clicked function: imgShowHide
        function imgShowHideClicked(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.imgShowHide.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.imgShowHide.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

        end

        % Image clicked function: imgDensity, imgRoute, imgScatter
        function imgPlotButtonClicked(app, event)
            
            switch event.Source
                case app.imgRoute
                    if app.hRoute.Visible
                        app.hRoute.Visible = 0;
                        app.imgRoute.ImageSource = 'DriveTest_Route_OFF.png';
                    else
                        app.hRoute.Visible = 1;
                        app.imgRoute.ImageSource = 'DriveTest_Route_ON.png';
                    end

                case app.imgScatter
                    if app.hScatter.Visible
                        app.hScatter.Visible = 0;
                        app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
                    else
                        app.hScatter.Visible = 1;
                        app.hDensity.Visible = 0;

                        app.imgScatter.ImageSource = 'DriveTest_Scatter_ON.png';
                        app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
                    end

                case app.imgDensity
                    if app.hDensity.Visible
                        app.hDensity.Visible = 0;
                        app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
                    else
                        app.hScatter.Visible = 0;
                        app.hDensity.Visible = 1;

                        app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
                        app.imgDensity.ImageSource = 'DriveTest_Density_ON.png';
                    end
            end

        end

        % Image clicked function: Tab1_Image, Tab2_Image, Tab3_Image, 
        % Tab4_Image, Tab5_Image
        function imgTreeSelectionChanged(app, event)
            
            switch event.Source
                case app.Tab1_Image; app.Panel_Grid.RowHeight([2,4,6,8,10]) = {'1x',0,0,0,0};
                case app.Tab2_Image; app.Panel_Grid.RowHeight([2,4,6,8,10]) = {0,'1x',0,0,0};
                case app.Tab3_Image; app.Panel_Grid.RowHeight([2,4,6,8,10]) = {0,0,'1x',0,0};
                case app.Tab4_Image; app.Panel_Grid.RowHeight([2,4,6,8,10]) = {0,0,0,'1x',0};
                case app.Tab5_Image; app.Panel_Grid.RowHeight([2,4,6,8,10]) = {0,0,0,0,'1x'};
            end

        end

        % Value changing function: imgRoute_Size
        function imgRoute_SizeValueChanging(app, event)
            
            try
                switch app.imgRoute_Type.Value
                    case 'Ponto';     set(app.hRoute, 'SizeData',   2*event.Value)
                    case 'Linha';     set(app.hRoute, 'LineWidth', .5*event.Value)
                    case 'Densidade'; set(app.hRoute, 'Radius',    50*event.Value)
                end
            catch
            end

            app.hBin.SizeData =  2*event.Value;

        end

        % Button pushed function: imgRoute_Color
        function imgRoute_ColorPushed(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                app.imgRoute_Color.BackgroundColor = selectedColor;

                try
                    switch app.imgRoute_Type.Value
                        case 'Ponto'
                            set(app.hRoute, 'MarkerFaceColor', selectedColor, ...
                                            'MarkerEdgeColor', selectedColor)

                        case 'Linha'
                            set(app.hRoute, 'Color', selectedColor)

                        case 'Densidade'
                            set(app.hRoute, 'FaceColor', selectedColor)
                    end
                catch
                end
            end

        end

        % Value changed function: Colormap
        function ColormapValueChanged(app, event)
            
            colormap(app.UIAxes1, app.Colormap.Value)
            
        end

        % Value changed function: binTable_Distance, binTable_Fcn
        function binTable_DistanceValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");            
            
            Fcn3_binTable(app)
            Fcn4_UITable_binTable(app)
            Fcn5_Plots(app)

            delete(d)
            
        end

        % Value changed function: binTable_Distance_2, locTable_Fcn
        function locTable_FcnValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");            
            
            Fcn3_locTable(app)
            Fcn4_UITable_locTable(app)
            Fcn5_Plots(app)

            delete(d)
            
        end

        % Image clicked function: Tab1_Lock
        function Tab1_LockClicked(app, event)

            if app.CallingApp.mainInfo_Tree.Enable
                Fields = {'Emissions', 'reportFlag', 'reportOCC', 'reportDetection', 'reportClassification', 'reportAttachments'};
    
                idx = [];
                for ii = 1:numel(app.CallingApp.specData)
                    if isequal(rmfield(app.CallingApp.specData(ii), Fields), rmfield(app.specData, Fields))
                        idx = ii;
                        break
                    end
                end
    
                if isempty(idx)
                    MessageBox(app, 'warning', 'Não identificado o fluxo de dados...')
                    return
                end

                if app.CallingApp.mainInfo_Tree.SelectedNodes.NodeData ~= idx
                    if isempty(app.CallingApp.fig.UserData) || (app.CallingApp.fig.UserData.NodeData ~= idx)
                        hh = [];
                        for ii = 1:numel(app.CallingApp.mainInfo_Tree.Children)
                            for jj = 1:numel(app.CallingApp.mainInfo_Tree.Children(ii).Children)
                                if app.CallingApp.mainInfo_Tree.Children(ii).Children(jj).NodeData == idx
                                    hh = app.CallingApp.mainInfo_Tree.Children(ii).Children(jj);
                                    break
                                end
                            end
                        end
        
                        if app.CallingApp.plotFlag
                            app.CallingApp.toolbarCallbacks_playback([], []);
                            drawnow nocallbacks
                        end                        

                        app.CallingApp.fig.UserData = [];
                        app.CallingApp.auxWin_MaskDraw('Plot_PlaybackMode')
        
                        app.CallingApp.mainInfo_Tree.SelectedNodes = hh;
                        app.CallingApp.auxWin_MaskDraw('Plot_TreeSelectionChanged')
                        
                        app.hROI_Line.Position(1:2) = 1;
                        notify(app.hROI_Line, 'MovingROI')
                    end
                end

                app.CallingApp.mainInfo_Tree.Enable = 0;
                set(app.CallingApp.mainInfo_Lock, 'Enable', 0, 'ImageSource', 'play_lockClose.png')
                app.Tab1_Lock.ImageSource = 'play_lockClose.png';

            else
                app.CallingApp.mainInfo_Tree.Enable = 1;
                set(app.CallingApp.mainInfo_Lock, 'Enable', 1, 'ImageSource', 'play_lockOpen.png')
                app.Tab1_Lock.ImageSource = 'play_lockOpen.png';
            end

        end

        % Callback function: Source_ButtonGroup, imgRoute_Type
        function Source_ButtonGroupSelectionChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            Fcn5_Plots(app)
            delete(d)

        end

        % Value changed function: DadosbrutosCheckBox, 
        % DadosprocessadosDataBinningCheckBox, 
        % DadosprocessadosPontosdemedioCheckBox
        function DadosprocessadosPontosdemedioCheckBoxValueChanged(app, event)
            
            switch event.Source
                case app.DadosbrutosCheckBox;                   Tag = 'rawTable';
                case app.DadosprocessadosPontosdemedioCheckBox; Tag = 'locTable';
                case app.DadosprocessadosDataBinningCheckBox;   Tag = 'binTable';
            end

            h = findall(app.UIAxes2, 'Tag', Tag);
            if event.Source.Value
                h.Visible = 1;
            else
                h.Visible = 0;
            end

        end

        % Value changed function: CurvasuaveCheckBox
        function CurvasuaveCheckBoxValueChanged(app, event)
            
            if app.CurvasuaveCheckBox.Value
                set(app.Smoothed_Grid.Children, 'Enable', 1)
                app.hSmoothedData.Visible = 1;

            else
                set(app.Smoothed_Grid.Children, 'Enable', 0)
                app.hSmoothedData.Visible = 0;
            end            
            
        end

        % Value changed function: Smoothed_Factor, Smoothed_Type
        function TipoDropDownValueChanged(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");            
            app.hSmoothedData.YData = smoothdata(app.rawTable.rawPower, app.Smoothed_Type.Value, 'SmoothingFactor', app.Smoothed_Factor.Value);
            delete(d)
            
        end

        % Value changed function: legend_Location, legend_Visibility
        function legend_VisibilityValueChanged(app, event)
            
            switch event.Source
                case app.legend_Visibility
                    app.UIAxes2.Legend.Visible = app.legend_Visibility.Value;

                case app.legend_Location
                    app.UIAxes2.Legend.Location = app.legend_Location.Value;
            end
            
        end

        % Menu selected function: FilterPosition_Circle, 
        % FilterPosition_Polygon, FilterPosition_Rectangle
        function FilterPosition_Callback(app, event)
            
            switch event.Source
                case app.FilterPosition_Circle;    Type = 'Circle';    hROI = drawcircle(app.UIAxes1,    'Tag', 'ROI_Circle');
                case app.FilterPosition_Rectangle; Type = 'Rectangle'; hROI = drawrectangle(app.UIAxes1, 'Tag', 'ROI_Rectangle');
                case app.FilterPosition_Polygon;   Type = 'Polygon';   hROI = drawpolygon(app.UIAxes1,   'Tag', 'ROI_Polygon');
            end

            app.filtTable(end+1,:) = {'Geographic ROI', Type, {hROI}, 1};
            Fcn2_General_TreeBuilding(app)

        end

        % Menu selected function: FilterPosition_Off, FilterPosition_On, 
        % FilterPosition_del, FilterPosition_lock, FilterPosition_unlock
        function FilterPosition_OnMenuSelected(app, event)
            
            idx = unique(app.Tree2.SelectedNodes.NodeData);
            switch event.Source
                case app.FilterPosition_On;     app.filtTable.enable(idx) = 1;
                case app.FilterPosition_Off;    app.filtTable.enable(idx) = 0;
                case app.FilterPosition_unlock; app.filtTable.handle{idx}.InteractionsAllowed = 'all';
                case app.FilterPosition_lock;   app.filtTable.handle{idx}.InteractionsAllowed = 'none';
                case app.FilterPosition_del;    delete(app.filtTable.handle{idx}); app.filtTable(idx,:) = [];
            end
            Fcn2_General_TreeBuilding(app)

        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;
            
        end

        % Value changed function: GridretangularDataBinningCheckBox, 
        % VeculoCheckBox
        function GridretangularDataBinningCheckBoxValueChanged(app, event)
            
            switch event.Source
                case app.GridretangularDataBinningCheckBox
                    app.hBin.Visible = app.GridretangularDataBinningCheckBox.Value;
                case app.VeculoCheckBox
                    app.hCar.Visible = app.VeculoCheckBox.Value;
            end

        end

        % Value changed function: imgScatter_Radius
        function imgScatter_RadiusValueChanged(app, event)

            if app.imgScatter.ImageSource == "DriveTest_Scatter_ON.png"
                set(app.hScatter, 'SizeData', 20*event.Value)
            end

            if app.imgDensity.ImageSource == "DriveTest_Density_ON.png"
                set(app.hDensity, 'Radius', app.binTable_Distance.Value*event.Value)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Drive-test';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Grid);
            xlabel(app.UIAxes2, 'Amostra')
            ylabel(app.UIAxes2, 'Potência canal (dBm)')
            app.Panel = uipanel(app.Grid);
            app.Panel_Grid = uigridlayout(app.Panel);
            app.Tab1_Grid1 = uigridlayout(app.Panel_Grid);
            app.Tab1_Grid2 = uigridlayout(app.Tab1_Grid1);
            app.EmissionsLabel = uilabel(app.Tab1_Grid2);
            app.EmissionsLabel.Text = 'Emissão:';
            app.EmissionsRefresh = uiimage(app.Tab1_Grid2);
            app.EmissionsRefresh.ImageClickedFcn = createCallbackFcn(app, @EmissionsRefreshImageClicked, true);
            app.EmissionsRefresh.ImageSource = 'LT_refresh.png';
            app.Emissions = uidropdown(app.Tab1_Grid1);
            app.Emissions.Items = {};
            app.Emissions.Value = {};
            app.TreeLabel = uilabel(app.Tab1_Grid1);
            app.TreeLabel.Text = 'Filtros';
            app.Tree = uitree(app.Tab1_Grid1, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.InfoLabel = uilabel(app.Tab1_Grid1);
            app.InfoLabel.Text = 'Metadados:';
            app.Info = uitextarea(app.Tab1_Grid1);
            app.Info.Editable = 'off';
            app.Tree2 = uitree(app.Tab1_Grid1);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.nodeLevel = uitreenode(app.Tree2);
            app.nodeLevel.Icon = 'LT_OCC.png';
            app.nodeLevel.Text = 'NÍVEL';
            app.nodeGeo = uitreenode(app.Tree2);
            app.nodeGeo.Icon = 'Instr_Global.png';
            app.nodeGeo.Text = 'GEOGRÁFICO';
            app.Tab1_Grid = uigridlayout(app.Panel_Grid);
            app.Tab1_Grid.Tag = 'COLORLOCKED';
            app.Tab1_Image = uiimage(app.Tab1_Grid);
            app.Tab1_Image.ImageClickedFcn = createCallbackFcn(app, @imgTreeSelectionChanged, true);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab1_Title = uilabel(app.Tab1_Grid);
            app.Tab1_Title.Text = 'ASPECTOS GERAIS';
            app.Tab1_Lock = uiimage(app.Tab1_Grid);
            app.Tab1_Lock.ImageClickedFcn = createCallbackFcn(app, @Tab1_LockClicked, true);
            app.Tab1_Lock.ImageSource = 'play_lockClose.png';
            app.Tab2_Grid = uigridlayout(app.Panel_Grid);
            app.Tab2_Grid.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_Grid);
            app.Tab2_Title.Text = 'PONTOS DE MEDIÇÃO';
            app.Tab2_Image = uiimage(app.Tab2_Grid);
            app.Tab2_Image.ImageClickedFcn = createCallbackFcn(app, @imgTreeSelectionChanged, true);
            app.Tab2_Image.ImageSource = 'Instr_Global.png';
            app.Tab3_Grid = uigridlayout(app.Panel_Grid);
            app.Tab3_Grid.Tag = 'COLORLOCKED';
            app.Tab3_Image = uiimage(app.Tab3_Grid);
            app.Tab3_Image.ImageClickedFcn = createCallbackFcn(app, @imgTreeSelectionChanged, true);
            app.Tab3_Image.ImageSource = 'LT_Detection.png';
            app.Tab3_Title = uilabel(app.Tab3_Grid);
            app.Tab3_Title.Text = 'DATA BINNING';
            app.Tab4_Grid = uigridlayout(app.Panel_Grid);
            app.Tab4_Grid.Tag = 'COLORLOCKED';
            app.Tab4_Image = uiimage(app.Tab4_Grid);
            app.Tab4_Image.ImageClickedFcn = createCallbackFcn(app, @imgTreeSelectionChanged, true);
            app.Tab4_Image.ImageSource = 'LT_Layers.png';
            app.Tab4_Title = uilabel(app.Tab4_Grid);
            app.Tab4_Title.Text = 'CAMADAS (MAPA)';
            app.Tab5_Grid = uigridlayout(app.Panel_Grid);
            app.Tab5_Grid.Tag = 'COLORLOCKED';
            app.Tab5_Title = uilabel(app.Tab5_Grid);
            app.Tab5_Title.Text = 'CAMADAS (EIXO CARTESIANO)';
            app.Tab5_Image = uiimage(app.Tab5_Grid);
            app.Tab5_Image.ImageClickedFcn = createCallbackFcn(app, @imgTreeSelectionChanged, true);
            app.Tab5_Image.ImageSource = 'LT_playback.png';
            app.locTable_Panel = uigridlayout(app.Panel_Grid);
            app.locTable_FcnLabel = uilabel(app.locTable_Panel);
            app.locTable_FcnLabel.Text = 'Operação de sumarização:';
            app.locTable_Fcn = uidropdown(app.locTable_Panel);
            app.locTable_Fcn.Items = {'Mínima', 'Média', 'Mediana', 'RMS', 'Máxima'};
            app.locTable_Fcn.ValueChangedFcn = createCallbackFcn(app, @locTable_FcnValueChanged, true);
            app.locTable_Fcn.Value = 'Média';
            app.locTable_Grid = uigridlayout(app.locTable_Panel);
            app.locTable_UITable = uitable(app.locTable_Grid);
            app.locTable_UITable.ColumnName = {''; ''; ''; ''};
            app.locTable_UITable.RowName = {};
            app.locTable_UITable.SelectionType = 'row';
            app.locTable_Col1 = uilabel(app.locTable_Grid);
            app.locTable_Col1.Text = {'  ID'; '  '};
            app.locTable_Col2 = uilabel(app.locTable_Grid);
            app.locTable_Col2.Text = {'  Latitude'; '  '};
            app.locTable_Col3 = uilabel(app.locTable_Grid);
            app.locTable_Col3.Text = {'  Longitude'; '  '};
            app.locTable_Col4 = uilabel(app.locTable_Grid);
            app.locTable_Col4.Text = {'  Varreduras'; '  '};
            app.locTable_Info = uilabel(app.locTable_Grid);
            app.locTable_Info.Text = {'10 |––––––––––––| 90 varreduras/ponto'; 'dee'; 'dede'};
            app.binTable_TypeLabel_2 = uilabel(app.locTable_Panel);
            app.binTable_TypeLabel_2.Text = 'Tipo:';
            app.binTable_Type_2 = uidropdown(app.locTable_Panel);
            app.binTable_Type_2.Items = {'Coordenadas truncadas'};
            app.binTable_Type_2.Enable = 'off';
            app.binTable_Type_2.Value = 'Coordenadas truncadas';
            app.binTable_DistanceLabel_2 = uilabel(app.locTable_Panel);
            app.binTable_DistanceLabel_2.Text = 'Casas decimais:';
            app.binTable_Distance_2 = uispinner(app.locTable_Panel);
            app.binTable_Distance_2.Limits = [1 10];
            app.binTable_Distance_2.RoundFractionalValues = 'on';
            app.binTable_Distance_2.ValueDisplayFormat = '%.0f';
            app.binTable_Distance_2.ValueChangedFcn = createCallbackFcn(app, @locTable_FcnValueChanged, true);
            app.binTable_Distance_2.Value = 6;
            app.binTable_Panel = uigridlayout(app.Panel_Grid);
            app.binTable_FcnLabel = uilabel(app.binTable_Panel);
            app.binTable_FcnLabel.Text = 'Operação de sumarização:';
            app.binTable_Fcn = uidropdown(app.binTable_Panel);
            app.binTable_Fcn.Items = {'Mínima', 'Média', 'Mediana', 'RMS', 'Máxima'};
            app.binTable_Fcn.ValueChangedFcn = createCallbackFcn(app, @binTable_DistanceValueChanged, true);
            app.binTable_Fcn.Value = 'Média';
            app.binTable_Grid = uigridlayout(app.binTable_Panel);
            app.binTable_UITable = uitable(app.binTable_Grid);
            app.binTable_UITable.ColumnName = {''; ''; ''; ''};
            app.binTable_UITable.RowName = {};
            app.binTable_UITable.SelectionType = 'row';
            app.binTable_Col1 = uilabel(app.binTable_Grid);
            app.binTable_Col1.Text = {'  ID'; '  '};
            app.binTable_Col3 = uilabel(app.binTable_Grid);
            app.binTable_Col3.Text = {'  Longitude'; '  '};
            app.binTable_Col4 = uilabel(app.binTable_Grid);
            app.binTable_Col4.Text = {'  Varreduras'; '  '};
            app.binTable_Info = uilabel(app.binTable_Grid);
            app.binTable_Info.Text = {'10 |––––––––––––| 90 varreduras/ponto'; 'dede'; 'dee'};
            app.binTable_Col2 = uilabel(app.binTable_Grid);
            app.binTable_Col2.Text = {'  Latitude'; ' '};
            app.binTable_TypeLabel = uilabel(app.binTable_Panel);
            app.binTable_TypeLabel.Text = 'Tipo:';
            app.binTable_Type = uidropdown(app.binTable_Panel);
            app.binTable_Type.Items = {'Grid retangular'};
            app.binTable_Type.Enable = 'off';
            app.binTable_Type.Value = 'Grid retangular';
            app.binTable_DistanceLabel = uilabel(app.binTable_Panel);
            app.binTable_DistanceLabel.Interpreter = 'html';
            app.binTable_DistanceLabel.Text = 'Distância <i>bins</i> (metros):';
            app.binTable_Distance = uispinner(app.binTable_Panel);
            app.binTable_Distance.Step = 50;
            app.binTable_Distance.Limits = [50 500];
            app.binTable_Distance.RoundFractionalValues = 'on';
            app.binTable_Distance.ValueDisplayFormat = '%.0f';
            app.binTable_Distance.ValueChangedFcn = createCallbackFcn(app, @binTable_DistanceValueChanged, true);
            app.binTable_Distance.Value = 100;
            app.Tab4_Panel = uigridlayout(app.Panel_Grid);
            app.imgRoute = uiimage(app.Tab4_Panel);
            app.imgRoute.ImageClickedFcn = createCallbackFcn(app, @imgPlotButtonClicked, true);
            app.imgRoute.Tooltip = {'Rota'};
            app.imgRoute.ImageSource = 'DriveTest_Route_ON.png';
            app.imgScatter = uiimage(app.Tab4_Panel);
            app.imgScatter.ImageClickedFcn = createCallbackFcn(app, @imgPlotButtonClicked, true);
            app.imgScatter.Tooltip = {'Distorção'};
            app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
            app.imgDensity = uiimage(app.Tab4_Panel);
            app.imgDensity.ImageClickedFcn = createCallbackFcn(app, @imgPlotButtonClicked, true);
            app.imgDensity.Tooltip = {'Densidade'};
            app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
            app.Tab4_Grid1_2 = uigridlayout(app.Tab4_Panel);
            app.imgRoute_Title = uilabel(app.Tab4_Grid1_2);
            app.imgRoute_Title.Text = 'Gráfico rota:';
            app.imgRoute_Panel = uipanel(app.Tab4_Grid1_2);
            app.imgRoute_Grid = uigridlayout(app.imgRoute_Panel);
            app.imgRoute_TypeLabel = uilabel(app.imgRoute_Grid);
            app.imgRoute_TypeLabel.Text = 'Tipo:';
            app.imgRoute_Type = uidropdown(app.imgRoute_Grid);
            app.imgRoute_Type.Items = {'Ponto', 'Linha', 'Densidade'};
            app.imgRoute_Type.ValueChangedFcn = createCallbackFcn(app, @Source_ButtonGroupSelectionChanged, true);
            app.imgRoute_Type.Value = 'Linha';
            app.imgRoute_PropertyLabel = uilabel(app.imgRoute_Grid);
            app.imgRoute_PropertyLabel.Text = 'Propriedades (cor, espessura/raio):';
            app.imgRoute_Color = uibutton(app.imgRoute_Grid, 'push');
            app.imgRoute_Color.ButtonPushedFcn = createCallbackFcn(app, @imgRoute_ColorPushed, true);
            app.imgRoute_Color.Text = '';
            app.imgRoute_Size = uislider(app.imgRoute_Grid);
            app.imgRoute_Size.Limits = [1 9];
            app.imgRoute_Size.MajorTicks = [];
            app.imgRoute_Size.MajorTickLabels = {''};
            app.imgRoute_Size.ValueChangingFcn = createCallbackFcn(app, @imgRoute_SizeValueChanging, true);
            app.imgRoute_Size.MinorTicks = [];
            app.imgRoute_Size.Value = 1;
            app.GridretangularDataBinningCheckBox = uicheckbox(app.imgRoute_Grid);
            app.GridretangularDataBinningCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridretangularDataBinningCheckBoxValueChanged, true);
            app.GridretangularDataBinningCheckBox.Text = 'Grid retangular (Data Binning)';
            app.VeculoCheckBox = uicheckbox(app.imgRoute_Grid);
            app.VeculoCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridretangularDataBinningCheckBoxValueChanged, true);
            app.VeculoCheckBox.Text = 'Veículo';
            app.imgRoute_Title_2 = uilabel(app.Tab4_Grid1_2);
            app.imgRoute_Title_2.Text = 'Gráficos distorção/densidade:';
            app.Tab4_Panel1 = uipanel(app.Tab4_Grid1_2);
            app.Tab4_Grid1 = uigridlayout(app.Tab4_Panel1);
            app.imgScatter_Title = uilabel(app.Tab4_Grid1);
            app.imgScatter_Title.Text = 'Raio:';
            app.ColormapLabel = uilabel(app.Tab4_Grid1);
            app.ColormapLabel.Text = 'Mapa de cor:';
            app.Colormap = uidropdown(app.Tab4_Grid1);
            app.Colormap.Items = {'hot', 'summer', 'winter', 'jet'};
            app.Colormap.ValueChangedFcn = createCallbackFcn(app, @ColormapValueChanged, true);
            app.Colormap.Value = 'jet';
            app.Source_ButtonGroup = uibuttongroup(app.Tab4_Grid1);
            app.Source_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Source_ButtonGroupSelectionChanged, true);
            app.Source_rawData = uiradiobutton(app.Source_ButtonGroup);
            app.Source_rawData.Text = 'Dados brutos';
            app.Source_locData = uiradiobutton(app.Source_ButtonGroup);
            app.Source_locData.Text = 'Dados processados (Pontos de medição)';
            app.Source_binData = uiradiobutton(app.Source_ButtonGroup);
            app.Source_binData.Text = 'Dados processados (Data Binning)';
            app.Source_binData.Value = true;
            app.SourceLabel = uilabel(app.Tab4_Grid1);
            app.SourceLabel.Text = 'Curvas:';
            app.imgScatter_Radius = uislider(app.Tab4_Grid1);
            app.imgScatter_Radius.Limits = [1 9];
            app.imgScatter_Radius.MajorTicks = [];
            app.imgScatter_Radius.MajorTickLabels = {''};
            app.imgScatter_Radius.ValueChangedFcn = createCallbackFcn(app, @imgScatter_RadiusValueChanged, true);
            app.imgScatter_Radius.MinorTicks = [];
            app.imgScatter_Radius.Value = 5;
            app.GridLayout = uigridlayout(app.Panel_Grid);
            app.legend_Label = uilabel(app.GridLayout);
            app.legend_Label.Text = 'Legenda';
            app.Panel_4 = uipanel(app.GridLayout);
            app.legend_Grid = uigridlayout(app.Panel_4);
            app.VisibilidadeDropDownLabel = uilabel(app.legend_Grid);
            app.VisibilidadeDropDownLabel.Text = 'Visibilidade:';
            app.legend_Visibility = uidropdown(app.legend_Grid);
            app.legend_Visibility.Items = {'on', 'off'};
            app.legend_Visibility.ValueChangedFcn = createCallbackFcn(app, @legend_VisibilityValueChanged, true);
            app.legend_Visibility.Value = 'off';
            app.PosioLabel = uilabel(app.legend_Grid);
            app.PosioLabel.Text = 'Posição:';
            app.legend_Location = uidropdown(app.legend_Grid);
            app.legend_Location.Items = {'northeast', 'northwest', 'northeastoutside', 'southeastoutside'};
            app.legend_Location.ValueChangedFcn = createCallbackFcn(app, @legend_VisibilityValueChanged, true);
            app.legend_Location.Value = 'northeast';
            app.legend_Label_2 = uilabel(app.GridLayout);
            app.legend_Label_2.Text = 'Cruvas';
            app.Panel_5 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_5);
            app.DadosbrutosCheckBox = uicheckbox(app.GridLayout2);
            app.DadosbrutosCheckBox.ValueChangedFcn = createCallbackFcn(app, @DadosprocessadosPontosdemedioCheckBoxValueChanged, true);
            app.DadosbrutosCheckBox.Text = 'Dados brutos';
            app.DadosbrutosCheckBox.Value = true;
            app.DadosprocessadosPontosdemedioCheckBox = uicheckbox(app.GridLayout2);
            app.DadosprocessadosPontosdemedioCheckBox.ValueChangedFcn = createCallbackFcn(app, @DadosprocessadosPontosdemedioCheckBoxValueChanged, true);
            app.DadosprocessadosPontosdemedioCheckBox.Text = 'Dados processados (Pontos de medição)';
            app.DadosprocessadosDataBinningCheckBox = uicheckbox(app.GridLayout2);
            app.DadosprocessadosDataBinningCheckBox.ValueChangedFcn = createCallbackFcn(app, @DadosprocessadosPontosdemedioCheckBoxValueChanged, true);
            app.DadosprocessadosDataBinningCheckBox.Text = 'Dados processados (Data Binning)';
            app.CurvasuaveCheckBox = uicheckbox(app.GridLayout2);
            app.CurvasuaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @CurvasuaveCheckBoxValueChanged, true);
            app.CurvasuaveCheckBox.Text = 'Curva suave';
            app.Smoothed_Panel = uipanel(app.GridLayout2);
            app.Smoothed_Grid = uigridlayout(app.Smoothed_Panel);
            app.FatorSpinnerLabel = uilabel(app.Smoothed_Grid);
            app.FatorSpinnerLabel.Enable = 'off';
            app.FatorSpinnerLabel.Text = 'Fator:';
            app.Smoothed_Factor = uispinner(app.Smoothed_Grid);
            app.Smoothed_Factor.Step = 0.1;
            app.Smoothed_Factor.Limits = [0 1];
            app.Smoothed_Factor.ValueDisplayFormat = '%.2f';
            app.Smoothed_Factor.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.Smoothed_Factor.Enable = 'off';
            app.Smoothed_Factor.Value = 0.25;
            app.TipoDropDownLabel = uilabel(app.Smoothed_Grid);
            app.TipoDropDownLabel.Enable = 'off';
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.Smoothed_Type = uidropdown(app.Smoothed_Grid);
            app.Smoothed_Type.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.Smoothed_Type.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.Smoothed_Type.Enable = 'off';
            app.Smoothed_Type.Value = 'gaussian';
            app.imgShowHide = uiimage(app.Grid);
            app.imgShowHide.ImageClickedFcn = createCallbackFcn(app, @imgShowHideClicked, true);
            app.imgShowHide.ImageSource = 'LT_HideLeft.png';
            app.ContextMenu_FilterLevel = uicontextmenu(app.UIFigure);
            app.FilterLevel_Add = uimenu(app.ContextMenu_FilterLevel);
            app.FilterLevel_Add.Text = 'Desabilitar';
            app.IncluirMenu = uimenu(app.ContextMenu_FilterLevel);
            app.IncluirMenu.Separator = 'on';
            app.IncluirMenu.Text = 'Incluir';
            app.LimiteinferiorMenu = uimenu(app.IncluirMenu);
            app.LimiteinferiorMenu.Text = 'Limite inferior';
            app.RegioMenu = uimenu(app.IncluirMenu);
            app.RegioMenu.Text = 'Região';
            app.ExcluirMenu = uimenu(app.ContextMenu_FilterLevel);
            app.ExcluirMenu.Text = 'Excluir';
            % Assign app.ContextMenu_FilterLevel
            app.nodeLevel.ContextMenu = app.ContextMenu_FilterLevel;
            app.ContextMenu_FilterPosition = uicontextmenu(app.UIFigure);
            app.FilterPosition_Off = uimenu(app.ContextMenu_FilterPosition);
            app.FilterPosition_Off.MenuSelectedFcn = createCallbackFcn(app, @FilterPosition_OnMenuSelected, true);
            app.FilterPosition_Off.Text = 'Desabilitar';
            app.IncluirMenu_2 = uimenu(app.ContextMenu_FilterPosition);
            app.IncluirMenu_2.Separator = 'on';
            app.IncluirMenu_2.Text = 'Incluir';
            app.FilterPosition_Circle = uimenu(app.IncluirMenu_2);
            app.FilterPosition_Circle.MenuSelectedFcn = createCallbackFcn(app, @FilterPosition_Callback, true);
            app.FilterPosition_Circle.Text = 'Círculo';
            app.FilterPosition_Rectangle = uimenu(app.IncluirMenu_2);
            app.FilterPosition_Rectangle.MenuSelectedFcn = createCallbackFcn(app, @FilterPosition_Callback, true);
            app.FilterPosition_Rectangle.Text = 'Retângulo';
            app.FilterPosition_Polygon = uimenu(app.IncluirMenu_2);
            app.FilterPosition_Polygon.MenuSelectedFcn = createCallbackFcn(app, @FilterPosition_Callback, true);
            app.FilterPosition_Polygon.Text = 'Polígono';
            app.FilterPosition_del = uimenu(app.ContextMenu_FilterPosition);
            app.FilterPosition_del.MenuSelectedFcn = createCallbackFcn(app, @FilterPosition_OnMenuSelected, true);
            app.FilterPosition_del.Text = 'Excluir';
            app.FilterPosition_unlock = uimenu(app.ContextMenu_FilterPosition);
            app.FilterPosition_unlock.MenuSelectedFcn = createCallbackFcn(app, @FilterPosition_OnMenuSelected, true);
            app.FilterPosition_unlock.Separator = 'on';
            app.FilterPosition_unlock.Text = 'Editar';
            app.FilterPosition_lock = uimenu(app.ContextMenu_FilterPosition);
            app.FilterPosition_lock.MenuSelectedFcn = createCallbackFcn(app, @FilterPosition_OnMenuSelected, true);
            app.FilterPosition_lock.Text = 'Bloquear';
            app.FilterPosition_On = uimenu(app.ContextMenu_FilterPosition);
            app.FilterPosition_On.MenuSelectedFcn = createCallbackFcn(app, @FilterPosition_OnMenuSelected, true);
            app.FilterPosition_On.Separator = 'on';
            app.FilterPosition_On.Text = 'Habilitar';
            % Assign app.ContextMenu_FilterPosition
            app.nodeGeo.ContextMenu = app.ContextMenu_FilterPosition;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end