classdef winFiscaliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid1                          matlab.ui.container.GridLayout
        GridLayout16                   matlab.ui.container.GridLayout
        Sublabel2                      matlab.ui.control.Label
        Sublabel1                      matlab.ui.control.Label
        Label1                         matlab.ui.control.Hyperlink
        Button_2                       matlab.ui.control.Button
        Grid2                          matlab.ui.container.GridLayout
        DatePicker2                    matlab.ui.control.DatePicker
        DateLabel2                     matlab.ui.control.Label
        DatePicker1                    matlab.ui.control.DatePicker
        DateLabel1                     matlab.ui.control.Label
        Panel1                         matlab.ui.container.Panel
        Grid3                          matlab.ui.container.GridLayout
        Grid3A                         matlab.ui.container.GridLayout
        issueStatus                    matlab.ui.control.EditField
        SituaoEditFieldLabel           matlab.ui.control.Label
        AnoexecuoLabel                 matlab.ui.control.Label
        issueYear                      matlab.ui.control.EditField
        ProcessoSEILabel               matlab.ui.control.Label
        Panel_11                       matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        Hyperlink1                     matlab.ui.control.Hyperlink
        issueType                      matlab.ui.control.EditField
        TipoEditFieldLabel             matlab.ui.control.Label
        ClasseEditFieldLabel           matlab.ui.control.Label
        issueClass                     matlab.ui.control.EditField
        PrioridadeEditFieldLabel       matlab.ui.control.Label
        issuePriority                  matlab.ui.control.EditField
        Grid3B                         matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        ConclusoEditFieldLabel         matlab.ui.control.Label
        Hours4                         matlab.ui.control.NumericEditField
        Hours3                         matlab.ui.control.NumericEditField
        ExecuoEditFieldLabel           matlab.ui.control.Label
        DeslocamentoEditFieldLabel     matlab.ui.control.Label
        Hours2                         matlab.ui.control.NumericEditField
        PreparaoEditFieldLabel         matlab.ui.control.Label
        Hours1                         matlab.ui.control.NumericEditField
        Sublabel3                      matlab.ui.control.Label
        DescriodainspeoTextAreaLabel   matlab.ui.control.Label
        Description                    matlab.ui.control.TextArea
        Grid3C                         matlab.ui.container.GridLayout
        Tree1                          matlab.ui.container.CheckBoxTree
        FiscalresponsvelDropDownLabel  matlab.ui.control.Label
        Responsable                    matlab.ui.control.DropDown
        Sublabel4                      matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        Grid4                          matlab.ui.container.GridLayout
        ReportFile                     matlab.ui.control.EditField
        EditFieldLabel                 matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        Grid4C                         matlab.ui.container.GridLayout
        InformaesainserirnohistricodainspeoLabel  matlab.ui.control.Label
        Journal                        matlab.ui.control.TextArea
        Services                       matlab.ui.control.TextArea
        ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel  matlab.ui.control.Label
        Hyperlink2                     matlab.ui.control.Hyperlink
        Grid4A                         matlab.ui.container.GridLayout
        Panel_9                        matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        MunicpiosTextAreaLabel         matlab.ui.control.Label
        City                           matlab.ui.control.TextArea
        Longitude                      matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel        matlab.ui.control.Label
        Latitude                       matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel         matlab.ui.control.Label
        Sublabel7                      matlab.ui.control.Label
        Grid4B                         matlab.ui.container.GridLayout
        EmissionsCount2                matlab.ui.control.NumericEditField
        EmissesnolicenciadasEditFieldLabel  matlab.ui.control.Label
        EmissesidentificadasEditFieldLabel  matlab.ui.control.Label
        EmissionsCount1                matlab.ui.control.NumericEditField
        Panel_10                       matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        Limit2                         matlab.ui.control.NumericEditField
        LimitesuperiorMHzEditFieldLabel  matlab.ui.control.Label
        Limit1                         matlab.ui.control.NumericEditField
        LimiteinferiorMHzLabel         matlab.ui.control.Label
        BandLimits                     matlab.ui.control.Label
        Label3                         matlab.ui.control.Label
        Label2                         matlab.ui.control.Label
        Button                         matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        CallingApp
        
        pyMod
        Login
        Password
        Teste
        
        Workers
        
        ISSUE
        ISSUEParent
        FinalReport = [];
        
    end
    
    methods (Access = private)
        
        function Fcn_Layout(app, Flag)
            
            switch Flag
                case 'On'
                    if ~strcmp(app.issueStatus.Value, 'Relatada')
                        app.Button.Enable = 1; 
                        set(app.CheckBox, 'Value', 1, 'Enable', 1)
                    else
                        app.Button.Enable = 0;
                        set(app.CheckBox, 'Value', 0, 'Enable', 0)
                    end

                    app.Latitude.FontColor        = [0,0,0];
                    app.Longitude.FontColor       = [0,0,0];
                    app.Limit1.FontColor          = [0,0,0];
                    app.Limit2.FontColor          = [0,0,0];
                    app.EmissionsCount1.FontColor = [0,0,0];
                    app.EmissionsCount2.FontColor = [0,0,0];
                                      
                case 'Off'
                    set(app.CheckBox,   'Value',  0, 'Enable', 0)
                    app.ReportFile.Value = '';
                    app.Services.Value   = '';
                    app.Journal.Value    = '';
                    app.City.Value       = '';
                    
                    app.Latitude.FontColor        = [.94,.94,.94];
                    app.Longitude.FontColor       = [.94,.94,.94];
                    app.Limit1.FontColor          = [.94,.94,.94];
                    app.Limit2.FontColor          = [.94,.94,.94];
                    app.EmissionsCount1.FontColor = [.94,.94,.94];
                    app.EmissionsCount2.FontColor = [.94,.94,.94];
                    
                    app.Button.Enable = 0;
            end
                        
        end
                
        
        function Fcn_infoReport(app, filename)
            
            load(replace(filename, '.html', '.mat'), 'ReportProject');
            app.FinalReport = ReportProject;
            
            app.ReportFile.Value = filename;
            
            app.Latitude.Value   = app.FinalReport.Latitude;
            app.Longitude.Value  = app.FinalReport.Longitude;
            app.City.Value       = app.FinalReport.City';
            
            app.Limit1.Value     = app.FinalReport.F0 / 1e+6;
            app.Limit2.Value     = app.FinalReport.F1 / 1e+6;
            
            app.EmissionsCount1.Value = app.FinalReport.emissionsValue1;
            app.EmissionsCount2.Value = app.FinalReport.emissionsValue2;
            
            app.Services.Value = app.FinalReport.Services{1};
            for ii = 2:numel(app.FinalReport.Services)
                app.Services.Value = sprintf('%s, %s', app.Services.Value{1}, app.FinalReport.Services{ii});
            end
            
            for ii = 1:numel(app.FinalReport.tableJournal)
                app.Journal.Value{ii} = jsonencode(app.FinalReport.tableJournal{ii});
            end
            
        end
        
        
        function Fcn_infoRedmine(app, issueInfo)
            
            if app.Teste; URL = sprintf('https://sistemashm.anatel.gov.br/fiscaliza/issues/%s', issueInfo.id);
            else;         URL = sprintf('https://sistemas.anatel.gov.br/fiscaliza/issues/%s',   issueInfo.id);
            end
            set(app.Label1, 'Text', sprintf('Inspeção nº %s, relacionada à Ação nº %d', issueInfo.id, issueInfo.id_ACAO), ...
                            'URL',  URL)
            
            app.Sublabel1.Text = issueInfo.subject;       
            app.Sublabel2.Text = issueInfo.Atualizado;
            
            if ~isempty(issueInfo.Numero_Sei_do_Processo)
                set(app.Hyperlink1, 'Text', issueInfo.Numero_Sei_do_Processo.numero, ...
                                    'URL',  issueInfo.Numero_Sei_do_Processo.link_acesso)
            end
            
            app.DatePicker1.BackgroundColor = [1,1,1];
            app.DatePicker2.BackgroundColor = [1,1,1];
            if ~isempty(issueInfo.start_date); app.DatePicker1.Value = datetime(issueInfo.start_date); end
            if ~isempty(issueInfo.due_date);   app.DatePicker2.Value = datetime(issueInfo.due_date);   end
            
            app.issueStatus.Value   = issueInfo.status;
            if contains(issueInfo.status, {'Relatada', 'Conferida'})
                Fcn_relatedIssue(app)
            end
            
            app.issuePriority.Value = issueInfo.priority;
            app.issueClass.Value    = issueInfo.Classe_da_Inspecao;
            app.issueYear.Value     = issueInfo.Ano;
            app.issueType.Value     = issueInfo.Tipo_de_Inspecao;
            
            if ~isempty(issueInfo.description)
                app.Description.Value = issueInfo.description;
            elseif ~isempty(app.FinalReport)
                Fcn_defaultDescription(app)
            else
                app.Description.Value = '';
            end
            
            if ~isempty(issueInfo.Horas_de_Preparacao);   app.Hours1.Value = str2double(issueInfo.Horas_de_Preparacao);   end
            if ~isempty(issueInfo.Horas_de_Deslocamento); app.Hours2.Value = str2double(issueInfo.Horas_de_Deslocamento); end
            if ~isempty(issueInfo.Horas_de_Execucao);     app.Hours3.Value = str2double(issueInfo.Horas_de_Execucao);     end
            if ~isempty(issueInfo.Horas_de_Conclusao);    app.Hours4.Value = str2double(issueInfo.Horas_de_Conclusao);    end
            
            if isfield(issueInfo, 'Relatorio_de_Monitoramento') && ~isempty(issueInfo.Relatorio_de_Monitoramento)
                set(app.Hyperlink2, 'Text', sprintf('SEI nº %s', issueInfo.Relatorio_de_Monitoramento.numero), ...
                                    'URL',  issueInfo.Relatorio_de_Monitoramento.link_acesso, ...
                                    'Visible', 1)
                set(app.CheckBox,   'Value', 0, 'Enable', 1, 'Text', 'Substituir relatório carregado no SEI.')
            elseif isempty(app.FinalReport)
                app.Hyperlink2.Visible = 0;
                set(app.CheckBox, 'Value', 0, 'Enable', 0, 'Text', 'Criar relatório no SEI.')
            else
                app.Hyperlink2.Visible = 0;
                set(app.CheckBox, 'Value', 1, 'Enable', 1, 'Text', 'Criar relatório no SEI.')
            end
            
            for ii = 1:numel(issueInfo.Users)
                uitreenode(app.Tree1, 'Text', issueInfo.Users{ii});
                if ismember(issueInfo.Users{ii}, issueInfo.Fiscais)
                    app.Tree1.CheckedNodes = [app.Tree1.CheckedNodes; app.Tree1.Children(ii)];
                end
            end
            
            app.Responsable.Items = [{''}, issueInfo.Users];
            if ~isempty(issueInfo.Fiscal_Responsavel)
                app.Responsable.Value = issueInfo.Fiscal_Responsavel;
            end
            
        end
        
        
        function Fcn_defaultDescription(app)
            
            app.Description.Value = sprintf(['Monitoração da(s) faixa(s) %s, no(s) município(s) %s, no âmbito ' ...
                                             'da demanda de fiscalização registrada na Ação nº %d.'],           ...
                                             strjoin(app.FinalReport.Bands, ', '), strjoin(app.City.Value, ', '), app.ISSUEParent);
            
        end
        
        
        function Fcn_relatedIssue(app)
            
            set(app.DatePicker1, 'Enable',   0, 'BackgroundColor', [.94,.94,.94])
            set(app.DatePicker2, 'Enable',   0, 'BackgroundColor', [.94,.94,.94])
            
            set(app.Description, 'Editable', 0, 'BackgroundColor', [.8,.8,.8])
            set(app.Hours1,      'Editable', 0, 'BackgroundColor', [.94,.94,.94])
            set(app.Hours2,      'Editable', 0, 'BackgroundColor', [.94,.94,.94])
            set(app.Hours3,      'Editable', 0, 'BackgroundColor', [.94,.94,.94])
            set(app.Hours4,      'Editable', 0, 'BackgroundColor', [.94,.94,.94])
            
            set(app.Tree1,       'Enable',   0, 'BackgroundColor', [.94,.94,.94])
            set(app.Responsable, 'Enable',   0, 'BackgroundColor', [.94,.94,.94])
            
            app.Button.Enable = 0;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, pyModule, USR, PWD, teste, issueInfo, issueNumber)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            app.pyMod    = pyModule;
            app.Login    = USR;
            app.Password = PWD;
            app.Teste    = teste;
            
            app.ISSUE       = issueNumber;
            app.ISSUEParent = issueInfo.id_ACAO;
            app.Workers     = issueInfo.Users;
            
            if isfield(app.CallingApp.reportInfo, 'Filename') && ~isempty(app.CallingApp.reportInfo.Filename)
                Fcn_Layout(app, 'On')
                Fcn_infoReport(app, app.CallingApp.reportInfo.Filename)
            else
                Fcn_Layout(app, 'Off')
            end
            Fcn_infoRedmine(app, issueInfo)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin3 = [];
            delete(app)
            
        end

        % Button pushed function: Button
        function PlayButton(app, event)
            
            % Validação das informações
            Hours = app.Hours1.Value + app.Hours2.Value + app.Hours3.Value + app.Hours4.Value;
            if isempty(strjoin(app.Description.Value)) | ~Hours | isempty(app.Tree1.CheckedNodes) | ...
               isnat(app.DatePicker1.Value) | isnat(app.DatePicker2.Value) | isempty(app.Responsable.Value)
                uialert(app.UIFigure, 'Ao menos um dos campos obrigatórios está pendente de edição.', 'appAnálise', 'Icon', 'warning')
                return
            elseif app.DatePicker1.Value > app.DatePicker2.Value
                uialert(app.UIFigure, 'A data limite deve ser posterior à previsão de início.', 'appAnálise', 'Icon', 'warning')
                return
            end
            
            % Início do processo...
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Em andamento a validação dos dados para submeter ao Fiscaliza.');
            
            workersList = {app.Tree1.CheckedNodes(1).Text};
            for ii = 2:numel(app.Tree1.CheckedNodes)
                workersList = [workersList, app.Tree1.CheckedNodes(ii).Text];
            end
            workersList = py.list(workersList);
            serviceList = py.list(app.FinalReport.Services');
            
            Cities = {};
            for ii = 1:numel(app.City.Value)
                Cities{ii} = sprintf('%s/%s', app.City.Value{ii}(end-1:end), app.City.Value{ii}(1:end-3));
            end
            Cities = py.list(Cities);
            
            tableJournal = '';
            for ii = 1:numel(app.FinalReport.tableJournal)
                if ~isempty(app.FinalReport.tableJournal{ii})
                    tableJournal{ii} = jsonencode(app.FinalReport.tableJournal{ii});
                end
            end
            
            if ~isempty(tableJournal); tableJournal = py.list(tableJournal);            
            end
            
            rawData = py.dict(pyargs('Classe_da_Inspecao',            'Técnica',                       ...
                                     'Tipo_de_Inspecao',              'Uso do Espectro - Monitoração', ...
                                     'description',                   strjoin(app.Description.Value),  ...
                                     'Fiscal_Responsavel',            app.Responsable.Value,           ...
                                     'Fiscais',                       workersList,                     ...
                                     'Html',                          app.ReportFile.Value,            ...
                                     'Gerar_Relatorio',               int8(app.CheckBox.Value),        ...
                                     'Frequencia_Inicial',            app.Limit1.Value,                ...
                                     'Unidade_da_Frequencia_Inicial', 'MHz',                           ...
                                     'Frequencia_Final',              app.Limit2.Value,                ...
                                     'Unidade_da_Frequencia_Final',   'MHz',                           ...
                                     'start_date',                    datestr(app.DatePicker1.Value, 'yyyy-mm-dd'), ...
                                     'due_date',                      datestr(app.DatePicker2.Value, 'yyyy-mm-dd'), ...
                                     'UF_Municipio',                  Cities,                           ...
                                     'Servicos_da_Inspecao',          serviceList,                      ...
                                     'Qnt_de_emissoes_na_faixa',      int32(app.EmissionsCount1.Value), ...
                                     'Emissoes_nao_autorizadas',      int32(app.EmissionsCount2.Value), ...
                                     'Horas_de_Preparacao',           int32(app.Hours1.Value),          ...
                                     'Horas_de_Deslocamento',         int32(app.Hours2.Value),          ...
                                     'Horas_de_Execucao',             int32(app.Hours3.Value),          ...
                                     'Horas_de_Conclusao',            int32(app.Hours4.Value),          ...
                                     'Latitude',                      round(app.Latitude.Value,  6),    ...
                                     'Longitude',                     round(app.Longitude.Value, 6),    ...
                                     'Uso_de_PF',                     'Não se aplica PF - uso apenas de formulários', ...
                                     'Acao_de_risco_a_vida_criada',   'Não',   ...
                                     'Impossibilidade_acesso_online', '0',     ...
                                     'Reservar_Instrumentos',         '0',     ...
                                     'Utilizou_algum_instrumento',    int8(0), ...
                                     'notes',                         tableJournal, ...
                                     'uploads',                       py.list({py.dict(pyargs('path',         replace(app.ReportFile.Value, 'html', 'json'), ...
                                                                                              'filename',     'Info.json', ...
                                                                                              'description',  'Informações gerais acerca da fiscalização (sensor, período de observação, faixas monitoradas, emissões identificadas etc).', ...
                                                                                              'content_type', '.json'))})));
            
%             try
                finalData = app.pyMod.validar_dicionario(pyargs('data_dict', rawData,            ...
                                                                'inspecao',  num2str(app.ISSUE), ...
                                                                'login',     app.Login,          ...
                                                                'senha',     app.Password,       ...
                                                                'teste',     app.Teste));
                
                d.Message = 'Em andamento a carga dos dados no Fiscaliza.';
                
                finalStatus   = 'Relatando';
                replaceReport = false;
                if app.CheckBox.Value && ~isempty(app.Hyperlink2.Text)
                    replaceReport = true;
                else
                    if ~isempty(app.Hyperlink2.Text); finalStatus = 'Relatada';
                    end
                end
                
                % PENDENTE SUBIR ARQUIVO JSON PRA ISSUE %
                
                app.pyMod.relatar_inspecao(pyargs('dados',    finalData,          ...
                                                  'inspecao', num2str(app.ISSUE), ...
                                                  'login',    app.Login,          ...
                                                  'senha',    app.Password,       ...
                                                  'teste',    app.Teste,          ...
                                                  'parar_em', finalStatus,        ...
                                                  'substituir_relatorio', replaceReport));
                
                d.Message = 'Em andamento a leitura dos novos valores dos campos...';
                issueInfo = app.pyMod.detalhar_issue(pyargs('inspecao', num2str(app.ISSUE), ...
                                                            'login', app.Login, 'senha', app.Password, 'teste', app.Teste));
                
                issueInfo = Misc_py2matRedmine(issueInfo, 0);
                Fcn_infoRedmine(app, issueInfo)
                
%             catch ME
%                 uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
%             end
            
        end

        % Button pushed function: Button_2
        function OpenButton(app, event)
            
            if ~strcmp(app.issueStatus.Value, 'Relatada')
                [File, Path] = uigetfile({'Report_*_FinalVersion.html', '(*.html)'}, ...
                                          'Selecione um arquivo', 'MultiSelect', 'off');
                figure(app.UIFigure)
                                
                if File
                    Fcn_Layout(app, 'On')
                    Fcn_infoReport(app, fullfile(Path, File))
                    
                    if isempty(strjoin(app.Description.Value))
                        Fcn_defaultDescription(app)
                    end
                end
            else
                uialert(app.UIFigure, 'A inspeção já está no estado "Relatada".', 'appAnálise', 'Icon', 'warning')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Fiscaliza';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @PlayButton, true);
            app.Button.Icon = 'White_Play.png';
            app.Button.Text = 'Iniciar relato';
            app.Label2 = uilabel(app.Grid1);
            app.Label2.Text = 'Aspectos gerais da fiscalização';
            app.Label3 = uilabel(app.Grid1);
            app.Label3.Text = 'Aspectos técnicos da fiscalização';
            app.Panel_4 = uipanel(app.Grid1);
            app.Grid4 = uigridlayout(app.Panel_4);
            app.Grid4B = uigridlayout(app.Grid4);
            app.BandLimits = uilabel(app.Grid4B);
            app.BandLimits.Text = 'Limites da(s) faixa(s) monitorada(s):';
            app.Panel_10 = uipanel(app.Grid4B);
            app.GridLayout14 = uigridlayout(app.Panel_10);
            app.LimiteinferiorMHzLabel = uilabel(app.GridLayout14);
            app.LimiteinferiorMHzLabel.Text = 'Limite inferior (MHz):';
            app.Limit1 = uieditfield(app.GridLayout14, 'numeric');
            app.Limit1.ValueDisplayFormat = '%.6f';
            app.Limit1.Editable = 'off';
            app.LimitesuperiorMHzEditFieldLabel = uilabel(app.GridLayout14);
            app.LimitesuperiorMHzEditFieldLabel.Text = 'Limite superior (MHz):';
            app.Limit2 = uieditfield(app.GridLayout14, 'numeric');
            app.Limit2.ValueDisplayFormat = '%.6f';
            app.Limit2.Editable = 'off';
            app.Limit2.Value = 6000;
            app.EmissionsCount1 = uieditfield(app.Grid4B, 'numeric');
            app.EmissionsCount1.Editable = 'off';
            app.EmissesidentificadasEditFieldLabel = uilabel(app.Grid4B);
            app.EmissesidentificadasEditFieldLabel.Text = 'Emissões identificadas:';
            app.EmissesnolicenciadasEditFieldLabel = uilabel(app.Grid4B);
            app.EmissesnolicenciadasEditFieldLabel.Text = 'Emissões não licenciadas:';
            app.EmissionsCount2 = uieditfield(app.Grid4B, 'numeric');
            app.EmissionsCount2.Editable = 'off';
            app.Grid4A = uigridlayout(app.Grid4);
            app.Sublabel7 = uilabel(app.Grid4A);
            app.Sublabel7.Text = 'Local da monitoração:';
            app.Panel_9 = uipanel(app.Grid4A);
            app.GridLayout13 = uigridlayout(app.Panel_9);
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.Latitude = uieditfield(app.GridLayout13, 'numeric');
            app.Latitude.ValueDisplayFormat = '%.6f';
            app.Latitude.Editable = 'off';
            app.Latitude.Value = -1;
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.Longitude = uieditfield(app.GridLayout13, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.6f';
            app.Longitude.Editable = 'off';
            app.Longitude.Value = -1;
            app.City = uitextarea(app.GridLayout13);
            app.City.Editable = 'off';
            app.City.Value = {'edede'; 'dedede'};
            app.MunicpiosTextAreaLabel = uilabel(app.GridLayout13);
            app.MunicpiosTextAreaLabel.Text = 'Município(s):';
            app.Hyperlink2 = uihyperlink(app.Grid4);
            app.Hyperlink2.Visible = 'off';
            app.Hyperlink2.Text = '';
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel = uilabel(app.Grid4);
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel.Text = 'Serviço(s) relacionado(s) à(s) faixa(s) monitorada(s) ou emissões identificadas:';
            app.Services = uitextarea(app.Grid4);
            app.Services.Editable = 'off';
            app.Grid4C = uigridlayout(app.Grid4);
            app.Journal = uitextarea(app.Grid4C);
            app.Journal.Editable = 'off';
            app.InformaesainserirnohistricodainspeoLabel = uilabel(app.Grid4C);
            app.InformaesainserirnohistricodainspeoLabel.Text = 'Informações a inserir no histórico da inspeção:';
            app.CheckBox = uicheckbox(app.Grid4);
            app.CheckBox.Text = 'Criar relatório no SEI.';
            app.EditFieldLabel = uilabel(app.Grid4);
            app.EditFieldLabel.Text = {'Relatório de monitoração:'; '(Tipologia SEI: "Relatório de Atividades")'};
            app.ReportFile = uieditfield(app.Grid4, 'text');
            app.ReportFile.Editable = 'off';
            app.Panel1 = uipanel(app.Grid1);
            app.Grid3 = uigridlayout(app.Panel1);
            app.Grid3C = uigridlayout(app.Grid3);
            app.Sublabel4 = uilabel(app.Grid3C);
            app.Sublabel4.Text = 'Fiscais envolvidos na fiscalização:';
            app.Responsable = uidropdown(app.Grid3C);
            app.Responsable.Items = {};
            app.Responsable.Value = {};
            app.FiscalresponsvelDropDownLabel = uilabel(app.Grid3C);
            app.FiscalresponsvelDropDownLabel.Text = 'Fiscal responsável:';
            app.Tree1 = uitree(app.Grid3C, 'checkbox');
            app.Grid3B = uigridlayout(app.Grid3);
            app.Description = uitextarea(app.Grid3B);
            app.Description.Value = {'Monitoração...'; 'dede'};
            app.DescriodainspeoTextAreaLabel = uilabel(app.Grid3B);
            app.DescriodainspeoTextAreaLabel.Text = 'Descrição da inspeção:';
            app.Sublabel3 = uilabel(app.Grid3B);
            app.Sublabel3.Text = 'Estimativas de horas:';
            app.GridLayout3 = uigridlayout(app.Grid3B);
            app.Hours1 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours1.Limits = [0 Inf];
            app.Hours1.RoundFractionalValues = 'on';
            app.Hours1.ValueDisplayFormat = '%.0f';
            app.PreparaoEditFieldLabel = uilabel(app.GridLayout3);
            app.PreparaoEditFieldLabel.Text = 'Preparação:';
            app.Hours2 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours2.Limits = [0 Inf];
            app.Hours2.RoundFractionalValues = 'on';
            app.Hours2.ValueDisplayFormat = '%.0f';
            app.DeslocamentoEditFieldLabel = uilabel(app.GridLayout3);
            app.DeslocamentoEditFieldLabel.Text = 'Deslocamento:';
            app.ExecuoEditFieldLabel = uilabel(app.GridLayout3);
            app.ExecuoEditFieldLabel.Text = 'Execução:';
            app.Hours3 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours3.Limits = [0 Inf];
            app.Hours3.RoundFractionalValues = 'on';
            app.Hours3.ValueDisplayFormat = '%.0f';
            app.Hours4 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours4.Limits = [0 Inf];
            app.Hours4.RoundFractionalValues = 'on';
            app.Hours4.ValueDisplayFormat = '%.0f';
            app.ConclusoEditFieldLabel = uilabel(app.GridLayout3);
            app.ConclusoEditFieldLabel.Text = 'Conclusão:';
            app.Grid3A = uigridlayout(app.Grid3);
            app.issuePriority = uieditfield(app.Grid3A, 'text');
            app.issuePriority.Editable = 'off';
            app.PrioridadeEditFieldLabel = uilabel(app.Grid3A);
            app.PrioridadeEditFieldLabel.Text = 'Prioridade:';
            app.issueClass = uieditfield(app.Grid3A, 'text');
            app.issueClass.Editable = 'off';
            app.ClasseEditFieldLabel = uilabel(app.Grid3A);
            app.ClasseEditFieldLabel.Text = 'Classe:';
            app.TipoEditFieldLabel = uilabel(app.Grid3A);
            app.TipoEditFieldLabel.Text = 'Tipo:';
            app.issueType = uieditfield(app.Grid3A, 'text');
            app.issueType.Editable = 'off';
            app.Panel_11 = uipanel(app.Grid3A);
            app.GridLayout15 = uigridlayout(app.Panel_11);
            app.Hyperlink1 = uihyperlink(app.GridLayout15);
            app.Hyperlink1.Text = '53554...';
            app.ProcessoSEILabel = uilabel(app.Grid3A);
            app.ProcessoSEILabel.Text = 'Processo SEI:';
            app.issueYear = uieditfield(app.Grid3A, 'text');
            app.issueYear.Editable = 'off';
            app.AnoexecuoLabel = uilabel(app.Grid3A);
            app.AnoexecuoLabel.Text = 'Ano execução:';
            app.SituaoEditFieldLabel = uilabel(app.Grid3A);
            app.SituaoEditFieldLabel.Text = 'Situação:';
            app.issueStatus = uieditfield(app.Grid3A, 'text');
            app.issueStatus.Editable = 'off';
            app.Grid2 = uigridlayout(app.Grid1);
            app.DateLabel1 = uilabel(app.Grid2);
            app.DateLabel1.Text = 'Previsão de início:';
            app.DatePicker1 = uidatepicker(app.Grid2);
            app.DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.DatePicker1.Editable = 'off';
            app.DateLabel2 = uilabel(app.Grid2);
            app.DateLabel2.Text = 'Data limite:';
            app.DatePicker2 = uidatepicker(app.Grid2);
            app.DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.DatePicker2.Editable = 'off';
            app.Button_2 = uibutton(app.Grid1, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton, true);
            app.Button_2.Icon = 'mainMenu_File.png';
            app.Button_2.Text = '';
            app.GridLayout16 = uigridlayout(app.Grid1);
            app.Label1 = uihyperlink(app.GridLayout16);
            app.Label1.Text = 'Insp...';
            app.Sublabel1 = uilabel(app.GridLayout16);
            app.Sublabel1.Text = 'INSP_...';
            app.Sublabel2 = uilabel(app.GridLayout16);
            app.Sublabel2.Text = 'Atualizado...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winFiscaliza(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end