classdef winOCC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Grid1                     matlab.ui.container.GridLayout
        Image                     matlab.ui.control.Image
        Title                     matlab.ui.control.Label
        Grid2                     matlab.ui.container.GridLayout
        Button2                   matlab.ui.control.Button
        Button1                   matlab.ui.control.Button
        Panel1                    matlab.ui.container.Panel
        Grid6                     matlab.ui.container.GridLayout
        Sublabel1                 matlab.ui.control.Label
        Panel5                    matlab.ui.container.Panel
        Grid7                     matlab.ui.container.GridLayout
        noise_uSamples            matlab.ui.control.Spinner
        noise_uSamplesLabel       matlab.ui.control.Label
        noise_tSamples            matlab.ui.control.Spinner
        noise_tSamplesLabel       matlab.ui.control.Label
        noise_Fcn                 matlab.ui.control.DropDown
        noise_FcnLabel            matlab.ui.control.Label
        occ_Factor                matlab.ui.control.DropDown
        occ_FactorLabel           matlab.ui.control.Label
        occ_Offset                matlab.ui.control.Spinner
        occ_OffsetLabel           matlab.ui.control.Label
        occ_THR                   matlab.ui.control.Spinner
        occ_THRLabel              matlab.ui.control.Label
        occ_IntegrationTime       matlab.ui.control.Spinner
        occ_IntegrationTimeLabel  matlab.ui.control.Label
        occ_Method                matlab.ui.control.DropDown
        occ_MethodLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
    end
    
    methods (Access = private)

        function Fcn_InitialValues(app)

            idx1 = app.CallingApp.Report_Tree.SelectedNodes.NodeData;

            app.occ_Method.Value = app.CallingApp.specData(idx1).reportOCC.Method;
            InitialValues        = jsondecode(app.CallingApp.specData(idx1).reportOCC.Parameters);

            app.occ_IntegrationTime.Value = InitialValues.IntegrationTime;

            switch app.occ_Method.Value
                case 'Linear fixo'
                    app.occ_THR.Value        = InitialValues.THR;
                
                case 'Linear adaptativo'
                    app.occ_Offset.Value     = InitialValues.Offset;
                    app.noise_Fcn.Value      = InitialValues.Fcn;
                    app.noise_tSamples.Value = InitialValues.Samples(1);
                    app.noise_uSamples.Value = InitialValues.Samples(2);

                case 'Piso de ruído (Offset)'
                    app.occ_Offset.Value     = InitialValues.Offset;
                    app.occ_Factor.Value     = InitialValues.Factor;
                    app.noise_Fcn.Value      = InitialValues.Fcn;
                    app.noise_tSamples.Value = InitialValues.Samples(1);
                    app.noise_uSamples.Value = InitialValues.Samples(2);
            end

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', fullfile(app.RootFolder, 'Icons', 'Toolbar_Icon3.png'))
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            Fcn_InitialValues(app)
            occ_MethodValueChanged(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Value changed function: occ_Method
        function occ_MethodValueChanged(app, event)
            
            idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;
            
            switch app.occ_Method.Value
                case 'Linear fixo'
                    set(findall(groot, 'Parent', app.Grid6, 'Tag', 'THR'),    'Enable', 1)
                    set(findall(groot, 'Parent', app.Grid6, 'Tag', 'Offset'), 'Enable', 0)
                    set(findall(groot, 'Parent', app.Grid6, 'Tag', 'Factor'), 'Enable', 0)

                    set(app.Sublabel1,      'Enable', 0)
                    set(app.Grid7.Children, 'Enable', 0)
                    
                    switch app.CallingApp.specData(idx).MetaData.metaString{1}
                        case 'dBm'
                            app.occ_THRLabel.Text = 'Valor (dBm):';
                            app.occ_THR.Value     = -80;    
                        case 'dBμV'
                            app.occ_THRLabel.Text = 'Valor (dBμV):';
                            app.occ_THR.Value     = 27;                        
                        case 'dBμV/m'
                            app.occ_THRLabel.Text = 'Valor (dBμV/m):';
                            app.occ_THR.Value     = 40;
                    end
                    
                case 'Linear adaptativo'
                    set(findall(groot, 'Parent', app.Grid6, 'Tag', 'THR'),    'Enable', 0)
                    set(findall(groot, 'Parent', app.Grid6, 'Tag', 'Offset'), 'Enable', 1)
                    set(findall(groot, 'Parent', app.Grid6, 'Tag', 'Factor'), 'Enable', 0)

                    set(app.Sublabel1,      'Enable', 1)
                    set(app.Grid7.Children, 'Enable', 1)
                    
                case 'Piso de ruído (Offset)'
                    set(findall(groot, 'Parent', app.Grid6, 'Tag', 'THR'),    'Enable', 0)
                    set(findall(groot, 'Parent', app.Grid6, 'Tag', 'Offset'), 'Enable', 1)
                    set(findall(groot, 'Parent', app.Grid6, 'Tag', 'Factor'), 'Enable', 1)

                    set(app.Sublabel1,      'Enable', 1)
                    set(app.Grid7.Children, 'Enable', 1)
            end

        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            
            msg = fileread(fullfile(app.RootFolder, 'DataBase', 'Help_OCC.txt'));
            Misc_uialertCreation(app, 'info', msg)
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;
            app.CallingApp.specData(idx).reportOCC.Method = app.occ_Method.Value;
            
            switch app.occ_Method.Value
                case 'Linear fixo'
                    app.CallingApp.specData(idx).reportOCC.Parameters = jsonencode(struct('IntegrationTime', app.occ_IntegrationTime.Value, ...
                                                                                           'THR', app.occ_THR.Value));
                
                case 'Linear adaptativo'
                    app.CallingApp.specData(idx).reportOCC.Parameters = jsonencode(struct('IntegrationTime', app.occ_IntegrationTime.Value, ...
                                                                                           'Offset', app.occ_Offset.Value,                  ...
                                                                                           'Fcn', app.noise_Fcn.Value,                      ...
                                                                                           'Samples', [app.noise_tSamples.Value, app.noise_uSamples.Value]));

                case 'Piso de ruído (Offset)'
                    app.CallingApp.specData(idx).reportOCC.Parameters = jsonencode(struct('IntegrationTime', app.occ_IntegrationTime.Value, ...
                                                                                           'Offset', app.occ_Offset.Value,                  ...
                                                                                           'Factor', app.occ_Factor.Value,                  ...
                                                                                           'Fcn', app.noise_Fcn.Value,                      ...
                                                                                           'Samples', [app.noise_tSamples.Value, app.noise_uSamples.Value]));
            end

            app.CallingApp.Report_Layout
            app.CallingApp.auxWin_TreeEdit
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel1 = uipanel(app.Grid1);
            app.Grid6 = uigridlayout(app.Panel1);
            app.occ_MethodLabel = uilabel(app.Grid6);
            app.occ_MethodLabel.Text = 'Método:';
            app.occ_Method = uidropdown(app.Grid6);
            app.occ_Method.Items = {'Linear fixo', 'Linear adaptativo', 'Piso de ruído (Offset)'};
            app.occ_Method.ValueChangedFcn = createCallbackFcn(app, @occ_MethodValueChanged, true);
            app.occ_Method.Value = 'Linear fixo';
            app.occ_IntegrationTimeLabel = uilabel(app.Grid6);
            app.occ_IntegrationTimeLabel.Text = 'Tempo de integração (min):';
            app.occ_IntegrationTime = uispinner(app.Grid6);
            app.occ_IntegrationTime.Limits = [1 60];
            app.occ_IntegrationTime.Value = 15;
            app.occ_THRLabel = uilabel(app.Grid6);
            app.occ_THRLabel.Tag = 'THR';
            app.occ_THRLabel.Text = 'Valor (dBm):';
            app.occ_THR = uispinner(app.Grid6);
            app.occ_THR.RoundFractionalValues = 'on';
            app.occ_THR.ValueDisplayFormat = '%d';
            app.occ_THR.Tag = 'THR';
            app.occ_THR.Value = -80;
            app.occ_OffsetLabel = uilabel(app.Grid6);
            app.occ_OffsetLabel.Tag = 'Offset';
            app.occ_OffsetLabel.Text = 'OffSet:';
            app.occ_Offset = uispinner(app.Grid6);
            app.occ_Offset.Limits = [5 12];
            app.occ_Offset.Tag = 'Offset';
            app.occ_Offset.Value = 12;
            app.occ_FactorLabel = uilabel(app.Grid6);
            app.occ_FactorLabel.Tag = 'Factor';
            app.occ_FactorLabel.Text = 'Fator ceifamento:';
            app.occ_Factor = uidropdown(app.Grid6);
            app.occ_Factor.Items = {'68', '95', '99'};
            app.occ_Factor.Tag = 'Factor';
            app.occ_Factor.Value = '95';
            app.Panel5 = uipanel(app.Grid6);
            app.Grid7 = uigridlayout(app.Panel5);
            app.noise_FcnLabel = uilabel(app.Grid7);
            app.noise_FcnLabel.Text = {'Função'; 'estatística:'};
            app.noise_Fcn = uidropdown(app.Grid7);
            app.noise_Fcn.Items = {'Mediana', 'Média'};
            app.noise_Fcn.Value = 'Média';
            app.noise_tSamplesLabel = uilabel(app.Grid7);
            app.noise_tSamplesLabel.Text = {'Amostras'; 'descartadas (%):'};
            app.noise_tSamples = uispinner(app.Grid7);
            app.noise_tSamples.Limits = [0 10];
            app.noise_uSamplesLabel = uilabel(app.Grid7);
            app.noise_uSamplesLabel.Text = {'Amostras'; 'úteis (%):'};
            app.noise_uSamples = uispinner(app.Grid7);
            app.noise_uSamples.Limits = [10 90];
            app.noise_uSamples.Value = 20;
            app.Sublabel1 = uilabel(app.Grid6);
            app.Sublabel1.Text = 'Relacionados à estimativa do piso de ruído:';
            app.Grid2 = uigridlayout(app.Grid1);
            app.Button1 = uibutton(app.Grid2, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Icon = 'Toolbar_Icon3.png';
            app.Button1.Text = '';
            app.Button2 = uibutton(app.Grid2, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'White_Play.png';
            app.Button2.Text = 'Confirmar';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Ocupação</b>'; '<font>Aferição de ocupação espectral por <i>bin</i></font>'; ' '};
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'occ4.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winOCC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end