classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Grid1                matlab.ui.container.GridLayout
        Button               matlab.ui.control.Button
        Grid2                matlab.ui.container.GridLayout
        Grid2d               matlab.ui.container.GridLayout
        GPU_Panel            matlab.ui.container.Panel
        GPU_Grid             matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        GridLayout           matlab.ui.container.GridLayout
        VisibilityLabel      matlab.ui.control.Label
        Visibility           matlab.ui.control.DropDown
        GPU_ResolutionLabel  matlab.ui.control.Label
        GPU_Resolution       matlab.ui.control.DropDown
        GPU_FormatLabel      matlab.ui.control.Label
        GPU_Format           matlab.ui.control.DropDown
        GPU_Label2           matlab.ui.control.Label
        GPU_Label1           matlab.ui.control.Label
        GPU_Type             matlab.ui.control.DropDown
        GPU_Info             matlab.ui.control.TextArea
        GPU_Button           matlab.ui.control.Button
        Grid2c               matlab.ui.container.GridLayout
        Button_2             matlab.ui.control.Button
        Merge_Panel          matlab.ui.container.Panel
        Merge_Grid           matlab.ui.container.GridLayout
        Merge_Label3         matlab.ui.control.Label
        Merge_Label2         matlab.ui.control.Label
        Merge_Label1         matlab.ui.control.Label
        Merge_Spinner        matlab.ui.control.Spinner
        Merge_CheckBox7      matlab.ui.control.CheckBox
        Merge_CheckBox6      matlab.ui.control.CheckBox
        Merge_CheckBox5      matlab.ui.control.CheckBox
        Merge_CheckBox4      matlab.ui.control.CheckBox
        Merge_CheckBox3      matlab.ui.control.CheckBox
        Merge_CheckBox2      matlab.ui.control.CheckBox
        Merge_CheckBox1      matlab.ui.control.CheckBox
        Merge_Button         matlab.ui.control.Button
        Grid2b               matlab.ui.container.GridLayout
        Variables_Button     matlab.ui.control.Button
        Variables_Panel      matlab.ui.container.Panel
        Variables_Grid       matlab.ui.container.GridLayout
        Variables_Label      matlab.ui.control.Label
        Variables_Info2      matlab.ui.control.Label
        Variables_CheckBox4  matlab.ui.control.CheckBox
        Variables_CheckBox3  matlab.ui.control.CheckBox
        Variables_CheckBox2  matlab.ui.control.CheckBox
        Variables_CheckBox1  matlab.ui.control.CheckBox
        Grid2a               matlab.ui.container.GridLayout
        Info                 matlab.ui.control.TextArea
        Version_Toolbar1     matlab.ui.control.Button
        Version_Toolbar2     matlab.ui.control.Button
        Version_Toolbar3     matlab.ui.control.Button
        Version_Button       matlab.ui.control.Button
        Logo                 matlab.ui.control.Image
        Title                matlab.ui.control.Label
        Tree                 matlab.ui.container.Tree
        Node1                matlab.ui.container.TreeNode
        Node2                matlab.ui.container.TreeNode
        Node3                matlab.ui.container.TreeNode
        Node4                matlab.ui.container.TreeNode
        TreeLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
        newVersion
        
    end
    
    methods (Access = private)
        
        function Fcn1_UpdateVersion(app)
            
            app.CallingApp.General.Version = appVersion(app.RootFolder);
            Fcn2_VersionInfo(app)
            
        end


        function Fcn2_VersionInfo(app)
            
            app.Info.Value = sprintf('Informações acerca das versões instaladas dos módulos do appAnálise:\n%s\n\n', ...
                                      jsonencode(app.CallingApp.General.Version, 'PrettyPrint', true));
            
            app.Version_Toolbar2.Enable = 0;
            app.Version_Toolbar3.Enable = 0;
            
        end
        
        
        function Fcn3_Graphics(app)

            graphRender = opengl('data');
            app.GPU_Info.Value = jsonencode(rmfield(graphRender, 'Extensions'), 'PrettyPrint', true);
            
            switch graphRender.HardwareSupportLevel
                case 'basic'; app.GPU_Type.Value = 'Hardware Basic';
                case 'full';  app.GPU_Type.Value = 'Hardware Full';
                case 'none';  app.GPU_Type.Value = 'Software';
            end

            app.Visibility.Value = app.CallingApp.General.Image.Visibility;

            switch app.CallingApp.General.Image.Format
                case 'jpeg'; app.GPU_Format.Value = 'Joint Photographics Experts Group (JPEG)';
                case 'png';  app.GPU_Format.Value = 'Portable Network Graphics (PNG)';
            end

            app.GPU_Resolution.Value = num2str(app.CallingApp.General.Image.Resolution);
            app.GPU_Button.Enable    = 0;
            
        end
        
        
        function Fcn4_MergeType(app)
            
            app.Merge_CheckBox1.Value = 0;
            app.Merge_CheckBox2.Value = 0;
            app.Merge_CheckBox3.Value = 0;
            app.Merge_CheckBox4.Value = 0;
            
            if ismember(app.Merge_CheckBox1.Text, app.CallingApp.General.Merge.OptionalFieldsToRemove); app.Merge_CheckBox1.Value = 1;
            end
            Merge_CheckBox1ValueChanged(app)
            
            if ismember(app.Merge_CheckBox2.Text, app.CallingApp.General.Merge.OptionalFieldsToRemove); app.Merge_CheckBox2.Value = 1;
            end
            
            if ismember(app.Merge_CheckBox3.Text, app.CallingApp.General.Merge.OptionalFieldsToRemove); app.Merge_CheckBox3.Value = 1;
            end
            
            if ismember(app.Merge_CheckBox4.Text, app.CallingApp.General.Merge.OptionalFieldsToRemove); app.Merge_CheckBox4.Value = 1;
            end
            
            switch app.CallingApp.General.Merge.DataTypeStatus
                case 'keep';   app.Merge_CheckBox6.Value = 0;
                case 'remove'; app.Merge_CheckBox6.Value = 1;
            end
            
            switch app.CallingApp.General.Merge.AntennaNameStatus
                case 'keep';   app.Merge_CheckBox7.Value = 0;
                case 'remove'; app.Merge_CheckBox7.Value = 1;
            end
            
            app.Merge_Spinner.Value = app.CallingApp.General.Merge.DistanceValue;
            app.Merge_Button.Enable = 0;
            
        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'toolbar_info.png')
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            if isdeployed
                set(app.Variables_Grid.Children, 'Enable', 0)
            end
            
            expand(app.Tree, 'all')
            app.Tree.SelectedNodes = app.Tree.Children(1);
            TreeSelectionChanged(app)
            
            Fcn2_VersionInfo(app)
            Fcn3_Graphics(app)
            Fcn4_MergeType(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin5 = [];
            delete(app)
            
        end

        % Value changed function: Variables_CheckBox1, 
        % Variables_CheckBox2, Variables_CheckBox3, Variables_CheckBox4
        function CheckBoxes(app, event)
            
            if app.Variables_CheckBox1.Value || app.Variables_CheckBox2.Value || app.Variables_CheckBox3.Value || app.Variables_CheckBox4.Value
                app.Variables_Button.Enable = 1;
            else
                app.Variables_Button.Enable = 0;
            end
            
        end

        % Button pushed function: Version_Button
        function Version_ButtonPushed(app, event)
            
            try
                FileVersion = jsondecode(webread(fileread(fullfile(app.RootFolder, 'Settings', 'PublicLink.json'))));
                app.newVersion = struct('appAnalise', FileVersion.appAnalise.Version, ...
                                        'fiscaliza',  FileVersion.fiscaliza.Version,  ...
                                        'anateldb',   FileVersion.anateldb);
                
                if isequal(rmfield(app.CallingApp.General.Version, {'Matlab', 'Python'}), app.newVersion)
                    Misc_uialertCreation(app, 'info', sprintf('Módulos atualizados:\n• appAnalise, fiscaliza, anateldb'))
                    
                else
                    app.Info.Value{end+1} = sprintf('Informações acerca das versões disponíveis dos módulos do appAnálise:\n%s\n\n%s\n\n', ...
                                                     jsonencode(app.newVersion, 'PrettyPrint', true));
                                    
                    str1 = {};
                    str2 = {};
                    if strcmp(app.CallingApp.General.Version.appAnalise, app.newVersion.appAnalise); str1(end+1) = {'appAnalise'};
                    else;                                                                            str2(end+1) = {'appAnalise'};
                    end                    
                    if strcmp(app.CallingApp.General.Version.fiscaliza,  app.newVersion.fiscaliza);  str1(end+1) = {'fiscaliza'};
                    else;                                                                            str2(end+1) = {'fiscaliza'}; app.Version_Toolbar2.Enable = 1;
                    end                    
                    if isequal(app.CallingApp.General.Version.anateldb,  app.newVersion.anateldb);   str1(end+1) = {'anateldb'};
                    else;                                                                            str2(end+1) = {'anateldb'};  app.Version_Toolbar3.Enable = 1;
                    end
                    
                    if ~isempty(str1) && ~isempty(str2); msg = sprintf('Módulo(s) atualizado(s):\n• %s\n\nMódulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(str1, ', '), strjoin(str2, ', '));
                    else;                                msg = sprintf('Módulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(str2, ', '));
                    end

                    Misc_uialertCreation(app, 'warning', msg)
                    
                end
                
            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end
            
        end

        % Button pushed function: Button_2
        function Merge_ButtonRefresh(app, event)
            
            set(findall(groot, 'Parent', app.Merge_Grid, 'Type', 'uicheckbox'), 'Value', 0)
            app.Merge_Spinner.Value = 100;

            Merge_ButtonPushed(app)

        end

        % Button pushed function: Merge_Button
        function Merge_ButtonPushed(app, event)
            
            OptionalFieldsToRemove = {};
            if app.Merge_CheckBox1.Value; OptionalFieldsToRemove = [OptionalFieldsToRemove, app.Merge_CheckBox1.Text];
            end
            
            if app.Merge_CheckBox2.Value; OptionalFieldsToRemove = [OptionalFieldsToRemove, app.Merge_CheckBox2.Text];
            end
            
            if app.Merge_CheckBox3.Value; OptionalFieldsToRemove = [OptionalFieldsToRemove, app.Merge_CheckBox3.Text];
            end
            
            if app.Merge_CheckBox4.Value; OptionalFieldsToRemove = [OptionalFieldsToRemove, app.Merge_CheckBox4.Text];
            end
            
            MergeType.OptionalFieldsToRemove = OptionalFieldsToRemove;
            
            switch app.Merge_CheckBox5.Value
                case 0; MergeType.ThreadIDStatus = 'keep';
                case 1; MergeType.ThreadIDStatus = 'remove';
            end
            
            switch app.Merge_CheckBox6.Value
                case 0; MergeType.DataTypeStatus = 'keep';
                case 1; MergeType.DataTypeStatus = 'remove';
            end
            
            switch app.Merge_CheckBox7.Value
                case 0; MergeType.AntennaNameStatus = 'keep';
                case 1; MergeType.AntennaNameStatus = 'remove';
            end
            
            MergeType.DistanceValue = app.Merge_Spinner.Value;
            
            fileID = fopen(fullfile(app.RootFolder, 'Settings', 'MergeType.cfg'), 'wt');
            fwrite(fileID, jsonencode(MergeType, 'PrettyPrint', true), 'char');
            fclose(fileID);
            
            app.CallingApp.startup_MergeType
            Fcn4_MergeType(app)            
            
        end

        % Button pushed function: Variables_Button
        function Variables_ButtonPushed(app, event)
            
            if app.Variables_CheckBox1.Value
                assignin('base', 'metaData', app.CallingApp.metaData)
                openvar('metaData')
            end
            
            if app.Variables_CheckBox2.Value
                assignin('base', 'specData', app.CallingApp.specData)
                openvar('specData')
            end
            
            if app.Variables_CheckBox3.Value
                assignin('base', 'peaksTable', app.CallingApp.peaksTable)
                openvar('peaksTable')
            end
            
            if app.Variables_CheckBox4.Value
                assignin('base', 'exceptionList', app.CallingApp.exceptionList)
                openvar('exceptionList')
            end
            
        end

        % Button pushed function: GPU_Button
        function GPU_ButtonPushed(app, event)
            
            switch app.GPU_Type.Value
                case 'Software';       opengl software
                case 'Hardware Full';  opengl hardware
                case 'Hardware Basic'; opengl hardwarebasic
            end

            switch app.GPU_Format.Value
                case 'Joint Photographics Experts Group (JPEG)'; Format = 'jpeg';
                case 'Portable Network Graphics (PNG)';          Format = 'png';
            end
            app.CallingApp.General.Image = struct('Visibility', app.Visibility.Value, 'Format', Format, 'Resolution', str2double(app.GPU_Resolution.Value));

            Fcn3_Graphics(app)
            
        end

        % Button pushed function: Version_Toolbar1
        function Version_Toolbar1Pushed(app, event)
            
            Python = pyenv;
            
            [file, pathfile] = uigetfile({'python*.exe', 'python*.exe'}, 'Selecione o arquivo executável do Python', Python.Executable);
            figure(app.UIFigure)
            
            if file
                try
                    pyenv('Version', fullfile(pathfile, file));
                    Misc_uialertCreation(app, 'info', 'Alteração realizada com sucesso!')
                    
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Verificando as versões dos módulos do appAnálise...');
                    Fcn1_UpdateVersion(app)
                
                catch ME
                    Misc_uialertCreation(app, 'error', getReport(ME))
                end
            end
            
        end

        % Button pushed function: Version_Toolbar2
        function Version_Toolbar2Pushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando atualizar a biblioteca Fiscaliza...');
            
            Python   = pyenv;
            [~, msg] = system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), sprintf('install fiscaliza==%s', app.newVersion.fiscaliza)));
            
            app.Info.Value{end+1} = sprintf('%s\n\n', msg);
            
            [~, fiscalizaVersion] = system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), 'list | findstr fiscaliza'));
            if ~isempty(fiscalizaVersion); app.CallingApp.General.Version.fiscaliza = strtrim(extractAfter(fiscalizaVersion, 'fiscaliza'));
            end
            
        end

        % Button pushed function: Version_Toolbar3
        function Version_Toolbar3Pushed(app, event)
            
            try
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando atualizar a base de dados offline da Agência...');

                websave(fullfile(app.RootFolder, 'Temp', 'AnatelDB.parquet.gzip'), 'https://github.com/ronaldokun/anateldb/blob/master/dados/AnatelDB.parquet.gzip?raw=true');

                ParquetInfoFile = parquetinfo(fullfile(app.RootFolder, 'Temp', 'AnatelDB.parquet.gzip'));
                if ~isequal(ParquetInfoFile.VariableTypes(1:9), ["string", "double", "single", "single", "string", "int16", "int32", "categorical", "single"])
                    error('Ao menos uma das colunas da nova versão do anateldb não estão no formato esperado.')
                end

                if isfile(fullfile(app.RootFolder, 'DataBase', 'Anatel_old.mat'))
                    delete(fullfile(app.RootFolder, 'DataBase', 'Anatel_old.mat'))
                end

                while true
                    status = system(sprintf('rename "%s" "%s"', fullfile(app.RootFolder, 'DataBase', 'Anatel.mat'), 'Anatel_old.mat'));
                    if ~status
                        break
                    end
                    pause(.1)
                end

                clear global AnatelDB
                clear global AnatelDB_info
                drawnow nocallbacks
                
                anateldb_Read(app.RootFolder)
                
                global AnatelDB_info
                app.CallingApp.General.Version.anateldb = AnatelDB_info;
                
                Fcn1_UpdateVersion(app)
                
            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

            delete(d)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            NodeData = app.Tree.SelectedNodes.NodeData;
            
            if ~isempty(NodeData)
                switch NodeData
                    case 1; app.Grid2.RowHeight = {'1x',0,0,0};
                    case 2; app.Grid2.RowHeight = {0,'1x',0,0};
                    case 3; app.Grid2.RowHeight = {0,0,'1x',0};
                    case 4; app.Grid2.RowHeight = {0,0,0,'1x'};
                end
                
            else
                app.Tree.SelectedNodes = app.Tree.Children(1);
                TreeSelectionChanged(app)
            end
            
        end

        % Value changed function: GPU_Format, GPU_Resolution, GPU_Type, 
        % Visibility
        function GPUTypeValueChanged(app, event)
            
            app.GPU_Button.Enable = 1;
            
        end

        % Value changed function: Merge_CheckBox1
        function Merge_CheckBox1ValueChanged(app, event)
            
            app.Merge_CheckBox5.Value = app.Merge_CheckBox1.Value;
            MergeTypeValueChanged(app)
            
        end

        % Value changed function: Merge_CheckBox2, Merge_CheckBox3, 
        % Merge_CheckBox4, Merge_CheckBox6, Merge_CheckBox7, Merge_Spinner
        function MergeTypeValueChanged(app, event)
            
            app.Merge_Button.Enable = 1;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winSettings>', '</winSettings>')));

            Misc_uialertCreation(app, 'info', msg)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'home_Puzzle.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.TreeLabel = uilabel(app.Grid1);
            app.TreeLabel.Text = 'TIPO';
            app.Tree = uitree(app.Grid1);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node1 = uitreenode(app.Tree);
            app.Node1.NodeData = 1;
            app.Node1.Text = 'Geral';
            app.Node2 = uitreenode(app.Tree);
            app.Node2.NodeData = 2;
            app.Node2.Text = 'Variáveis';
            app.Node3 = uitreenode(app.Tree);
            app.Node3.NodeData = 3;
            app.Node3.Text = 'Junção dos fluxos de dados';
            app.Node4 = uitreenode(app.Tree);
            app.Node4.NodeData = 4;
            app.Node4.Text = 'Unidade gráfica';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Configurações</b>'; '<font>Ajustes gerais</font>'; ' '};
            app.Logo = uiimage(app.Grid1);
            app.Logo.ImageSource = 'toolbar_settings.png';
            app.Grid2 = uigridlayout(app.Grid1);
            app.Grid2a = uigridlayout(app.Grid2);
            app.Version_Button = uibutton(app.Grid2a, 'push');
            app.Version_Button.ButtonPushedFcn = createCallbackFcn(app, @Version_ButtonPushed, true);
            app.Version_Button.Icon = 'General_RefreshWhite.png';
            app.Version_Button.Text = 'Verificar versões';
            app.Version_Toolbar3 = uibutton(app.Grid2a, 'push');
            app.Version_Toolbar3.ButtonPushedFcn = createCallbackFcn(app, @Version_Toolbar3Pushed, true);
            app.Version_Toolbar3.Icon = 'winAddFiles_Sheet.png';
            app.Version_Toolbar3.Tooltip = {'Atualizar base de dados offline'};
            app.Version_Toolbar3.Text = '';
            app.Version_Toolbar2 = uibutton(app.Grid2a, 'push');
            app.Version_Toolbar2.ButtonPushedFcn = createCallbackFcn(app, @Version_Toolbar2Pushed, true);
            app.Version_Toolbar2.Icon = 'winFiscaliza.png';
            app.Version_Toolbar2.Tooltip = {'Atualizar biblioteca Fiscaliza'};
            app.Version_Toolbar2.Text = '';
            app.Version_Toolbar1 = uibutton(app.Grid2a, 'push');
            app.Version_Toolbar1.ButtonPushedFcn = createCallbackFcn(app, @Version_Toolbar1Pushed, true);
            app.Version_Toolbar1.Icon = 'Settings_Python.png';
            app.Version_Toolbar1.Tooltip = {'Editar ambiente Python'};
            app.Version_Toolbar1.Text = '';
            app.Info = uitextarea(app.Grid2a);
            app.Info.Editable = 'off';
            app.Grid2b = uigridlayout(app.Grid2);
            app.Variables_Panel = uipanel(app.Grid2b);
            app.Variables_Grid = uigridlayout(app.Variables_Panel);
            app.Variables_CheckBox1 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.Variables_CheckBox1.Text = 'app.metaData';
            app.Variables_CheckBox2 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.Variables_CheckBox2.Text = 'app.specData';
            app.Variables_CheckBox3 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.Variables_CheckBox3.Text = 'app.peaksTable';
            app.Variables_CheckBox4 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.Variables_CheckBox4.Text = 'app.exceptionList';
            app.Variables_Info2 = uilabel(app.Variables_Grid);
            app.Variables_Info2.Text = 'Trata-se de variável criada no modo "ARQUIVO(S)" que estrura a informações de arquivos de espectro nos campos "Node", "MetaData", "Data", "gps" etc. Essa variável é editada no modo "PLAYBACK", armazenando informações das emissões identificadas manualmente (ROI ou DataTip) ou automaticamente.';
            app.Variables_Label = uilabel(app.Variables_Grid);
            app.Variables_Label.Text = 'Exportar variáveis para o workspace base do Matlab:';
            app.Variables_Button = uibutton(app.Grid2b, 'push');
            app.Variables_Button.ButtonPushedFcn = createCallbackFcn(app, @Variables_ButtonPushed, true);
            app.Variables_Button.Icon = 'General_play.png';
            app.Variables_Button.Enable = 'off';
            app.Variables_Button.Text = 'Exportar';
            app.Grid2c = uigridlayout(app.Grid2);
            app.Merge_Button = uibutton(app.Grid2c, 'push');
            app.Merge_Button.ButtonPushedFcn = createCallbackFcn(app, @Merge_ButtonPushed, true);
            app.Merge_Button.Icon = 'General_editWhite.png';
            app.Merge_Button.Enable = 'off';
            app.Merge_Button.Text = 'Alterar';
            app.Merge_Panel = uipanel(app.Grid2c);
            app.Merge_Grid = uigridlayout(app.Merge_Panel);
            app.Merge_CheckBox1 = uicheckbox(app.Merge_Grid);
            app.Merge_CheckBox1.ValueChangedFcn = createCallbackFcn(app, @Merge_CheckBox1ValueChanged, true);
            app.Merge_CheckBox1.Text = 'ThreadID';
            app.Merge_CheckBox2 = uicheckbox(app.Merge_Grid);
            app.Merge_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.Merge_CheckBox2.Text = 'FileFormat';
            app.Merge_CheckBox3 = uicheckbox(app.Merge_Grid);
            app.Merge_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.Merge_CheckBox3.Text = 'TaskName';
            app.Merge_CheckBox4 = uicheckbox(app.Merge_Grid);
            app.Merge_CheckBox4.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.Merge_CheckBox4.Text = 'Description';
            app.Merge_CheckBox5 = uicheckbox(app.Merge_Grid);
            app.Merge_CheckBox5.Enable = 'off';
            app.Merge_CheckBox5.Text = 'ThreadID';
            app.Merge_CheckBox6 = uicheckbox(app.Merge_Grid);
            app.Merge_CheckBox6.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.Merge_CheckBox6.Text = 'DataType';
            app.Merge_CheckBox7 = uicheckbox(app.Merge_Grid);
            app.Merge_CheckBox7.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.Merge_CheckBox7.Text = 'AntennaName';
            app.Merge_Spinner = uispinner(app.Merge_Grid);
            app.Merge_Spinner.Step = 50;
            app.Merge_Spinner.Limits = [50 Inf];
            app.Merge_Spinner.RoundFractionalValues = 'on';
            app.Merge_Spinner.ValueDisplayFormat = '%.0f';
            app.Merge_Spinner.ValueChangedFcn = createCallbackFcn(app, @MergeTypeValueChanged, true);
            app.Merge_Spinner.Value = 100;
            app.Merge_Label1 = uilabel(app.Merge_Grid);
            app.Merge_Label1.Text = 'Campos da variável app.metaData que serão ignorados na junção dos fluxos de dados:';
            app.Merge_Label2 = uilabel(app.Merge_Grid);
            app.Merge_Label2.Text = 'Campos da estrutura MetaData, da variável app.metaData, que serão ignorados na junção dos fluxos de dados:';
            app.Merge_Label3 = uilabel(app.Merge_Grid);
            app.Merge_Label3.Text = 'Distância mínima registrada nos diferentes arquivos gerados por um mesmo sensor (metros):';
            app.Button_2 = uibutton(app.Grid2c, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Merge_ButtonRefresh, true);
            app.Button_2.Icon = 'General_Refresh.png';
            app.Button_2.Tooltip = {'Voltar às configurações iniciais'};
            app.Button_2.Text = '';
            app.Grid2d = uigridlayout(app.Grid2);
            app.GPU_Button = uibutton(app.Grid2d, 'push');
            app.GPU_Button.ButtonPushedFcn = createCallbackFcn(app, @GPU_ButtonPushed, true);
            app.GPU_Button.Icon = 'General_editWhite.png';
            app.GPU_Button.Enable = 'off';
            app.GPU_Button.Text = 'Alterar';
            app.GPU_Panel = uipanel(app.Grid2d);
            app.GPU_Grid = uigridlayout(app.GPU_Panel);
            app.GPU_Info = uitextarea(app.GPU_Grid);
            app.GPU_Info.Editable = 'off';
            app.GPU_Type = uidropdown(app.GPU_Grid);
            app.GPU_Type.Items = {'Software', 'Hardware Full', 'Hardware Basic'};
            app.GPU_Type.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.GPU_Type.Value = 'Hardware Basic';
            app.GPU_Label1 = uilabel(app.GPU_Grid);
            app.GPU_Label1.Text = 'Tipo de unidade de processamento gráfico:';
            app.GPU_Label2 = uilabel(app.GPU_Grid);
            app.GPU_Label2.Text = 'Imagem a ser incluída no relatório:';
            app.Panel = uipanel(app.GPU_Grid);
            app.GridLayout = uigridlayout(app.Panel);
            app.GPU_Format = uidropdown(app.GridLayout);
            app.GPU_Format.Items = {'Joint Photographics Experts Group (JPEG)', 'Portable Network Graphics (PNG)'};
            app.GPU_Format.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.GPU_Format.Value = 'Joint Photographics Experts Group (JPEG)';
            app.GPU_FormatLabel = uilabel(app.GridLayout);
            app.GPU_FormatLabel.Text = 'Formato:';
            app.GPU_Resolution = uidropdown(app.GridLayout);
            app.GPU_Resolution.Items = {'100', '120', '150', '200'};
            app.GPU_Resolution.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.GPU_Resolution.Value = '120';
            app.GPU_ResolutionLabel = uilabel(app.GridLayout);
            app.GPU_ResolutionLabel.Text = 'Resolução (dpi):';
            app.Visibility = uidropdown(app.GridLayout);
            app.Visibility.Items = {'on', 'off'};
            app.Visibility.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.Visibility.Value = 'on';
            app.VisibilityLabel = uilabel(app.GridLayout);
            app.VisibilityLabel.Text = 'Visibilidade:';
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'toolbar_info.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end