classdef winTemplate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        mainGrid            matlab.ui.container.GridLayout
        HTML_Grid           matlab.ui.container.GridLayout
        ModeLabel           matlab.ui.control.Label
        HTML                matlab.ui.control.HTML
        imgShowHide         matlab.ui.control.Image
        mainPanel           matlab.ui.container.Panel
        mainPanel_Grid      matlab.ui.container.GridLayout
        Tab2_Panel          matlab.ui.container.GridLayout
        GridLayout16        matlab.ui.container.GridLayout
        Separator           matlab.ui.control.Image
        Button_save         matlab.ui.control.Button
        Button_open         matlab.ui.control.Button
        Button_edit         matlab.ui.control.StateButton
        Button_preview      matlab.ui.control.Button
        GridLayout15        matlab.ui.container.GridLayout
        FontSize_Grid       matlab.ui.container.GridLayout
        FontSize_plus       matlab.ui.control.Image
        FontSize_minus      matlab.ui.control.Image
        AuxiliarText        matlab.ui.control.TextArea
        Tab2_Label2         matlab.ui.control.Label
        Tab2_Grid1          matlab.ui.container.GridLayout
        FilterAction_Grid2  matlab.ui.container.GridLayout
        objAction_del       matlab.ui.control.Image
        objAction_down      matlab.ui.control.Image
        objAction_up        matlab.ui.control.Image
        Tree                matlab.ui.container.Tree
        FilterAction_Grid1  matlab.ui.container.GridLayout
        FilterAction_add    matlab.ui.control.Image
        FilterTree1         matlab.ui.container.Tree
        levelFilter_thr     matlab.ui.container.TreeNode
        SUBITEMNode         matlab.ui.container.TreeNode
        PARGRAFONode        matlab.ui.container.TreeNode
        LISTANode           matlab.ui.container.TreeNode
        RODAPNode           matlab.ui.container.TreeNode
        IMAGEMNode          matlab.ui.container.TreeNode
        geoFilterNode       matlab.ui.container.TreeNode
        Tab2_Label1         matlab.ui.control.Label
        Tab2_Grid           matlab.ui.container.GridLayout
        Tab2_Image          matlab.ui.control.Image
        Tab2_Title          matlab.ui.control.Label
        Tab1_Panel          matlab.ui.container.GridLayout
        ModelInfo_Panel     matlab.ui.container.Panel
        ModelInfo_Grid      matlab.ui.container.GridLayout
        ModelInfo           matlab.ui.control.Label
        ModelRefresh        matlab.ui.control.Image
        Model               matlab.ui.control.DropDown
        ModelLabel          matlab.ui.control.Label
        Tab1_Grid           matlab.ui.container.GridLayout
        Tab1_Image          matlab.ui.control.Image
        Tab1_Title          matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        Template

        K1 = ["Item",      "ITEM";      ...
              "Subitem",   "SUBITEM";   ...
              "Paragraph", "PARÁGRAFO"; ...
              "Footnote",  "RODAPÉ";    ...
              "List",      "LISTA";     ...
              "Image",     "IMAGEM";    ...
              "Table",     "TABELA"]

        K2 = ["000", "000 - Não Aplicável"; ...
            "001", "001 - COLETIVO - SERVIÇO DE INTERESSE COLETIVO"; ...
            "002", "002 - RESTRITO - SERVIÇO DE INTERESSE RESTRITO"; ...
            "010", "010 - COLETIVO - SERVIÇO MOVEL PESSOAL"; ...
            "011", "011 - RESTRITO - LIMITADO PRIVADO - PRESTAÇÃO A TERCEIROS"; ...
            "012", "012 - RESTRITO - RADIOENLACES ASSOCIADOS AO SERVIÇO MÓVEL PRIVADO"; ...
            "013", "013 - COLETIVO - RADIOENLACES ASSOCIADOS AO SERVIÇO ESPECIALIZADO RADIOCHAMADA"; ...
            "014", "014 - RESTRITO - RADIOENLACES ASSOCIADOS AO SERVIÇO DE REDE ESPECIALIZADO"; ...
            "015", "015 - COLETIVO - RADIOENLACES ASSOCIADOS A SISTEMA DE SATÉLITES"; ...
            "016", "016 - COLETIVO - RADIOENLACES ASSOCIADOS AO SERVIÇO POR LINHA DEDICADA"; ...
            "017", "017 - RESTRITO - LIMITADO ESPECIALIZADO"; ...
            "019", "019 - RESTRITO - LIMITADO PRIVADO"; ...
            "020", "020 - COLETIVO - SERVIÇO MOVEL ESPECIALIZADO"; ...
            "021", "021 - RESTRITO - LIMITADO - FIBRAS ÓTICAS"; ...
            "023", "023 - RESTRITO - SERVIÇO LIMITADO MOVEL PRIVATIVO"; ...
            "024", "024 - COLETIVO - RADIOENLACES ASSOCIADOS AO SERVIÇO ESPECIALIZADO REPETIÇÃO SINAL ÁUDIO"; ...
            "025", "025 - COLETIVO - RADIOENLACES ASSOCIADOS AO SERVIÇO ESPECIALIZADO REPETIÇÃO TV/VÍDEO"; ...
            "026", "026 - RESTRITO - RADIOENLACES ASSOCIADOS AO SERVIÇO DE CIRCUITO ESPECIALIZADO"; ...
            "027", "027 - COLETIVO - RADIOENLACES ASSOCIADOS AO SERVIÇO MÓVEL ESPECIALIZADO"; ...
            "028", "028 - RESTRITO - LIMITADO PRIVADO ESTAÇÕES ITINERANTES"; ...
            "029", "029 - COLETIVO - RADIOENLACES ASSOCIADOS AO SERVIÇO LIMITADO ESPECIALIZADO"; ...
            "030", "030 - COLETIVO - SERVIÇO REDE DE TRANSPORTE TELECOMUNICAÇÕES - SRTT"; ...
            "031", "031 - COLETIVO - SERV. REDE DE TRANSPORTE TELECOM-SRTT - SATELITE"; ...
            "032", "032 - COLETIVO - RADIOENLACES ASSOCIADOS AO SERVIÇO REDE TRANSPORTE TELECOMUNICAÇÃO"; ...
            "033", "033 - COLETIVO - RADIOENLACES ASSOCIADOS AO SERVIÇO DE RÁDIOTAXI"; ...
            "034", "034 - RESTRITO - SERVIÇO SLMP PRESTADO A DETERMINADOS GRUPOS DE USUARIOS"; ...
            "035", "035 - RESTRITO - ESPC. P/FINS CIENTIF.EXPERIMENTAIS"; ...
            "036", "036 - RESTRITO - SERVIÇO DE MONITORAGEM DO ESPECTRO"; ...
            "037", "037 - RESTRITO - SERVIÇO USO TEMPORÁRIO DO ESPECTRO"; ...
            "038", "038 - COLETIVO - SERVIÇO ESPECIAL DE REPETIÇÃO DE SINAIS DE TV E VÍDEO"; ...
            "039", "039 - COLETIVO - SERVIÇO ESPECIAL DE REPETIÇÃO DE SINAIS DE ÁUDIO"; ...
            "040", "040 - COLETIVO - SERVIÇO POR LINHA DEDICADA (SLD)"; ...
            "041", "041 - COLETIVO - SERVIÇO DE REDE COMUTADA POR PACOTE"; ...
            "042", "042 - COLETIVO - SERVIÇO DE REDE COMUTADA POR CIRCUITO"; ...
            "043", "043 - COLETIVO - ESPECIAL DE RADIORRECADO"; ...
            "044", "044 - RESTRITO - ENLACES FÍSICOS ASSOCIADOS AO SERVIÇO LIMITADO PRIVADO"; ...
            "045", "045 - COLETIVO - SERVIÇO DE COMUNICAÇÃO MULTIMÍDIA"; ...
            "046", "046 - COLETIVO - RADIOENLACES ASSOCIADOS AO SCM"; ...
            "047", "047 - COLETIVO - SERVIÇO DE COMUNICAÇÃO MULTIMÍDIA - EST. TERRENA"; ...
            "048", "048 - RESTRITO - RÁDIO-ACESSO"; ...
            "049", "049 - RESTRITO - SERVIÇO LIMITADO PRIVADO PARA PRESTAÇÃO DE SERVIÇO PELAS PREFEITURAS MUNICIPAIS"; ...
            "050", "050 - RESTRITO - SERVIÇO LIMITADO PRIVADO PARA INCLUSÃO DIGITAL NAS FAIXAS DE 2,5GHZ E 3,5GHZ"; ...
            "051", "051 - COLETIVO - SERVIÇO ESPECIAL DE RADIOCHAMADA"; ...
            "053", "053 - COLETIVO - RADIOENLACES ASSOCIADOS AO SMP"; ...
            "060", "060 - RESTRITO - SERVIÇO LIMITADO PRIVADO DE RADIOCHAMADA-SLPR"; ...
            "064", "064 - COLETIVO - MÓVEL MARÍTIMO ESPECIALIZADO"; ...
            "067", "067 - COLETIVO - SERVIÇO AVANÇADO DE MENSAGEM (SAM)"; ...
            "069", "069 - RESTRITO - SERVIÇO DE CIRCUITO ESPECIALIZADO"; ...
            "076", "076 - RESTRITO - SERVIÇO DE REDE PRIVADO"; ...
            "077", "077 - RESTRITO - SERVIÇO DE REDE ESPECIALIZADO"; ...
            "078", "078 - RESTRITO - SERVIÇO DE RADIOTÁXI PRIVADO"; ...
            "079", "079 - COLETIVO - SERVIÇO DE RADIOTÁXI ESPECIALIZADO"; ...
            "086", "086 - COLETIVO - ESPECIAL DE FREQÜÊNCIA PADRÃO"; ...
            "094", "094 - COLETIVO - SERVIÇO ESPECIAL DE SINAIS HORÁRIOS"; ...
            "099", "099 - RESTRITO - SERVIÇO DE RADIAÇÃO RESTRITA"; ...
            "108", "108 - RESTRITO - SERVIÇO LIMITADO PRIVADO SUBMODALIDADE RADIODETERMINAÇÃO"; ...
            "116", "116 - COLETIVO - TEL.PUBLICO MOVEL RODOVIARIO-TELESTRADA"; ...
            "124", "124 - RESTRITO - ESPECIAL DE SUPERVISÃO E CONTROLE/USO PRÓPRIO"; ...
            "125", "125 - RESTRITO - ESPECIAL DE SUPERVISÃO E CONTROLE/TERCEIROS"; ...
            "132", "132 - RESTRITO - ESPECIAL DE RADIOAUTOCINE"; ...
            "140", "140 - RESTRITO - LIMITADO - RADIOESTRADA"; ...
            "159", "159 - COLETIVO - RADIOCOMUNICAÇÃO AERONÁUTICA PÚBLICO - RESTRITO"; ...
            "163", "163 - COLETIVO - SERVIÇO DE COMUNICAÇÃO DE TEXTOS"; ...
            "167", "167 - COLETIVO - ESPECIAL DE TELEVISAO POR ASSINATURA"; ...
            "171", "171 - COLETIVO - SERVICO TELEFONICO FIXO COMUTADO"; ...
            "175", "175 - COLETIVO - STFC/RADIOTELEFONICO - ESTACOES TERRESTRES"; ...
            "176", "176 - COLETIVO - STFC/RADIOTELEFONICO - ESTACOES TERRENAS"; ...
            "181", "181 - RESTRITO - LIMITADO PRIVADO POR SATELITE"; ...
            "182", "182 - RESTRITO - LIMITADO ESPECIALIZADO POR SATELITE"; ...
            "183", "183 - COLETIVO - ESPECIAL DE BOLETINS METEOROLOGICOS"; ...
            "185", "185 - RESTRITO - EXPLORACAO DE SATELITE E ESTACOES DE ACESSO"; ...
            "186", "186 - RESTRITO - EXPLORAÇÃO DE SATÉLITE NÃO-GEOESTACIONÁRIO E ESTAÇÃO DE ACESSO"; ...
            "187", "187 - RESTRITO - SERVICO DE REDE ESPECIALIZADO P/SATELITE"; ...
            "188", "188 - RESTRITO - SERVICO DE CIRCUITO ESPECIALIZADO P/SATELITE"; ...
            "189", "189 - COLETIVO - SERVICO MOVEL GLOBAL POR SATELITE(SMGS)"; ...
            "191", "191 - COLETIVO - SERVIÇO DE COMUNICAÇÃO DE DADOS COMUTADO"; ...
            "205", "205 - COLETIVO - RADIODIFUSÃO SONORA EM ONDA MÉDIA"; ...
            "213", "213 - COLETIVO - RADIODIFUSÃO SONORA EM ONDAS CURTAS"; ...
            "221", "221 - COLETIVO - RADIODIFUSÃO SONORA EM ONDA TROPICAL"; ...
            "230", "230 - COLETIVO - RADIODIFUSÃO SONORA EM FREQÜÊNCIA MODULADA"; ...
            "231", "231 - COLETIVO - RADIODIFUSÃO COMUNITÁRIA"; ...
            "247", "247 - COLETIVO - GERADORA DE RADIODIFUSÃO DE SONS E IMAGENS - DIGITAL"; ...
            "248", "248 - COLETIVO - RADIODIFUSÃO DE SONS E IMAGENS"; ...
            "251", "251 - RESTRITO - AUXILIAR RADIODIF.- TRANSMISS. DE PROGRAMAS"; ...
            "252", "252 - RESTRITO - AUXILIAR RADIODIF.- REPORTAGEM EXTERNA"; ...
            "253", "253 - RESTRITO - AUXILIAR RADIODIF.- COM. DE ORDENS INTERNAS"; ...
            "254", "254 - RESTRITO - AUX. DE RADIODIFUSAO - TELECOMANDO"; ...
            "255", "255 - RESTRITO - AUX. DE RADIODIFUSAO - TELEMEDICAO"; ...
            "256", "256 - RESTRITO - AUXILIAR DE RADIODIFUSAO E CORRELATOS"; ...
            "264", "264 - RESTRITO - ESPECIAL CANAL SECUNDARIO DE SONS E IMAGENS"; ...
            "302", "302 - RESTRITO - RADIOAMADOR"; ...
            "400", "400 - RESTRITO - RÁDIO DO CIDADÃO"; ...
            "450", "450 - COLETIVO - COMUNICAÇÃO MULTIMÍDIA - DISPENSA DE AUTORIZAÇÃO"; ...
            "507", "507 - RESTRITO - MÓVEL AERONÁUTICO"; ...
            "604", "604 - RESTRITO - MÓVEL MARÍTIMO"; ...
            "701", "701 - RESTRITO - ESPECIAL DE MUSICA FUNCIONAL"; ...
            "710", "710 - RESTRITO - ESPECIAL EM CANAL SECUNDARIO DE FM"; ...
            "728", "728 - RESTRITO - ESPECIAL DE REPETICAO DE TELEVISAO"; ...
            "729", "729 - COLETIVO - TV A CABO"; ...
            "730", "730 - COLETIVO - ESPECIAL DE REPETICAO DE TV - SATELITE"; ...
            "735", "735 - COLETIVO - DISTRIB. SINAIS TV/AUDIO P/ASSINATURA VIA SATELITE"; ...
            "740", "740 - COLETIVO - ESPEC.DISTRIB.SINAIS MULTIPONTO/MULTICANAL"; ...
            "750", "750 - COLETIVO - SERVIÇO DE ACESSO CONDICIONADO"; ...
            "800", "800 - COLETIVO - RETRANSMISSAO DE T.V."; ...
            "801", "801 - COLETIVO - RETRANSMISSÃO DE RADIODIFUSÃO DE SONS E IMAGENS - DIGITAL"; ...
            "802", "802 - COLETIVO - PLANO BÁSICO DE RADIODIFUSÃO DE SONS E IMAGENS - DIGITAL"; ...
            "810", "810 - COLETIVO - DISTRIBUICAO DE SINAIS DE TELEVISAO - DISTV"; ...
            "820", "820 - RESTRITO - DE TELEV. EM CIRCUITO FECHADO (RADIOENLACE)"; ...
            "900", "900 - COLETIVO - FUST"; ...
            "901", "901 - COLETIVO - FUNTTEL"]
        
    end
    
    methods (Access = private)

        function Fcn_ReadModels(app)

            app.Model.Items = app.CallingApp.Report_Type.Items;

        end


        function Fcn_ReadTemplate(app)

            idx = find(strcmp(app.Model.Items, app.Model.Value), 1);

            serviceList = '';
            try
                serviceList = strjoin(app.K2(cellfun(@(x) find(app.K2(:,1) == x, 1), app.CallingApp.General.Models.RelatedServices{idx}),2), '\n');
            catch
            end

            app.Template        = jsondecode(fileread(fullfile(app.RootFolder, 'Template', app.CallingApp.General.Models.Template{idx})));
            app.ModelInfo.Text = {sprintf('<b style="color: #000000; font-size: 12;">%s</b>', app.CallingApp.General.Models.Name{idx});    ...
                                   sprintf('<font> v. %s</font>',                              app.CallingApp.General.Models.Version{idx}); ...
                                   sprintf('%s\n',                                             app.CallingApp.General.Models.Release{idx}); ...
                                   sprintf('<b style="color: #000000;">Autor:    </b>\n%s\n', app.CallingApp.General.Models.Author{idx}); ...
                                   sprintf('<b style="color: #000000;">Descrição:</b>\n%s\n', app.CallingApp.General.Models.Description{idx}); ...
                                   sprintf('<b style="color: #000000;">Serviços: </b>\n%s',   serviceList)};

            Fcn_TreeBuilding(app)

        end


        function Fcn_TreeBuilding(app)

            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end
         
            for ii = 1:numel(app.Template)                
                Parent = uitreenode(app.Tree, 'Text', 'ITEM', 'NodeData', ii);

                for jj = 1:numel(app.Template(ii).Data.Children)
                    idx = find(app.K1(:,1) == app.Template(ii).Data.Children(jj).Type, 1);
                    uitreenode(Parent, 'Text', app.K1(idx,2), 'NodeData', jj);
                end
            end
            expand(app.Tree, 'all')

            app.Tree.SelectedNodes = app.Tree.Children(1);
            TreeSelectionChanged(app)
            
        end


        function Fcn_Layout_UpDown(app)

            Parent = app.Tree.SelectedNodes.Parent;
            
            NN   = numel(Parent.Children);
            idx1 = find([Parent.Children.NodeData] == Parent.Children(1).NodeData);
            idx2 = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);

            if NN == 1
                app.objAction_up.Enable   = 0;
                app.objAction_down.Enable = 0;

            elseif idx2 == idx1
                app.objAction_up.Enable   = 0;
                app.objAction_down.Enable = 1;

            elseif idx2 - idx1 + 1 == NN
                app.objAction_up.Enable   = 1;
                app.objAction_down.Enable = 0;

            else
                app.objAction_up.Enable   = 1;
                app.objAction_down.Enable = 1;
            end

        end
        

        function Fcn_NodeData(app)

            for ii = 1:numel(app.Tree.Children)
                set(app.Tree.Children(ii), 'Text', 'ITEM', 'NodeData', ii)

                for jj = 1:numel(app.Tree.Children(ii).Children)
                    app.Tree.Children(ii).Children(jj).NodeData = jj;
                end
            end

            TreeSelectionChanged(app)

        end

        
        function Fcn_updateValues(app)

            try
                if app.Tree.SelectedNodes.Parent == app.Tree
                    idx1 = app.Tree.SelectedNodes.NodeData;
                    app.AuxiliarText.Value = jsonencode(app.Template(idx1), 'PrettyPrint', true);
    
                else
                    idx1 = app.Tree.SelectedNodes.Parent.NodeData;
                    idx2 = app.Tree.SelectedNodes.NodeData;

                    app.AuxiliarText.Value = jsonencode(app.Template(idx1).Data.Children(idx2), 'PrettyPrint', true);
                end

            catch ME
                MessageBox(app, 'error', getReport(ME))
                
                app.Tree.SelectedNodes = [];
                app.AuxiliarText.Value = '';
            end

        end


        function Fcn_reportPreview(app)

            try                
                CheckedRows = find([app.CallingApp.specData.reportFlag]);
                
                idx = Misc_FindOCCData(app.CallingApp.specData(CheckedRows));                
                ProjectRows = sort([CheckedRows, idx]);
                
                % OCC Map (Step 1 of 2)
                OCCLength = numel(idx);
                if OCCLength
                    OCCMap = zeros(OCCLength, 2);
                    
                    jj = 0;
                    for ii = 1:numel(ProjectRows)
                        if ~isempty(app.CallingApp.specData(ProjectRows(ii)).reportOCC.Index)
                            jj=jj+1;
                            OCCMap(jj,:) = [ProjectRows(ii), app.CallingApp.specData(ProjectRows(ii)).reportOCC.Index];
                            app.CallingApp.specData(ProjectRows(ii)).reportOCC.Index = find(ProjectRows == OCCMap(jj,2));
                        end
                    end
                end

                Date1 = app.CallingApp.Report_DatePicker1.Value + hours(app.CallingApp.Report_Spinner1.Value) + minutes(app.CallingApp.Report_Spinner2.Value);
                Date2 = app.CallingApp.Report_DatePicker2.Value + hours(app.CallingApp.Report_Spinner3.Value) + minutes(app.CallingApp.Report_Spinner4.Value);
                
                if Date1 > Date2
                    error('Os valores inseridos para aplicação do filtro de TimeStamp devem ser ajustados!')
                end
    
                idx = find(strcmp(app.Model.Items, app.Model.Value), 1);

                reportInfo = struct('appVersion', app.CallingApp.General.ver,    ...
                                    'Issue',      -1,                            ...
                                    'General',    struct('Mode',       "Report", ...
                                                         'Version',    app.CallingApp.Report_Version.Value, ...
                                                         'Image',      struct('Visibility', 'off', 'Format', 'jpeg', 'Resolution', 120), ...
                                                         'RootFolder', app.RootFolder,            ...
                                                         'UserPath',   app.CallingApp.menu_userPath.Value),  ...
                                    'Model',      struct('Name',       app.Model.Value,     ...
                                                         'Type',       app.CallingApp.General.Models(idx,:), ...
                                                         'Template',   jsonencode(app.Template)), ...
                                    'Attachments', app.CallingApp.projData,  ...
                                    'TimeStamp',  [Date1, Date2], ...
                                    'Filename',   '');

                htmlReport = ReportGenerator(app.CallingApp.specData(ProjectRows), reportInfo, []);

                % OCC Map (Step 2 of 2)
                if OCCLength
                    for jj = 1:height(OCCMap)
                        app.CallingApp.specData(OCCMap(jj,1)).reportOCC.Index = OCCMap(jj,2);
                    end
                end

                app.HTML.HTMLSource = htmlReport;

            catch ME
                MessageBox(app, 'error', getReport(ME))
            end

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.UIFigure.Position(4) = 660;
            PanelTab_SelectionChanged(app, struct('Source', app.Tab1_Image))

            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Interpreter", "html", "Message", "Em andamento...");

            Fcn_ReadModels(app)
            Fcn_ReadTemplate(app)

            delete(d)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin7 = [];
            delete(app)
            
        end

        % Image clicked function: Tab1_Image, Tab2_Image
        function PanelTab_SelectionChanged(app, event)
            
            switch event.Source
                case app.Tab1_Image; app.mainPanel_Grid.RowHeight([2,4]) = {'1x',0};
                case app.Tab2_Image; app.mainPanel_Grid.RowHeight([2,4]) = {0,'1x'};
            end

        end

        % Image clicked function: imgShowHide
        function PanelVisibility_ImageClicked(app, event)
            
            if app.mainGrid.ColumnWidth{1} == 300
                app.imgShowHide.ImageSource = 'LT_HideRight.png';
                app.mainGrid.ColumnWidth{1} = 0;
            else
                app.imgShowHide.ImageSource = 'LT_HideLeft.png';
                app.mainGrid.ColumnWidth{1} = 300;
            end

        end

        % Image clicked function: ModelRefresh
        function ModelRefreshPushed(app, event)
            
            app.CallingApp.General.Models    = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Template', 'html_General.cfg'))));
            app.CallingApp.Report_Type.Items = app.CallingApp.General.Models.Name;

            Fcn_ReadModels(app)
            Fcn_ReadTemplate(app)

        end

        % Value changed function: Model
        function ModelValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Interpreter", "html", "Message", "Em andamento...");
            Fcn_ReadTemplate(app)
            delete(d)

            if ~isempty(app.HTML.HTMLSource)
                Button_previewPushed(app)
            end            
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            if ~isempty(app.Tree.SelectedNodes)
                Fcn_updateValues(app)
            end

        end

        % Image clicked function: objAction_del, objAction_down, 
        % objAction_up
        function Operation_ImageClicked(app, event)
            
            Parent   = app.Tree.SelectedNodes.Parent;
            Selected = app.Tree.SelectedNodes;

            try                
                switch event.Source
                    case app.objAction_down
                        if Parent == app.Tree
                            idx2 = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);
                            
                            Parent.Children(idx2:idx2+1) = flip(Parent.Children(idx2:idx2+1));
                            app.Template(idx2:idx2+1)    = flip(app.Template(idx2:idx2+1));
                        else
                            idx1 = Parent.NodeData;
                            idx2 = Selected.NodeData;

                            Parent.Children(idx2:idx2+1)                  = flip(Parent.Children(idx2:idx2+1));
                            app.Template(idx1).Data.Children(idx2:idx2+1) = flip(app.Template(idx1).Data.Children(idx2:idx2+1));
                        end
                        app.Tree.SelectedNodes = Parent.Children(idx2+1);
    
                    case app.objAction_up
                        if Parent == app.Tree
                            idx2 = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);
                            
                            Parent.Children(idx2-1:idx2) = flip(Parent.Children(idx2-1:idx2));
                            app.Template(idx2-1:idx2)    = flip(app.Template(idx2-1:idx2));
                        else
                            idx1 = Parent.NodeData;
                            idx2 = Selected.NodeData;

                            Parent.Children(idx2-1:idx2)                  = flip(Parent.Children(idx2-1:idx2));
                            app.Template(idx1).Data.Children(idx2-1:idx2) = flip(app.Template(idx1).Data.Children(idx2-1:idx2));
                        end
                        app.Tree.SelectedNodes = Parent.Children(idx2-1);

                    case app.objAction_del
                        if Parent == app.Tree
                            idx = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);

                            app.Template(idx) = [];
                        else
                            idx1 = Parent.NodeData;
                            idx2 = Selected.NodeData;

                            app.Template(idx1).Data.Children(idx2) = [];
                        end
                        delete(Selected)
                        app.Tree.SelectedNodes = app.Tree.Children(1);
                end
    
                Fcn_NodeData(app)
            catch
            end
            
        end

        % Value changed function: AuxiliarText
        function AuxiliarText_ValueChanged(app, event)
            
            try
                newValue = jsondecode(strjoin(event.Value, '\n'));
                
                if app.Tree.SelectedNodes.Parent == app.Tree
                    idx = app.Tree.SelectedNodes.NodeData;
                    app.Template(idx) = newValue;
                else
                    idx1 = app.Tree.SelectedNodes.Parent.NodeData;
                    idx2 = app.Tree.SelectedNodes.NodeData;
                    app.Template(idx1).Data.Children(idx2) = newValue;
                end

            catch ME
                MessageBox(app, 'error', getReport(ME))
                TreeSelectionChanged(app)
            end
            
        end

        % Image clicked function: FontSize_minus, FontSize_plus
        function FontSize_ImageClicked(app, event)
            
            try
                switch event.Source
                    case app.FontSize_minus; app.AuxiliarText.FontSize = app.AuxiliarText.FontSize-1;
                    case app.FontSize_plus;  app.AuxiliarText.FontSize = app.AuxiliarText.FontSize+1;
                end
            catch
            end

        end

        % Button pushed function: Button_open
        function Button_openImageClicked(app, event)
            
        end

        % Button pushed function: Button_save
        function Button_saveImageClicked(app, event)
            
        end

        % Value changed function: Button_edit
        function Button_edit_editValueChanged(app, event)
            
            if app.Button_edit.Value
                app.ModeLabel.Text = 'MODO DE EDIÇÃO  ';

                set(app.FilterTree1, 'Enable', 'on')
                set(app.FilterAction_Grid2.Children, 'Enable', 'on')
                set(app.AuxiliarText, 'Editable', 'on', 'FontColor', [.15,.15,.15])
                set(app.GridLayout16.Children, 'Enable', 'off')
                app.Button_edit.Enable = 'on';
            else
                app.ModeLabel.Text = 'MODO DE VISUALIZAÇÃO  ';

                set(app.FilterTree1, 'Enable', 'off', 'SelectedNodes', [])
                app.FilterAction_add.Enable = 'off';
                set(app.FilterAction_Grid2.Children, 'Enable', 'off')
                set(app.AuxiliarText, 'Editable', 'off', 'FontColor', [.50,.50,.50])
                set(app.GridLayout16.Children, 'Enable', 'on')
                app.Separator.Enable = 'off';
            end

        end

        % Button pushed function: Button_preview
        function Button_previewPushed(app, event)
            
            if ~isempty(app.CallingApp.Report_Tree.Children)
                d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento criação do modelo do relatório...");
                Fcn_reportPreview(app)
    
                delete(d)
            else
                MessageBox(app, 'warning', 'Visualização do modelo do relatório depende de inclusão de ao menos um fluxo de dados a processar no modo "RELATÓRIO".')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Modelos de Relatórios';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.mainGrid = uigridlayout(app.UIFigure);
            app.mainPanel = uipanel(app.mainGrid);
            app.mainPanel_Grid = uigridlayout(app.mainPanel);
            app.Tab1_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab1_Grid.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_Grid);
            app.Tab1_Title.Text = 'ASPECTOS GERAIS';
            app.Tab1_Image = uiimage(app.Tab1_Grid);
            app.Tab1_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab1_Panel = uigridlayout(app.mainPanel_Grid);
            app.ModelLabel = uilabel(app.Tab1_Panel);
            app.ModelLabel.Text = 'Modelo:';
            app.Model = uidropdown(app.Tab1_Panel);
            app.Model.Items = {'PMEC 2022', 'PMRD 2022', 'PMEF 2022', 'Rádio pirata', 'Drive-test'};
            app.Model.ValueChangedFcn = createCallbackFcn(app, @ModelValueChanged, true);
            app.Model.Value = 'PMEC 2022';
            app.ModelRefresh = uiimage(app.Tab1_Panel);
            app.ModelRefresh.ImageClickedFcn = createCallbackFcn(app, @ModelRefreshPushed, true);
            app.ModelRefresh.ImageSource = 'D:\MATLAB\appAnalise\Icons\LT_refresh.png';
            app.ModelInfo_Panel = uipanel(app.Tab1_Panel);
            app.ModelInfo_Grid = uigridlayout(app.ModelInfo_Panel);
            app.ModelInfo = uilabel(app.ModelInfo_Grid);
            app.ModelInfo.Interpreter = 'html';
            app.ModelInfo.Text = '';
            app.Tab2_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab2_Grid.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_Grid);
            app.Tab2_Title.Text = 'ELEMENTOS';
            app.Tab2_Image = uiimage(app.Tab2_Grid);
            app.Tab2_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab2_Image.ImageSource = 'D:\MATLAB\appAnalise\Icons\LT_Layers.png';
            app.Tab2_Panel = uigridlayout(app.mainPanel_Grid);
            app.Tab2_Label1 = uilabel(app.Tab2_Panel);
            app.Tab2_Label1.Text = 'Estrutura:';
            app.Tab2_Grid1 = uigridlayout(app.Tab2_Panel);
            app.FilterTree1 = uitree(app.Tab2_Grid1);
            app.FilterTree1.Enable = 'off';
            app.levelFilter_thr = uitreenode(app.FilterTree1);
            app.levelFilter_thr.NodeData = 1;
            app.levelFilter_thr.Icon = 'D:\MATLAB\appAnalise\Icons\LT_text.png';
            app.levelFilter_thr.Text = 'ITEM';
            app.SUBITEMNode = uitreenode(app.FilterTree1);
            app.SUBITEMNode.NodeData = 2;
            app.SUBITEMNode.Icon = 'D:\MATLAB\appAnalise\Icons\LT_text.png';
            app.SUBITEMNode.Text = 'SUBITEM';
            app.PARGRAFONode = uitreenode(app.FilterTree1);
            app.PARGRAFONode.NodeData = 3;
            app.PARGRAFONode.Icon = 'D:\MATLAB\appAnalise\Icons\LT_text.png';
            app.PARGRAFONode.Text = 'PARÁGRAFO';
            app.LISTANode = uitreenode(app.FilterTree1);
            app.LISTANode.NodeData = 4;
            app.LISTANode.Icon = 'D:\MATLAB\appAnalise\Icons\LT_text.png';
            app.LISTANode.Text = 'LISTA';
            app.RODAPNode = uitreenode(app.FilterTree1);
            app.RODAPNode.NodeData = 5;
            app.RODAPNode.Icon = 'D:\MATLAB\appAnalise\Icons\LT_text.png';
            app.RODAPNode.Text = 'RODAPÉ';
            app.IMAGEMNode = uitreenode(app.FilterTree1);
            app.IMAGEMNode.NodeData = 6;
            app.IMAGEMNode.Icon = 'D:\MATLAB\appAnalise\Icons\LT_photo.png';
            app.IMAGEMNode.Text = 'IMAGEM';
            app.geoFilterNode = uitreenode(app.FilterTree1);
            app.geoFilterNode.NodeData = 7;
            app.geoFilterNode.Icon = 'D:\MATLAB\appAnalise\Icons\LT_Sheet.png';
            app.geoFilterNode.Text = 'TABELA';
            app.FilterAction_Grid1 = uigridlayout(app.Tab2_Grid1);
            app.FilterAction_add = uiimage(app.FilterAction_Grid1);
            app.FilterAction_add.Enable = 'off';
            app.FilterAction_add.ImageSource = 'LT_play.png';
            app.Tree = uitree(app.Tab2_Grid1);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.FilterAction_Grid2 = uigridlayout(app.Tab2_Grid1);
            app.objAction_up = uiimage(app.FilterAction_Grid2);
            app.objAction_up.ImageClickedFcn = createCallbackFcn(app, @Operation_ImageClicked, true);
            app.objAction_up.Enable = 'off';
            app.objAction_up.ImageSource = 'LT_upArrow_Zoom.png';
            app.objAction_down = uiimage(app.FilterAction_Grid2);
            app.objAction_down.ImageClickedFcn = createCallbackFcn(app, @Operation_ImageClicked, true);
            app.objAction_down.Enable = 'off';
            app.objAction_down.ImageSource = 'LT_downArrow_Zoom.png';
            app.objAction_del = uiimage(app.FilterAction_Grid2);
            app.objAction_del.ImageClickedFcn = createCallbackFcn(app, @Operation_ImageClicked, true);
            app.objAction_del.Enable = 'off';
            app.objAction_del.ImageSource = 'LT_redX.png';
            app.Tab2_Label2 = uilabel(app.Tab2_Panel);
            app.Tab2_Label2.Text = 'Elemento selecionado:';
            app.GridLayout15 = uigridlayout(app.Tab2_Panel);
            app.AuxiliarText = uitextarea(app.GridLayout15);
            app.AuxiliarText.ValueChangedFcn = createCallbackFcn(app, @AuxiliarText_ValueChanged, true);
            app.AuxiliarText.Editable = 'off';
            app.FontSize_Grid = uigridlayout(app.GridLayout15);
            app.FontSize_minus = uiimage(app.FontSize_Grid);
            app.FontSize_minus.ImageClickedFcn = createCallbackFcn(app, @FontSize_ImageClicked, true);
            app.FontSize_minus.ImageSource = 'D:\MATLAB\appAnalise\Icons\Font_SizeMinus.png';
            app.FontSize_plus = uiimage(app.FontSize_Grid);
            app.FontSize_plus.ImageClickedFcn = createCallbackFcn(app, @FontSize_ImageClicked, true);
            app.FontSize_plus.ImageSource = 'D:\MATLAB\appAnalise\Icons\Font_SizePlus.png';
            app.GridLayout16 = uigridlayout(app.Tab2_Panel);
            app.Button_preview = uibutton(app.GridLayout16, 'push');
            app.Button_preview.ButtonPushedFcn = createCallbackFcn(app, @Button_previewPushed, true);
            app.Button_preview.Icon = 'DT_zoom.png';
            app.Button_preview.Enable = 'off';
            app.Button_preview.Text = 'Pré-visualização';
            app.Button_edit = uibutton(app.GridLayout16, 'state');
            app.Button_edit.ValueChangedFcn = createCallbackFcn(app, @Button_edit_editValueChanged, true);
            app.Button_edit.Icon = 'LT_edit.png';
            app.Button_edit.Text = '';
            app.Button_open = uibutton(app.GridLayout16, 'push');
            app.Button_open.ButtonPushedFcn = createCallbackFcn(app, @Button_openImageClicked, true);
            app.Button_open.Icon = 'LT_OpenFile.png';
            app.Button_open.Text = '';
            app.Button_save = uibutton(app.GridLayout16, 'push');
            app.Button_save.ButtonPushedFcn = createCallbackFcn(app, @Button_saveImageClicked, true);
            app.Button_save.Icon = 'LT_save.png';
            app.Button_save.Text = '';
            app.Separator = uiimage(app.GridLayout16);
            app.Separator.Enable = 'off';
            app.Separator.ImageSource = 'LT_LineV.png';
            app.imgShowHide = uiimage(app.mainGrid);
            app.imgShowHide.ImageClickedFcn = createCallbackFcn(app, @PanelVisibility_ImageClicked, true);
            app.imgShowHide.ImageSource = 'LT_HideLeft.png';
            app.HTML_Grid = uigridlayout(app.mainGrid);
            app.HTML = uihtml(app.HTML_Grid);
            app.ModeLabel = uilabel(app.HTML_Grid);
            app.ModeLabel.Text = 'MODO DE VISUALIZAÇÃO  ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTemplate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end